classdef ChooseAxLimits < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ZLimMax                matlab.ui.control.NumericEditField
        ZLimMin                matlab.ui.control.NumericEditField
        ZLimitsLabel           matlab.ui.control.Label
        CancelButton           matlab.ui.control.Button
        FinishedButton         matlab.ui.control.Button
        ChooseAxesLimitsLabel  matlab.ui.control.Label
        MaximumLabel           matlab.ui.control.Label
        MinimumLabel           matlab.ui.control.Label
        YLimMax                matlab.ui.control.NumericEditField
        XLimMax                matlab.ui.control.NumericEditField
        YLimMin                matlab.ui.control.NumericEditField
        YLimitsEditFieldLabel  matlab.ui.control.Label
        XLimMin                matlab.ui.control.NumericEditField
        XLimitsEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            movegui(app.UIFigure, 'center') 
            app.CallingApp = caller;
            app.CallingApp.XLimMin = NaN;
            app.CallingApp.XLimMax = NaN;
            app.CallingApp.YLimMin = NaN;
            app.CallingApp.YLimMax = NaN;
            app.CallingApp.ZLimMin = NaN;
            app.CallingApp.ZLimMax = NaN;
        end

        % Value changed function: XLimMin
        function XLimMinValueChanged(app, event)
            value = app.XLimMin.Value;
            app.CallingApp.XLimMin = value;
        end

        % Value changed function: XLimMax
        function XLimMaxValueChanged(app, event)
            value = app.XLimMax.Value;
            app.CallingApp.XLimMax = value;
        end

        % Value changed function: YLimMin
        function YLimMinValueChanged(app, event)
            value = app.YLimMin.Value;
            app.CallingApp.YLimMin = value;
        end

        % Value changed function: YLimMax
        function YLimMaxValueChanged(app, event)
            value = app.YLimMax.Value;
            app.CallingApp.YLimMax = value;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.XLimMin = [];
            app.CallingApp.XLimMax = [];
            app.CallingApp.YLimMin = [];
            app.CallingApp.YLimMax = [];
            app.CallingApp.ZLimMin = [];
            app.CallingApp.ZLimMax = [];
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.XLimMin = [];
            app.CallingApp.XLimMax = [];
            app.CallingApp.YLimMin = [];
            app.CallingApp.YLimMax = [];
            app.CallingApp.ZLimMin = [];
            app.CallingApp.ZLimMax = [];
            delete(app)
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: ZLimMin
        function ZLimMinValueChanged(app, event)
            value = app.ZLimMin.Value;
            app.CallingApp.ZLimMin = value;
        end

        % Value changed function: ZLimMax
        function ZLimMaxValueChanged(app, event)
            value = app.ZLimMax.Value;
            app.CallingApp.ZLimMax = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.XLimitsEditFieldLabel = uilabel(app.UIFigure);
            app.XLimitsEditFieldLabel.Text = 'X Limits';
            app.XLimMin = uieditfield(app.UIFigure, 'numeric');
            app.XLimMin.ValueChangedFcn = createCallbackFcn(app, @XLimMinValueChanged, true);
            app.XLimMin.Value = Inf;
            app.YLimitsEditFieldLabel = uilabel(app.UIFigure);
            app.YLimitsEditFieldLabel.Text = 'Y Limits';
            app.YLimMin = uieditfield(app.UIFigure, 'numeric');
            app.YLimMin.ValueChangedFcn = createCallbackFcn(app, @YLimMinValueChanged, true);
            app.YLimMin.Value = Inf;
            app.XLimMax = uieditfield(app.UIFigure, 'numeric');
            app.XLimMax.ValueChangedFcn = createCallbackFcn(app, @XLimMaxValueChanged, true);
            app.XLimMax.Value = Inf;
            app.YLimMax = uieditfield(app.UIFigure, 'numeric');
            app.YLimMax.ValueChangedFcn = createCallbackFcn(app, @YLimMaxValueChanged, true);
            app.YLimMax.Value = Inf;
            app.MinimumLabel = uilabel(app.UIFigure);
            app.MinimumLabel.Text = 'Minimum';
            app.MaximumLabel = uilabel(app.UIFigure);
            app.MaximumLabel.Text = 'Maximum';
            app.ChooseAxesLimitsLabel = uilabel(app.UIFigure);
            app.ChooseAxesLimitsLabel.Text = 'Choose Axes Limits';
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ZLimitsLabel = uilabel(app.UIFigure);
            app.ZLimitsLabel.Text = 'Z Limits';
            app.ZLimMin = uieditfield(app.UIFigure, 'numeric');
            app.ZLimMin.ValueChangedFcn = createCallbackFcn(app, @ZLimMinValueChanged, true);
            app.ZLimMin.Value = Inf;
            app.ZLimMax = uieditfield(app.UIFigure, 'numeric');
            app.ZLimMax.ValueChangedFcn = createCallbackFcn(app, @ZLimMaxValueChanged, true);
            app.ZLimMax.Value = Inf;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChooseAxLimits(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end