classdef EnterElectrodeLocations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CancelButton               matlab.ui.control.Button
        FinishedButton             matlab.ui.control.Button
        DistanceFromCristammLabel  matlab.ui.control.Label
        ElectrodeNumberLabel       matlab.ui.control.Label
        ElectrodeLocationsLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
        ElectrodeDets
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, an2Use)
            app.CallingApp = caller;
            movegui(app.UIFigure, 'center')
            app.ElectrodeLocationsLabel.Text = {app.ElectrodeLocationsLabel.Text;an2Use{1}};
            availSpace = app.ElectrodeNumberLabel.Position(2)-(app.CancelButton.Position(2)+app.CancelButton.Position(4));
            es = [cell2mat(app.CallingApp.AnimalProperties.(an2Use{1}).elecByCanal)];
            es = es(es~=10);
            inbtw = availSpace/length(es);
            sz = inbtw*(2/3);
            gap = (availSpace-sz*length(es))/(length(es)+1);
            app.ElectrodeDets.ElectrodeTxt = [];
            app.ElectrodeDets.ElectrodeDist = [];
            for i = 1:length(es)
                app.ElectrodeDets.ElectrodeTxt = [app.ElectrodeDets.ElectrodeTxt uilabel(app.UIFigure)];
                app.ElectrodeDets.ElectrodeTxt(i).HorizontalAlignment = 'center';
                app.ElectrodeDets.ElectrodeTxt(i).FontSize = 14;
                app.ElectrodeDets.ElectrodeTxt(i).FontWeight = 'bold';
                app.ElectrodeDets.ElectrodeTxt(i).Position = [1 app.ElectrodeNumberLabel.Position(2)-((inbtw*i)-gap) 270 sz];
                [~,name,~,~] = app.CallingApp.GetElecColorandNameByAnimal(an2Use{1}, es(i));
                app.ElectrodeDets.ElectrodeTxt(i).Text = ['Electrode ',num2str(es(i)),' - ',name{1}];
                app.ElectrodeDets.ElectrodeTxt(i).UserData = es(i);
                
                app.ElectrodeDets.ElectrodeDist = [app.ElectrodeDets.ElectrodeDist uieditfield(app.UIFigure, 'numeric')];
                app.ElectrodeDets.ElectrodeDist(i).Position = [270 app.ElectrodeNumberLabel.Position(2)-((inbtw*i)-gap) 250 sz];
                app.ElectrodeDets.ElectrodeDist(i).FontSize = 18;
                app.ElectrodeDets.ElectrodeDist(i).FontWeight = 'bold';
                app.ElectrodeDets.ElectrodeDist(i).ValueDisplayFormat = '%.2f mm';
%                 if ~isempty(app.CallingApp.ElectrodePositions)
%                     app.ElectrodeDets.ElectrodeDist(i).Value = app.CallingApp.ElectrodePositions(i,2);
%                 end
                if isfield(app.CallingApp.AnimalProperties.(an2Use{1}),'LocationData')
                    if ~isempty([app.CallingApp.AnimalProperties.(an2Use{1}).LocationData.ElectrodeDistance])
                        app.ElectrodeDets.ElectrodeDist(i).Value = app.CallingApp.AnimalProperties.(an2Use{1}).LocationData(i).ElectrodeDistance;
                    end
                end

            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            if all(~[app.ElectrodeDets.ElectrodeDist.Value])
                app.CallingApp.ElectrodePositions = [];
            else
                app.CallingApp.ElectrodePositions = [[app.ElectrodeDets.ElectrodeTxt.UserData]' [app.ElectrodeDets.ElectrodeDist.Value]'];
            end
            delete(app);
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            if all(~[app.ElectrodeDets.ElectrodeDist.Value])
                app.CallingApp.ElectrodePositions = [];
            else
                app.CallingApp.ElectrodePositions = [[app.ElectrodeDets.ElectrodeTxt.UserData]' [app.ElectrodeDets.ElectrodeDist.Value]'];
            end
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ElectrodePositions = [];
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ElectrodeLocationsLabel = uilabel(app.UIFigure);
            app.ElectrodeLocationsLabel.Text = 'Electrode Locations';
            app.ElectrodeNumberLabel = uilabel(app.UIFigure);
            app.ElectrodeNumberLabel.Text = 'Electrode Number';
            app.DistanceFromCristammLabel = uilabel(app.UIFigure);
            app.DistanceFromCristammLabel.Text = 'Distance From Crista (mm)';
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnterElectrodeLocations(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end