classdef ProcessAndPlotFromCycles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TransparencyThreshold           matlab.ui.control.NumericEditField
        degsVORMagnitudeEditFieldLabel  matlab.ui.control.Label
        DataTransparency                matlab.ui.control.CheckBox
        text4_10                        matlab.ui.control.Label
        ElectrodeMeasurementsButton     matlab.ui.control.Button
        PlottingNystagmusCorrectedDataCheckBox  matlab.ui.control.CheckBox
        PlottingStandardDeviationCheckBox  matlab.ui.control.CheckBox
        DataCursorButton                matlab.ui.control.StateButton
        OptionTxt                       matlab.ui.control.Label
        Options                         matlab.ui.control.ListBox
        MergewithotherCycleParamsFileButton  matlab.ui.control.Button
        SaveFigureandLegendButton       matlab.ui.control.Button
        PlotSelectedButton              matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ByStimAmpX                      matlab.ui.container.Tab
        ByRefAmpX                       matlab.ui.container.Tab
        ByRefandCanalAmpasXTab          matlab.ui.container.Tab
        ComparePairsByStimAmpasXTab     matlab.ui.container.Tab
        AllOnSameAxisButton_3           matlab.ui.control.StateButton
        IncludeAverageButton            matlab.ui.control.StateButton
        MaxComparePairsByStimTab        matlab.ui.container.Tab
        AllOnSameAxisButton_4           matlab.ui.control.StateButton
        ComparePairsByRefAmpasXTab      matlab.ui.container.Tab
        AllOnSameAxisButton             matlab.ui.control.StateButton
        IncludeAverageButton_2          matlab.ui.control.StateButton
        MaxComparePairsByRefTab         matlab.ui.container.Tab
        AllOnSameAxisButton_2           matlab.ui.control.StateButton
        SurfPlotsByStimIPGandPDTab      matlab.ui.container.Tab
        AverageSurfByRefIPGandPDTab     matlab.ui.container.Tab
        MisalignmentButton              matlab.ui.control.StateButton
        EyeVButton                      matlab.ui.control.StateButton
        CompareStandardAcrossTimeTab    matlab.ui.container.Tab
        CompareAvCStandardsTab          matlab.ui.container.Tab
        AllStandards                    matlab.ui.container.Tab
        NormIPGasX                      matlab.ui.container.Tab
        CycleAverageTab                 matlab.ui.container.Tab
        SavecycleParmsDataChangesButton  matlab.ui.control.Button
        QuickPhaseRemoval               matlab.ui.control.Button
        SpherePlotTab                   matlab.ui.container.Tab
        ViewOptionsDropDown             matlab.ui.control.DropDown
        ViewOptionsLabel                matlab.ui.control.Label
        MaxvsDistanceByStimTab          matlab.ui.container.Tab
        NoParameterizationCheckBox      matlab.ui.control.CheckBox
        MaxvsDistanceByRefTab           matlab.ui.container.Tab
        MaxPointCloudByStimTab          matlab.ui.container.Tab
        MaxPointCloudByRefTab           matlab.ui.container.Tab
        PlotByCanalButton               matlab.ui.control.StateButton
        SvMvDStimMeanDiff               matlab.ui.container.Tab
        FigureDirectoryButton           matlab.ui.control.Button
        CycleParamsDirectoryButton      matlab.ui.control.Button
        ProcessButton                   matlab.ui.control.Button
        FigureDirectoryText             matlab.ui.control.EditField
        text4_9                         matlab.ui.control.Label
        NormBy                          matlab.ui.control.ListBox
        text4_8                         matlab.ui.control.Label
        IPG                             matlab.ui.control.ListBox
        text4_7                         matlab.ui.control.Label
        P2A                             matlab.ui.control.ListBox
        text4_6                         matlab.ui.control.Label
        P1A                             matlab.ui.control.ListBox
        text4_5                         matlab.ui.control.Label
        P2D                             matlab.ui.control.ListBox
        text4_4                         matlab.ui.control.Label
        P1D                             matlab.ui.control.ListBox
        text4_3                         matlab.ui.control.Label
        LhrhCombs                       matlab.ui.control.ListBox
        text4_2                         matlab.ui.control.Label
        RalpCombs                       matlab.ui.control.ListBox
        text4                           matlab.ui.control.Label
        LarpCombs                       matlab.ui.control.ListBox
        ConsolidateandOpenCycleAvergaeDataLabel  matlab.ui.control.Label
        CycleDirectoryText              matlab.ui.control.EditField
        ProgressBar                     matlab.ui.control.UIAxes
    end


    properties (Access = public)
        cycDir % Description
        listing % Description
        params
        maxNorm
        PBarObj % Description
        PBarTxt % Description
        allStim %
        allRef %
        allCombs %
        LARPCombs %
        RALPCombs %
        LHRHCombs %
        allP1d %
        allP2d %
        allP1a %
        allP2a %
        allIPG %
        animal %
        AnimalsandEyes
        AnimalProperties
        AnimalsandEyesToUse

        LEye %
        REye %
        normBy %
        
        pullIndsL = {1};
        pullIndsR = {1};
        % LARP RALP LHRH DIST CC
        elecByCanal = struct();
        
        Stims
        Refs
        P1Ds
        P2Ds
        P1As
        P2As
        IPGs
        dsp2ds
        Canals
        ids
        Eyes
       
        SaveFigHandle
        FigureName
        
        mergeFlg = 0;

        XLimMin
        XLimMax
        YLimMin
        YLimMax
        ZLimMin
        ZLimMax

        ChooseAxLimApp

        EnterElectrodeLocationsApp
        ElectrodePositions
        LocationData

        NancyButtonGroup
        NancyLeftEyeButton
        NancyRightEyeButton

        colorV
    end

    properties (Access = private)
    end

    methods (Access = public)
        function [m, CI, PM] = CalcCI(app, dat, interval)
            if length(dat) > 3
                [h,p,k,c] = lillietest(dat)
            else
                disp('dat < 3')
                h = 1;
            end
            if h
                disp('REJECT data comes from normal distribution')
                disp(['Mean = ', num2str(mean(dat))])
                d = sort(dat);

                m = median(d);
                ts = 1.96;
                r = (length(d)/2)-(ts*(sqrt(length(d)/2)));
                r = round(r);
                if r < 1
                    r = 1;
                end
                s = 1+(length(d)/2)+(ts*(sqrt(length(d)/2)));
                s = round(s);
                if s > length(d)
                    s = length(d);
                end
                CI = [d(r) d(s)];
                PM = [];
            else
                disp('DONT REJECT data comes from normal distribution')
                SEM = std(dat)/sqrt(length(dat));
                l = (1-interval)/2;
                ts = tinv([l interval+l],length(dat)-1);
                m = mean(dat);
                CI = m + ts*SEM;
                PM = m-CI(1);
            end
            
        end

        function [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, inds, eye, animal, ax)
            magValsMean = [];
            magValsSTD = [];
            misValsMean = [];
            misValsSTD = [];
            tot = [];
            for i = inds
                if ismember({eye},'REye')
                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                        origMis = app.params(i).M3DR_NystagCorr';
                    else
                        origMis = app.params(i).M3DR';
                    end
                else
                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                        origMis = app.params(i).M3DL_NystagCorr';
                    else
                        origMis = app.params(i).M3DL';
                    end
                end
                % Transform to Canal LRZ
                magValsN = app.AnimalProperties.(animal).CLRZfromFLRZ*origMis;
                magValsN = magValsN';

                magValsMean = [magValsMean; mean(magValsN)];
                magValsSTD = [magValsSTD; std(magValsN)];
                
                [~,~,~,~,pureRot,~,~] = GetElecColorandNameByAnimal(app, animal, app.params(i).stim);
                sz = size(magValsN);
                rpr = repmat(pureRot,sz(1),1);
                % Recalculate Misalignment Values 
                misVals = acosd(dot(rpr,magValsN,2)./(vecnorm(rpr,2,2).*vecnorm(magValsN,2,2)));
                misValsMean = [misValsMean; mean(misVals)];
                misValsSTD = [misValsSTD; std(misVals)];
                tot = [tot; sz(1)];
            end
        end
        
        function AddMicrotext(app)
            myBox = uicontrol(app.SaveFigHandle,'style','text');
            myBox.Units = 'normalized';
            myBox.Position = [0 -0.01 1 0.02];
            myBox.HorizontalAlignment = 'left';
            myBox.Max = 5;
            myBox.FontSize = 5.5;
            crtScript = 'Created using: 1. Coil_data_Segmenting.m (VNEL Github\Coil-Analysis\Data Segmenting\), 2. Voma_Processing.m (VNEL Github\Coil-Analysis\Cycle Analysis), 3. ProcessAndPlotFromCycles.mlapp (VNEL Github\Coil-Analysis\Plotting), 4. Max_Compare_Pairs_By_Stim-InnervsOuter.m(VNEl Github\Coil-Analysis\Plotting)';
            animalI = ['   Animals: Nancy, Opal'];
            rawDataI = ['   Base Raw Files: R:\Morris, Brian\Monkey Data\Nancy\20191115 and 20191118, R:\Morris, Brian\Monkey Data\Opal\20220617 and 20220620, Nancy cycleParms files merged and saved in: R:\Morris, Brian\Monkey Data\Nancy\Keithley-Teensy Current Sweep Analysis, Opal cycleParms files merged and saved in: R:\Morris, Brian\Monkey Data\Opal\Keithley-Teensy Current Sweep Analysis - 0617 and 0620, those cycleParams files are meregd and saved in: R:\Morris, Brian\Monkey Data\Multi Animal Analysis\Nancy, Opal, and Gigi Current Sweep',];
            filename1 = ['   Figure Saved as: Nancy and Opal Inner vs Outer - all refs (.jpg, .mat, and .svg), figures saved in: R:\Morris, Brian\Monkey Data\Multi Animal Analysis\Nancy, Opal, and Gigi Current Sweep\Inner vs Outer'];
            myBox.String = [crtScript,animalI,rawDataI,filename1];
            figDir = 'R:\Morris, Brian\Monkey Data\Multi Animal Analysis\Nancy, Opal, and Gigi Current Sweep\Inner vs Outer\';
            name = 'Nancy and Opal Inner vs Outer - all refs';
            saveas(SaveFigHandle,[figDir,name,'.svg']);
            saveas(SaveFigHandle,[figDir,name,'.jpg']);
            saveas(SaveFigHandle,[figDir,name,'.fig']);

            myBox = uicontrol(ldgFig,'style','text');
            myBox.Units = 'normalized';
            myBox.Position = [0 -0.01 1 0.02];
            myBox.HorizontalAlignment = 'left';
            myBox.Max = 5;
            myBox.FontSize = 5.5;
            crtScript = 'Created using: 1. Coil_data_Segmenting.m (VNEL Github\Coil-Analysis\Data Segmenting\), 2. Voma_Processing.m (VNEL Github\Coil-Analysis\Cycle Analysis), 3. ProcessAndPlotFromCycles.mlapp (VNEL Github\Coil-Analysis\Plotting), 4. Max_Compare_Pairs_By_Stim-InnervsOuter.m(VNEl Github\Coil-Analysis\Plotting)';
            animalI = ['   Animals: Nancy, Opal'];
            rawDataI = ['   Base Raw Files: R:\Morris, Brian\Monkey Data\Nancy\20191115 and 20191118, R:\Morris, Brian\Monkey Data\Opal\20220617 and 20220620, Nancy cycleParms files merged and saved in: R:\Morris, Brian\Monkey Data\Nancy\Keithley-Teensy Current Sweep Analysis, Opal cycleParms files merged and saved in: R:\Morris, Brian\Monkey Data\Opal\Keithley-Teensy Current Sweep Analysis - 0617 and 0620, those cycleParams files are meregd and saved in: R:\Morris, Brian\Monkey Data\Multi Animal Analysis\Nancy, Opal, and Gigi Current Sweep',];
            filename1 = ['   Figure Saved as: Nancy and Opal Inner vs Outer - all refs LEGEND (.jpg, .mat, and .svg), figures saved in: R:\Morris, Brian\Monkey Data\Multi Animal Analysis\Nancy, Opal, and Gigi Current Sweep\Inner vs Outer'];
            myBox.String = [crtScript,animalI,rawDataI,filename1];
            figDir = 'R:\Morris, Brian\Monkey Data\Multi Animal Analysis\Nancy, Opal, and Gigi Current Sweep\Inner vs Outer\';
            name = 'Nancy and Opal Inner vs Outer - all refs LEGEND';
            saveas(ldgFig,[figDir,name,'.svg']);
            saveas(ldgFig,[figDir,name,'.jpg']);
            saveas(ldgFig,[figDir,name,'.fig']);
        end
        
        function CreateAndEBtnGroups(app)
            its = 1;
            for i = app.animal
                % Create NancyButtonGroup
                app.AnimalProperties.(i{1}).ButtonGroup = uibuttongroup(app.UIFigure);
                app.AnimalProperties.(i{1}).ButtonGroup.AutoResizeChildren = 'off';
                app.AnimalProperties.(i{1}).ButtonGroup.Title = i{1};
                app.AnimalProperties.(i{1}).ButtonGroup.FontWeight = 'bold';
                app.AnimalProperties.(i{1}).ButtonGroup.Position = [6 551-(148*(its-1)) 118 148];
                app.AnimalProperties.(i{1}).ButtonGroup.BackgroundColor = [1 1 1];
                
                if length(find(contains(app.AnimalsandEyes,i{1}(1)))) == 2
                    LEColor = 'g';
                    REColor = 'g';
                    LEEnable = 'on';
                    REEnable = 'on';
                else
                    if contains(app.AnimalsandEyes(contains(app.AnimalsandEyes,i{1}(1))),'REye')
                        LEColor = 'r';
                        REColor = 'g';
                        LEEnable = 'off';
                        REEnable = 'on';
                    else
                        LEColor = 'g';
                        REColor = 'r';
                        LEEnable = 'on';
                        REEnable = 'off';
                    end
                end
                % Create RightEyeButton_2
                app.AnimalProperties.(i{1}).REye = uibutton(app.AnimalProperties.(i{1}).ButtonGroup, 'state');
                app.AnimalProperties.(i{1}).REye.Text = 'Right Eye';
                app.AnimalProperties.(i{1}).REye.Position = [11 82 100 22];
                app.AnimalProperties.(i{1}).REye.BackgroundColor = REColor;
                app.AnimalProperties.(i{1}).REye.Enable = REEnable;

                % Create LeftEyeButton_2
                app.AnimalProperties.(i{1}).LEye = uibutton(app.AnimalProperties.(i{1}).ButtonGroup, 'state');
                app.AnimalProperties.(i{1}).LEye.Text = 'Left Eye';
                app.AnimalProperties.(i{1}).LEye.Position = [11 105 100 22];
                app.AnimalProperties.(i{1}).LEye.BackgroundColor = LEColor;
                app.AnimalProperties.(i{1}).LEye.Enable = LEEnable;

                app.AnimalProperties.(i{1}).CorrectionLabel = uibutton(app.AnimalProperties.(i{1}).ButtonGroup, 'state');
                app.AnimalProperties.(i{1}).CorrectionLabel.BackgroundColor = [1 1 1];
                app.AnimalProperties.(i{1}).CorrectionLabel.HorizontalAlignment = 'center';
                app.AnimalProperties.(i{1}).CorrectionLabel.FontSize = 8.5;
                app.AnimalProperties.(i{1}).CorrectionLabel.FontWeight = 'bold';
                app.AnimalProperties.(i{1}).CorrectionLabel.Position = [2 61 114 20];
                app.AnimalProperties.(i{1}).CorrectionLabel.Text = {'Canal Position Correction'};

                app.AnimalProperties.(i{1}).i0 = uieditfield(app.AnimalProperties.(i{1}).ButtonGroup, 'numeric');
                app.AnimalProperties.(i{1}).i0.Position = [2 42 38 20];
                app.AnimalProperties.(i{1}).i0.Value = app.AnimalProperties.(i{1}).CLRZfromFLRZ(1,1);
                app.AnimalProperties.(i{1}).i0.ValueChangedFcn = createCallbackFcn(app, @RotateMatrixValueChanged, true);

                app.AnimalProperties.(i{1}).j0 = uieditfield(app.AnimalProperties.(i{1}).ButtonGroup, 'numeric');
                app.AnimalProperties.(i{1}).j0.Position = [2 22 38 20];
                app.AnimalProperties.(i{1}).j0.Value = app.AnimalProperties.(i{1}).CLRZfromFLRZ(2,1);
                app.AnimalProperties.(i{1}).j0.ValueChangedFcn = createCallbackFcn(app, @RotateMatrixValueChanged, true);

                app.AnimalProperties.(i{1}).k0 = uieditfield(app.AnimalProperties.(i{1}).ButtonGroup, 'numeric');
                app.AnimalProperties.(i{1}).k0.Position = [2 2 38 20];
                app.AnimalProperties.(i{1}).k0.Value = app.AnimalProperties.(i{1}).CLRZfromFLRZ(3,1);
                app.AnimalProperties.(i{1}).k0.ValueChangedFcn = createCallbackFcn(app, @RotateMatrixValueChanged, true);

                app.AnimalProperties.(i{1}).i1 = uieditfield(app.AnimalProperties.(i{1}).ButtonGroup, 'numeric');
                app.AnimalProperties.(i{1}).i1.Position = [40 42 38 20];
                app.AnimalProperties.(i{1}).i1.Value = app.AnimalProperties.(i{1}).CLRZfromFLRZ(1,2);
                app.AnimalProperties.(i{1}).i1.ValueChangedFcn = createCallbackFcn(app, @RotateMatrixValueChanged, true);

                app.AnimalProperties.(i{1}).j1 = uieditfield(app.AnimalProperties.(i{1}).ButtonGroup, 'numeric');
                app.AnimalProperties.(i{1}).j1.Position = [40 22 38 20];
                app.AnimalProperties.(i{1}).j1.Value = app.AnimalProperties.(i{1}).CLRZfromFLRZ(2,2);
                app.AnimalProperties.(i{1}).j1.ValueChangedFcn = createCallbackFcn(app, @RotateMatrixValueChanged, true);

                app.AnimalProperties.(i{1}).k1 = uieditfield(app.AnimalProperties.(i{1}).ButtonGroup, 'numeric');
                app.AnimalProperties.(i{1}).k1.Position = [40 2 38 20];
                app.AnimalProperties.(i{1}).k1.Value = app.AnimalProperties.(i{1}).CLRZfromFLRZ(3,2);
                app.AnimalProperties.(i{1}).k1.ValueChangedFcn = createCallbackFcn(app, @RotateMatrixValueChanged, true);

                app.AnimalProperties.(i{1}).i2 = uieditfield(app.AnimalProperties.(i{1}).ButtonGroup, 'numeric');
                app.AnimalProperties.(i{1}).i2.Position = [78 42 38 20];
                app.AnimalProperties.(i{1}).i2.Value = app.AnimalProperties.(i{1}).CLRZfromFLRZ(1,3);
                app.AnimalProperties.(i{1}).i2.ValueChangedFcn = createCallbackFcn(app, @RotateMatrixValueChanged, true);

                app.AnimalProperties.(i{1}).j2 = uieditfield(app.AnimalProperties.(i{1}).ButtonGroup, 'numeric');
                app.AnimalProperties.(i{1}).j2.Position = [78 22 38 20];
                app.AnimalProperties.(i{1}).j2.Value = app.AnimalProperties.(i{1}).CLRZfromFLRZ(2,3);
                app.AnimalProperties.(i{1}).j2.ValueChangedFcn = createCallbackFcn(app, @RotateMatrixValueChanged, true);

                app.AnimalProperties.(i{1}).k2 = uieditfield(app.AnimalProperties.(i{1}).ButtonGroup, 'numeric');
                app.AnimalProperties.(i{1}).k2.Position = [78 2 38 20];
                app.AnimalProperties.(i{1}).k2.Value = app.AnimalProperties.(i{1}).CLRZfromFLRZ(3,3);
                app.AnimalProperties.(i{1}).k2.ValueChangedFcn = createCallbackFcn(app, @RotateMatrixValueChanged, true);

                its = its +1;
            end
        end

        function DeleteAndEBtnGroups(app)
            fs = fields(app.AnimalProperties);
            for i = fs'
                fs2 = fields(app.AnimalProperties.(i{1}));
                for j = fs2'
                    if ismember(j,[{'ButtonGroup'} {'REye'} {'LEye'} {'CorrectionLabel'} {'i0'} {'j0'} {'k0'} {'i1'} {'j1'} {'k1'} {'i2'} {'j2'} {'k2'}])
                        delete(app.AnimalProperties.(i{1}).(j{1}))
                        app.AnimalProperties.(i{1}) = rmfield(app.AnimalProperties.(i{1}),j{1});
                    end
                end

            end
        end
        
        function RotateMatrixValueChanged(app, event)
            fs = fields(app.AnimalProperties);
            for i = fs'
                fs2 = fields(app.AnimalProperties.(i{1}));
                if any(ismember(fs2,{'ButtonGroup'}))
                    app.AnimalProperties.(i{1}).RotationMatrix = [app.AnimalProperties.(i{1}).i0.Value app.AnimalProperties.(i{1}).i1.Value app.AnimalProperties.(i{1}).i2.Value;...
                        app.AnimalProperties.(i{1}).j0.Value app.AnimalProperties.(i{1}).j1.Value app.AnimalProperties.(i{1}).j2.Value;...
                        app.AnimalProperties.(i{1}).k0.Value app.AnimalProperties.(i{1}).k1.Value app.AnimalProperties.(i{1}).k2.Value];
                end
            end
            PlotSelectedButtonPushed(app,[])
        end
        
        function clearTabs(app)
            for i = 1:length(app.TabGroup.Children)
                delete(app.TabGroup.Children(i).Children(isgraphics(app.TabGroup.Children(i).Children,'axes')))
            end
%             delete(app.ByStimAmpX.Children)
%             delete(app.ByRefAmpX.Children)
%             delete(app.ByRefandCanalAmpasXTab.Children)
%             delete(app.ComparePairsByStimAmpasXTab.Children(~isgraphics(app.ComparePairsByStimAmpasXTab.Children,'uistatebutton')))
%             delete(app.MaxComparePairsByStimTab.Children(~isgraphics(app.MaxComparePairsByStimTab.Children,'uistatebutton')))
%             delete(app.ComparePairsByRefAmpasXTab.Children(~isgraphics(app.ComparePairsByRefAmpasXTab.Children,'uistatebutton')))
%             delete(app.MaxComparePairsByRefTab.Children(~isgraphics(app.MaxComparePairsByRefTab.Children,'uistatebutton')))
%             delete(app.SurfPlotsByStimIPGandPDTab.Children)
%             delete(app.AverageSurfByRefIPGandPDTab.Children(~isgraphics(app.AverageSurfByRefIPGandPDTab.Children,'uistatebutton')))
%             delete(app.CompareStandardAcrossTimeTab.Children)
%             delete(app.CompareAvCStandardsTab.Children)
%             delete(app.AllStandards.Children)
%             delete(app.NormIPGasX.Children)
%             delete(app.CycleAverageTab.Children)
%             delete(app.SpherePlotTab.Children(isgraphics(app.SpherePlotTab.Children,'uiaxes')))
        end

        function ids2U = GetDataInds(app, animals, stim, ref, p1d, p2d, p1a, p2a, ipg, dup, dsp2d)
            if stim == 1
                stimId = find([app.params.stim]==[stim]);
            else
                stimId = find(ismember([app.params.stim],[stim]));
            end

            if ref == 1
                refId = find([app.params.ref]==[ref]);
            else
                refId = find(ismember([app.params.ref],[ref]));
            end

            p1dId = find(ismember(round([app.params.p1d],4),[p1d]));
            p2dId = find(ismember(round([app.params.p2d],4),[p2d]));
            p1aId = find(ismember(round([app.params.p1amp],4),[p1a]));
            p2aId = find(ismember(round([app.params.p2amp],4),[p2a]));
            ipgId = find(ismember(round([app.params.ipg],4),[ipg]));
            duplicatesToUse = find(ismember(round([app.params.TOUSE],4),[dup]));
            if dsp2d < 0
                dsp2dId = find(ismember(round([app.params.dSp2d],4),dsp2d));
            elseif dsp2d == 0
                dsp2dId = find([app.params.dSp2d]>0);
            else
%                 dsp2dId = find([app.params.dSp2d]>0);
                dsp2dId = find(ismember([app.params.dSp2d],dsp2d));
            end
            names = find(ismember({app.params.animal},animals));


            cut1 = refId(ismember(refId,stimId));
            cut2 = cut1(ismember(cut1,p1dId));
            cut3 = cut2(ismember(cut2,p2dId));
            cut4 = cut3(ismember(cut3,p1aId));
            cut5 = cut4(ismember(cut4,p2aId));
            cut6 = cut5(ismember(cut5,ipgId));
            cut7 = cut6(ismember(cut6,duplicatesToUse));
            cut8 = cut7(ismember(cut7,dsp2dId));
            ids2U = cut8(ismember(cut8,names));

        end
        
        function PullCombInfo(app)
            app.Stims = [];
            app.Refs = [];
            app.Canals = [0 0 0];
            fs = fields(app.AnimalProperties);
            app.AnimalsandEyesToUse = [];
            for i = fs'
                if isfield(app.AnimalProperties.(i{1}),'REye')
                    if app.AnimalProperties.(i{1}).REye.Value
                        app.AnimalsandEyesToUse = [app.AnimalsandEyesToUse; i {'REye'} 0];
                    end
                end
                if isfield(app.AnimalProperties.(i{1}),'LEye')
                    if app.AnimalProperties.(i{1}).LEye.Value
                        app.AnimalsandEyesToUse = [app.AnimalsandEyesToUse; i {'LEye'} 1];
                    end
                end
            end
            [app.Stims, app.Refs, app.Canals] = switchedVal(app, app.LarpCombs.Value, app.Stims, app.Refs, app.Canals, 1);
            [app.Stims, app.Refs, app.Canals] = switchedVal(app, app.RalpCombs.Value, app.Stims, app.Refs, app.Canals, 2);
            [app.Stims, app.Refs, app.Canals] = switchedVal(app, app.LhrhCombs.Value, app.Stims, app.Refs, app.Canals, 3);
            app.P1Ds = cell2mat(cellfun(@str2num, app.P1D.Value, 'UniformOutput', 0));
            app.P2Ds = cell2mat(cellfun(@str2num, app.P2D.Value, 'UniformOutput', 0));
            app.P1As = cell2mat(cellfun(@str2num, app.P1A.Value, 'UniformOutput', 0));
            app.P2As = cell2mat(cellfun(@str2num, app.P2A.Value, 'UniformOutput', 0));
            app.IPGs = cell2mat(cellfun(@str2num, app.IPG.Value, 'UniformOutput', 0));
            app.dsp2ds = -1;
        end

        function [s, r, c] = switchedVal(app, data, s, r, c, pos)
            if ~ismember(data,'None')
                c(pos) = 1;
                for i = 1:length(data)
                    dp = strfind(data{i},'-');
                    s = [s str2num(data{i}(1:dp-1))];
                    r = [r str2num(data{i}(dp+1:end))];
                end
            end
        end

        function m = GetAmpMarker(app, amp)
            switch amp
                case 20
                    m = 'o';
                case 25
                    m = 'v';
                case 50
                    m = '+';
                case 75
                    m = '*';
                case 100
                    m = '^';
                case 125
                    m = 's';
                case 150
                    m = 'd';
                case 175
                    m = '>';
                case 200
                    m = '<';
                case 250
                    m = 'p';
                case 300
                    m = 'h';
            end
        end

        function [color,name,mark,shift,pureRot,canalColorL,canalColorR] = GetElecColorandNameByAnimal(app, animal, electrode)
            switch animal
                case 'Nancy'
                    switch electrode
                        case 1
                            name = 'LP, Shallow';
                            color = [0.7 0.7 1];%[255 166 158]/255;
                            mark = 'd';
                            shift = 0;
                            pureRot = [0 1 0];
                            canalColorL = app.colorV.l_r;
                            canalColorR = app.colorV.r_r;
                        case 2
                            name = 'LP, Middle';
                            color = [0 0 1];%[72 169 60]/255;
                            mark = 'o';
                            shift = 1;
                            pureRot = [0 1 0];
                            canalColorL = app.colorV.l_r;
                            canalColorR = app.colorV.r_r;
                        case 3
                            name = 'LP, Deep';
                            color = [0 0 .5];%[104 78 50]/255;
                            mark = '>';
                            shift = -1;
                            pureRot = [0 1 0];
                            canalColorL = app.colorV.l_r;
                            canalColorR = app.colorV.r_r;
                        case 4
                            name = 'LA, Shallow';
                            color = [0.6078 0.7725 0.2392];%[13 0 164]/255;
                            mark = '*';
                            shift = 3;
                            pureRot = [1 0 0];
                            canalColorL = app.colorV.l_l;
                            canalColorR = app.colorV.r_l;
                        case 5
                            name = 'LA, Middle Outside';
                            color = [0.5 .54 0.3];%[255 196 61]/255;
                            mark = 'p';
                            shift = -3;
                            pureRot = [1 0 0];
                            canalColorL = app.colorV.l_l;
                            canalColorR = app.colorV.r_l;
                        case 6
                            name = 'LA, Deep';
                            color = [0 .44 0];%[3 157 201]/255;
                            mark = 'v';
                            shift = 3.5;
                            pureRot = [1 0 0];
                            canalColorL = app.colorV.l_l;
                            canalColorR = app.colorV.r_l;
                        case 14
                            name = 'LA, Middle Inside';
                            color = [0 .84 0];%[180 227 61]/255;
                            mark = 'h';
                            shift = -3.5;
                            pureRot = [1 0 0];
                            canalColorL = app.colorV.l_l;
                            canalColorR = app.colorV.r_l;
                        case 7
                            name = 'LH, Deep';
                            color = [.44 0 0];%[215 38 56]/255;%%%%%
                            mark = '<';
                            shift = 5;
                            pureRot = [0 0 -1];
                            canalColorL = app.colorV.l_z;
                            canalColorR = app.colorV.r_z;
                        case 8
                            name = 'LH, Middle Outside';
                            color = [.54 0.3 0.3];%[183 184 104]/255;%%%%%
                            mark = 's';
                            shift = -5;
                            pureRot = [0 0 -1];
                            canalColorL = app.colorV.l_z;
                            canalColorR = app.colorV.r_z;
                        case 9
                            name = 'LH, Shallow';
                            color = [150 59 115]/255;%[219 108 35]/255;
                            mark = '^';
                            shift = 5.5;
                            pureRot = [0 0 -1];
                            canalColorL = app.colorV.l_z;
                            canalColorR = app.colorV.r_z;
                        case 15
                            name = 'LH, Middle Inside';
                            color = [.84 0 0];%[107 15 26]/255;
                            mark = '_';
                            shift = -5.5;
                            pureRot = [0 0 -1];
                            canalColorL = app.colorV.l_z;
                            canalColorR = app.colorV.r_z;
                        case 10
                            name = 'Distant';
                            color = [57 61 63]/255;
                            mark = '^';
                            shift = [];
                            pureRot = [];
                            canalColorL = '';
                            canalColorR = '';
                        case 11
                            name = 'Labyrinth, Deep';
                            color = [128 147 241]/255;
                            mark = '<';
                            shift = [];
                            pureRot = [];
                            canalColorL = '';
                            canalColorR = '';
                        case 12
                          name = 'Labyrinth, Middle';
                          color = [147 92 188]/255;
                            mark = 'v';
                            shift = [];
                            pureRot = [];
                            canalColorL = '';
                            canalColorR = '';
                        case 13
                            name = 'Labyrinth, Shallow';
                            color = [129 244 149]/255;
                            mark = '^';
                            shift = [];
                            pureRot = [];
                            canalColorL = '';
                            canalColorR = '';
                    end
                case {'Gigi', 'GiGi'}
                    switch electrode
                        case 1
                            name = 'LP, Shallow';
                            color = [0.7 0.7 1];
                            mark = 'd';
                            shift = 0;
                            pureRot = [0 1 0];
                            canalColorL = app.colorV.l_r;
                            canalColorR = app.colorV.r_r;
                        case 2
                            name = 'LP, Middle';
                            color = [0 0 1];
                            mark = 'o';
                            shift = 1;
                            pureRot = [0 1 0];
                            canalColorL = app.colorV.l_r;
                            canalColorR = app.colorV.r_r;
                        case 3
                            name = 'LP, Deep';
                            color = [0 0 .5];
                            mark = '>';
                            shift = -1;
                            pureRot = [0 1 0];
                            canalColorL = app.colorV.l_r;
                            canalColorR = app.colorV.r_r;
                        case 4
                            name = 'LA, Shallow';
                            color = [0.6078 0.7725 0.2392];
                            mark = '*';
                            shift = 3;
                            pureRot = [1 0 0];
                            canalColorL = app.colorV.l_l;
                            canalColorR = app.colorV.r_l;
                        case 5
                            name = 'LA, Middle Outside';
                            color = [0.5 .54 0.3];
                            mark = 'p';
                            shift = -3;
                            pureRot = [1 0 0];
                            canalColorL = app.colorV.l_l;
                            canalColorR = app.colorV.r_l;
                        case 6
                            name = 'LA, Deep';
                            color = [0 .44 0];
                            mark = 'v';
                            shift = 3.5;
                            pureRot = [1 0 0];
                            canalColorL = app.colorV.l_l;
                            canalColorR = app.colorV.r_l;
                        case 7
                            name = 'LH, Deep';
                            color = [.44 0 0];%%%%%
                            mark = '<';
                            shift = -5;
                            pureRot = [0 0 -1];
                            canalColorL = app.colorV.l_z;
                            canalColorR = app.colorV.r_z;
                        case 8
                            name = 'LH, Middle Outside';
                            color = [.54 0.3 0.3];%%%%%
                            mark = 's';
                            shift = 5;
                            pureRot = [0 0 -1];
                            canalColorL = app.colorV.l_z;
                            canalColorR = app.colorV.r_z;
                        case 9
                            name = 'LH, Shallow';
                            color = [150 59 115]/255;
                            mark = '^';
                            shift = 5.5;
                            pureRot = [0 0 -1];
                            canalColorL = app.colorV.l_z;
                            canalColorR = app.colorV.r_z;
                        case 10
                            name = 'Distant';
                            color = [57 61 63]/255;
                            mark = 'o';
                            shift = [];
                            pureRot = [];
                            canalColorL = '';
                            canalColorR = '';
                        case 11
                            name = 'Labyrinth, Deep';
                            color = [128 147 241]/255;
                            mark = 'o';
                            shift = [];
                            pureRot = [];
                            canalColorL = '';
                            canalColorR = '';
                    end
                case 'Opal'
                    switch electrode
                        case 1
                            name = 'LP, Shallow';
                            color = [0.7 0.7 1];%[255 166 158]/255;
                            mark = 'd';
                            shift = 0;
                            pureRot = [0 1 0];
                            canalColorL = app.colorV.l_r;
                            canalColorR = app.colorV.r_r;
                        case 2
                            name = 'LP, Middle';
                            color = [0 0 1];%[72 169 60]/255;
                            mark = 'o';
                            shift = 1;
                            pureRot = [0 1 0];
                            canalColorL = app.colorV.l_r;
                            canalColorR = app.colorV.r_r;
                        case 3
                            name = 'LP, Deep';
                            color = [0 0 .5];%[104 78 50]/255;
                            mark = '>';
                            shift = -1;
                            pureRot = [0 1 0];
                            canalColorL = app.colorV.l_r;
                            canalColorR = app.colorV.r_r;
                        case 9
                            name = 'LA, Shallow';
                            color = [0.6078 0.7725 0.2392];%[13 0 164]/255;
                            mark = '*';
                            shift = 3;
                            pureRot = [1 0 0];
                            canalColorL = app.colorV.l_l;
                            canalColorR = app.colorV.r_l;
                        case 8
                            name = 'LA, Middle Outside';
                            color = [0.5 .54 0.3];%[255 196 61]/255;
                            mark = 'p';
                            shift = -3;
                            pureRot = [1 0 0];
                            canalColorL = app.colorV.l_l;
                            canalColorR = app.colorV.r_l;
                        case 7
                            name = 'LA, Deep';
                            color = [0 .44 0];%[3 157 201]/255;
                            mark = 'v';
                            shift = 3.5;
                            pureRot = [1 0 0];
                            canalColorL = app.colorV.l_l;
                            canalColorR = app.colorV.r_l;
                        case 15
                            name = 'LA, Middle Inside';
                            color = [0 .84 0];%[180 227 61]/255;
                            mark = 'h';
                            shift = -3.5;
                            pureRot = [1 0 0];
                            canalColorL = app.colorV.l_l;
                            canalColorR = app.colorV.r_l;
                        case 4
                            name = 'LH, Shallow';
                            color = [150 59 115]/255;%[215 38 56]/255;
                            mark = '<';
                            shift = 5;
                            pureRot = [0 0 -1];
                            canalColorL = app.colorV.l_z;
                            canalColorR = app.colorV.r_z;
                        case 5
                            name = 'LH, Middle Outside';
                            color = [.54 0.3 0.3];%[183 184 104]/255;
                            mark = 's';
                            shift = -5;
                            pureRot = [0 0 -1];
                            canalColorL = app.colorV.l_z;
                            canalColorR = app.colorV.r_z;
                        case 6
                            name = 'LH, Deep';
                            color = [.44 0 0];%[219 108 35]/255;
                            mark = '^';
                            shift = 5.5;
                            pureRot = [0 0 -1];
                            canalColorL = app.colorV.l_z;
                            canalColorR = app.colorV.r_z;
                        case 14
                            name = 'LH, Middle Inside';
                            color = [.84 0 0];%[107 15 26]/255;
                            mark = '_';
                            shift = -5.5;
                            pureRot = [0 0 -1];
                            canalColorL = app.colorV.l_z;
                            canalColorR = app.colorV.r_z;
                        case 10
                            name = 'Distant';
                            color = [57 61 63]/255;
                            mark = 's';
                            shift = [];
                            pureRot = [];
                            canalColorL = '';
                            canalColorR = '';
                        case 11
                            name = 'Labyrinth, Deep';
                            color = [128 147 241]/255;
                            mark = 'pentagram';
                            shift = [];
                            pureRot = [];
                            canalColorL = '';
                            canalColorR = '';
                        case 12
                            name = 'Labyrinth, Middle';
                            color = [147 92 188]/255;
                            mark = 'd';
                            shift = [];
                            pureRot = [];
                            canalColorL = '';
                            canalColorR = '';
                        case 13
                            name = 'Labyrinth, Shallow';
                            color = [129 244 149]/255;
                            mark = 's';
                            shift = [];
                            pureRot = [];
                            canalColorL = '';
                            canalColorR = '';
                        case 16
                            name = 'Horizontal 1';
                            color = [1 61 63]/255;
                            mark = [];
                            shift = [];
                            pureRot = [];
                            canalColorL = '';
                            canalColorR = '';
                        case 17
                            name = 'Horizontal 2';
                            color = [1 61 99]/255;
                            mark = [];
                            shift = [];
                            pureRot = [];
                            canalColorL = '';
                            canalColorR = '';
                        case 18
                            name = 'Horizontal 3';
                            color = [1 83 105]/255;
                            mark = [];
                            shift = [];
                            pureRot = [];
                            canalColorL = '';
                            canalColorR = '';
                    end
            end
            if ~exist('color','var')
                name = {''};
                color = {};
                mark = [];
                shift = [];
                pureRot = [];
                canalColorL = '';
                canalColorR = '';
            else
                color = {color};
                name = {name};
            end
        end

        function [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames)
            newOrder = [];
            do=[{'Unity Line'} {'Gaussian Fit'} {'Nancy'} {'Gigi'} {'Opal'} {'Nancy - LA'} {'Nancy - LH'} {'Opal - LA'} {'Opal - LH'}...
                {'Nancy - Labyrinth, Deep'} {'Nancy - Labyrinth, Middle'} {'Nancy - Labyrinth, Shallow'} {'Opal - Vestibule, Deep'} {'Opal - Vestibule, Middle'} {'Opal - Vestibule, Shallow'} {'Gigi - CC'}...
                {'LP, Shallow'} {'LP, Middle'} {'LP, Deep'}...
                {'LA, Shallow'} {'LA, Middle Outside'} {'LA, Middle Inside'} {'LA, Deep'}...
                {'LH, Shallow'} {'LH, Middle Outside'} {'LH, Middle Inside'}...
                {'LH, Deep'} {'Distant'} {'Labyrinth, Shallow'} {'Labyrinth, Middle'} {'Labyrinth, Deep'} {'Horizontal 1'} {'Horizontal 2'}...
                {'Horizontal 3'} {'Facial Twitch Observed'}];
            for i = 1:length(do)
                if any(ismember(ldgNames,do(i)))
                    newOrder = [newOrder find(ismember(ldgNames,do(i)))];
                end
            end
            ldgLine = ldgLine(newOrder);
            ldgNames = ldgNames(newOrder);
        end
        
        function s = OrderElectrodesByAnimal(app, animal, stim)
            switch animal
                case 'Nancy'
                    correctOrder = [4 5 14 6 1 2 3 9 8 15 7 10 13 12 11];
                    correctOrder(~ismember(correctOrder,stim)) = [];
                    s = correctOrder;
                case {'Gigi', 'GiGi'}
                    correctOrder = [4 5 6 1 2 3 9 8 15 7 10 11];
                    correctOrder(~ismember(correctOrder,stim)) = [];
                    s = correctOrder;
                case 'Opal'
                    correctOrder = [9 8 15 7 1 2 3 4 5 14 6 10 11 12 13 16 17 18];
                    correctOrder(~ismember(correctOrder,stim)) = [];
                    s = correctOrder;
            end
        end

        function plotByStimCurrentAsX(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.ByStimAmpX;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                marg_w = [.05 .015];
            end
            uStims = unique(app.Stims);
            s = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uStims);
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = s;
            uRefs = unique(app.Refs,'stable');
            r = OrderElectrodesByAnimal(app, uAns{1}, uRefs);
            uRefs = r;
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];

            uStimsUsed = [];
            uRefsUsed = [];
            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
            if ismember(uAns,{'Gigi'})
                if all(ismember(uStims,[4 5 6]))
                    uStims = [uStims 14];
                end
                if all(ismember(uStims,[9 8 7]))
                    uStims = [uStims 15];
                end
            end

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                for i = 1:length(uStims)
                    magPlot = subtightplot(2,length(uStims),i,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot = subtightplot(2,length(uStims),i+length(uStims),[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    if (i == 1) && (plotLoc == 1)
                        app.ByStimAmpX.UserData = [];
                    end
                    [~,name,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uStims(i));
                    for j = 1:length(uRefs)
                        ids2U = GetDataInds(app, uAns{1}, uStims(i), uRefs(j), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                        if ~isempty(ids2U)
                            if isempty(uStimsUsed)
                                uStimsUsed = [uStimsUsed uStims(i)];
                            else
                                if ~any(ismember(uStimsUsed,uStims(i)))
                                    uStimsUsed = [uStimsUsed uStims(i)];
                                end
                            end
                            if isempty(uRefsUsed)
                                uRefsUsed = [uRefsUsed uRefs(j)];
                            else
                                if ~any(ismember(uRefsUsed,uRefs(j)))
                                    uRefsUsed = [uRefsUsed uRefs(j)];
                                end
                            end

                            if app.AnimalsandEyesToUse{1,3}
                                eyeSel = 'LEye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagL_NystagCorr';
                                    toUseMag = 'MagL_NystagCorr';
                                    toUseSMag = 'stdMagL_NystagCorr';
                                    toUseMMis = 'meanMisalignL_NystagCorr';
                                    toUseMis = 'MisalignL_NystagCorr';
                                    toUseSMis = 'stdMisalignL_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagL';
                                    toUseMag = 'MagL';
                                    toUseSMag = 'stdMagL';
                                    toUseMMis = 'meanMisalignL';
                                    toUseMis = 'MisalignL';
                                    toUseSMis = 'stdMisalignL';
                                    DataType = 'NonCorrected';
                                end
                            else
                                eyeSel = 'REye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagR_NystagCorr';
                                    toUseMag = 'MagR_NystagCorr';
                                    toUseSMag = 'stdMagR_NystagCorr';
                                    toUseMMis = 'meanMisalignR_NystagCorr';
                                    toUseMis = 'MisalignR_NystagCorr';
                                    toUseSMis = 'stdMisalignR_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagR';
                                    toUseMag = 'MagR';
                                    toUseSMag = 'stdMagR';
                                    toUseMMis = 'meanMisalignR';
                                    toUseMis = 'MisalignR';
                                    toUseSMis = 'stdMisalignR';
                                    DataType = 'NonCorrected';
                                end
                            end
                            if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                transformed = 'NONTRANSFORMED';
                            else
                                transformed = 'TRANSFORMED';
                            end
                            if app.DataTransparency.Value
                                transparencyTH = ['Thresh',num2str(app.TransparencyThreshold.Value),'_'];
                            else
                                transparencyTH = '';
                            end
                            toAlpha = [];
                            alphaVal = .45;
                            lw = 2.5;
                            hold(magPlot,'on');

                            [color,refName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uRefs(j));
                            if ~ldgFlg
                                if ~issorted([app.params(ids2U).p1amp])
                                    [samp, saI] = sort([app.params(ids2U).p1amp]);
                                    ids2U = ids2U(saI);
                                end
                                amp = [app.params(ids2U).p1amp];
                                vel = [app.params(ids2U).(toUseMMag)];
                                a={app.params(ids2U).(toUseMag)};
                                aa=cellfun(@size,a,'uni',false);
                                ca = cell2mat(aa');
                                if app.PlottingStandardDeviationCheckBox.Value
                                    std = [app.params(ids2U).(toUseSMag)]./sqrt([ca(:,2)']);
                                    stType = 'SEM';
                                else
                                    std = [app.params(ids2U).(toUseSMag)];
                                    stType = 'STD';
                                end
                            else
                                if app.PlottingStandardDeviationCheckBox.Value
                                    stType = 'SEM';
                                else
                                    stType = 'STD';
                                end
                                amp = [0];
                                vel = [0];
                                std = [0];
                            end
                            if app.DataTransparency.Value && ~ldgFlg
                                avail = 1:length(vel);
                                toAlpha = find(vel<app.TransparencyThreshold.Value);
                                if ~isempty(toAlpha)
                                    if length(toAlpha)+1 <= length(vel)
                                        bd = [toAlpha toAlpha(end)+1];
                                        line  = patchline(amp(bd),vel(bd),'Parent',magPlot,'linestyle','-','edgecolor',color{1},'linewidth',lw,'edgealpha',alphaVal);
                                    else
                                        line  = patchline(amp(toAlpha),vel(toAlpha),'Parent',magPlot,'linestyle','-','edgecolor',color{1},'linewidth',lw,'edgealpha',alphaVal);
                                    end

                                    points = scatter(magPlot,amp(toAlpha),vel(toAlpha),'SizeData',400,'MarkerEdgeColor',color{1},'marker','o','LineWidth',lw,'MarkerEdgeAlpha',alphaVal);
                                    erPlot = errorbar(magPlot,amp(toAlpha),vel(toAlpha),std(toAlpha),'color',color{1},'LineWidth',lw,'CapSize',12,'LineStyle','none');
                                    erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                    set([erPlot.Bar, erPlot.Line], 'ColorType', 'truecoloralpha', 'ColorData', [erPlot.Line.ColorData(1:3); 255*(alphaVal-.1)])
                                    set(erPlot.Cap, 'EdgeColorType', 'truecoloralpha', 'EdgeColorData', [erPlot.Cap.EdgeColorData(1:3); 255*(alphaVal-.1)])
                                    erPlot.UserData = ids2U(toAlpha);
                                end
                                lb = find(~ismember(avail,toAlpha));
                                if ~isempty(lb)
%                                     if lb(1) ~= 1
%                                         lb = [lb(1)-1 lb];
%                                     end
                                    line  = patchline(amp(lb),vel(lb),'Parent',magPlot,'linestyle','-','edgecolor',color{1},'linewidth',4,'edgealpha',1);
                                    points = scatter(magPlot,amp(lb),vel(lb),'SizeData',400,'MarkerEdgeColor',color{1},'marker','o','LineWidth',4,'MarkerEdgeAlpha',1);
                                    erPlot = errorbar(magPlot,amp(lb),vel(lb),std(lb),'color',color{1},'LineWidth',4,'CapSize',12,'LineStyle','none');
                                    erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                    erPlot.UserData = ids2U(lb);
                                end

                                
                            else
                                line = plot(magPlot,amp,vel,'color',color{1},'LineWidth',4);
                                points = scatter(magPlot,amp,vel,'SizeData',400,'MarkerEdgeColor',color{1},'marker','o','LineWidth',4,'MarkerEdgeAlpha',1);
                                erPlot = errorbar(magPlot,amp,vel,std,'color',color{1},'LineWidth',4,'CapSize',12);
                                erPlot.UserData = ids2U;
                            end
%                             line = plot(magPlot,amp,vel,'color',color{1},'LineWidth',4);
%                             points = plot(magPlot,amp,vel,'color',color{1},'marker','o','MarkerSize',20,'LineStyle','none','LineWidth',4);
%                             erPlot = errorbar(magPlot,amp,vel,std,'color',color{1},'LineWidth',4,'CapSize',12);
%                             erPlot.UserData = ids2U;
                            if any([app.params(ids2U).FacialNerve])
                                if ~ldgFlg
                                    f2u = find([app.params(ids2U).FacialNerve]);
                                    twitchPlot = plot(magPlot,amp(f2u),vel(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                    twitchPlot.UserData = ids2U(f2u);
                                    twitchPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                else
                                    twitchPlot = plot(magPlot,0,0,'rx','MarkerSize', 30,'LineWidth',4);
                                end
                                allTwitch = [allTwitch twitchPlot];
                            else
                                twitchPlot = 0;
                            end
                            hold(magPlot,'off');

                            if ~ldgFlg
                                hold(misalignPlot,'on');
                                [color,refName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uRefs(j));
                                if ~issorted([app.params(ids2U).p1amp])
                                    [samp, saI] = sort([app.params(ids2U).p1amp]);
                                    ids2U = ids2U(saI);
                                end
                                amp = [app.params(ids2U).p1amp];

                                if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                    mis = [app.params(ids2U).(toUseMMis)];
                                    a={app.params(ids2U).(toUseMis)};
                                    aa=cellfun(@size,a,'uni',false);
                                    ca = cell2mat(aa');
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
                                        %                                         std = [valsSTD]./sqrt([ca(:,2)']);
                                        stType = 'SEM';
                                    else
                                        std = [app.params(ids2U).(toUseSMis)];
                                        %                                         std = [valsSTD];
                                        stType = 'STD';
                                    end
                                    transformed = 'NONTRANSFORMED';
                                else
                                    [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U, eyeSel, uAns{1});
                                    mis = misValsMean';
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [misValsSTD']./sqrt(tot');
                                        stType = 'SEM';
                                    else
                                        std = [misValsSTD'];
                                        stType = 'STD';
                                    end
                                    transformed = 'TRANSFORMED';
                                end
                                if ~isempty(toAlpha)
                                    avail = 1:length(mis);
                                    if ~isempty(toAlpha)
                                        if length(toAlpha)+1 <= length(mis)
                                            bd = [toAlpha toAlpha(end)+1];
                                            line  = patchline(amp(bd),mis(bd),'Parent',misalignPlot,'linestyle','-','edgecolor',color{1},'linewidth',lw,'edgealpha',alphaVal);
                                        else
                                            line  = patchline(amp(toAlpha),mis(toAlpha),'Parent',misalignPlot,'linestyle','-','edgecolor',color{1},'linewidth',lw,'edgealpha',alphaVal);
                                        end


                                        points = scatter(misalignPlot,amp(toAlpha),mis(toAlpha),'SizeData',400,'MarkerEdgeColor',color{1},'marker','o','LineWidth',lw,'MarkerEdgeAlpha',alphaVal);

                                        erPlot = errorbar(misalignPlot,amp(toAlpha),mis(toAlpha),std(toAlpha),'color',color{1},'LineWidth',lw,'CapSize',12,'LineStyle','none');
                                        set([erPlot.Bar, erPlot.Line], 'ColorType', 'truecoloralpha', 'ColorData', [erPlot.Line.ColorData(1:3); 255*(alphaVal-.1)])
                                        set(erPlot.Cap, 'EdgeColorType', 'truecoloralpha', 'EdgeColorData', [erPlot.Cap.EdgeColorData(1:3); 255*(alphaVal-.1)])
                                        erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                        erPlot.UserData = ids2U(toAlpha);
                                    end

                                    lb = find(~ismember(avail,toAlpha));
                                    if ~isempty(lb)
%                                         if lb ~= 1
%                                             lb = [lb(1)-1 lb];
%                                         end
                                        line  = patchline(amp(lb),mis(lb),'Parent',misalignPlot,'linestyle','-','edgecolor',color{1},'linewidth',4,'edgealpha',1);
                                        points = scatter(misalignPlot,amp(lb),mis(lb),'SizeData',400,'MarkerEdgeColor',color{1},'marker','o','LineWidth',4,'MarkerEdgeAlpha',1);
                                        erPlot = errorbar(misalignPlot,amp(lb),mis(lb),std(lb),'color',color{1},'LineWidth',4,'CapSize',12,'LineStyle','none');
                                        erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                        erPlot.UserData = ids2U(lb);
                                    end
                                else
                                    line = plot(misalignPlot,amp,mis,'color',color{1},'LineWidth',4);
                                    points = scatter(misalignPlot,amp,mis,'SizeData',400,'MarkerEdgeColor',color{1},'marker','o','LineWidth',4,'MarkerEdgeAlpha',1);
                                    erPlot = errorbar(misalignPlot,amp,mis,std,'color',color{1},'LineWidth',4,'CapSize',12);
                                    erPlot.UserData = ids2U;
                                end
                            
%                                 line = plot(misalignPlot,amp,mis,'color',color{1},'LineWidth',4);
%                                 points = plot(misalignPlot,amp,mis,'color',color{1},'marker','o','MarkerSize',20,'LineStyle','none','LineWidth',4);
%                                 erPlot = errorbar(misalignPlot,amp,mis,std,'color',color{1},'LineWidth',4,'CapSize',12);
%                                 erPlot.UserData = ids2U;

                                if any([app.params(ids2U).FacialNerve])
                                    twitchPlot = plot(misalignPlot,amp(f2u),mis(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                    twitchPlot.UserData = ids2U(f2u);
                                    twitchPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                else
                                    twitchPlot = 0;
                                end
                                hold(misalignPlot,'off');
                            end
                            if isempty(ldgNames)
                                ldgNames = [ldgNames {[refName{1}]}];
                                ldgLine = [ldgLine line];
                            else
                                if ~ismember(ldgNames,[refName{1}])
                                    ldgNames = [ldgNames {[refName{1}]}];
                                    ldgLine = [ldgLine line];
                                end
                            end

                            ids = [ids; {ids2U}];
                        end

                    end

                    if i == 1
                        magPlot.YLabel.String = 'Velocity (dps)';
                        magPlot.YLabel.FontSize = 30;
                        misalignPlot.YLabel.String = 'Misalignment (dps)';
                        misalignPlot.YLabel.FontSize = 30;
                    else
                        magPlot.YTickLabel = [];
                        misalignPlot.YTickLabel = [];
                    end
                    magPlot.XTickLabel = [];
                    magPlot.XTick = [up1a(1)-25:50:up1a(end)];
                    magPlot.XGrid = 'on';
                    magPlot.YGrid = 'on';
                    magPlot.XLim = [up1a(1)-25 up1a(end)+25];
                    magPlot.FontSize = 30;
                    magPlot.Title.String = name{1};
                    magPlot.LineWidth = 2.5;
                    magPlot.Title.FontSize = 30;

                    misalignPlot.XTick = [up1a(1)-25:50:up1a(end)];
                    misalignPlot.XGrid = 'on';
                    misalignPlot.YGrid = 'on';
                    misalignPlot.XLim = [up1a(1)-25 up1a(end)+25];
                    misalignPlot.FontSize = 30;
                    misalignPlot.XLabel.String = {'Current (uA)'};
                    misalignPlot.XLabel.FontSize = 30;
                    misalignPlot.LineWidth = 2.5;
                    if isempty(name{1})
                        magPlot.XTickLabel = [];
                        misalignPlot.XTickLabel = [];
                    end

                    if plotLoc ~= 1
                        if ~isempty(app.ByStimAmpX.UserData)
                            if isfield(app.ByStimAmpX.UserData,'MagPlot')
                                if ~isempty(app.ByStimAmpX.UserData.MagPlot)
                                    magPlot.XLim = app.ByStimAmpX.UserData.MagPlot(1,:);
                                    magPlot.YLim = app.ByStimAmpX.UserData.MagPlot(2,:);
                                end
                            end

                            if isfield(app.ByStimAmpX.UserData,'MisPlot')
                                if ~isempty(app.ByStimAmpX.UserData.MisPlot)
                                    misalignPlot.XLim = app.ByStimAmpX.UserData.MisPlot(1,:);
                                    misalignPlot.YLim = app.ByStimAmpX.UserData.MisPlot(2,:);
                                end
                            end
                        end
                    end

                    magPlots = [magPlots magPlot];
                    misalignPlots = [misalignPlots misalignPlot];

                    if ldgFlg
                        magPlot.Visible = 0;
                        misalignPlot.Visible = 0;
                    end
                end

                sameaxes([],[magPlots])
                sameaxes([],[misalignPlots])


                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end


                if ~isempty(allTwitch)
                    ldgLine = [ldgLine allTwitch(1)];
                    ldgNames = [ldgNames {'Facial Twitch Observed'}];
                end
                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                    if length(ldgLine) > 1
                        ldg.Title.String = 'Return Electrodes:';
                    else
                        ldg.Title.String = 'Return Electrode:';
                    end
                end
                uStims = sort(uStimsUsed);
                uRefs = sort(uRefsUsed);
                app.FigureName = [uAns{1},'_',eyeSel,'_MisandVel_Current_ByStim_',transformed,'_',transparencyTH,stType,'_',strrep(num2str(uStims),' ',''),'_',strrep(num2str(uRefs),' ',''),'_',cUsed,'_',DataType];
            end
        end

        function plotByRefCurrentAsX(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.ByRefAmpX;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                if length(unique(app.Refs)) == 2
                marg_w = [.05 .5];
                elseif length(unique(app.Refs)) == 1
                    marg_w = [.05 .6];
                else
                marg_w = [.05 .015];
                end
            end
            uStims = unique(app.Stims);
            s = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uStims);
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = s;
            uRefs = unique(app.Refs);
            r = OrderElectrodesByAnimal(app, uAns{1}, uRefs);
            uRefs = r;
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];

            uStimsUsed = [];
            uRefsUsed = [];

            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
            switch uAns{1}
                case 'Nancy'
                    if isequal(uRefs,[9 8 7 15 11])
                        uRefs = [11 9 8 7 15];
                    end
                    if isequal(uRefs,[9 8 15 7 10 11])
                        uRefs = [10 11 9 8 7 15];
                    end
                    if isequal(uRefs,[1 2 3 11])
                        uRefs = [11 1 2 3];
                    end
                    if isequal(uRefs,[1 2 3 10 11])
                        uRefs = [10 11 1 2 3];
                    end
                    if isequal(uRefs,[4 5 6 14 11])
                        uRefs = [11 4 5 6 14];
                    end
                    if isequal(uRefs,[4 5 14 6 10 11])
                        uRefs = [10 11 4 5 6 14];
                    end
                case 'Opal'
                    if isequal(uRefs,[9 8 7 15 13])
                        uRefs = [13 9 8 7 15];
                    end
                    if isequal(uRefs,[9 8 15 7 10 13])
                        uRefs = [10 13 9 8 7 15];
                    end
                    if isequal(uRefs,[1 2 3 13])
                        uRefs = [13 1 2 3];
                    end
                    if isequal(uRefs,[1 2 3 10 13])
                        uRefs = [10 13 1 2 3];
                    end
                    if isequal(uRefs,[4 5 6 14 13])
                        uRefs = [13 4 5 6 14];
                    end
                    if isequal(uRefs,[4 5 14 6 10 13])
                        uRefs = [10 13 4 5 6 14];
                    end
                case 'Gigi'
                    if isequal(uRefs,[9 8 15 7 11])
                        uRefs = [11 9 8 7 15];
                    end
                    if isequal(uRefs,[9 8 7 10 11])
                        uRefs = [10 11 9 8 7 15];
                    end
                    if isequal(uRefs,[1 2 3 11])
                        uRefs = [11 1 2 3];
                    end
                    if isequal(uRefs,[1 2 3 10 11])
                        uRefs = [10 11 1 2 3];
                    end
                    if isequal(uRefs,[4 5 6 11])
                        uRefs = [11 4 5 6 14];
                    end
                    if isequal(uRefs,[4 5 6 10 11])
                        uRefs = [10 11 4 5 6 14];
                    end
            end

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                for i = 1:length(uRefs)
                    uuu=length(uRefs);
                    magPlot = subtightplot(2,uuu,i,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot = subtightplot(2,uuu,i+uuu,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    if (i == 1) && (plotLoc == 1)
                        app.ByRefAmpX.UserData = [];
                    end
                    [~,name,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uRefs(i));
                    sp2U = find(app.Refs==uRefs(i));
                    for j = sp2U
                        ids2U = GetDataInds(app, uAns{1}, app.Stims(j), app.Refs(j), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                        if ~isempty(ids2U)
                            if isempty(uStimsUsed)
                                uStimsUsed = [uStimsUsed app.Stims(j)];
                            else
                                if ~any(ismember(uStimsUsed,app.Stims(j)))
                                    uStimsUsed = [uStimsUsed app.Stims(j)];
                                end
                            end
                            if isempty(uRefsUsed)
                                uRefsUsed = [uRefsUsed app.Refs(j)];
                            else
                                if ~any(ismember(uRefsUsed,app.Refs(j)))
                                    uRefsUsed = [uRefsUsed app.Refs(j)];
                                end
                            end

                            if app.AnimalsandEyesToUse{1,3}
                                eyeSel = 'LEye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagL_NystagCorr';
                                    toUseMag = 'MagL_NystagCorr';
                                    toUseSMag = 'stdMagL_NystagCorr';
                                    toUseMMis = 'meanMisalignL_NystagCorr';
                                    toUseMis = 'MisalignL_NystagCorr';
                                    toUseSMis = 'stdMisalignL_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagL';
                                    toUseMag = 'MagL';
                                    toUseSMag = 'stdMagL';
                                    toUseMMis = 'meanMisalignL';
                                    toUseMis = 'MisalignL';
                                    toUseSMis = 'stdMisalignL';
                                    DataType = 'NonCorrected';
                                end
                            else
                                eyeSel = 'REye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagR_NystagCorr';
                                    toUseMag = 'MagR_NystagCorr';
                                    toUseSMag = 'stdMagR_NystagCorr';
                                    toUseMMis = 'meanMisalignR_NystagCorr';
                                    toUseMis = 'MisalignR_NystagCorr';
                                    toUseSMis = 'stdMisalignR_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagR';
                                    toUseMag = 'MagR';
                                    toUseSMag = 'stdMagR';
                                    toUseMMis = 'meanMisalignR';
                                    toUseMis = 'MisalignR';
                                    toUseSMis = 'stdMisalignR';
                                    DataType = 'NonCorrected';
                                end
                            end
                            if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                transformed = 'NONTRANSFORMED';
                            else
                                transformed = 'TRANSFORMED';
                            end
                            if app.DataTransparency.Value
                                transparencyTH = ['Thresh',num2str(app.TransparencyThreshold.Value),'_'];
                            else
                                transparencyTH = '';
                            end
                            toAlpha = [];
                            alphaVal = .45;
                            lw = 2.5;

                            hold(magPlot,'on');
                            [color,stimName,mark,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, app.Stims(j));
                            if ~ldgFlg
                                if ~issorted([app.params(ids2U).p1amp])
                                    [samp, saI] = sort([app.params(ids2U).p1amp]);
                                    ids2U = ids2U(saI);
                                end
                                a={app.params(ids2U).(toUseMag)};
                                aa=cellfun(@size,a,'uni',false);
                                ca = cell2mat(aa');
                                amp = [app.params(ids2U).p1amp];
                                vel = [app.params(ids2U).(toUseMMag)];
                                if app.PlottingStandardDeviationCheckBox.Value
                                    std = [app.params(ids2U).(toUseSMag)]./sqrt([ca(:,2)']);
                                    stType = 'SEM';
                                else
                                    std = [app.params(ids2U).(toUseSMag)];
                                    stType = 'STD';
                                end
                            else
                                if app.PlottingStandardDeviationCheckBox.Value
                                    stType = 'SEM';
                                else
                                    stType = 'STD';
                                end
                                amp = [0];
                                vel = [0];
                                std = [0];
                            end
                            if app.DataTransparency.Value && ~ldgFlg
                                avail = 1:length(vel);
                                toAlpha = find(vel<app.TransparencyThreshold.Value);
                                if ~isempty(toAlpha)
                                    if length(toAlpha)+1 <= length(vel)
                                        bd = [toAlpha toAlpha(end)+1];
                                        line  = patchline(amp(bd),vel(bd),'Parent',magPlot,'linestyle','-','edgecolor',color{1},'linewidth',lw,'edgealpha',alphaVal);
                                    else
                                        line  = patchline(amp(toAlpha),vel(toAlpha),'Parent',magPlot,'linestyle','-','edgecolor',color{1},'linewidth',lw,'edgealpha',alphaVal);
                                    end

                                    points = scatter(magPlot,amp(toAlpha),vel(toAlpha),'SizeData',400,'MarkerEdgeColor',color{1},'marker','o','LineWidth',lw,'MarkerEdgeAlpha',alphaVal);
                                    erPlot = errorbar(magPlot,amp(toAlpha),vel(toAlpha),std(toAlpha),'color',color{1},'LineWidth',lw,'CapSize',12,'LineStyle','none');
                                    erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                    set([erPlot.Bar, erPlot.Line], 'ColorType', 'truecoloralpha', 'ColorData', [erPlot.Line.ColorData(1:3); 255*(alphaVal-.1)])
                                    set(erPlot.Cap, 'EdgeColorType', 'truecoloralpha', 'EdgeColorData', [erPlot.Cap.EdgeColorData(1:3); 255*(alphaVal-.1)])
                                    erPlot.UserData = ids2U(toAlpha);
                                end

                                lb = find(~ismember(avail,toAlpha));
                                if ~isempty(lb)
%                                     if lb(1) ~= 1
%                                         lb = [lb(1)-1 lb];
%                                     end
                                    line  = patchline(amp(lb),vel(lb),'Parent',magPlot,'linestyle','-','edgecolor',color{1},'linewidth',4,'edgealpha',1);
                                    points = scatter(magPlot,amp(lb),vel(lb),'SizeData',400,'MarkerEdgeColor',color{1},'marker','o','LineWidth',4,'MarkerEdgeAlpha',1);
                                    erPlot = errorbar(magPlot,amp(lb),vel(lb),std(lb),'color',color{1},'LineWidth',4,'CapSize',12,'LineStyle','none');
                                    erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                    erPlot.UserData = ids2U(lb);
                                end

                                
                            else
                                line = plot(magPlot,amp,vel,'color',color{1},'LineWidth',4);
                                points = scatter(magPlot,amp,vel,'SizeData',400,'MarkerEdgeColor',color{1},'marker','o','LineWidth',4,'MarkerEdgeAlpha',1);
                                erPlot = errorbar(magPlot,amp,vel,std,'color',color{1},'LineWidth',4,'CapSize',12);
                                erPlot.UserData = ids2U;
                            end
%                             line = plot(magPlot,amp,vel,'color',color{1},'LineWidth',4);
%                             points = plot(magPlot,amp,vel,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
%                             erPlot = errorbar(magPlot,amp,vel,std,'color',color{1},'LineWidth',4,'CapSize',12);
%                             erPlot.UserData = ids2U;
                            if any([app.params(ids2U).FacialNerve])
                                if ~ldgFlg
                                    f2u = find([app.params(ids2U).FacialNerve]);
                                    twitchPlot = plot(magPlot,amp(f2u),vel(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                    twitchPlot.UserData = ids2U(f2u);
                                    twitchPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                else
                                    twitchPlot = plot(magPlot,0,0,'rx','MarkerSize', 30,'LineWidth',4);
                                end
                                allTwitch = [allTwitch twitchPlot];
                            else
                                twitchPlot = 0;
                            end
                            hold(magPlot,'off');

                            if ~ldgFlg
                                hold(misalignPlot,'on');
                                [color,stimName,mark,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, app.Stims(j));
                                if ~issorted([app.params(ids2U).p1amp])
                                    [samp, saI] = sort([app.params(ids2U).p1amp]);
                                    ids2U = ids2U(saI);
                                end
                                amp = [app.params(ids2U).p1amp];

                                if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                    mis = [app.params(ids2U).(toUseMMis)];
                                    a={app.params(ids2U).(toUseMis)};
                                    aa=cellfun(@size,a,'uni',false);
                                    ca = cell2mat(aa');
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
                                        %                                         std = [valsSTD]./sqrt([ca(:,2)']);
                                        stType = 'SEM';
                                    else
                                        std = [app.params(ids2U).(toUseSMis)];
                                        %                                         std = [valsSTD];
                                        stType = 'STD';
                                    end
                                    transformed = 'NONTRANSFORMED';
                                else
                                    [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U, eyeSel, uAns{1});
                                    mis = misValsMean';
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [misValsSTD']./sqrt(tot');
                                        stType = 'SEM';
                                    else
                                        std = [misValsSTD'];
                                        stType = 'STD';
                                    end
                                    transformed = 'TRANSFORMED';
                                end
                                if ~isempty(toAlpha)
                                    avail = 1:length(mis);
                                    if ~isempty(toAlpha)
                                        if length(toAlpha)+1 <= length(vel)
                                            bd = [toAlpha toAlpha(end)+1];
                                            line  = patchline(amp(bd),mis(bd),'Parent',misalignPlot,'linestyle','-','edgecolor',color{1},'linewidth',lw,'edgealpha',alphaVal);
                                        else
                                            line  = patchline(amp(toAlpha),mis(toAlpha),'Parent',misalignPlot,'linestyle','-','edgecolor',color{1},'linewidth',lw,'edgealpha',alphaVal);
                                        end
                                        points = scatter(misalignPlot,amp(toAlpha),mis(toAlpha),'SizeData',400,'MarkerEdgeColor',color{1},'marker','o','LineWidth',lw,'MarkerEdgeAlpha',alphaVal);

                                        erPlot = errorbar(misalignPlot,amp(toAlpha),mis(toAlpha),std(toAlpha),'color',color{1},'LineWidth',lw,'CapSize',12,'LineStyle','none');
                                        set([erPlot.Bar, erPlot.Line], 'ColorType', 'truecoloralpha', 'ColorData', [erPlot.Line.ColorData(1:3); 255*(alphaVal-.1)])
                                        set(erPlot.Cap, 'EdgeColorType', 'truecoloralpha', 'EdgeColorData', [erPlot.Cap.EdgeColorData(1:3); 255*(alphaVal-.1)])
                                        erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                        erPlot.UserData = ids2U(toAlpha);
                                    end

                                    lb = find(~ismember(avail,toAlpha));
                                    if ~isempty(lb)
%                                         if lb ~= 1
%                                             lb = [lb(1)-1 lb];
%                                         end
                                        line  = patchline(amp(lb),mis(lb),'Parent',misalignPlot,'linestyle','-','edgecolor',color{1},'linewidth',4,'edgealpha',1);
                                        points = scatter(misalignPlot,amp(lb),mis(lb),'SizeData',400,'MarkerEdgeColor',color{1},'marker','o','LineWidth',4,'MarkerEdgeAlpha',1);
                                        erPlot = errorbar(misalignPlot,amp(lb),mis(lb),std(lb),'color',color{1},'LineWidth',4,'CapSize',12,'LineStyle','none');
                                        erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                        erPlot.UserData = ids2U(lb);
                                    end
                                else
                                    line = plot(misalignPlot,amp,mis,'color',color{1},'LineWidth',4);
                                    points = scatter(misalignPlot,amp,mis,'SizeData',400,'MarkerEdgeColor',color{1},'marker','o','LineWidth',4,'MarkerEdgeAlpha',1);
                                    erPlot = errorbar(misalignPlot,amp,mis,std,'color',color{1},'LineWidth',4,'CapSize',12);
                                    erPlot.UserData = ids2U;
                                end
%                                 line = plot(misalignPlot,amp,mis,'color',color{1},'LineWidth',4);
%                                 points = plot(misalignPlot,amp,mis,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
%                                 erPlot = errorbar(misalignPlot,amp,mis,std,'color',color{1},'LineWidth',4,'CapSize',12);
%                                 erPlot.UserData = ids2U;
                                if any([app.params(ids2U).FacialNerve])
                                    twitchPlot = plot(misalignPlot,amp(f2u),mis(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                    twitchPlot.UserData = ids2U(f2u);
                                    twitchPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                else
                                    twitchPlot = 0;
                                end
                                hold(misalignPlot,'off');
                            end

                            if isempty(ldgNames)
                                ldgNames = [ldgNames {[stimName{1}]}];
                                ldgLine = [ldgLine line];
                            else
                                if ~ismember(ldgNames,[stimName{1}])
                                    ldgNames = [ldgNames {[stimName{1}]}];
                                    ldgLine = [ldgLine line];
                                end
                            end
                            ids = [ids; {ids2U}];
                        end

                    end

                    fsze = 27;
                    if i == 1
                        magPlot.YLabel.String = 'Velocity (dps)';
                        magPlot.YLabel.FontSize = fsze;
                        misalignPlot.YLabel.String = 'Misalignment (d)';
                        misalignPlot.YLabel.FontSize = fsze;
                    else
                        magPlot.YTickLabel = [];
                        misalignPlot.YTickLabel = [];
                    end
                    magPlot.XTickLabel = [];
                    if isequal(up1a,[20 50 75 100 125 150 175 200])
                        magPlot.XTick = [20 50 75 100 125 150 175 200];
                    else
                        magPlot.XTick = [0:50:up1a(end)];
                    end
                    magPlot.XGrid = 'on';
                    magPlot.YGrid = 'on';
                    if isequal(up1a,[20 50 75 100 125 150 175 200])
                        magPlot.XLim = [up1a(1)-20 up1a(end)+20];
                    else
                        if length(uRefs) > 3
                            magPlot.XLim = [up1a(1)-25 up1a(end)+40];
                        else
                            magPlot.XLim = [up1a(1)-25 up1a(end)+25];
                        end
                    end
                    magPlot.FontSize = fsze;
                    magPlot.Title.String = name{1};
                    magPlot.LineWidth = 2.5;
                    magPlot.Title.FontSize = fsze;

                    if isequal(up1a,[20 50 75 100 125 150 175 200])
                        misalignPlot.XTick = [20 50 75 100 125 150 175 200];
                    else
                        misalignPlot.XTick = [0:50:up1a(end)];
                    end
                    
                    misalignPlot.XGrid = 'on';
                    misalignPlot.YGrid = 'on';
                    if isequal(up1a,[20 50 75 100 125 150 175 200])
                        misalignPlot.XLim = [up1a(1)-20 up1a(end)+20];
                    else
                        if length(uRefs) > 3
                            misalignPlot.XLim = [up1a(1)-25 up1a(end)+40];
                        else
                            misalignPlot.XLim = [up1a(1)-25 up1a(end)+25];
                        end
                    end
                    misalignPlot.FontSize = fsze;
                    misalignPlot.XLabel.String = {'Current (uA)'};
                    misalignPlot.XLabel.FontSize = fsze;
                    misalignPlot.LineWidth = 2.5;
                    misalignPlot.XTickLabelRotation = 0;

                    if plotLoc ~= 1
                        if ~isempty(app.ByRefAmpX.UserData)
                            if isfield(app.ByRefAmpX.UserData,'MagPlot')
                                if ~isempty(app.ByRefAmpX.UserData.MagPlot)
                                    magPlot.XLim = app.ByRefAmpX.UserData.MagPlot(1,:);
                                    magPlot.YLim = app.ByRefAmpX.UserData.MagPlot(2,:);
                                end
                            end

                            if isfield(app.ByRefAmpX.UserData,'MisPlot')
                                if ~isempty(app.ByRefAmpX.UserData.MisPlot)
                                    misalignPlot.XLim = app.ByRefAmpX.UserData.MisPlot(1,:);
                                    misalignPlot.YLim = app.ByRefAmpX.UserData.MisPlot(2,:);
                                end
                            end
                        end
                    end

                    magPlots = [magPlots magPlot];
                    misalignPlots = [misalignPlots misalignPlot];

                    if ldgFlg
                        magPlot.Visible = 0;
                        misalignPlot.Visible = 0;
                    end
                end
                sameaxes([],[magPlots])
                sameaxes([],[misalignPlots])
                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end


                if ~isempty(allTwitch)
                    ldgNames = [ldgNames {'Facial Twitch Observed'}];
                    ldgLine = [ldgLine allTwitch(1)];
                end
                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                    if length(ldgLine) > 1
                        ldg.Title.String = 'Stimulating Electrodes:';
                    else
                        ldg.Title.String = 'Stimulating Electrode:';
                    end
                end
                uStims = sort(uStimsUsed);
                uRefs = sort(uRefsUsed);
                app.FigureName = [uAns{1},'_',eyeSel,'_MisandVel_Current_ByRef_',transformed,'_',transparencyTH,stType,'_',strrep(num2str(uStims),' ',''),'_',strrep(num2str(uRefs),' ',''),'_',cUsed,'_',DataType];
            end
        end

        function plotByRefandCanalCurrentAsX(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.ByRefandCanalAmpasXTab;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                marg_w = [.05 .015];
            end
            uStims = unique(app.Stims);
            s = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uStims);
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = s;
            uRefs = unique(app.Refs);
            r = OrderElectrodesByAnimal(app, uAns{1}, uRefs);
            uRefs = r;
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];
            axs = sum(app.Canals);
            axpos = 1;

            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                for i = 1:length(uRefs)
                    for j = 1:axs
                        if app.Canals(j)
                            magPlot = subtightplot(2,length(uRefs)*axs,axpos,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                            magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                            misalignPlot = subtightplot(2,length(uRefs)*axs,axpos+axs*length(uRefs),[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                            misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                            if (i == 1) && (plotLoc == 1)
                                app.ByRefandCanalAmpasXTab.UserData = [];
                            end
                            [~,name,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uRefs(i));
                            canalStims = find(ismember(app.AnimalProperties.(uAns{1}).elecByCanal{j},uStims));

                            for k = canalStims
                                us = app.AnimalProperties.(uAns{1}).elecByCanal{j}(k);
                                ids2U = GetDataInds(app, uAns{1}, us, uRefs(i), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                                if ~isempty(ids2U)
                                    if app.AnimalsandEyesToUse{1,3}
                                        eyeSel = 'LEye';
                                        if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                            toUseMMag = 'meanMagL_NystagCorr';
                                            toUseMag = 'MagL_NystagCorr';
                                            toUseSMag = 'stdMagL_NystagCorr';
                                            toUseMMis = 'meanMisalignL_NystagCorr';
                                            toUseMis = 'MisalignL_NystagCorr';
                                            toUseSMis = 'stdMisalignL_NystagCorr';
                                            DataType = 'NystagCorr';
                                        else
                                            toUseMMag = 'meanMagL';
                                            toUseMag = 'MagL';
                                            toUseSMag = 'stdMagL';
                                            toUseMMis = 'meanMisalignL';
                                            toUseMis = 'MisalignL';
                                            toUseSMis = 'stdMisalignL';
                                            DataType = 'NonCorrected';
                                        end
                                    else
                                        eyeSel = 'REye';
                                        if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                            toUseMMag = 'meanMagR_NystagCorr';
                                            toUseMag = 'MagR_NystagCorr';
                                            toUseSMag = 'stdMagR_NystagCorr';
                                            toUseMMis = 'meanMisalignR_NystagCorr';
                                            toUseMis = 'MisalignR_NystagCorr';
                                            toUseSMis = 'stdMisalignR_NystagCorr';
                                            DataType = 'NystagCorr';
                                        else
                                            toUseMMag = 'meanMagR';
                                            toUseMag = 'MagR';
                                            toUseSMag = 'stdMagR';
                                            toUseMMis = 'meanMisalignR';
                                            toUseMis = 'MisalignR';
                                            toUseSMis = 'stdMisalignR';
                                            DataType = 'NonCorrected';
                                        end
                                    end
                                    if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                        transformed = 'NONTRANSFORMED';
                                    else
                                        transformed = 'TRANSFORMED';
                                    end
                                    hold(magPlot,'on');

                                    [color,stimName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, us);
                                    if ~ldgFlg
                                        if ~issorted([app.params(ids2U).p1amp])
                                            [samp, saI] = sort([app.params(ids2U).p1amp]);
                                            ids2U = ids2U(saI);
                                        end
                                        amp = [app.params(ids2U).p1amp];
                                        vel = [app.params(ids2U).(toUseMMag)];
                                        a={app.params(ids2U).(toUseMag)};
                                        aa=cellfun(@size,a,'uni',false);
                                        ca = cell2mat(aa');
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            std = [app.params(ids2U).(toUseSMag)]./sqrt([ca(:,2)']);
                                            stType = 'SEM';
                                        else
                                            std = [app.params(ids2U).(toUseSMag)];
                                            stType = 'STD';
                                        end
                                    else
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            stType = 'SEM';
                                        else
                                            stType = 'STD';
                                        end
                                        amp = [0];
                                        vel = [0];
                                        std = [0];
                                    end
                                    line = plot(magPlot,amp,vel,'color',color{1},'LineWidth',4);
                                    points = plot(magPlot,amp,vel,'color',color{1},'marker','o','MarkerSize',20,'LineStyle','none','LineWidth',4);
                                    erPlot = errorbar(magPlot,amp,vel,std,'color',color{1},'LineWidth',4,'CapSize',12);
                                    erPlot.UserData = ids2U;
                                    if any([app.params(ids2U).FacialNerve])
                                        if ~ldgFlg
                                            f2u = find([app.params(ids2U).FacialNerve]);
                                            twitchPlot = plot(magPlot,amp(f2u),vel(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                        else
                                            twitchPlot = plot(magPlot,0,0,'rx','MarkerSize', 30,'LineWidth',4);
                                        end
                                        allTwitch = [allTwitch twitchPlot];
                                    else
                                        twitchPlot = 0;
                                    end
                                    hold(magPlot,'off');

                                    if ~ldgFlg
                                        hold(misalignPlot,'on');
                                        [color,stimName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, us);
                                            if ~issorted([app.params(ids2U).p1amp])
                                                [samp, saI] = sort([app.params(ids2U).p1amp]);
                                                ids2U = ids2U(saI);
                                            end
                                            amp = [app.params(ids2U).p1amp];
                                            if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                                mis = [app.params(ids2U).(toUseMMis)];
                                                a={app.params(ids2U).(toUseMis)};
                                                aa=cellfun(@size,a,'uni',false);
                                                ca = cell2mat(aa');
                                                if app.PlottingStandardDeviationCheckBox.Value
                                                    std = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
                                                    %                                         std = [valsSTD]./sqrt([ca(:,2)']);
                                                    stType = 'SEM';
                                                else
                                                    std = [app.params(ids2U).(toUseSMis)];
                                                    %                                         std = [valsSTD];
                                                    stType = 'STD';
                                                end
                                                transformed = 'NONTRANSFORMED';
                                            else
                                                [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U, eyeSel, uAns{1});
                                                mis = misValsMean';
                                                if app.PlottingStandardDeviationCheckBox.Value
                                                    std = [misValsSTD']./sqrt(tot');
                                                    stType = 'SEM';
                                                else
                                                    std = [misValsSTD'];
                                                    stType = 'STD';
                                                end
                                                transformed = 'TRANSFORMED';
                                            end
                                        line = plot(misalignPlot,amp,mis,'color',color{1},'LineWidth',4);
                                        points = plot(misalignPlot,amp,mis,'color',color{1},'marker','o','MarkerSize',20,'LineStyle','none','LineWidth',4);
                                        erPlot = errorbar(misalignPlot,amp,mis,std,'color',color{1},'LineWidth',4,'CapSize',12);
                                        erPlot.UserData = ids2U;
                                        if any([app.params(ids2U).FacialNerve])
                                            twitchPlot = plot(misalignPlot,amp(f2u),mis(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                        else
                                            twitchPlotL = 0;
                                        end
                                        hold(misalignPlot,'off');
                                    end

                                    if isempty(ldgNames)
                                        ldgNames = [ldgNames {[stimName{1}]}];
                                        ldgLine = [ldgLine line];
                                    else
                                        if ~ismember(ldgNames,[stimName{1}])
                                            ldgNames = [ldgNames {[stimName{1}]}];
                                            ldgLine = [ldgLine line];
                                        end
                                    end

                                    ids = [ids; {ids2U}];
                                end
                            end
                        end
                        if axpos == 1
                            magPlot.YLabel.String = 'Velocity (dps)';
                            magPlot.YLabel.FontSize = 30;
                            misalignPlot.YLabel.String = 'Misalignment (dps)';
                            misalignPlot.YLabel.FontSize = 30;
                        else
                            magPlot.YTickLabel = [];
                            misalignPlot.YTickLabel = [];
                        end
                        magPlot.XTickLabel = [];
                        magPlot.XTick = [up1a(1)-20 up1a];
                        magPlot.XGrid = 'on';
                        magPlot.YGrid = 'on';
                        magPlot.XLim = [up1a(1)-20 up1a(end)+20];
                        magPlot.FontSize = 30;
                        if j == 1
                            cUsed = 'LARP';
                        elseif j == 2
                            cUsed = 'RALP';
                        else
                            cUsed = 'LHRH';
                        end
                        magPlot.Title.String = [cUsed,' vs ',name{1}];
                        magPlot.LineWidth = 3;
                        magPlot.Title.FontSize = 30;


                        misalignPlot.XTick = [up1a(1)-20 up1a];
                        misalignPlot.XGrid = 'on';
                        misalignPlot.YGrid = 'on';
                        misalignPlot.XLim = [up1a(1)-20 up1a(end)+20];
                        misalignPlot.FontSize = 30;
                        misalignPlot.XLabel.String = {'Current (uA)'};
                        misalignPlot.XLabel.FontSize = 30;
                        misalignPlot.LineWidth = 3;

                        if plotLoc ~= 1
                            if ~isempty(app.ByRefandCanalAmpasXTab.UserData)
                                if isfield(app.ByRefandCanalAmpasXTab.UserData,'MagPlot')
                                    if ~isempty(app.ByRefandCanalAmpasXTab.UserData.MagPlot)
                                        magPlot.XLim = app.ByRefandCanalAmpasXTab.UserData.MagPlot(1,:);
                                        magPlot.YLim = app.ByRefandCanalAmpasXTab.UserData.MagPlot(2,:);
                                    end
                                end

                                if isfield(app.ByRefandCanalAmpasXTab.UserData,'MisPlot')
                                    if ~isempty(app.ByRefandCanalAmpasXTab.UserData.MisPlot)
                                        misalignPlot.XLim = app.ByRefandCanalAmpasXTab.UserData.MisPlot(1,:);
                                        misalignPlot.YLim = app.ByRefandCanalAmpasXTab.UserData.MisPlot(2,:);
                                    end
                                end
                            end
                        end

                        magPlots = [magPlots magPlot];
                        misalignPlots = [misalignPlots misalignPlot];

                        if ldgFlg
                            magPlot.Visible = 0;
                            misalignPlot.Visible = 0;
                        end
                        axpos = axpos+1;
                    end


                end
                misalignPlot.YLim = [0 100];
                sameaxes([],[magPlots])
                sameaxes([],[misalignPlots])
                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end


                if ~isempty(allTwitch)
                    ldgNames = [ldgNames {'Facial Twitch Observed'}];
                    ldgLine = [ldgLine allTwitch(1)];
                end
                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                    if length(ldgLine) > 1
                        ldg.Title.String = 'Stimulating Electrodes:';
                    else
                        ldg.Title.String = 'Stimulating Electrode:';
                    end
                end
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                end
                app.FigureName = [uAns{1},'_',eyeSel,'_MisalignmentandVelocity_Current_ByRef_',transformed,'_',stType,'_',strrep(num2str(uStims),' ',''),'_',strrep(num2str(uRefs),' ',''),'_',cUsed,'_',DataType];
            end
        end
        
        function GetPossiblePairsStims(app)
            possSubt = [];
            possDivd = [];
            subT = [];
            divD = [];
            coms = [];
            uso = unique([app.Stims],'stable');
            for i = uso
                findDif = ~ismember([app.Stims],i);
                us = unique([app.Stims(findDif)],'stable');
                ff = find(~findDif);
                for ii = us
                    findComp = find(ismember([app.Stims],ii));
                    ro = app.Refs(ff);
                    rn = app.Refs(findComp);
                    pairs = ro(ismember(ro,rn));
                    pairs = unique(pairs,'stable');
                    for j = pairs
                        coms = [coms; i j ii j];
                        nS = ['s',num2str(i),'r',num2str(j),'-','s',num2str(ii),'r',num2str(j)];
                        nD = ['s',num2str(i),'r',num2str(j),'/','s',num2str(ii),'r',num2str(j)];
                        possSubt = [possSubt {nS}];
                        subT = [subT; 1];
                        divD = [divD; 2];
                        possDivd = [possDivd {nD}];
                    end
                end
            end
            app.Options.Items = [possSubt possDivd];
            optStruct = struct();
            optStruct.coms = [coms;coms];
            optStruct.mat = [subT; divD];
            app.Options.UserData = optStruct;
        end
        
        function ComparePairsByStim(app, plotLoc,ldgFlg)
            if plotLoc == 1
                loc = app.ComparePairsByStimAmpasXTab;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                marg_w = [.05 .015];
            end
            toUse = ismember(app.Options.Items,app.Options.Value);
            allCs = app.Options.UserData.coms(toUse,:);
            allMs = app.Options.UserData.mat(toUse,:);
            
            uStims = unique([allCs(:,1) allCs(:,3)],'rows','stable');
            uStimsSZ = size(uStims);
            
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');

            uRefs = unique(app.Options.UserData.coms(toUse,2));
            uRefs = OrderElectrodesByAnimal(app, uRefs);
            
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];
            yLab1 = '';
            yLab2 = '';
            normType = '';
            meanTxtV = [];
            meanTxtM = [];

            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                for i = 1:uStimsSZ(1)
                    axTitle = '';
                    magPlot = subtightplot(2,uStimsSZ(1),i,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot = subtightplot(2,uStimsSZ(1),i+uStimsSZ(1),[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    if (i == 1) && (plotLoc == 1)
                        app.ComparePairsByStimAmpasXTab.UserData = [];
                    end
                    [~,name1,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uStims(i,1));

                    [~,name2,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uStims(i,2));
                    comIds = find(allCs(:,1)==uStims(i,1));
                    vd = zeros(length(comIds),length(up1a));
                    md = zeros(length(comIds),length(up1a));
                    for j = 1:length(comIds)
                        r = allCs(comIds(j),2);
                        ids2U1 = GetDataInds(app, uAns{1}, uStims(i,1), r, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                        ids2U2 = GetDataInds(app, uAns{1}, uStims(i,2), r, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                        if (~isempty(ids2U1)) && (~isempty(ids2U2))
                            if app.AnimalsandEyesToUse{1,3}
                                eyeSel = 'LEye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagL_NystagCorr';
                                    toUseMag = 'MagL_NystagCorr';
                                    toUseSMag = 'stdMagL_NystagCorr';
                                    toUseMMis = 'meanMisalignL_NystagCorr';
                                    toUseMis = 'MisalignL_NystagCorr';
                                    toUseSMis = 'stdMisalignL_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagL';
                                    toUseMag = 'MagL';
                                    toUseSMag = 'stdMagL';
                                    toUseMMis = 'meanMisalignL';
                                    toUseMis = 'MisalignL';
                                    toUseSMis = 'stdMisalignL';
                                    DataType = 'NonCorrected';
                                end
                            else
                                eyeSel = 'REye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagR_NystagCorr';
                                    toUseMag = 'MagR_NystagCorr';
                                    toUseSMag = 'stdMagR_NystagCorr';
                                    toUseMMis = 'meanMisalignR_NystagCorr';
                                    toUseMis = 'MisalignR_NystagCorr';
                                    toUseSMis = 'stdMisalignR_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagR';
                                    toUseMag = 'MagR';
                                    toUseSMag = 'stdMagR';
                                    toUseMMis = 'meanMisalignR';
                                    toUseMis = 'MisalignR';
                                    toUseSMis = 'stdMisalignR';
                                    DataType = 'NonCorrected';
                                end
                            end
                            if ~issorted([app.params(ids2U1).p1amp])
                                [samp, saI] = sort([app.params(ids2U1).p1amp]);
                                ids2U1 = ids2U1(saI);
                            end
                            if ~issorted([app.params(ids2U2).p1amp])
                                [samp, saI] = sort([app.params(ids2U2).p1amp]);
                                ids2U2 = ids2U2(saI);
                            end
                            if length(ids2U1) > length(ids2U2)
                                ampMatch = ismember([app.params(ids2U1).p1amp],[app.params(ids2U2).p1amp]);
                                ids2U1(~ampMatch) = [];
                            elseif length(ids2U1) < length(ids2U2)
                                ampMatch = ismember([app.params(ids2U2).p1amp],[app.params(ids2U1).p1amp]);
                                ids2U2(~ampMatch) = [];
                            end
                            hold(magPlot,'on');

                            [color,refName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, r);
                            if ~ldgFlg
                                amp = [app.params(ids2U1).p1amp];
                                if allMs(comIds(j)) == 1
                                    vel = [[app.params(ids2U1).(toUseMMag)]-[app.params(ids2U2).(toUseMMag)]];
                                    yLab1 = 'Velocity Difference (dps)';
                                    yLab2 = 'Misalignment Differnce (degrees)';
                                    axTitle = [name1{1},' - ',name2{1}];
                                    normType = 'Difference';
                                elseif allMs(comIds(j)) == 2
                                    vel = [[app.params(ids2U1).(toUseMMag)]./[app.params(ids2U2).(toUseMMag)]];
                                    yLab1 = 'Velocity Ratio';
                                    yLab2 = 'Misalignment Ratio';
                                    axTitle = [name1{1},'/',name2{1}];
                                    normType = 'Ratio';
                                end
                                if length(vel) < length(vd(j,:))
                                    am = ismember([up1a],[app.params(ids2U1).p1amp]);
                                    vd(j,am) = vel;
                                    vd(j,~am) = NaN;
                                else
                                    vd(j,:) = vel;
                                end
                                mAmp = up1a;
                            else
                                if allMs(comIds(j)) == 1
                                    normType = 'Difference';
                                elseif allMs(comIds(j)) == 2
                                    normType = 'Ratio';
                                end
                                amp = [0];
                                vel = [0];
                                std = [0];
                                vd = 0;
                                mAmp = 0;
                            end

                            line = plot(magPlot,amp,vel,'color',color{1},'LineWidth',4);
                            points = plot(magPlot,amp,vel,'color',color{1},'marker','o','MarkerSize',20,'LineStyle','none','LineWidth',4);
                            points.UserData = [ids2U1; ids2U2];

                            if (j == length(comIds)) && (app.IncludeAverageButton.Value)
                                mv = mean(vd,'omitnan');
                                szz = length(vd);
                                if app.PlottingStandardDeviationCheckBox.Value
                                    sv = [std(vd,'omitnan');]./sqrt(szz);
                                    stType = 'SEM_';
                                else
                                    sv = std(vd,'omitnan');
                                    stType = 'STD_';
                                end
                                lineM = plot(magPlot,mAmp,mv,'color','k','LineWidth',4);
                                points = plot(magPlot,mAmp,mv,'color','k','marker','o','MarkerSize',20,'LineStyle','none','LineWidth',4);
                                erPlot = errorbar(magPlot,mAmp,mv,sv,'color','k','LineWidth',4,'CapSize',12);
                                if plotLoc == 1
                                    x = mAmp(end-1);
                                    y = magPlot.YLim(2);
                                    mtot = mean(mv);
                                    szz = length(mv);
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        stdTot = [std(mv,'omitnan');]./sqrt(szz);
                                        stType = 'SEM_';
                                    else
                                        stdTot = std(mv,'omitnan');
                                        stType = 'STD_';
                                    end
                                    txt = [num2str(round(mtot,3)),'±',num2str(round(stdTot,3))];
                                    mtv = text(magPlot,x,y,txt);
                                    meanTxtV = [meanTxtV mtv];
                                end
                            else
                                stType = '';
                            end
                            hold(magPlot,'off');

                            if ~ldgFlg
                                hold(misalignPlot,'on');
                                [color,refName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, r);
                                if ~ldgFlg
                                    amp = [app.params(ids2U1).p1amp];
                                    if allMs(comIds(j)) == 1
                                        mis = [[app.params(ids2U1).(toUseMMis)]-[app.params(ids2U2).(toUseMMis)]];
                                    elseif allMs(comIds(j)) == 2
                                        mis = [[app.params(ids2U1).(toUseMMis)]./[app.params(ids2U2).(toUseMMis)]];
                                    end
                                    if length(mis) < length(md(j,:))
                                        am = ismember([up1a],[app.params(ids2U1).p1amp]);
                                        md(j,am) = mis;
                                        md(j,~am) = NaN;
                                    else
                                        md(j,:) = mis;
                                    end
                                else
                                    amp = [0];
                                    mis = [0];
                                    std = [0];
                                end
                                line = plot(misalignPlot,amp,mis,'color',color{1},'LineWidth',4);
                                points = plot(misalignPlot,amp,mis,'color',color{1},'marker','o','MarkerSize',20,'LineStyle','none','LineWidth',4);
                                points.UserData = [ids2U1; ids2U2];
                                if (j == length(comIds)) && (app.IncludeAverageButton.Value)
                                    mm = mean(md,'omitnan');
                                    szz = length(md);
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        sm = [std(md,'omitnan');]./sqrt(szz);
                                        stType = 'SEM_';
                                    else
                                        sm = std(md,'omitnan');
                                        stType = 'STD_';
                                    end
                                    lineM = plot(misalignPlot,up1a,mm,'color','k','LineWidth',4);
                                    points = plot(misalignPlot,up1a,mm,'color','k','marker','o','MarkerSize',20,'LineStyle','none','LineWidth',4);
                                    erPlot = errorbar(misalignPlot,up1a,mm,sm,'color','k','LineWidth',4,'CapSize',12);

                                    if plotLoc == 1
                                        x = up1a(end-1);
                                        y = misalignPlot.YLim(2);
                                        mtot = mean(mm);
                                        szz = length(mm);
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            stdTot = [std(mm,'omitnan');]./sqrt(szz);
                                            stType = 'SEM_';
                                        else
                                            stdTot = std(mm,'omitnan');
                                            stType = 'STD_';
                                        end
                                        txt = [num2str(round(mtot,3)),'±',num2str(round(stdTot,3))];
                                        mtm = text(misalignPlot,x,y,txt);
                                        meanTxtM = [meanTxtM mtm];
                                    end
                                else
                                    stType = '';
                                end

                                hold(misalignPlot,'off');
                            end

                            if isempty(ldgNames)
                                ldgNames = [ldgNames {[refName{1}]}];
                                ldgLine = [ldgLine line];
                            else
                                if ~ismember(ldgNames,[refName{1}])
                                    ldgNames = [ldgNames {[refName{1}]}];
                                    ldgLine = [ldgLine line];
                                end
                            end
                        end
                    end
                    if i == 1

                        magPlot.YLabel.String = yLab1;
                        magPlot.YLabel.FontSize = 30;
                        misalignPlot.YLabel.String = yLab2;
                        misalignPlot.YLabel.FontSize = 30;
                    else
                        magPlot.YTickLabel = [];
                        misalignPlot.YTickLabel = [];
                    end
                    magPlot.XTickLabel = [];
                    magPlot.XTick = [up1a(1)-25 up1a];
                    magPlot.XGrid = 'on';
                    magPlot.YGrid = 'on';
                    magPlot.XLim = [up1a(1)-25 up1a(end)+25];
                    magPlot.FontSize = 30;
                    magPlot.Title.String = axTitle;
                    magPlot.LineWidth = 2.5;
                    magPlot.Title.FontSize = 30;


                    misalignPlot.XTick = [up1a(1)-25 up1a];
                    misalignPlot.XGrid = 'on';
                    misalignPlot.YGrid = 'on';
                    misalignPlot.XLim = [up1a(1)-25 up1a(end)+25];
                    misalignPlot.FontSize = 30;
                    misalignPlot.XLabel.String = {'Current (uA)'};
                    misalignPlot.XLabel.FontSize = 30;
                    misalignPlot.LineWidth = 2.5;

                    magPlots = [magPlots magPlot];
                    misalignPlots = [misalignPlots misalignPlot];

                    if ldgFlg
                        magPlot.Visible = 0;
                        misalignPlot.Visible = 0;
                    end
                end
                if app.IncludeAverageButton.Value
                    ldgNames = [ldgNames {['Average']}];
                    ldgLine = [ldgLine lineM];
                end

                if plotLoc ~= 1
                    if ~isempty(app.ComparePairsByStimAmpasXTab.UserData)
                        if isfield(app.ComparePairsByStimAmpasXTab.UserData,'MagPlot')
                            if ~isempty(app.ComparePairsByStimAmpasXTab.UserData.MagPlot)
                                magPlot.XLim = app.ComparePairsByStimAmpasXTab.UserData.MagPlot(1,:);
                                magPlot.YLim = app.ComparePairsByStimAmpasXTab.UserData.MagPlot(2,:);
                            end
                        end

                        if isfield(app.ComparePairsByStimAmpasXTab.UserData,'MisPlot')
                            if ~isempty(app.ComparePairsByStimAmpasXTab.UserData.MisPlot)
                                misalignPlot.XLim = app.ComparePairsByStimAmpasXTab.UserData.MisPlot(1,:);
                                misalignPlot.YLim = app.ComparePairsByStimAmpasXTab.UserData.MisPlot(2,:);
                            end
                        end
                    end
                end

                sameaxes([],[magPlots])
                sameaxes([],[misalignPlots])

                if ~isempty(meanTxtV)
                    newY = magPlots(1).YLim(2)*.9;
                    newY2 = misalignPlots(1).YLim(2)*.9;
                    for uu = 1:length(meanTxtV)
                        meanTxtV(uu).Position(2) = newY;
                        meanTxtM(uu).Position(2) = newY2;
                    end
                end

                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end


                if ~isempty(allTwitch)
                    ldgLine = [ldgLine allTwitch(1)];
                    ldgNames = [ldgNames {'Facial Twitch Observed'}];
                end
                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                end
                app.FigureName = [uAns{1},'_',eyeSel,'_NormCompare-',normType,'_MisalignmentandVelocity_Current_ByStim_',stType,strrep(num2str(unique(uStims(:,1)')),' ',''),'-',normType,'-',strrep(num2str(unique(uStims(:,2)')),' ',''),'_',strrep(num2str(uRefs),' ',''),'_',cUsed,'_',DataType];
            end
        end
        
        function ComparePairsByStimMax(app, plotLoc,ldgFlg)     
            if plotLoc == 1
                loc = app.MaxComparePairsByStimTab;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                marg_w = [.05 .015];
            end
            toUse = ismember(app.Options.Items,app.Options.Value);
            allCs = app.Options.UserData.coms(toUse,:);
            allMs = app.Options.UserData.mat(toUse,:);
            
            uStims = unique([allCs(:,1) allCs(:,3)],'rows','stable');
            uStimsSZ = size(uStims);

            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            
            uRefs = unique(app.Options.UserData.coms(toUse,2));
            uRefs = OrderElectrodesByAnimal(app, uRefs);
            
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];
            yLab1 = '';
            yLab2 = '';
            normType = '';
            meanTxtV = [];
            meanTxtM = [];

            ULineV = [];
            ULineM = [];

            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                for i = 1:uStimsSZ(1)
                    axTitle = '';
                    magPlot = subtightplot(2,uStimsSZ(1),i,[0.05 0.000000000005],marg_h,marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot = subtightplot(2,uStimsSZ(1),i+uStimsSZ(1),[0.05 0.000000000005],marg_h,marg_w,'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    if ~ldgFlg
                        vals = [0 1];
                    else
                        vals = [0];
                    end

                    hold(magPlot,'on');
                    lineVel = plot(magPlot,vals,vals,'color','k','LineWidth',4);
                    ULineV = [lineVel ULineV];
                    hold(magPlot,'off');

                    if ~ldgFlg
                    hold(misalignPlot,'on');
                    lineMis = plot(misalignPlot,vals,vals,'color','k','LineWidth',4);
                    ULineM = [lineMis ULineM];
                    hold(misalignPlot,'off');
                    end

                    if isempty(ldgNames)
                        ldgNames = [ldgNames {'Unity Line'}];
                        ldgLine = [ldgLine lineVel];
                    else
                        if ~ismember(ldgNames,['Unity Line'])
                            ldgNames = [ldgNames {'Unity Line'}];
                            ldgLine = [ldgLine lineVel];
                        end
                    end

                    if (i == 1) && (plotLoc == 1)
                        app.MaxComparePairsByStimTab.UserData = [];
                    end
                    [~,name1,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uStims(i,1));

                    [~,name2,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uStims(i,2));
                    comIds = find(allCs(:,1)==uStims(i,1));
                    normType = 'VS';
                    for j = 1:length(comIds)
                        r = allCs(comIds(j),2);
                        ids2U1 = GetDataInds(app, uAns{1}, uStims(i,1), r, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                        ids2U2 = GetDataInds(app, uAns{1}, uStims(i,2), r, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);

                        if (~isempty(ids2U1)) && (~isempty(ids2U2))
                            if ~issorted([app.params(ids2U1).p1amp])
                                [samp, saI] = sort([app.params(ids2U1).p1amp]);
                                ids2U1 = ids2U1(saI);
                            end
                            if ~issorted([app.params(ids2U2).p1amp])
                                [samp, saI] = sort([app.params(ids2U2).p1amp]);
                                ids2U2 = ids2U2(saI);
                            end
                            if app.AnimalsandEyesToUse{1,3}
                                eyeSel = 'LEye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagL_NystagCorr';
                                    toUseMag = 'MagL_NystagCorr';
                                    toUseSMag = 'stdMagL_NystagCorr';
                                    toUseMMis = 'meanMisalignL_NystagCorr';
                                    toUseMis = 'MisalignL_NystagCorr';
                                    toUseSMis = 'stdMisalignL_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagL';
                                    toUseMag = 'MagL';
                                    toUseSMag = 'stdMagL';
                                    toUseMMis = 'meanMisalignL';
                                    toUseMis = 'MisalignL';
                                    toUseSMis = 'stdMisalignL';
                                    DataType = 'NonCorrected';
                                end
                            else
                                eyeSel = 'REye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagR_NystagCorr';
                                    toUseMag = 'MagR_NystagCorr';
                                    toUseSMag = 'stdMagR_NystagCorr';
                                    toUseMMis = 'meanMisalignR_NystagCorr';
                                    toUseMis = 'MisalignR_NystagCorr';
                                    toUseSMis = 'stdMisalignR_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagR';
                                    toUseMag = 'MagR';
                                    toUseSMag = 'stdMagR';
                                    toUseMMis = 'meanMisalignR';
                                    toUseMis = 'MisalignR';
                                    toUseSMis = 'stdMisalignR';
                                    DataType = 'NonCorrected';
                                end
                            end
                            hold(magPlot,'on');

                            [color,refName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, r);

                            if ~ldgFlg
                                if any([app.params(ids2U1).FacialNerve])
                                    FNI = find([app.params(ids2U1).FacialNerve]);
                                    ids2U1 = ids2U1(FNI-1);
                                    velX = app.params(ids2U1).(toUseMMag);
                                else
                                    velX = app.params(ids2U1(end)).(toUseMMag);
                                    ids2U1 = ids2U1(end);
                                end

                                if any([app.params(ids2U2).FacialNerve])
                                    FNI = find([app.params(ids2U2).FacialNerve]);
                                    ids2U2 = ids2U2(FNI-1);
                                    velY = app.params(ids2U2).(toUseMMag);
                                else
                                    velY = app.params(ids2U2(end)).(toUseMMag);
                                    ids2U2 = ids2U2(end);
                                end
                                points = plot(magPlot,velX,velY,'color',color{1},'marker','o','MarkerSize',20,'LineStyle','none','LineWidth',4);
                                points.UserData = [ids2U1; ids2U2];

                            else

                                velX = 0;
                                velY = 0;
                                line = plot(magPlot,velX,velY,'color',color{1},'LineWidth',4);
                                if ~ismember(ldgNames,[refName{1}])
                                    ldgNames = [ldgNames {[refName{1}]}];
                                    ldgLine = [ldgLine line];
                                end

                            end
                            stType = 'NA';
                            hold(magPlot,'off');

                            if ~ldgFlg
                                hold(misalignPlot,'on');
                                [color,refName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, r);

                                velX = app.params(ids2U1).(toUseMMis);

                                velY = app.params(ids2U2).(toUseMMis);

                                points = plot(misalignPlot,velX,velY,'color',color{1},'marker','o','MarkerSize',20,'LineStyle','none','LineWidth',4);
                                points.UserData = [ids2U1; ids2U2];

                                hold(misalignPlot,'off');
                            end
                        end
                    end
                    if i == 1
                        magPlot.YLabel.String = [name2{1},' Velocity (deg/s)'];
                        magPlot.YLabel.FontSize = 30;
                        misalignPlot.YLabel.String = [name2{1},' Misalignment (deg)'];
                        misalignPlot.YLabel.FontSize = 30;
                    else
                        magPlot.YTickLabel = [];
                        misalignPlot.YTickLabel = [];
                    end
                    magPlot.XLabel.String = {[name1{1},' Velocity (deg/s)']};
                    magPlot.XGrid = 'on';
                    magPlot.YGrid = 'on';
                    magPlot.XLim(2) = magPlot.XLim(2)+5;
                    magPlot.YLim(2) = magPlot.YLim(2)+5;
                    magPlot.FontSize = 30;
                    magPlot.Title.String = [name1{1}, ' vs ', name2{1}];
                    magPlot.LineWidth = 2.5;
                    magPlot.Title.FontSize = 30;

                    misalignPlot.XGrid = 'on';
                    misalignPlot.YGrid = 'on';
                    misalignPlot.XLim(2) = misalignPlot.XLim(2)+5;
                    misalignPlot.YLim(2) = misalignPlot.YLim(2)+5;
                    misalignPlot.FontSize = 30;
                    misalignPlot.XLabel.String = {[name1{1},' Misalignment (deg)']};
                    misalignPlot.XLabel.FontSize = 30;
                    misalignPlot.LineWidth = 2.5;

                    magPlots = [magPlots magPlot];
                    misalignPlots = [misalignPlots misalignPlot];

                    if ldgFlg
                        magPlot.Visible = 0;
                        misalignPlot.Visible = 0;
                    end
                end

                if plotLoc ~= 1
                    if ~isempty(app.MaxComparePairsByStimTab.UserData)
                        if isfield(app.MaxComparePairsByStimTab.UserData,'MagPlot')
                            if ~isempty(app.MaxComparePairsByStimTab.UserData.MagPlot)
                                magPlot.XLim = app.MaxComparePairsByStimTab.UserData.MagPlot(1,:);
                                magPlot.YLim = app.MaxComparePairsByStimTab.UserData.MagPlot(2,:);
                            end
                        end

                        if isfield(app.MaxComparePairsByStimTab.UserData,'MisPlot')
                            if ~isempty(app.MaxComparePairsByStimTab.UserData.MisPlot)
                                misalignPlot.XLim = app.MaxComparePairsByStimTab.UserData.MisPlot(1,:);
                                misalignPlot.YLim = app.MaxComparePairsByStimTab.UserData.MisPlot(2,:);
                            end
                        end
                    end
                end

                sameaxes([],[magPlots])
                sameaxes([],[misalignPlots])

                maxV = max([magPlot.XLim magPlot.YLim]);
                maxM = max([misalignPlot.XLim misalignPlot.YLim]);

                set(ULineV,'XData',[0 maxV]);
                set(ULineV,'YData',[0 maxV]);

                set(ULineM,'XData',[0 maxM]);
                set(ULineM,'YData',[0 maxM]);
                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end

                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                end
                app.FigureName = [uAns{1},'_',eyeSel,'_NormCompare-',normType,'_MisalignmentandVelocity_MaxCurrent_ByStim_',stType,strrep(num2str(unique(uStims(:,1)')),' ',''),'-',normType,'-',strrep(num2str(unique(uStims(:,2)')),' ',''),'_',strrep(num2str(uRefs),' ',''),'_',cUsed,'_',DataType];
            end
        end

        function ComparePairsByStimSameAxisMax(app, plotLoc,ldgFlg)
            if plotLoc == 1
                loc = app.MaxComparePairsByStimTab;
                marg_hm = [0.22 0.075];
                marg_hmi = [0.12 0.12];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_hm = [0.12 0.05];
                marg_hmi = [0.085 0.09];
                marg_w = [.05 .015];
            end
            toUse = ismember(app.Options.Items,app.Options.Value);
            allCs = app.Options.UserData.coms(toUse,:);
            allMs = app.Options.UserData.mat(toUse,:);

            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uRefs = unique(app.Options.UserData.coms(toUse,2));
%             uRefs = OrderElectrodesByAnimal(app, uAns{1}, uRefs);
            uRefsSZ = size(uRefs);
            szAllC = size(allCs);

            uStims = unique([allCs(:,1) allCs(:,3)],'rows','stable');
            uStimsSZ = size(uStims);

            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];
            yLab1 = '';
            yLab2 = '';
            normType = '';
            meanTxtV = [];
            meanTxtM = [];

            ULineV = [];
            ULineM = [];

            genLdg = 0;
            animalsUsed = [];
            ids2T = GetDataInds(app, uAns, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                magPlot = subtightplot(2,1,1,[0.05 0.000000000005],marg_hm,marg_w,'Parent', loc);
                magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                axis(magPlot,'square')
                misalignPlot = subtightplot(2,1,2,[0.05 0.000000000005],marg_hmi,marg_w,'Parent', loc);
                misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                axis(misalignPlot,'square')

                if ~ldgFlg
                    vals = [0 1];
                else
                    vals = [0];
                end

                hold(magPlot,'on');
                lineVel = plot(magPlot,vals,vals,'color','k','LineWidth',4);
                ULineV = [lineVel ULineV];
                hold(magPlot,'off');

                if ~ldgFlg
                    hold(misalignPlot,'on');
                    lineMis = plot(misalignPlot,vals,vals,'color','k','LineWidth',4);
                    ULineM = [lineMis ULineM];
                    hold(misalignPlot,'off');
                end

                if isempty(ldgNames)
                    ldgNames = [ldgNames {'Unity Line'}];
                    ldgLine = [ldgLine lineVel];
                else
                    if ~ismember(ldgNames,['Unity Line'])
                        ldgNames = [ldgNames {'Unity Line'}];
                        ldgLine = [ldgLine lineVel];
                    end
                end
                if plotLoc == 1
                    app.MaxComparePairsByStimTab.UserData = [];
                end
                ediffsV = [];
                ediffsM = [];
                for i = uAns'
                    if isempty(animalsUsed)
                        animalsUsed = [animalsUsed i{1}];
                    else
                        animalsUsed = [animalsUsed,'-', i{1}];
                    end
                    for j = 1:uStimsSZ(1)
                        comIds = find(allCs(:,1)==uStims(j,1));
                        for k = 1:length(comIds)
                            r = allCs(comIds(k),2);
                            ids2U1 = GetDataInds(app, i{1}, uStims(j,1), r, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                            ids2U2 = GetDataInds(app, i{1}, uStims(j,2), r, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);

                            if (~isempty(ids2U1)) && (~isempty(ids2U2))
                                e2U = find(ismember(app.AnimalsandEyesToUse(:,1),i));
                                if app.AnimalsandEyesToUse{e2U,3}
                                    eyeSel = 'LEye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagL_NystagCorr';
                                        toUseMag = 'MagL_NystagCorr';
                                        toUseSMag = 'stdMagL_NystagCorr';
                                        toUseMMis = 'meanMisalignL_NystagCorr';
                                        toUseMis = 'MisalignL_NystagCorr';
                                        toUseSMis = 'stdMisalignL_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagL';
                                        toUseMag = 'MagL';
                                        toUseSMag = 'stdMagL';
                                        toUseMMis = 'meanMisalignL';
                                        toUseMis = 'MisalignL';
                                        toUseSMis = 'stdMisalignL';
                                        DataType = 'NonCorrected';
                                    end
                                else
                                    eyeSel = 'REye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagR_NystagCorr';
                                        toUseMag = 'MagR_NystagCorr';
                                        toUseSMag = 'stdMagR_NystagCorr';
                                        toUseMMis = 'meanMisalignR_NystagCorr';
                                        toUseMis = 'MisalignR_NystagCorr';
                                        toUseSMis = 'stdMisalignR_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagR';
                                        toUseMag = 'MagR';
                                        toUseSMag = 'stdMagR';
                                        toUseMMis = 'meanMisalignR';
                                        toUseMis = 'MisalignR';
                                        toUseSMis = 'stdMisalignR';
                                        DataType = 'NonCorrected';
                                    end
                                end
                                if ~app.AnimalProperties.(i{1}).CorrectionLabel.Value
                                    transformed = 'NONTRANSFORMED';
                                else
                                    transformed = 'TRANSFORMED';
                                end

                                [color,refName,~,~,~,~] = GetElecColorandNameByAnimal(app, i{1}, r);

%                                 hold(ldgPlot,"on")
%                                 lineR = plot(ldgPlot,0,0,'color',color{1},'LineWidth',4);
%                                 if ~ismember(ldgNames,[refName{1}])
%                                     ldgNames = [ldgNames {[refName{1}]}];
%                                     ldgLine = [ldgLine lineR];
%                                 end
%                                 hold(ldgPlot,"off")

                                [~,~,mark,~,~,~,~] = GetElecColorandNameByAnimal(app, i{1}, uStims(j,1));
                                if length(uAns) > 1
                                    switch i{1}
                                        case 'Nancy'
                                            switch uStims(j,1)
                                                case {5 14}
                                                    mark = '^';
                                                case {8 15}
                                                    mark = 'v';
                                            end
                                        case 'Opal'
                                            switch uStims(j,1)
                                                case {5 14}
                                                    mark = 'd';
                                                case {8 15}
                                                    mark = 's';
                                            end
                                    end
                                end

                                if ~ldgFlg                                
                                    hold(magPlot,'on');
                                    if any([app.params(ids2U1).FacialNerve])
                                        FNI = find([app.params(ids2U1).FacialNerve]);
                                        ids2U1 = ids2U1(min(FNI)-1);
                                        velX = app.params(ids2U1).(toUseMMag);
                                    else
                                        velX = app.params(ids2U1(end)).(toUseMMag);
                                        ids2U1 = ids2U1(end);
                                    end

                                    if any([app.params(ids2U2).FacialNerve])
                                        FNI = find([app.params(ids2U2).FacialNerve]);
                                        ids2U2 = ids2U2(min(FNI)-1);
                                        velY = app.params(ids2U2).(toUseMMag);
                                    else
                                        velY = app.params(ids2U2(end)).(toUseMMag);
                                        ids2U2 = ids2U2(end);
                                    end

                                    %velY - First stim electrode number
                                    %velX - Second stim electrode number
                                    if isnan(velY)
                                        sh = 1;
                                    end
                                    ediffsV = [ediffsV; velY velX];
                                    pointsR = plot(magPlot,velX,velY,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                    pointsR.UserData = [ids2U1; ids2U2];
                                    hold(magPlot,'off');

                                    hold(misalignPlot,'on');
                                    if ~app.AnimalProperties.(i{1}).CorrectionLabel.Value
                                        velX = app.params(ids2U1).(toUseMMis);

                                        velY = app.params(ids2U2).(toUseMMis);
                                        transformed = 'NONTRANSFORMED';
                                    else
                                        [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U1, eyeSel, i{1});
                                        velX = misValsMean;
                                        [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U2, eyeSel, i{1});
                                        velY = misValsMean;
                                        transformed = 'TRANSFORMED';
                                    end

                                    
                                    ediffsM = [ediffsM; velY velX];
                                    pointsR = plot(misalignPlot,velX,velY,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                    pointsR.UserData = [ids2U1; ids2U2];

                                    hold(misalignPlot,'off');
                                else

                                    velX = 0;
                                    velY = 0;
                                    if (length(uAns) > 1) && (~genLdg)
                                        if isequal(uStims,[5 14; 8 15]) | isequal(uStims,[8 15; 5 14])
                                            for iT = uAns'
                                                for jT = 1:uStimsSZ(1)
                                                    switch iT{1}
                                                        case 'Nancy'
                                                            switch uStims(jT,1)
                                                                case {5 14}
                                                                    mark = '^';
                                                                    nameM = 'LA';
                                                                case {8 15}
                                                                    mark = 'v';
                                                                    nameM = 'LH';
                                                            end
                                                        case 'Opal'
                                                            switch uStims(jT,1)
                                                                case {5 14}
                                                                    mark = 'd';
                                                                    nameM = 'LH';
                                                                case {8 15}
                                                                    mark = 's';
                                                                    nameM = 'LA';
                                                            end
                                                    end
                                                    
                                                    hold(magPlot,'on');
                                                    ldgM = plot(magPlot,0,0,'color','k','marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                                    hold(magPlot,'off');
                                                    ldgNames = [ldgNames {[iT{1},' - ',nameM]}];
                                                    ldgLine = [ldgLine ldgM];
                                                end
                                            end
                                            genLdg = 1;
                                        end
                                    end
                                    hold(magPlot,'on');
                                    line = plot(magPlot,velX,velY,'color',color{1},'LineWidth',4);
                                    hold(magPlot,'off');
                                    if ~ismember(ldgNames,[refName{1}])
                                        ldgNames = [ldgNames {[refName{1}]}];
                                        ldgLine = [ldgLine line];
                                    end

                                end

                            end
                        end
                    end

                end
                [~,name1] = GetElecColorandNameByAnimal(app, i{1}, uStims(j,1));
                [~,name2] = GetElecColorandNameByAnimal(app, i{1}, uStims(j,2));
                magPlot.YLabel.String = [name2{1},', Velocity (deg/s)'];
                magPlot.YLabel.FontSize = 30;
                misalignPlot.YLabel.String = [name2{1},', Misalignment (deg)'];
                misalignPlot.YLabel.FontSize = 30;

               
                magPlot.XLabel.String = {[name1{1},', Velocity (deg/s)']};

                magPlot.XGrid = 'on';
                magPlot.YGrid = 'on';

                magPlot.FontSize = 30;
                % magPlot.Title.String = [name1{1}, ' vs ', name2{1}];
                magPlot.LineWidth = 2.5;
                magPlot.Title.FontSize = 30;


                misalignPlot.XGrid = 'on';
                misalignPlot.YGrid = 'on';

                misalignPlot.FontSize = 30;
                misalignPlot.XLabel.String = {['Outer, Misalignment (deg)']};
                misalignPlot.XLabel.FontSize = 30;
                misalignPlot.LineWidth = 2.5;
                maxV = max([magPlot.XLim magPlot.YLim]);
                maxM = max([misalignPlot.XLim misalignPlot.YLim]);
                magPlot.XLim = [0 maxV];
                magPlot.YLim = [0 maxV];
                misalignPlot.XLim = [0 maxM];
                misalignPlot.YLim = [0 maxM];
                if plotLoc ~= 1
                    if ~isempty(app.MaxComparePairsByStimTab.UserData)
                        if isfield(app.MaxComparePairsByStimTab.UserData,'MagPlot')
                            if ~isempty(app.MaxComparePairsByStimTab.UserData.MagPlot)
                                magPlot.XLim = app.MaxComparePairsByStimTab.UserData.MagPlot(1,:);
                                magPlot.YLim = app.MaxComparePairsByStimTab.UserData.MagPlot(2,:);
                            end
                        end

                        if isfield(app.MaxComparePairsByStimTab.UserData,'MisPlot')
                            if ~isempty(app.MaxComparePairsByStimTab.UserData.MisPlot)
                                misalignPlot.XLim = app.MaxComparePairsByStimTab.UserData.MisPlot(1,:);
                                misalignPlot.YLim = app.MaxComparePairsByStimTab.UserData.MisPlot(2,:);
                            end
                        end
                    end
                end

                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ~ldgFlg
                maxV = max([magPlot.XLim magPlot.YLim]);
                maxM = max([misalignPlot.XLim misalignPlot.YLim]);

                set(ULineV,'XData',[0 maxV]);
                set(ULineV,'YData',[0 maxV]);

                set(ULineM,'XData',[0 maxM]);
                set(ULineM,'YData',[0 maxM]);
                else
                    magPlot.Visible = 0;
                    misalignPlot.Visible = 0;
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position(1) = 0.3079;
                    ldg.Position(2) = 0.2987;
                    if length(ldgLine) > 1
                        ldg.Title.String = 'Return Electrodes:';
                    else
                        ldg.Title.String = 'Return Electrode:';
                    end
                end
                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end
                app.FigureName = [animalsUsed,'_',eyeSel,'_NormCompare-',normType,'_',transformed,'_MisandVel_MaxCurrent_ByStim_',strrep(num2str(unique(uStims(:,1)')),' ',''),'-',strrep(num2str(unique(uStims(:,2)')),' ',''),'_',strrep(num2str(uRefs'),' ',''),'_',cUsed,'_',DataType];

                if ~ldgFlg
                    n = length(ediffsV(:,1))
                    [mV, CIV, PMV] = CalcCI(app, ediffsV(:,1)-ediffsV(:,2), .95)
                    [mM, CIM, PMM] = CalcCI(app, ediffsM(:,1)-ediffsM(:,2), .95)
                end


            end
        end

        function GetPossiblePairsRefs(app)
            possSubt = [];
            possDivd = [];
            subT = [];
            divD = [];
            coms = [];
            uro = unique([app.Refs],'stable');
            for i = uro
                findDif = ~ismember([app.Refs],i);
                us = unique([app.Refs(findDif)],'stable');
                ff = find(~findDif);
                for ii = us
                    findComp = find(ismember([app.Refs],ii));
                    ro = app.Stims(ff);
                    rn = app.Stims(findComp);
                    pairs = ro(ismember(ro,rn));
                    for j = pairs
                        coms = [coms; i j ii j];
                        nS = ['r',num2str(i),'s',num2str(j),'-','r',num2str(ii),'s',num2str(j)];
                        nD = ['r',num2str(i),'s',num2str(j),'/','r',num2str(ii),'s',num2str(j)];
                        possSubt = [possSubt {nS}];
                        subT = [subT; 1];
                        divD = [divD; 2];
                        possDivd = [possDivd {nD}];
                    end
                end
            end
            app.Options.Items = [possSubt possDivd];
            optStruct = struct();
            optStruct.coms = [coms;coms];
            optStruct.mat = [subT; divD];
            app.Options.UserData = optStruct;
        end
        
        function ComparePairsByRef(app, plotLoc,ldgFlg)
            if plotLoc == 1
                loc = app.ComparePairsByRefAmpasXTab;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                marg_w = [.05 .015];
            end
            toUse = ismember(app.Options.Items,app.Options.Value);
            allCs = app.Options.UserData.coms(toUse,:);
            allMs = app.Options.UserData.mat(toUse,:);
            uStims = unique(app.Options.UserData.coms(toUse,2));
            uStims = OrderElectrodesByAnimal(app, uStims);
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            
            uRefs = unique([allCs(:,1) allCs(:,3)],'rows','stable');
            uRefsSZ = size(uRefs);
            
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];
            yLab1 = '';
            yLab2 = '';
            normType = '';
            meanTxtV = [];
            meanTxtM = [];

            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else

                for i = 1:uRefsSZ(1)
                    uuu = uRefsSZ(1);
                    axTitle = '';
                    magPlot = subtightplot(2,uuu,i,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot = subtightplot(2,uuu,i+uuu,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    if (i == 1) && (plotLoc == 1)
                        app.ComparePairsByRefAmpasXTab.UserData = [];
                    end
                    [~,name1,~,~,~] = GetElecColorandNameByAnimal(app, uRefs(i,1));

                    [~,name2,~,~,~] = GetElecColorandNameByAnimal(app, uRefs(i,2));
                    comIds = find(allCs(:,3)==uRefs(i,2));

                    vd = zeros(length(comIds),length(up1a));
                    md = zeros(length(comIds),length(up1a));


                    for j = 1:length(comIds)
                        s = allCs(comIds(j),2);
                        ids2U1 = GetDataInds(app, uAns{1}, s, uRefs(i,1), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                        ids2U2 = GetDataInds(app, uAns{1}, s, uRefs(i,2), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);

                        if (~isempty(ids2U1)) && (~isempty(ids2U2))
                            if app.AnimalsandEyesToUse{1,3}
                                eyeSel = 'LEye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagL_NystagCorr';
                                    toUseMag = 'MagL_NystagCorr';
                                    toUseSMag = 'stdMagL_NystagCorr';
                                    toUseMMis = 'meanMisalignL_NystagCorr';
                                    toUseMis = 'MisalignL_NystagCorr';
                                    toUseSMis = 'stdMisalignL_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagL';
                                    toUseMag = 'MagL';
                                    toUseSMag = 'stdMagL';
                                    toUseMMis = 'meanMisalignL';
                                    toUseMis = 'MisalignL';
                                    toUseSMis = 'stdMisalignL';
                                    DataType = 'NonCorrected';
                                end
                            else
                                eyeSel = 'REye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagR_NystagCorr';
                                    toUseMag = 'MagR_NystagCorr';
                                    toUseSMag = 'stdMagR_NystagCorr';
                                    toUseMMis = 'meanMisalignR_NystagCorr';
                                    toUseMis = 'MisalignR_NystagCorr';
                                    toUseSMis = 'stdMisalignR_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagR';
                                    toUseMag = 'MagR';
                                    toUseSMag = 'stdMagR';
                                    toUseMMis = 'meanMisalignR';
                                    toUseMis = 'MisalignR';
                                    toUseSMis = 'stdMisalignR';
                                    DataType = 'NonCorrected';
                                end
                            end
                            if ~issorted([app.params(ids2U1).p1amp])
                                [samp, saI] = sort([app.params(ids2U1).p1amp]);
                                ids2U1 = ids2U1(saI);
                            end
                            if ~issorted([app.params(ids2U2).p1amp])
                                [samp, saI] = sort([app.params(ids2U2).p1amp]);
                                ids2U2 = ids2U2(saI);
                            end
                            if length(ids2U1) > length(ids2U2)
                                ampMatch = ismember([app.params(ids2U1).p1amp],[app.params(ids2U2).p1amp]);
                                ids2U1(~ampMatch) = [];
                            elseif length(ids2U1) < length(ids2U2)
                                ampMatch = ismember([app.params(ids2U2).p1amp],[app.params(ids2U1).p1amp]);
                                ids2U2(~ampMatch) = [];
                            end
                            hold(magPlot,'on');
                            [color,stimName,mark,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, s);
                            if ~ldgFlg
                                if allMs(comIds(j)) == 1
                                    vel = [[app.params(ids2U1).(toUseMMag)]-[app.params(ids2U2).(toUseMMag)]];
                                    yLab1 = 'Velocity Difference (dps)';
                                    yLab2 = 'Misalignment Differnce (degrees)';
                                    axTitle = [name1{1},' - ',name2{1}];
                                    normType = 'Difference';
                                elseif allMs(comIds(j)) == 2
                                    vel = [[app.params(ids2U1).(toUseMMag)]./[app.params(ids2U2).(toUseMMag)]];
                                    yLab1 = 'Velocity Ratio';
                                    yLab2 = 'Misalignment Ratio';
                                    axTitle = [name1{1},'/',name2{1}];
                                    normType = 'Ratio';
                                end
                                if length(vel) < length(vd(j,:))
                                    am = ismember([up1a],[app.params(ids2U1).p1amp]);
                                    vd(j,am) = vel;
                                    vd(j,~am) = NaN;
                                else
                                    vd(j,:) = vel;
                                end
                                mAmp = up1a;
                                amp = [app.params(ids2U1).p1amp];
                                if length(ids2U1) == 1
                                    amp = [200];
                                    mAmp = 200;
                                end
                            else
                                if allMs(comIds(j)) == 1
                                    normType = 'Difference';
                                elseif allMs(comIds(j)) == 2
                                    normType = 'Ratio';
                                end
                                amp = [0];
                                vel = [0];
                                std = [0];
                                vd = 0;
                                mAmp = 0;
                            end

                            line = plot(magPlot,amp,vel,'color',color{1},'LineWidth',4);
                            points = plot(magPlot,amp,vel,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                            points.UserData = [ids2U1; ids2U2];
                            if (j == length(comIds)) && (app.IncludeAverageButton.Value)
                                if length(ids2U1) == 1
                                    iis = ~isnan(vd);
                                    vd = vd(iis);
                                end
                                mv = mean(vd,'omitnan');
                                szz = size(vd);
                                if app.PlottingStandardDeviationCheckBox.Value
                                    sv = [std(vd,'omitnan');]./sqrt(szz(1));
                                    stType = 'SEM_';
                                else
                                    sv = std(vd,'omitnan');
                                    stType = 'STD_';
                                end

                                lineM = plot(magPlot,mAmp,mv,'color','k','LineWidth',4);
                                points = plot(magPlot,mAmp,mv,'color','k','marker','.','MarkerSize',40,'LineStyle','none','LineWidth',4);
                                erPlot = errorbar(magPlot,mAmp,mv,sv,'color','k','LineWidth',4,'CapSize',12);

                                if plotLoc == 1
                                    x = mAmp(end);
                                    y = magPlot.YLim(2);
                                    mtot = mean(mv,'omitnan');
                                    szz = length(mv);
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        stdTot = [std(mv,'omitnan');]./sqrt(szz);
                                    else
                                        stdTot = std(mv,'omitnan');
                                    end
                                    if length(ids2U1) == 1
                                        mtot = mv;
                                        stdTot = sv;
                                        stType = [stType,'1_'];
                                    end
                                    txt = [num2str(round(mtot,3)),'±',num2str(round(stdTot,3))];
                                    mtv = text(magPlot,x,y,txt);
                                    meanTxtV = [meanTxtV mtv];
                                end
                            else
                                stType = '';
                            end
                            hold(magPlot,'off');

                            if ~ldgFlg
                                hold(misalignPlot,'on');
                                [color,stimName,mark,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, s);
                                if ~ldgFlg
                                    if allMs(comIds(j)) == 1
                                        mis = [[app.params(ids2U1).(toUseMMis)]-[app.params(ids2U2).(toUseMMis)]];
                                    elseif allMs(comIds(j)) == 2
                                        mis = [[app.params(ids2U1).(toUseMMis)]./[app.params(ids2U2).(toUseMMis)]];
                                    end
                                    if length(mis) < length(md(j,:))
                                        am = ismember([up1a],[app.params(ids2U1).p1amp]);
                                        md(j,am) = mis;
                                        md(j,~am) = NaN;
                                    else
                                        md(j,:) = mis;
                                    end

                                    mAmp = up1a;
                                    amp = [app.params(ids2U1).p1amp];
                                    if length(ids2U1) == 1
                                        amp = [200];
                                        mAmp = 200;
                                    end
                                else
                                    amp = [0];
                                    mis = [0];
                                    std = [0];
                                end
                                line = plot(misalignPlot,amp,mis,'color',color{1},'LineWidth',4);
                                points = plot(misalignPlot,amp,mis,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                points.UserData = [ids2U1; ids2U2];
                                if (j == length(comIds)) && (app.IncludeAverageButton.Value)
                                    if length(ids2U1) == 1
                                        iis = ~isnan(md);
                                        md = md(iis);
                                    end
                                    mmL = mean(md,'omitnan');
                                    szz = size(md);
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        sm = [std(md,'omitnan');]./sqrt(szz(1));
                                        stType = 'SEM_';
                                    else
                                        sm = std(md,'omitnan');
                                        stType = 'STD_';
                                    end
                                    lineM = plot(misalignPlot,mAmp,mm,'color','k','LineWidth',4);
                                    points = plot(misalignPlot,mAmp,mm,'color','k','marker','.','MarkerSize',40,'LineStyle','none','LineWidth',4);
                                    erPlot = errorbar(misalignPlot,mAmp,mm,sm,'color','k','LineWidth',4,'CapSize',12);

                                    if plotLoc == 1
                                        x = up1a(end-1);
                                        y = misalignPlot.YLim(2);
                                        mtot = mean(mm,'omitnan');
                                        szz = length(mm);
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            stdTot = [std(mm,'omitnan');]./sqrt(szz);
                                        else
                                            stdTot = std(mm,'omitnan');
                                        end
                                        if length(ids2U1) == 1
                                            mtot = mm;
                                            stdTot = sm;
                                        end
                                        txt = [num2str(round(mtot,3)),'±',num2str(round(stdTot,3))];
                                        mtm = text(misalignPlot,x,y,txt);
                                        meanTxtM = [meanTxtM mtm];
                                    end
                                else
                                    stType = '';
                                end

                                hold(misalignPlot,'off');
                            end

                            if isempty(ldgNames)
                                ldgNames = [ldgNames {[stimName{1}]}];
                                ldgLine = [ldgLine line];
                            else
                                if ~ismember(ldgNames,[stimName{1}])
                                    ldgNames = [ldgNames {[stimName{1}]}];
                                    ldgLine = [ldgLine line];
                                end
                            end
                        end
                    end
                    if i == 1

                        magPlot.YLabel.String = yLab1;
                        magPlot.YLabel.FontSize = 30;
                        misalignPlot.YLabel.String = yLab2;
                        misalignPlot.YLabel.FontSize = 30;
                    else
                        magPlot.YTickLabel = [];
                        misalignPlot.YTickLabel = [];
                    end
                    magPlot.XTickLabel = [];

                    magPlot.XGrid = 'on';
                    magPlot.YGrid = 'on';

                    magPlot.FontSize = 30;
                    magPlot.Title.String = axTitle;
                    magPlot.LineWidth = 2.5;
                    magPlot.Title.FontSize = 30;
%                     magPlot.YLim = [0 1.2];
                    if length(ids2U1) == 1
                        magPlot.XTick = [100:50:300];
                        magPlot.XLim = [100 300];
                        misalignPlot.XTick = [100:50:300];
                        misalignPlot.XLim = [100 300];
                    else
                        magPlot.XTick = [up1a(1)-25 up1a];
                        magPlot.XLim = [up1a(1)-25 up1a(end)+25];
                        misalignPlot.XTick = [up1a(1)-25 up1a];
                        misalignPlot.XLim = [up1a(1)-25 up1a(end)+25];
                    end

                    misalignPlot.XGrid = 'on';
                    misalignPlot.YGrid = 'on';

                    misalignPlot.FontSize = 30;
                    misalignPlot.XLabel.String = {'Current (uA)'};
                    misalignPlot.XLabel.FontSize = 30;
                    misalignPlot.LineWidth = 2.5;
%                     misalignPlot.YLim = [0 2.5];
                    magPlots = [magPlots magPlot];
                    misalignPlots = [misalignPlots misalignPlot];

                    if ldgFlg
                        magPlot.Visible = 0;
                        misalignPlot.Visible = 0;
                    end
                end
                if app.IncludeAverageButton.Value
                    ldgNames = [ldgNames {['Average']}];
                    ldgLine = [ldgLine lineM];
                end

                if plotLoc ~= 1
                    if ~isempty(app.ComparePairsByRefAmpasXTab.UserData)
                        if isfield(app.ComparePairsByRefAmpasXTab.UserData,'MagPlot')
                            if ~isempty(app.ComparePairsByRefAmpasXTab.UserData.MagPlot)
                                magPlot.XLim = app.ComparePairsByRefAmpasXTab.UserData.MagPlot(1,:);
                                magPlot.YLim = app.ComparePairsByRefAmpasXTab.UserData.MagPlot(2,:);
                            end
                        end

                        if isfield(app.ComparePairsByRefAmpasXTab.UserData,'MisPlot')
                            if ~isempty(app.ComparePairsByRefAmpasXTab.UserData.MisPlot)
                                misalignPlot.XLim = app.ComparePairsByRefAmpasXTab.UserData.MisPlot(1,:);
                                misalignPlot.YLim = app.ComparePairsByRefAmpasXTab.UserData.MisPlot(2,:);
                            end
                        end
                    end
                end
                sameaxes([],[magPlots])
                sameaxes([],[misalignPlots])

                if ~isempty(meanTxtV)
                    newY = magPlots(1).YLim(2)*.9;
                    newY2 = misalignPlots(1).YLim(2)*.9;
                    for uu = 1:length(meanTxtV)
                        meanTxtV(uu).Position(2) = newY;
                        meanTxtM(uu).Position(2) = newY2;
                    end
                end

                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end
                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                end
                app.FigureName = [uAns{1},'_',eyeSel,'_NormCompare-',normType,'_MisalignmentandVelocity_Current_ByRef_',stType,strrep(num2str(unique(uRefs(:,1)')),' ',''),'-',normType,'-',strrep(num2str(unique(uRefs(:,2)')),' ',''),'_',strrep(num2str(uStims),' ',''),'_',cUsed,'_',DataType];
            end
        end
        

        function ComparePairsByRefSameAxis(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.ComparePairsByRefAmpasXTab;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                marg_w = [.05 .015];
            end
            toUse = ismember(app.Options.Items,app.Options.Value);
            allCs = app.Options.UserData.coms(toUse,:);
            allMs = app.Options.UserData.mat(toUse,:);
            uStims = unique(app.Options.UserData.coms(toUse,2));
            uStims = OrderElectrodesByAnimal(app, uStims);
            szAllC = size(allCs);

            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');

            uRefs = unique([allCs(:,1) allCs(:,3)],'rows','stable');
            uRefsSZ = size(uRefs);
            
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];
            yLab1 = '';
            yLab2 = '';
            normType = '';
            meanTxtV = [];
            meanTxtM = [];
            magPlot = subtightplot(2,1,1,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
            magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
            misalignPlot = subtightplot(2,1,2,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
            misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
            if plotLoc == 1
                app.ComparePairsByRefAmpasXTab.UserData = [];
            end
            axTitle = '';
            vd = zeros(szAllC(1),length(up1a));
            md = zeros(szAllC(1),length(up1a));
            
            if allMs == 1
                [~,name1,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, allCs(1,1));
                axTitle = [name1{1},' - All Refs'];
                normType = 'Difference';
            elseif allMs(1) == 2
                [~,name1,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, allCs(1,1));
                axTitle = [name1{1},'/All Refs'];
                normType = 'Ratio';
            end
            for i = 1:szAllC(1)
                
                ids2U1 = GetDataInds(app, uAns{1}, allCs(i,2), allCs(i,1), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                ids2U2 = GetDataInds(app, uAns{1}, allCs(i,4), allCs(i,3), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                
                [~,name1,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, allCs(i,1));
                
                [~,name2,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, allCs(i,3));
                    
                    if (~isempty(ids2U1)) && (~isempty(ids2U2))
                        if ~issorted([app.params(ids2U1).p1amp])
                                    [samp, saI] = sort([app.params(ids2U1).p1amp]);
                                    ids2U1 = ids2U1(saI);
                        end
                        if ~issorted([app.params(ids2U2).p1amp])
                            [samp, saI] = sort([app.params(ids2U2).p1amp]);
                            ids2U2 = ids2U2(saI);
                        end
                        if length(ids2U1) > length(ids2U2)
                            ampMatch = ismember([app.params(ids2U1).p1amp],[app.params(ids2U2).p1amp]);
                            ids2U1(~ampMatch) = [];
                        elseif length(ids2U1) < length(ids2U2)
                            ampMatch = ismember([app.params(ids2U2).p1amp],[app.params(ids2U1).p1amp]);
                            ids2U2(~ampMatch) = [];
                        end
                        if app.AnimalsandEyesToUse{1,3}
                            eyeSel = 'LEye';
                            if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                toUseMMag = 'meanMagL_NystagCorr';
                                toUseMag = 'MagL_NystagCorr';
                                toUseSMag = 'stdMagL_NystagCorr';
                                toUseMMis = 'meanMisalignL_NystagCorr';
                                toUseMis = 'MisalignL_NystagCorr';
                                toUseSMis = 'stdMisalignL_NystagCorr';
                                DataType = 'NystagCorr';
                            else
                                toUseMMag = 'meanMagL';
                                toUseMag = 'MagL';
                                toUseSMag = 'stdMagL';
                                toUseMMis = 'meanMisalignL';
                                toUseMis = 'MisalignL';
                                toUseSMis = 'stdMisalignL';
                                DataType = 'NonCorrected';
                            end
                        else
                            eyeSel = 'REye';
                            if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                toUseMMag = 'meanMagR_NystagCorr';
                                toUseMag = 'MagR_NystagCorr';
                                toUseSMag = 'stdMagR_NystagCorr';
                                toUseMMis = 'meanMisalignR_NystagCorr';
                                toUseMis = 'MisalignR_NystagCorr';
                                toUseSMis = 'stdMisalignR_NystagCorr';
                                DataType = 'NystagCorr';
                            else
                                toUseMMag = 'meanMagR';
                                toUseMag = 'MagR';
                                toUseSMag = 'stdMagR';
                                toUseMMis = 'meanMisalignR';
                                toUseMis = 'MisalignR';
                                toUseSMis = 'stdMisalignR';
                                DataType = 'NonCorrected';
                            end
                        end
                        hold(magPlot,'on');
                        s = allCs(i,2);
                        [color,stimName,mark,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, s);
                        if ~ldgFlg
                            if allMs(i) == 1
                                vel = [[app.params(ids2U1).(toUseMMag)]-[app.params(ids2U2).(toUseMMag)]];
                                yLab1 = 'Velocity Difference (dps)';
                                yLab2 = 'Misalignment Differnce (degrees)';
                            elseif allMs(i) == 2
                                vel = [[app.params(ids2U1).(toUseMMag)]./[app.params(ids2U2).(toUseMMag)]];
                                yLab1 = 'Velocity Ratio';
                                yLab2 = 'Misalignment Ratio';
                            end
                            if length(vel) < length(vd(i,:))
                                am = ismember([up1a],[app.params(ids2U1).p1amp]);
                                vd(i,am) = vel;
                                vd(i,~am) = NaN;
                            else
                                vd(i,:) = vel;
                            end
                            mAmp = up1a;
                            amp = [app.params(ids2U1).p1amp];
                        else
                            if allMs(i) == 1
                                normType = 'Difference';
                            elseif allMs(i) == 2
                                normType = 'Ratio';
                            end
                            amp = [0];
                            vel = [0];
                            std = [0];
                            vd = 0;
                            mAmp = 0;
                        end

                        line = plot(magPlot,amp,vel,'color',color{1},'LineWidth',4);
                        points = plot(magPlot,amp,vel,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                        points.UserData = [ids2U1; ids2U2];
                        if (i == szAllC(1)) && (app.IncludeAverageButton.Value)
                            mv = mean(vd,'omitnan');
                            szz = length(vd);
                            if app.PlottingStandardDeviationCheckBox.Value
                                sv = [std(vd,'omitnan');]./sqrt(szz);
                                stType = 'SEM_';
                            else
                                sv = std(vd,'omitnan');
                                stType = 'STD_';
                            end
                            lineM = plot(magPlot,mAmp,mv,'color','k','LineWidth',4);
                            points = plot(magPlot,mAmp,mv,'color','k','marker','.','MarkerSize',40,'LineStyle','none','LineWidth',4);
                            erPlot = errorbar(magPlot,mAmp,mv,sv,'color','k','LineWidth',4,'CapSize',12);

                            if plotLoc == 1
                                x = mAmp(end-1);
                                y = magPlot.YLim(2);
                                mtot = mean(mv,'omitnan');
                                szz = length(mv);
                                if app.PlottingStandardDeviationCheckBox.Value
                                    stdTot = [std(mv,'omitnan');]./sqrt(szz);
                                else
                                    stdTot = std(mv,'omitnan');
                                end
                                txt = [num2str(round(mtot,3)),'±',num2str(round(stdTot,3))];
                                mtv = text(magPlot,x,y,txt);
                                meanTxtV = [meanTxtV mtv];
                            end
                        else
                            stType = '';
                        end
                        hold(magPlot,'off');

                        if ~ldgFlg
                            hold(misalignPlot,'on');
                            [color,stimName,mark,~,~] = GetElecColorandNameByAnimal(app, s);
                            if ~ldgFlg
                                if allMs(i) == 1
                                    mis = [[app.params(ids2U1).(toUseMMis)]-[app.params(ids2U2).(toUseMMis)]];
                                elseif allMs(i) == 2
                                    mis = [[app.params(ids2U1).(toUseMMis)]./[app.params(ids2U2).(toUseMMis)]];
                                end
                                if length(mis) < length(md(i,:))
                                    am = ismember([up1a],[app.params(ids2U1).p1amp]);
                                    md(i,am) = mis;
                                    md(i,~am) = NaN;
                                else
                                    md(i,:) = mis;
                                end

                                mAmp = up1a;
                                amp = [app.params(ids2U1).p1amp];
                            else
                                amp = [0];
                                mis = [0];
                                std = [0];
                            end
                            line = plot(misalignPlot,amp,mis,'color',color{1},'LineWidth',4);
                            points = plot(misalignPlot,amp,mis,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                            points.UserData = [ids2U1; ids2U2];
                            if (i == szAllC(1)) && (app.IncludeAverageButton.Value)
                                mm = mean(md,'omitnan');
                                szz = length(md);
                                if app.PlottingStandardDeviationCheckBox.Value
                                    sm = [std(md,'omitnan');]./sqrt(szz);
                                else
                                    sm = std(md,'omitnan');
                                end
                                lineM = plot(misalignPlot,mAmp,mm,'color','k','LineWidth',4);
                                points = plot(misalignPlot,mAmp,mm,'color','k','marker','.','MarkerSize',40,'LineStyle','none','LineWidth',4);
                                erPlot = errorbar(misalignPlot,mAmp,mm,sm,'color','k','LineWidth',4,'CapSize',12);

                                if plotLoc == 1
                                    x = up1a(end-1);
                                    y = misalignPlot.YLim(2);
                                    mtot = mean(mm,'omitnan');
                                    szz = length(mm);
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        stdTot = [std(mm,'omitnan');]./sqrt(szz);
                                    else
                                        stdTot = std(mm,'omitnan');
                                    end
                                    txt = [num2str(round(mtot,3)),'±',num2str(round(stdTot,3))];
                                    mtm = text(misalignPlot,x,y,txt);
                                    meanTxtM = [meanTxtM mtm];
                                end
                            end

                            hold(misalignPlot,'off');
                        end

                        if isempty(ldgNames)
                            ldgNames = [ldgNames {[stimName{1}]}];
                            ldgLine = [ldgLine line];
                        else
                            if ~ismember(ldgNames,[stimName{1}])
                                ldgNames = [ldgNames {[stimName{1}]}];
                                ldgLine = [ldgLine line];
                            end
                        end
                    end
                
            end
                    
                    magPlot.YLabel.String = yLab1;
                    magPlot.YLabel.FontSize = 30;
                    misalignPlot.YLabel.String = yLab2;
                    misalignPlot.YLabel.FontSize = 30;
                magPlot.XTickLabel = [];
                magPlot.XTick = [up1a(1)-25 up1a];
                magPlot.XGrid = 'on';
                magPlot.YGrid = 'on';
                magPlot.XLim = [up1a(1)-25 up1a(end)+25];
                magPlot.FontSize = 30;
                magPlot.Title.String = axTitle;
                magPlot.LineWidth = 2.5;
                magPlot.Title.FontSize = 30;
%                 magPlot.YLim = [0 8];


                misalignPlot.XTick = [up1a(1)-25 up1a];
                misalignPlot.XGrid = 'on';
                misalignPlot.YGrid = 'on';
                misalignPlot.XLim = [up1a(1)-25 up1a(end)+25];
                misalignPlot.FontSize = 30;
                misalignPlot.XLabel.String = {'Current (uA)'};
                misalignPlot.XLabel.FontSize = 30;
                misalignPlot.LineWidth = 2.5;
%                 misalignPlot.YLim = [0 3];

                magPlots = [magPlots magPlot];
                misalignPlots = [misalignPlots misalignPlot];

                if ldgFlg
                    magPlot.Visible = 0;
                    misalignPlot.Visible = 0;
                end
            if app.IncludeAverageButton.Value
                ldgNames = [ldgNames {['Average']}];
                ldgLine = [ldgLine lineM];
            end

            if plotLoc ~= 1
                if ~isempty(app.ComparePairsByRefAmpasXTab.UserData)
                    if isfield(app.ComparePairsByRefAmpasXTab.UserData,'MagPlot')
                        if ~isempty(app.ComparePairsByRefAmpasXTab.UserData.MagPlot)
                            magPlot.XLim = app.ComparePairsByRefAmpasXTab.UserData.MagPlot(1,:);
                            magPlot.YLim = app.ComparePairsByRefAmpasXTab.UserData.MagPlot(2,:);
                        end
                    end

                    if isfield(app.ComparePairsByRefAmpasXTab.UserData,'MisPlot')
                        if ~isempty(app.ComparePairsByRefAmpasXTab.UserData.MisPlot)
                            misalignPlot.XLim = app.ComparePairsByRefAmpasXTab.UserData.MisPlot(1,:);
                            misalignPlot.YLim = app.ComparePairsByRefAmpasXTab.UserData.MisPlot(2,:);
                        end
                    end
                end
            end

            sameaxes([],[magPlots])
            sameaxes([],[misalignPlots])
            
            if ~isempty(meanTxtV)
                newY = magPlots(1).YLim(2)*.9;
                newY2 = misalignPlots(1).YLim(2)*.9;
                for uu = 1:length(meanTxtV)
                    meanTxtV(uu).Position(2) = newY;
                    meanTxtM(uu).Position(2) = newY2;
                end
            end
            
            if sum(app.Canals)==1
                if app.Canals(1) == 1
                    cUsed = 'LARP';
                elseif app.Canals(2) == 1
                    cUsed = 'RALP';
                else
                    cUsed = 'LHRH';
                end
            else
                cUsed = [];
                if app.Canals(1) == 1
                    cUsed = 'LARP';
                end
                if app.Canals(2) == 1
                    cUsed = [cUsed,'-RALP'];
                end
                if app.Canals(3) == 1
                    cUsed = [cUsed,'-LHRH'];
                end
            end

            
            if ~isempty(allTwitch)
                ldgLine = [ldgLine allTwitch(1)];
                ldgNames = [ldgNames {'Facial Twitch Observed'}];
            end
            [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
            if ldgFlg
                ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                ldg.Position = [0.3079 0.2987 0.1744 0.2214];
            end
            app.FigureName = [uAns{1},'_',eyeSel,'_NormCompare-',normType,'_MisalignmentandVelocity_Current_ByRef_',stType,strrep(num2str(unique(uRefs(:,1)')),' ',''),'-',normType,'-',strrep(num2str(unique(uRefs(:,2)')),' ',''),'_',strrep(num2str(uStims),' ',''),'_',cUsed,'_',DataType];
       
        end
        
        function ComparePairsByRefMax(app, plotLoc,ldgFlg)
            
            if plotLoc == 1
                loc = app.MaxComparePairsByRefTab;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                marg_w = [.05 .015];
            end
            
            toUse = ismember(app.Options.Items,app.Options.Value);
            allCs = app.Options.UserData.coms(toUse,:);
            allMs = app.Options.UserData.mat(toUse,:);
            
            uStims = unique(app.Options.UserData.coms(toUse,2));
            uStims = OrderElectrodesByAnimal(app, uStims);
            
            uRefs = unique([allCs(:,1) allCs(:,3)],'rows','stable');
            uRefsSZ = size(uRefs);
            
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');

            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];
            yLab1 = '';
            yLab2 = '';
            normType = '';
            meanTxtV = [];
            meanTxtM = [];

            ULineV = [];
            ULineM = [];

            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                for i = 1:uRefsSZ(1)
                    axTitle = '';
                    magPlot = subtightplot(2,uRefsSZ(1),i,[0.05 0.000000000005],marg_h,marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot = subtightplot(2,uRefsSZ(1),i+uRefsSZ(1),[0.05 0.000000000005],marg_h,marg_w,'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    if ~ldgFlg
                        vals = [0 1];
                    else
                        vals = [0];
                    end

                    hold(magPlot,'on');
                    lineVel = plot(magPlot,vals,vals,'color','k','LineWidth',4);
                    ULineV = [lineVel ULineV];
                    hold(magPlot,'off');

                    if ~ldgFlg
                    hold(misalignPlot,'on');
                    lineMis = plot(misalignPlot,vals,vals,'color','k','LineWidth',4);
                    ULineM = [lineMis ULineM];
                    hold(misalignPlot,'off');
                    end

                    if isempty(ldgNames)
                        ldgNames = [ldgNames {'Unity Line'}];
                        ldgLine = [ldgLine lineVel];
                    else
                        if ~ismember(ldgNames,['Unity Line'])
                            ldgNames = [ldgNames {'Unity Line'}];
                            ldgLine = [ldgLine lineVel];
                        end
                    end

                    if (i == 1) && (plotLoc == 1)
                        app.MaxComparePairsByRefTab.UserData = [];
                    end
                    [~,name1,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uRefs(i,1));

                    [~,name2,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uRefs(i,2));
                    comIds = find(allCs(:,3)==uRefs(i,2));
                    normType = 'VS';
                    for j = 1:length(comIds)
                        s = allCs(comIds(j),2);
                        ids2U1 = GetDataInds(app, uAns{1}, s, uRefs(i,1), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                        ids2U2 = GetDataInds(app, uAns{1}, s, uRefs(i,2), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                        

                        if (~isempty(ids2U1)) && (~isempty(ids2U2))
                            if app.AnimalsandEyesToUse{1,3}
                                eyeSel = 'LEye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagL_NystagCorr';
                                    toUseMag = 'MagL_NystagCorr';
                                    toUseSMag = 'stdMagL_NystagCorr';
                                    toUseMMis = 'meanMisalignL_NystagCorr';
                                    toUseMis = 'MisalignL_NystagCorr';
                                    toUseSMis = 'stdMisalignL_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagL';
                                    toUseMag = 'MagL';
                                    toUseSMag = 'stdMagL';
                                    toUseMMis = 'meanMisalignL';
                                    toUseMis = 'MisalignL';
                                    toUseSMis = 'stdMisalignL';
                                    DataType = 'NonCorrected';
                                end
                            else
                                eyeSel = 'REye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagR_NystagCorr';
                                    toUseMag = 'MagR_NystagCorr';
                                    toUseSMag = 'stdMagR_NystagCorr';
                                    toUseMMis = 'meanMisalignR_NystagCorr';
                                    toUseMis = 'MisalignR_NystagCorr';
                                    toUseSMis = 'stdMisalignR_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagR';
                                    toUseMag = 'MagR';
                                    toUseSMag = 'stdMagR';
                                    toUseMMis = 'meanMisalignR';
                                    toUseMis = 'MisalignR';
                                    toUseSMis = 'stdMisalignR';
                                    DataType = 'NonCorrected';
                                end
                            end
                            if ~issorted([app.params(ids2U1).p1amp])
                                [samp, saI] = sort([app.params(ids2U1).p1amp]);
                                ids2U1 = ids2U1(saI);
                            end
                            if ~issorted([app.params(ids2U2).p1amp])
                                [samp, saI] = sort([app.params(ids2U2).p1amp]);
                                ids2U2 = ids2U2(saI);
                            end

                            hold(magPlot,'on');

                            [color,stimName,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, s);
                            if ~ldgFlg
                                if any([app.params(ids2U1).FacialNerve])
                                    FNI = find([app.params(ids2U1).FacialNerve]);
                                    ids2U1 = ids2U1(FNI-1);
                                    velX = app.params(ids2U1).(toUseMMag);
                                else
                                    velX = app.params(ids2U1(end)).(toUseMMag);
                                    ids2U1 = ids2U1(end);
                                end

                                if any([app.params(ids2U2).FacialNerve])
                                    FNI = find([app.params(ids2U2).FacialNerve]);
                                    ids2U2 = ids2U2(FNI-1);
                                    velY = app.params(ids2U2).(toUseMMag);
                                else
                                    velY = app.params(ids2U2(end)).(toUseMMag);
                                    ids2U2 = ids2U2(end);
                                end
                                points = plot(magPlot,velX,velY,'color',color{1},'marker','o','MarkerSize',20,'LineStyle','none','LineWidth',4);
                                points.UserData = [ids2U1; ids2U2];

                            else

                                velX = 0;
                                velY = 0;
                                line = plot(magPlot,velX,velY,'color',color{1},'LineWidth',4);
                                if ~ismember(ldgNames,[stimName{1}])
                                    ldgNames = [ldgNames {[stimName{1}]}];
                                    ldgLine = [ldgLine line];
                                end

                            end
                            stType = 'NA';
                            hold(magPlot,'off');
                            if ~ldgFlg
                                hold(misalignPlot,'on');
                                [color,stimName,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, s);

                                velX = app.params(ids2U1).(toUseMMis);

                                velY = app.params(ids2U2).(toUseMMis);

                                points = plot(misalignPlot,velX,velY,'color',color{1},'marker','o','MarkerSize',20,'LineStyle','none','LineWidth',4);
                                points.UserData = [ids2U1; ids2U2];

                                hold(misalignPlot,'off');
                            end
                        end
                    end
                    if i == 1

                        magPlot.YLabel.String = [name2{1},' Velocity (deg/s)'];
                        magPlot.YLabel.FontSize = 30;
                        misalignPlot.YLabel.String = [name2{1},' Misalignment (deg)'];
                        misalignPlot.YLabel.FontSize = 30;
                    else
                        magPlot.YTickLabel = [];
                        misalignPlot.YTickLabel = [];
                    end
                    magPlot.XLabel.String = {[name1{1},' Velocity (deg/s)']};
                    magPlot.XGrid = 'on';
                    magPlot.YGrid = 'on';
                    magPlot.XLim(2) = magPlot.XLim(2)+5;
                    magPlot.YLim(2) = magPlot.YLim(2)+5;
                    magPlot.FontSize = 30;
                    magPlot.Title.String = [name1{1}, ' vs ', name2{1}];
                    magPlot.LineWidth = 2.5;
                    magPlot.Title.FontSize = 30;


                    misalignPlot.XGrid = 'on';
                    misalignPlot.YGrid = 'on';
                    misalignPlot.XLim(2) = misalignPlot.XLim(2)+5;
                    misalignPlot.YLim(2) = misalignPlot.YLim(2)+5;
                    misalignPlot.FontSize = 30;
                    misalignPlot.XLabel.String = {[name1{1},' Misalignment (deg)']};
                    misalignPlot.XLabel.FontSize = 30;
                    misalignPlot.LineWidth = 2.5;

                    magPlots = [magPlots magPlot];
                    misalignPlots = [misalignPlots misalignPlot];

                    if ldgFlg
                        magPlot.Visible = 0;
                        misalignPlot.Visible = 0;
                    end
                end

                if plotLoc ~= 1
                    if ~isempty(app.MaxComparePairsByRefTab.UserData)
                        if isfield(app.MaxComparePairsByRefTab.UserData,'MagPlot')
                            if ~isempty(app.MaxComparePairsByRefTab.UserData.MagPlot)
                                magPlot.XLim = app.MaxComparePairsByRefTab.UserData.MagPlot(1,:);
                                magPlot.YLim = app.MaxComparePairsByRefTab.UserData.MagPlot(2,:);
                            end
                        end

                        if isfield(app.MaxComparePairsByRefTab.UserData,'MisPlot')
                            if ~isempty(app.MaxComparePairsByRefTab.UserData.MisPlot)
                                misalignPlot.XLim = app.MaxComparePairsByRefTab.UserData.MisPlot(1,:);
                                misalignPlot.YLim = app.MaxComparePairsByRefTab.UserData.MisPlot(2,:);
                            end
                        end
                    end
                end

                sameaxes([],[magPlots])
                sameaxes([],[misalignPlots])

                maxV = max([magPlot.XLim magPlot.YLim]);
                maxM = max([misalignPlot.XLim misalignPlot.YLim]);

                set(ULineV,'XData',[0 maxV]);
                set(ULineV,'YData',[0 maxV]);

                set(ULineM,'XData',[0 maxM]);
                set(ULineM,'YData',[0 maxM]);
                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end
                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                end
                app.FigureName = [uAns{1},'_',eyeSel,'_NormCompare-',normType,'_MisalignmentandVelocity_MaxCurrent_ByRef_',stType,strrep(num2str(unique(uRefs(:,1)')),' ',''),'-',normType,'-',strrep(num2str(unique(uRefs(:,2)')),' ',''),'_',strrep(num2str(uStims),' ',''),'_',cUsed,'_',DataType];
            end
        end

        function ComparePairsByRefSameAxisMax(app, plotLoc,ldgFlg)
            if plotLoc == 1
                loc = app.MaxComparePairsByRefTab;
                marg_hm = [0.22 0.075];
                marg_hmi = [0.12 0.12];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_hm = [0.12 0.05];
                marg_hmi = [0.085 0.09];
                marg_w = [.05 .015];
            end
            toUse = ismember(app.Options.Items,app.Options.Value);
            allCs = app.Options.UserData.coms(toUse,:);
            allMs = app.Options.UserData.mat(toUse,:);

            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = unique(app.Options.UserData.coms(toUse,2));
            uStims = OrderElectrodesByAnimal(app, uAns{1}, uStims);
            szAllC = size(allCs);

            uRefs = unique([allCs(:,1) allCs(:,3)],'rows','stable');
            uRefsSZ = size(uRefs);

            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];
            yLab1 = '';
            yLab2 = '';
            normType = '';
            meanTxtV = [];
            meanTxtM = [];

            ULineV = [];
            ULineM = [];

            genLdg = 0;
            animalsUsed = [];
            ids2T = GetDataInds(app, uAns, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                magPlot = subtightplot(2,1,1,[0.05 0.000000000005],marg_hm,marg_w,'Parent', loc);
                magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                axis(magPlot,'square')
                misalignPlot = subtightplot(2,1,2,[0.05 0.000000000005],marg_hmi,marg_w,'Parent', loc);
                misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                axis(misalignPlot,'square')

                if ~ldgFlg
                    vals = [0 1];
                else
                    vals = [0];
                end

                hold(magPlot,'on');
                lineVel = plot(magPlot,vals,vals,'color','k','LineWidth',4);
                ULineV = [lineVel ULineV];
                hold(magPlot,'off');

                if ~ldgFlg
                    hold(misalignPlot,'on');
                    lineMis = plot(misalignPlot,vals,vals,'color','k','LineWidth',4);
                    ULineM = [lineMis ULineM];
                    hold(misalignPlot,'off');
                end

                if isempty(ldgNames)
                    ldgNames = [ldgNames {'Unity Line'}];
                    ldgLine = [ldgLine lineVel];
                else
                    if ~ismember(ldgNames,['Unity Line'])
                        ldgNames = [ldgNames {'Unity Line'}];
                        ldgLine = [ldgLine lineVel];
                    end
                end

                if plotLoc == 1
                    app.MaxComparePairsByRefTab.UserData = [];
                end
                ediffsV = [];
                ediffsM = [];
                for i = uAns'
                    if isempty(animalsUsed)
                        animalsUsed = [animalsUsed i{1}];
                    else
                        animalsUsed = [animalsUsed,'-', i{1}];
                    end
                    for j = 1:szAllC(1)
%                         comIds = find(allCs(:,1)==uRefs(j,1));
%                             s = allCs(comIds(k),2);
                            ids2U1 = GetDataInds(app, i{1}, allCs(j,2), allCs(j,1), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                            ids2U2 = GetDataInds(app, i{1}, allCs(j,2), allCs(j,3), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                            if length(uAns)>1
                                switch i{1}
                                    case 'Opal'
                                        if allCs(j,3) == 8
                                            ids2U1 = [];
                                            ids2U2 = [];
                                        end
                                        if (allCs(j,1) == 10) && (allCs(j,3) == 11)
                                            ids2U1 = [];
                                            ids2U2 = [];
                                        end
                                        if (allCs(j,1) == 11)
                                            ids2U1 = [];
                                            ids2U2 = [];
                                        end
                                    case 'Nancy'
                                        if allCs(j,3) == 8
                                            ids2U1 = [];
                                            ids2U2 = [];
                                        end
                                        if (allCs(j,1) == 10) && (allCs(j,3) == 13)
                                            ids2U1 = [];
                                            ids2U2 = [];
                                        end
                                        if (allCs(j,1) == 13)
                                            ids2U1 = [];
                                            ids2U2 = [];
                                        end
                                    case 'Gigi'
                                        if allCs(j,3) == 7
                                            ids2U1 = [];
                                            ids2U2 = [];
                                        end
                                end
                            end
                            if (~isempty(ids2U1)) && (~isempty(ids2U2))
                                e2U = find(ismember(app.AnimalsandEyesToUse(:,1),i));
                                if app.AnimalsandEyesToUse{e2U,3}
                                    eyeSel = 'LEye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagL_NystagCorr';
                                        toUseMag = 'MagL_NystagCorr';
                                        toUseSMag = 'stdMagL_NystagCorr';
                                        toUseMMis = 'meanMisalignL_NystagCorr';
                                        toUseMis = 'MisalignL_NystagCorr';
                                        toUseSMis = 'stdMisalignL_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagL';
                                        toUseMag = 'MagL';
                                        toUseSMag = 'stdMagL';
                                        toUseMMis = 'meanMisalignL';
                                        toUseMis = 'MisalignL';
                                        toUseSMis = 'stdMisalignL';
                                        DataType = 'NonCorrected';
                                    end
                                else
                                    eyeSel = 'REye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagR_NystagCorr';
                                        toUseMag = 'MagR_NystagCorr';
                                        toUseSMag = 'stdMagR_NystagCorr';
                                        toUseMMis = 'meanMisalignR_NystagCorr';
                                        toUseMis = 'MisalignR_NystagCorr';
                                        toUseSMis = 'stdMisalignR_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagR';
                                        toUseMag = 'MagR';
                                        toUseSMag = 'stdMagR';
                                        toUseMMis = 'meanMisalignR';
                                        toUseMis = 'MisalignR';
                                        toUseSMis = 'stdMisalignR';
                                        DataType = 'NonCorrected';
                                    end
                                end
                                if ~app.AnimalProperties.(i{1}).CorrectionLabel.Value
                                    transformed = 'NONTRANSFORMED';
                                else
                                    transformed = 'TRANSFORMED';
                                end
                                [color,stimName,~,~] = GetElecColorandNameByAnimal(app, i{1}, allCs(j,2));
                                [~,~,mark,~] = GetElecColorandNameByAnimal(app, i{1}, allCs(j,1));
                                switch i{1}
                                    case 'Nancy'
                                        mark = '^';
                                        if allCs(j,1) == 12
                                            mark = 'v';
                                        end
                                        if allCs(j,1) == 13
                                            mark = '^';
                                        end
                                end
                                if length(uAns) > 1
                                    %%%%%%%%%%
                                end
                                if ~ldgFlg
                                    hold(magPlot,'on');
                                    if any([app.params(ids2U1).FacialNerve])
                                        FNI = find([app.params(ids2U1).FacialNerve]);
                                        ids2U1 = ids2U1(min(FNI)-1);
                                        velX = app.params(ids2U1).(toUseMMag);
                                    else
                                        velX = app.params(ids2U1(end)).(toUseMMag);
                                        ids2U1 = ids2U1(end);
                                    end

                                    if any([app.params(ids2U2).FacialNerve])
                                        FNI = find([app.params(ids2U2).FacialNerve]);
                                        ids2U2 = ids2U2(min(FNI)-1);
                                        velY = app.params(ids2U2).(toUseMMag);
                                    else
                                        velY = app.params(ids2U2(end)).(toUseMMag);
                                        ids2U2 = ids2U2(end);
                                    end

                                    ediffsV = [ediffsV; velY velX];
                                    points = plot(magPlot,velX,velY,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                    points.UserData = [ids2U1; ids2U2];
                                    hold(magPlot,'off');

                                    hold(misalignPlot,'on');
                                    if ~app.AnimalProperties.(i{1}).CorrectionLabel.Value
                                        velX = app.params(ids2U1).(toUseMMis);

                                        velY = app.params(ids2U2).(toUseMMis);
                                        transformed = 'NONTRANSFORMED';
                                    else
                                        [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U1, eyeSel, i{1});
                                        velX = misValsMean;
                                        [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U2, eyeSel, i{1});
                                        velY = misValsMean;
                                        transformed = 'TRANSFORMED';
                                    end

                                    
                                    ediffsM = [ediffsM; velY velX];
                                    pointsR = plot(misalignPlot,velX,velY,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                    pointsR.UserData = [ids2U1; ids2U2];

                                    hold(misalignPlot,'off');
                                else
                                    velX = 0;
                                    velY = 0;
                                    if ~genLdg
                                        if isequal(uRefs,[12 11; 13 11]) || isequal(uRefs,[11 13; 12 13])
                                            for iT = uAns'
                                                for jT = 1:uRefsSZ(1)
                                                    [~,nameM,mark,~] = GetElecColorandNameByAnimal(app, iT{1}, uRefs(jT,1));
                                                    hold(magPlot,'on');
                                                    ldgM = plot(magPlot,0,0,'color','k','marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                                    hold(magPlot,'off');
                                                    if length(uAns)==1
                                                        switch i{1}
                                                            case 'Opal'
                                                                if uRefs(jT,1) == 11
                                                                    nameM = {'Vestibule, Deep'};
                                                                elseif uRefs(jT,1) == 12
                                                                    nameM = {'Vestibule, Middle'};
                                                                end
                                                        end
                                                    end
                                                    ldgNames = [ldgNames {[iT{1},' - ',nameM{1}]}];
                                                    ldgLine = [ldgLine ldgM];
                                                end
                                            end
                                            genLdg = 1;
                                        end
                                        if isequal(uRefs,[10 11; 10 13])
                                            for iT = uAns'
                                                for jT = 1:uRefsSZ(1)
                                                    [~,nameM,mark,~] = GetElecColorandNameByAnimal(app, iT{1}, uRefs(jT,1));
                                                    if ~ismember(ldgNames,{[iT{1}]})
                                                    hold(magPlot,'on');
                                                    ldgM = plot(magPlot,0,0,'color','k','marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                                    hold(magPlot,'off');
                                                    ldgNames = [ldgNames {[iT{1}]}];
                                                    ldgLine = [ldgLine ldgM];
                                                    end
                                                end
                                            end
                                            genLdg = 1;
                                        elseif all(any(ismember(uRefs,[11 3])))
                                            for iT = uAns'
                                                    [~,nameM,mark,~] = GetElecColorandNameByAnimal(app, iT{1}, uRefs(1,1));
                                                    hold(magPlot,'on');
                                                    ldgM = plot(magPlot,0,0,'color','k','marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                                    hold(magPlot,'off');
                                                    ldgNames = [ldgNames {[iT{1}]}];
                                                    ldgLine = [ldgLine ldgM];
                                            end
                                            genLdg = 1;
                                        end
                                    end
                                    hold(magPlot,'on');
                                    line = plot(magPlot,velX,velY,'color',color{1},'LineWidth',4);
                                    hold(magPlot,'off');
                                    if ~ismember(ldgNames,[stimName{1}])
                                        ldgNames = [ldgNames {[stimName{1}]}];
                                        ldgLine = [ldgLine line];
                                    end
                                end
                            end
                    end
                end
                [~,name1,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, allCs(1,1));
                [~,name2,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, allCs(1,3));


                magPlot.YLabel.String = [name2{1},' Velocity (deg/s)'];
                magPlot.YLabel.FontSize = 30;
                misalignPlot.YLabel.String = [name2{1},' Misalignment (deg)'];
                misalignPlot.YLabel.FontSize = 30;

%                 magPlot.XLabel.String = {[name1{1},' Velocity (deg/s)']};
                magPlot.XGrid = 'on';
                magPlot.YGrid = 'on';
                magPlot.XLim(2) = max([magPlot.XLim(2) magPlot.YLim(2)]);
                magPlot.YLim(2) = max([magPlot.XLim(2) magPlot.YLim(2)]);
                magPlot.FontSize = 30;
                switch allCs(1,1)
                    case [{11} {12} {13}]
                        titleFull = 'Intra Canal';
                        normType = 'IC';
                        magPlot.XLabel.String = {['IC Velocity (deg/s)']};
                        misalignPlot.XLabel.String = {['IC Misalignment (deg)']};
                    case 10
                        titleFull = 'Distant';
                        normType = 'Dist';
                        magPlot.XLabel.String = {['Distant Velocity (deg/s)']};
                        misalignPlot.XLabel.String = {['Distant Misalignment (deg)']};
                    case num2cell([1:9 14 15])
                        titleFull = 'Bipolar';
                        normType = 'Bipolar';
                end
                switch allCs(1,3)
                    case [{11} {12} {13}]
                        titleFull = [titleFull,' vs Intra Canal, all Stim'];
                        normType = [normType,'vsIC'];                        
                    case 10
                        titleFull = [titleFull,' vs Distant, all Stim'];
                        normType = [normType,'vsDist'];
                    case num2cell([1:9 14 15])
                        titleFull = [titleFull,' vs Bipolar, all Stim'];
                        normType = [normType,'vsBipolar'];
                end
                magPlot.Title.String = titleFull;
                magPlot.LineWidth = 2.5;
                magPlot.Title.FontSize = 30;


                misalignPlot.XGrid = 'on';
                misalignPlot.YGrid = 'on';
                misalignPlot.XLim(2) = max([misalignPlot.XLim(2) misalignPlot.YLim(2)]);
                misalignPlot.YLim(2) = max([misalignPlot.XLim(2) misalignPlot.YLim(2)]);
                misalignPlot.FontSize = 30;
                
                misalignPlot.XLabel.FontSize = 30;
                misalignPlot.LineWidth = 2.5;

                magPlots = [magPlots magPlot];
                misalignPlots = [misalignPlots misalignPlot];

                if ldgFlg
                    magPlot.Visible = 0;
                    misalignPlot.Visible = 0;
                end
                if plotLoc ~= 1
                    if ~isempty(app.MaxComparePairsByRefTab.UserData)
                        if isfield(app.MaxComparePairsByRefTab.UserData,'MagPlot')
                            if ~isempty(app.MaxComparePairsByRefTab.UserData.MagPlot)
                                magPlot.XLim = app.MaxComparePairsByRefTab.UserData.MagPlot(1,:);
                                magPlot.YLim = app.MaxComparePairsByRefTab.UserData.MagPlot(2,:);
                            end
                        end

                        if isfield(app.MaxComparePairsByRefTab.UserData,'MisPlot')
                            if ~isempty(app.MaxComparePairsByRefTab.UserData.MisPlot)
                                misalignPlot.XLim = app.MaxComparePairsByRefTab.UserData.MisPlot(1,:);
                                misalignPlot.YLim = app.MaxComparePairsByRefTab.UserData.MisPlot(2,:);
                            end
                        end
                    end
                end

                maxV = max([magPlot.XLim magPlot.YLim]);
                maxM = max([misalignPlot.XLim misalignPlot.YLim]);

                set(ULineV,'XData',[0 maxV]);
                set(ULineV,'YData',[0 maxV]);

                set(ULineM,'XData',[0 maxM]);
                set(ULineM,'YData',[0 maxM]);
                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end

                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.1987 0.1744 0.2214];
                    if length(ldgLine) > 1
                        ldg.Title.String = 'Stimulating Electrodes:';
                    else
                        ldg.Title.String = 'Stimulating Electrode:';
                    end
                end
                app.FigureName = [animalsUsed,'_',eyeSel,'_Compare-',normType,'_',transformed,'_MisandVel_MaxCurrent_ByRef_',strrep(num2str(unique(uRefs(:,1)')),' ',''),'-',strrep(num2str(unique(uRefs(:,2)')),' ',''),'_',strrep(num2str(uStims),' ',''),'_',cUsed,'_',DataType];
                if ~ldgFlg
                    n = length(ediffsV(:,1))
                    [mV, CIV, PMV] = CalcCI(app, ediffsV(:,1)-ediffsV(:,2), .95)
                    [mM, CIM, PMM] = CalcCI(app, ediffsM(:,1)-ediffsM(:,2), .95)
                end
            end
        end
     
        function SurfPlotByStimIPGandSPD(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.SurfPlotsByStimIPGandPDTab;
%                 marg_h = [0.15 0.075];
%                 marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
%                 marg_h = [0.10 0.075];
%                 marg_w = [.05 .015];
            end
            if any(contains(app.NormBy.Items,'Anodic'))
                all = GetIPGandFPDPairs(app);
            else
                all = GetIPGandSPDPairs(app);
            end
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            sza = size(all);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];
            maxZV = 0;
            minZV = 0;
            maxZM = 0;
            minZM = 0;

            if isempty(all)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                ids2T = GetDataInds(app, uAns{1}, [all{1,:}], [all{2,:}], [all{3,1}], [all{4,1}], [all{5,1}], [all{6,1}], [all{7,1}], 1, -1);
            end

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                for i = 1:sza(2)
                    ids2U = GetDataInds(app, uAns{1}, all{1,i}, all{2,i}, all{3,i}, all{4,i}, all{5,i}, all{6,i}, all{7,i}, 1, -1);

                    if ~isempty(ids2U)
                        misalignPlot = subtightplot(2,sza(2),i+sza(2),[0.01 0.03],[0.025 0.03],[.032 .03],'Parent', loc);
                        misalignPlot.Layer = 'bottom';
                        misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                        magPlot = subtightplot(2,sza(2),i,[0.01 0.03],[0.025 0.03],[.032 .03],'Parent', loc);
                        magPlot.Layer = 'Top';
                        magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;

                        if (i == 1) && (plotLoc == 1)
                            app.SurfPlotsByStimIPGandPDTab.UserData = [];
                        end

                        [~,nameS,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, all{1,i});
                        [~,nameR,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, all{2,i});
                        if app.AnimalsandEyesToUse{1,3}
                            eyeSel = 'LEye';
                            if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                toUseMMag = 'meanMagL_NystagCorr';
                                toUseMag = 'MagL_NystagCorr';
                                toUseSMag = 'stdMagL_NystagCorr';
                                toUseMMis = 'meanMisalignL_NystagCorr';
                                toUseMis = 'MisalignL_NystagCorr';
                                toUseSMis = 'stdMisalignL_NystagCorr';
                                DataType = 'NystagCorr';
                            else
                                toUseMMag = 'meanMagL';
                                toUseMag = 'MagL';
                                toUseSMag = 'stdMagL';
                                toUseMMis = 'meanMisalignL';
                                toUseMis = 'MisalignL';
                                toUseSMis = 'stdMisalignL';
                                DataType = 'NonCorrected';
                            end
                        else
                            eyeSel = 'REye';
                            if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                toUseMMag = 'meanMagR_NystagCorr';
                                toUseMag = 'MagR_NystagCorr';
                                toUseSMag = 'stdMagR_NystagCorr';
                                toUseMMis = 'meanMisalignR_NystagCorr';
                                toUseMis = 'MisalignR_NystagCorr';
                                toUseSMis = 'stdMisalignR_NystagCorr';
                                DataType = 'NystagCorr';
                            else
                                toUseMMag = 'meanMagR';
                                toUseMag = 'MagR';
                                toUseSMag = 'stdMagR';
                                toUseMMis = 'meanMisalignR';
                                toUseMis = 'MisalignR';
                                toUseSMis = 'stdMisalignR';
                                DataType = 'NonCorrected';
                            end
                        end
                        color = 'b';
                        Magf2Use = [toUseMMag,app.NormBy.Value{1}];
                        MagfSD2Use = [toUseSMag,app.NormBy.Value{1}];
                        Misf2Use = [toUseMMis,app.NormBy.Value{1}];
                        MisfSD2Use = [toUseSMis,app.NormBy.Value{1}];

                        nrmI = app.params(ids2U(1)).normInd;%%%%%may be more than one normed by and will need a way to figure out which one to use
                        %ids2U = GetDataInds(app, stim, ref, p1d, p2d, p1a, p2a, ipg, dup, dsp2d)
                        id = GetDataInds(app, uAns{1}, all{1,i}, all{2,i}, all{3,i}, all{4,i}, all{5,i}, all{6,i}, all{7,i}, 1, nrmI);
                        normMag = app.params(id).(toUseMMag);
                        normMis = app.params(id).(toUseMMis);

                        if any(contains(app.NormBy.Items,'Anodic'))
                            x = [app.params(ids2U).p1d]';
                        else
                            x = [app.params(ids2U).p2d]';
                        end
                        y = [app.params(ids2U).ipg]';
                        tempz = vertcat(app.params(ids2U).(Magf2Use));
                        z = tempz;
                        maxZV = max(maxZV,max(z));
                        if minZV == 0
                            minZV = min(z);
                        else
                            minZV = min(minZV,min(z));
                        end

                        F = scatteredInterpolant(x,y,z);
                        F.Method = 'natural';

                        if any(contains(app.NormBy.Items,'Anodic'))
                            minVX = min([all{3,1}]');
                            maxVX = max([all{3,1}]');
                            minVY = min([all{7,1}]');
                            maxVY = max([all{7,1}]');
                            [xq,yq] = meshgrid([minVX:10:maxVX],[minVY:10:maxVY]);
                            minV = min([minVX minVY]);
                        else
                            minV = min([all{4,i} all{7,i}]');
                            maxV = max([all{4,i} all{7,i}]');
                            [xq,yq] = meshgrid(minV:10:maxV);
                        end


                        F.Method = 'natural';
                        vq1 = F(xq,yq);

                        hold(magPlot,'on');
                        pt = plot3(magPlot,x,y,z,'LineWidth',2.5,'DisplayName','','Marker','o','MarkerSize',20,'MarkerEdgeColor',color,'LineStyle','none');
                        magPlot.XLim = [minV max(x)];
                        magPlot.XGrid = 'On';
                        magPlot.YLim = [minV max(y)];
                        magPlot.YGrid = 'On';
                        magPlot.ZGrid = 'On';
                        magPlot.View = [-37.5000 46.5];
                        magPlot.CameraPositionMode = 'auto';

                        magPlot.Title.String = [nameS{1},' vs ',nameR{1}];
                        magPlot.YTick = unique(y);
                        magPlot.XTick = unique(x);
                        magPlot.YTickLabel{2} = ' ';
                        magPlot.YTickLabel{3} = ' ';
                        magPlot.XTickLabel{1} = ' ';
                        zlimmin = 10;
                        zlimmax = -10;


                        for ptS = 1:length(ids2U)
                            if app.PlottingStandardDeviationCheckBox.Value
                                stdMag = app.params(ids2U(ptS)).(MagfSD2Use)/sqrt(length(app.params(ids2U(ptS)).(toUseMag)));
                                stType = 'SEM';
                            else
                                stdMag = app.params(ids2U(ptS)).(MagfSD2Use);
                                stType = 'STD';
                            end
                            plot3(magPlot,[x(ptS) x(ptS)], [y(ptS) y(ptS)], [z(ptS)-stdMag z(ptS)+stdMag],'Color',color,'LineWidth',4);
                            zlimmin = min(zlimmin,z(ptS)-stdMag);
                            zlimmax = max(zlimmax,z(ptS)+stdMag);
                        end
                        surfR = mesh(magPlot,xq,yq,vq1,'FaceColor','interp','FaceAlpha', 0.5);
%                         magPlot.ZLim = [0 4];
                        colormap(magPlot,'jet')
                        caxis(magPlot,magPlot.ZLim)
                        hold(magPlot,'off');

                        hold(misalignPlot,'on');
                        tempz = vertcat(app.params(ids2U).(Misf2Use));
                        z = tempz;
                        maxZM = max(maxZM,max(z));
                        if minZM == 0
                            minZM = min(z);
                        else
                            minZM = min(minZM,min(z));
                        end
                        F = scatteredInterpolant(x,y,z);
                        F.Method = 'natural';

                        if any(contains(app.NormBy.Items,'Anodic'))
                            minVX = min([all{3,1}]');
                            maxVX = max([all{3,1}]');
                            minVY = min([all{7,1}]');
                            maxVY = max([all{7,1}]');
                            [xq,yq] = meshgrid([minVX:10:maxVX],[minVY:10:maxVY]);
                            minV = min([minVX minVY]);
                        else
                            minV = min([all{4,i} all{7,i}]');
                            maxV = max([all{4,i} all{7,i}]');
                            [xq,yq] = meshgrid(minV:10:maxV);
                        end

                        F.Method = 'natural';
                        vq1 = F(xq,yq);
                        ptL = plot3(misalignPlot,x,y,z,'LineWidth',2.5,'DisplayName','','Marker','o','MarkerSize',20,'MarkerEdgeColor',color,'LineStyle','none');
                        misalignPlot.XLim = [minV max(x)];
                        misalignPlot.XGrid = 'On';
                        misalignPlot.YLim = [minV max(y)];
                        misalignPlot.YGrid = 'On';
                        misalignPlot.ZGrid = 'On';
                        misalignPlot.View = [-37.5000 46.5];
                        misalignPlot.CameraPositionMode = 'auto';

                        misalignPlot.YTick = unique(y);
                        misalignPlot.XTick = unique(x);
                        misalignPlot.YTickLabel{2} = ' ';
                        misalignPlot.YTickLabel{3} = ' ';
                        misalignPlot.XTickLabel{1} = ' ';
                        zlimmin = 10;
                        zlimmax = -10;

                        for ptS = 1:length(ids2U)
                            if app.PlottingStandardDeviationCheckBox.Value
                                stdMisalign = app.params(ids2U(ptS)).(MisfSD2Use)/sqrt(length(app.params(ids2U(ptS)).(toUseMag)));
                                stType = 'SEM';
                            else
                                stdMisalign = app.params(ids2U(ptS)).(MisfSD2Use);
                                stType = 'STD';
                            end
                            plot3(misalignPlot,[x(ptS) x(ptS)], [y(ptS) y(ptS)], [z(ptS)-stdMisalign z(ptS)+stdMisalign],'Color',color,'LineWidth',4);
                            zlimmin = min(zlimmin,z(ptS)-stdMisalign);
                            zlimmax = max(zlimmax,z(ptS)+stdMisalign);
                        end
                        surfR = mesh(misalignPlot,xq,yq,vq1,'FaceColor','interp','FaceAlpha', 0.5);
%                         misalignPlot.ZLim = [0 4];
                        colormap(misalignPlot,'jet')
                        caxis(misalignPlot,misalignPlot.ZLim)
                        hold(misalignPlot,'off');


                        fs = 15;
                        magPlot.FontSize = fs;
                        misalignPlot.FontSize = fs;
                        magPlot.ZTick = 0:4;
                        misalignPlot.ZTick = 0:4;

                        if any(contains(app.NormBy.Items,'Anodic'))
                            magPlot.YTickLabel = cellfun(@num2str, num2cell(all{7,i}), 'UniformOutput', 0);
                            magPlot.XTickLabel = cellfun(@num2str, num2cell(all{3,i}), 'UniformOutput', 0);

                            misalignPlot.YTickLabel = cellfun(@num2str, num2cell(all{7,i}), 'UniformOutput', 0);
                            misalignPlot.XTickLabel = cellfun(@num2str, num2cell(all{3,i}), 'UniformOutput', 0);
                        else
                            magPlot.YTickLabel = cellfun(@num2str, num2cell(all{7,i}), 'UniformOutput', 0);
                            magPlot.XTickLabel = cellfun(@num2str, num2cell(all{4,i}), 'UniformOutput', 0);

                            misalignPlot.YTickLabel = cellfun(@num2str, num2cell(all{7,i}), 'UniformOutput', 0);
                            misalignPlot.XTickLabel = cellfun(@num2str, num2cell(all{4,i}), 'UniformOutput', 0);
                        end
                        magPlot.FontSize = 13.5;
                        misalignPlot.FontSize = 13.5;
                        if any([app.params(ids2U).FacialNerve])
                            trueFace = find([app.params(ids2U).FacialNerve]);
                            for xs = 1:length(trueFace)
                                hold(misalignPlot,'on')
                                if any(contains(app.NormBy.Items,'Anodic'))
                                    p2d2 = app.params(ids2U(trueFace(xs))).p1d;
                                else
                                    p2d2 = app.params(ids2U(trueFace(xs))).p2d;
                                end
                                ipg2 = app.params(ids2U(trueFace(xs))).ipg;
                                mis2 = app.params(ids2U(trueFace(xs))).(Misf2Use);
                                handles.cutOff1 = plot3(misalignPlot,p2d2,ipg2,mis2,'rx','MarkerSize', 30,'LineWidth',4);
                                hold(misalignPlot,'off')

                                hold(magPlot,'on')
                                mag2 = app.params(ids2U(trueFace(xs))).(Magf2Use);
                                handles.cutOff2 = plot3(magPlot,p2d2,ipg2,mag2,'rx','MarkerSize', 30,'LineWidth',4);
                                hold(magPlot,'off')
                            end
                        end

                        magPlot.LineWidth = 2;
                        magPlot.GridAlpha = .3;

                        misalignPlot.LineWidth = 2;
                        misalignPlot.GridAlpha = .3;
                        set(magPlot,'Layer','Top')
                        if i == 1
                            magPlot.ZLabel.String = 'Normalized VOR';
                            misalignPlot.ZLabel.String = 'Normalized Misalignment';
                        end
                        if any(contains(app.NormBy.Items,'Anodic'))
                            misalignPlot.XLabel.String = 'P1D (uS)';
                        else
                            misalignPlot.XLabel.String = 'P2D (uS)';
                        end
                        misalignPlot.YLabel.String = 'IPG (uS)';
                        if ldgFlg
                            magPlot.Visible = 0;
                            misalignPlot.Visible = 0;
                        end

                        if plotLoc ~= 1
                            if ~isempty(app.SurfPlotsByStimIPGandPDTab.UserData)
                                if isfield(app.SurfPlotsByStimIPGandPDTab.UserData,'MagPlot')
                                    if ~isempty(app.SurfPlotsByStimIPGandPDTab.UserData.MagPlot)
                                        magPlot.XLim = app.SurfPlotsByStimIPGandPDTab.UserData.MagPlot(1,:);
                                        magPlot.YLim = app.SurfPlotsByStimIPGandPDTab.UserData.MagPlot(2,:);
                                    end
                                end

                                if isfield(app.SurfPlotsByStimIPGandPDTab.UserData,'MisPlot')
                                    if ~isempty(app.SurfPlotsByStimIPGandPDTab.UserData.MisPlot)
                                        misalignPlot.XLim = app.SurfPlotsByStimIPGandPDTab.UserData.MisPlot(1,:);
                                        misalignPlot.YLim = app.SurfPlotsByStimIPGandPDTab.UserData.MisPlot(2,:);
                                    end
                                end
                            end
                        end

                        magPlots = [magPlots magPlot];
                        misalignPlots = [misalignPlots misalignPlot];


                    end

                end
                sameaxes([],[magPlots])
                sameaxes([],[misalignPlots])

                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end
                app.FigureName = [uAns{1},'_',eyeSel,'_NormailzedMisalignmentandVelocity_IPG_P2D_ByStim_',stType,'_',strrep(num2str([all{1,:}]),' ',''),'_',strrep(num2str([all{2,:}]),' ',''),'_',cUsed,'_',DataType];
                if ldgFlg
                    cb = colorbar(misalignPlot);
                    cb.FontSize = 22;
                    cb.LineWidth = 2.5;
                    cb.TickLength = 0.02;
                end
            end
            
        end
        
        function all = GetIPGandSPDPairs(app)
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = app.Stims;
            s = OrderElectrodesByAnimal(app, uAns{1}, uStims);
            ordr = [];
            for ii = s
                ordr = [ordr find(uStims==ii)];
            end
            uStims = uStims(ordr);
            uRefs = app.Refs;
            uRefs = uRefs(ordr);
            up1d = unique(app.P1Ds,'stable');
            up2d = unique(app.P2Ds,'stable');
            up1a = unique(app.P1As,'stable');
            up2a = unique(app.P2As,'stable');
            uipg = unique(app.IPGs,'stable');
            all =[];
            for i = 1:length(uStims)
                for j = 1:length(up1d)
                    for k = 1:length(up1a)
                        s1 = {uStims(i)};
                        r1 = {uRefs(i)};
                        p1 = {up1d(j)};
                        p2 = {up2d};
                        p1a = {up1a(k)};
                        p2a = {up2a};
                        ip = {uipg};
                        %ids2U = GetDataInds(app, stim, ref, p1d, p2d, p1a, p2a, ipg, dup, dsp2d) 
                        ids2U = GetDataInds(app, uAns{1}, uStims(i), uRefs(i), up1d(j), up2d, up1a(k), up2a, uipg, 1, -1);
                        if ~isempty(ids2U)
                            temp = [s1; r1; p1; p2; p1a; p2a; ip];
                            all = [all temp];
                        end
                    end
                end
            end
        end

        function all = GetIPGandFPDPairs(app)
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = app.Stims;
            s = OrderElectrodesByAnimal(app, uAns{1}, uStims);
            ordr = [];
            for ii = s
                ordr = [ordr find(uStims==ii)];
            end
            uStims = uStims(ordr);
            uRefs = app.Refs;
            uRefs = uRefs(ordr);
            up1d = unique(app.P1Ds,'stable');
            up2d = unique(app.P2Ds,'stable');
            up1a = unique(app.P1As,'stable');
            up2a = unique(app.P2As,'stable');
            uipg = unique(app.IPGs,'stable');
            all =[];
            for i = 1:length(uStims)
                for j = 1:length(up2d)
                    for k = 1:length(up2a)
                        s1 = {uStims(i)};
                        r1 = {uRefs(i)};
                        p1 = {up1d};
                        p2 = {up2d(j)};
                        p1a = {up1a};
                        p2a = {up2a(k)};
                        ip = {uipg};
                        %ids2U = GetDataInds(app, stim, ref, p1d, p2d, p1a, p2a, ipg, dup, dsp2d) 
                        ids2U = GetDataInds(app, uAns{1}, uStims(i), uRefs(i), up1d, up2d(j), up1a, up2a(k), uipg, 1, -1);
                        if ~isempty(ids2U)
                            temp = [s1; r1; p1; p2; p1a; p2a; ip];
                            all = [all temp];
                        end
                    end
                end
            end
        end

        function AverageSurfPlotByRefIPGandSPD(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.AverageSurfByRefIPGandPDTab;
%                 marg_h = [0.15 0.075];
%                 marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
%                 marg_h = [0.10 0.075];
%                 marg_w = [.05 .015];
            end

            mpFlg = 0;
            mFlg = 0;
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            all = GetIPGandSPDPairs(app);
            sza = size(all);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];
            maxZV = 0;
            minZV = 0;
            maxZM = 0;
            minZM = 0;
            if isempty(all)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                ids2T = GetDataInds(app, uAns{1}, [all{1,:}], [all{2,:}], [all{3,1}], [all{4,1}], [all{5,1}], [all{6,1}], [all{7,1}], 1, -1);
            end

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                if app.EyeVButton.Value && app.MisalignmentButton.Value
                    misalignPlot = subtightplot(2,1,2,[0.01 0.03],[0.025 0.03],[.032 .03],'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot.Layer = 'bottom';
                    magPlot = subtightplot(2,1,1,[0.01 0.03],[0.025 0.03],[.032 .03],'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    magPlot.Layer = 'Top';
                    mpFlg = 1;
                    mFlg = 1;
                elseif app.EyeVButton.Value
                    magPlot = subtightplot(1,1,1,[0.01 0.03],[0.025 0.03],[.032 .03],'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    magPlot.Layer = 'Top';
                    mpFlg = 1;
                else
                    misalignPlot = subtightplot(1,1,1,[0.01 0.03],[0.025 0.03],[.032 .03],'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot.Layer = 'bottom';
                    mFlg = 1;
                end

                if (plotLoc == 1)
                    app.AverageSurfByRefIPGandPDTab.UserData = [];
                end

                allDataX = [];
                allDataY = [];
                allDataZ = [];
                allDataZMis = [];
                for i = 1:sza(2)

                    %ids2U = GetDataInds(app, stim, ref, p1d, p2d, p1a, p2a, ipg, dup, dsp2d)
                    ids2U = GetDataInds(app, uAns{1}, all{1,i}, all{2,i}, all{3,i}, all{4,i}, all{5,i}, all{6,i}, all{7,i}, 1, -1);

                    if ~isempty(ids2U)


                        [~,nameS,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, all{1,i});
                        [~,nameR,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, all{2,i});
                        if app.AnimalsandEyesToUse{1,3}
                                eyeSel = 'LEye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagL_NystagCorr';
                                    toUseMag = 'MagL_NystagCorr';
                                    toUseSMag = 'stdMagL_NystagCorr';
                                    toUseMMis = 'meanMisalignL_NystagCorr';
                                    toUseMis = 'MisalignL_NystagCorr';
                                    toUseSMis = 'stdMisalignL_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagL';
                                    toUseMag = 'MagL';
                                    toUseSMag = 'stdMagL';
                                    toUseMMis = 'meanMisalignL';
                                    toUseMis = 'MisalignL';
                                    toUseSMis = 'stdMisalignL';
                                    DataType = 'NonCorrected';
                                end
                            else
                                eyeSel = 'REye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagR_NystagCorr';
                                    toUseMag = 'MagR_NystagCorr';
                                    toUseSMag = 'stdMagR_NystagCorr';
                                    toUseMMis = 'meanMisalignR_NystagCorr';
                                    toUseMis = 'MisalignR_NystagCorr';
                                    toUseSMis = 'stdMisalignR_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagR';
                                    toUseMag = 'MagR';
                                    toUseSMag = 'stdMagR';
                                    toUseMMis = 'meanMisalignR';
                                    toUseMis = 'MisalignR';
                                    toUseSMis = 'stdMisalignR';
                                    DataType = 'NonCorrected';
                                end
                        end
                        color = 'b';
                        Magf2Use = [toUseMMag,app.NormBy.Value{1}];
                        MagfSD2Use = [toUseSMag,app.NormBy.Value{1}];
                        Misf2Use = [toUseMMis,app.NormBy.Value{1}];
                        MisfSD2Use = [toUseSMis,app.NormBy.Value{1}];

                        nrmI = app.params(ids2U(1)).normInd;%%%%%may be more than one normed by and will need a way to figure out which one to use
                        %ids2U = GetDataInds(app, stim, ref, p1d, p2d, p1a, p2a, ipg, dup, dsp2d)
                        id = GetDataInds(app, uAns{1}, all{1,i}, all{2,i}, all{3,i}, all{4,i}, all{5,i}, all{6,i}, all{7,i}, 1, nrmI);
                        normMag = app.params(id).(toUseMMag);
                        normMis = app.params(id).(toUseMMis);

                        x = [app.params(ids2U).p2d]';
                        y = [app.params(ids2U).ipg]';
                        tempz = vertcat(app.params(ids2U).(Magf2Use));
                        z = tempz;
                        tempz = vertcat(app.params(ids2U).(Misf2Use));
                        zm = tempz;
                        if length(x)<length(allDataX)
                            for iii = 1:length(allDataX)
                                if iii >length(x)
                                    x = [x; allDataX(1,iii)];
                                    y = [y; allDataY(1,iii)];
                                    z = [z; NaN];
                                    zm = [zm; NaN];
                                else
                                    if x(iii) ~= allDataX(1,iii)
                                        x = [x(1:iii-1); allDataX(1,iii); x(iii:end)];
                                        y = [y(1:iii-1); allDataY(1,iii); y(iii:end)];
                                        z = [z(1:iii-1); NaN; z(iii:end)];
                                        zm = [zm(1:iii-1); NaN; zm(iii:end)];
                                    end
                                end
                            end
                        end
                        allDataX = [allDataX; x'];
                        allDataY = [allDataY; y'];
                        allDataZ = [allDataZ; z'];
                        allDataZMis = [allDataZMis; zm'];
                    end
                end
                avgX = mean(allDataX)';
                avgY = mean(allDataY)';
                avgZ = mean(allDataZ,'omitnan')';
                sz = size(allDataY);
                if app.PlottingStandardDeviationCheckBox.Value
                    stdZ = std(allDataZ,'omitnan')'./sqrt(sz(1));
                    stType = 'SEM';
                else
                    stdZ = std(allDataZ,'omitnan')';
                    stType = 'STD';
                end

                maxZV = max(maxZV,max(avgZ));
                if minZV == 0
                    minZV = min(avgZ);
                else
                    minZV = min(minZV,min(avgZ));
                end
                F = scatteredInterpolant(avgX,avgY,avgZ);
                F.Method = 'natural';
                minVX = min([all{4,1}]');
                maxVX = max([all{4,1}]');
                minVY = min([all{7,1}]');
                maxVY = max([all{7,1}]');
                [xq,yq] = meshgrid([minVX:10:maxVX],[minVY:10:maxVY]);

                F.Method = 'natural';
                vq1 = F(xq,yq);
                nMP = '';
                nm = '';
                if mpFlg
                    hold(magPlot,'on');
                    ptL = plot3(magPlot,avgX,avgY,avgZ,'LineWidth',2.5,'DisplayName','','Marker','o','MarkerSize',20,'MarkerEdgeColor',color,'LineStyle','none');
%                     magPlot.XLim = [minV max(avgX)];
                    magPlot.XGrid = 'On';
%                     magPlot.YLim = [minV max(avgY)];
                    magPlot.YGrid = 'On';
                    magPlot.ZGrid = 'On';
                    magPlot.View = [37.5000 46.5];
                    magPlot.CameraPositionMode = 'auto';

                    magPlot.Title.String = ['Average vs ',nameR{1}];
                    magPlot.YTick = unique(avgY);
                    magPlot.XTick = unique(avgX);
                    magPlot.YTickLabel{2} = ' ';
                    magPlot.YTickLabel{3} = ' ';
                    magPlot.XTickLabel{1} = ' ';

                    for ptS = 1:length(avgZ)
                        stdMagR = stdZ(ptS);
                        plot3(magPlot,[avgX(ptS) avgX(ptS)], [avgY(ptS) avgY(ptS)], [avgZ(ptS)-stdMagR avgZ(ptS)+stdMagR],'Color',color,'LineWidth',4);
                        if ptS == 1
                            zlimmin = avgZ(ptS)-stdMagR;
                            zlimmax = avgZ(ptS)+stdMagR;
                        else
                            zlimmin = min(zlimmin,avgZ(ptS)-stdMagR);
                            zlimmax = max(zlimmax,avgZ(ptS)+stdMagR);
                        end
                        
                    end
                    surfR = mesh(magPlot,xq,yq,vq1,'FaceColor','interp','FaceAlpha', 0.5);
                    colormap(magPlot,'jet')
                    caxis(magPlot,magPlot.ZLim)
                    if plotLoc ~= 1
                        if ~isempty(app.AverageSurfByRefIPGandPDTab.UserData)
                            if isfield(app.AverageSurfByRefIPGandPDTab.UserData,'MagPlot')
                                if ~isempty(app.AverageSurfByRefIPGandPDTab.UserData.MagPlot)
                                    magPlot.XLim = app.AverageSurfByRefIPGandPDTab.UserData.MagPlot(1,:);
                                    magPlot.YLim = app.AverageSurfByRefIPGandPDTab.UserData.MagPlot(2,:);
                                    magPlot.ZLim = app.AverageSurfByRefIPGandPDTab.UserData.MagPlot(3,:);
                                    colormap(magPlot,'jet')
                                    caxis(magPlot,magPlot.ZLim)
%                                     magPlot.ZTick = 0:4;
                                end
                            end
                        end
                    end
%                     magPlot.ZLim = [0 4];
%                     colormap(magPlot,'jet')
%                     caxis(magPlot,[0 4])
                    hold(magPlot,'off');
                    magPlot.FontSize = 20;
%                     magPlot.ZTick = 0:4;
                    magPlot.YTickLabel = cellfun(@num2str, num2cell(all{7,1}), 'UniformOutput', 0);
                    magPlot.XTickLabel = cellfun(@num2str, num2cell(all{4,1}), 'UniformOutput', 0);
%                     magPlot.FontSize = 13.5;
                    magPlot.LineWidth = 3;
                    magPlot.GridAlpha = .3;
                    set(magPlot,'Layer','Top')
                    magPlot.ZLabel.String = 'Normalized VOR';
                    if ~mFlg
                        magPlot.XLabel.String = 'P2D (uS)';
                        magPlot.YLabel.String = 'IPG (uS)';
                    end
                    if ldgFlg
                        magPlot.Visible = 0;
                    end
                    nMP = '-Velocity';
                end

                if mFlg
                    hold(misalignPlot,'on');
                    avgZS = mean(allDataZMis,'omitnan')';
                    if app.PlottingStandardDeviationCheckBox.Value
                        stdZS = std(allDataZMis,'omitnan')'./sqrt(sz(1));
                        stType = 'SEM';
                    else
                        stdZS = std(allDataZMis,'omitnan')';
                        stType = 'STD';
                    end

                    maxZM = max(maxZM,max(avgZS));
                    if minZM == 0
                        minZM = min(avgZS);
                    else
                        minZM = min(minZM,min(avgZS));
                    end
                    F = scatteredInterpolant(avgX,avgY,avgZS);
                    F.Method = 'natural';
                    minVX = min([all{4,1}]');
                maxVX = max([all{4,1}]');
                minVY = min([all{7,1}]');
                maxVY = max([all{7,1}]');
                [xq,yq] = meshgrid([minVX:10:maxVX],[minVY:10:maxVY]);


                    F.Method = 'natural';
                    vq1 = F(xq,yq);
                    ptL = plot3(misalignPlot,avgX,avgY,avgZS,'LineWidth',2.5,'DisplayName','','Marker','o','MarkerSize',20,'MarkerEdgeColor',color,'LineStyle','none');
%                     misalignPlot.XLim = [minV max(avgX)];
                    misalignPlot.XGrid = 'On';
%                     misalignPlot.YLim = [minV max(avgY)];
                    misalignPlot.YGrid = 'On';
                    misalignPlot.ZGrid = 'On';
                    misalignPlot.View = [37.5000 46.5];
                    misalignPlot.CameraPositionMode = 'auto';

                    misalignPlot.YTick = unique(avgY);
                    misalignPlot.XTick = unique(avgX);
                    misalignPlot.YTickLabel{2} = ' ';
                    misalignPlot.YTickLabel{3} = ' ';
                    misalignPlot.XTickLabel{1} = ' ';

                    for ptS = 1:length(avgY)
                        stdMisalignR = stdZS(ptS);
                        plot3(misalignPlot,[avgX(ptS) avgX(ptS)], [avgY(ptS) avgY(ptS)], [avgZS(ptS)-stdMisalignR avgZS(ptS)+stdMisalignR],'Color',color,'LineWidth',4);
                        if ptS == 1
                            zlimmin = avgZS(ptS)-stdMisalignR;
                            zlimmax = avgZS(ptS)+stdMisalignR;
                        else
                            zlimmin = min(zlimmin,avgZS(ptS)-stdMisalignR);
                            zlimmax = max(zlimmax,avgZS(ptS)+stdMisalignR);
                        end
                        
                    end
                    surfR = mesh(misalignPlot,xq,yq,vq1,'FaceColor','interp','FaceAlpha', 0.5);
                    if mpFlg
                        misalignPlot.ZLim = magPlot.ZLim;
                    end
                    colormap(misalignPlot,'jet')
                    caxis(misalignPlot,misalignPlot.ZLim)
                    if plotLoc ~= 1
                        if ~isempty(app.AverageSurfByRefIPGandPDTab.UserData)
                            if isfield(app.AverageSurfByRefIPGandPDTab.UserData,'MisPlot')
                                if ~isempty(app.AverageSurfByRefIPGandPDTab.UserData.MisPlot)
                                    misalignPlot.XLim = app.AverageSurfByRefIPGandPDTab.UserData.MisPlot(1,:);
                                    misalignPlot.YLim = app.AverageSurfByRefIPGandPDTab.UserData.MisPlot(2,:);
                                    misalignPlot.ZLim = app.AverageSurfByRefIPGandPDTab.UserData.MisPlot(3,:);
                                    colormap(misalignPlot,'jet')
                                    caxis(misalignPlot,misalignPlot.ZLim)
                                end
                            end
                        end
                    end
                    
%                     misalignPlot.ZLim = [0 4];
%                     colormap(misalignPlot,'jet')
%                     caxis(misalignPlot,[0 4])
                    hold(misalignPlot,'off');
                    misalignPlot.FontSize = 20;
%                     misalignPlot.ZTick = 0:4;
                    misalignPlot.YTickLabel = cellfun(@num2str, num2cell(all{7,1}), 'UniformOutput', 0);
                    misalignPlot.XTickLabel = cellfun(@num2str, num2cell(all{4,1}), 'UniformOutput', 0);
%                     misalignPlot.FontSize = 13.5;
                    misalignPlot.LineWidth = 3;
                    misalignPlot.GridAlpha = .3;
                    misalignPlot.ZLabel.String = 'Normalized Misalignment';
                    misalignPlot.XLabel.String = 'P2D (uS)';
                    misalignPlot.YLabel.String = 'IPG (uS)';
                    if ldgFlg
                        misalignPlot.Visible = 0;
                    end
                    nm = 'Misalignment';
                end
                %             if any([app.params(ids2U).FacialNerve])
                %                 trueFace = find([app.params(ids2U).FacialNerve]);
                %                 for xs = 1:length(trueFace)
                %                     hold(misalignPlot,'on')
                %                     p2d2 = app.params(ids2U(trueFace(xs))).p2d;
                %                     ipg2 = app.params(ids2U(trueFace(xs))).ipg;
                %                     mis2 = app.params(ids2U(trueFace(xs))).(Misf2Use);
                %                     handles.cutOff1 = plot3(misalignPlot,p2d2,ipg2,mis2,'rx','MarkerSize', 30,'LineWidth',4);
                %                     hold(misalignPlot,'off')
                %
                %                     hold(magPlot,'on')
                %                     mag2 = app.params(ids2U(trueFace(xs))).(Magf2Use);
                %                     handles.cutOff2 = plot3(magPlot,p2d2,ipg2,mag2,'rx','MarkerSize', 30,'LineWidth',4);
                %                     hold(magPlot,'off')
                %                 end
                %             end
                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end
                app.FigureName = [uAns{1},'_',eyeSel,'_Normailzed',nm,nMP,'_IPG_P2D_ByStim_',stType,'_',strrep(num2str([all{1,:}]),' ',''),'_',strrep(num2str([all{2,:}]),' ',''),'_',cUsed,'_',DataType];
                if ldgFlg
                    if mpFlg + mFlg == 2
                        cb = colorbar(misalignPlot);
                    elseif mpFlg
                        cb = colorbar(magPlot);
                    else
                        cb = colorbar(misalignPlot);
                    end
                    cb.FontSize = 22;
                    cb.LineWidth = 2.5;
                    cb.TickLength = 0.02;
                end
            end
            
        end
        

        function PlotStandardAcrossTime(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.CompareStandardAcrossTimeTab;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                %                 marg_w = [.05 .015];
%                 marg_w = [.05 .3];
                marg_w = [.05 .55];
            end
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = unique(app.Stims);
            s = OrderElectrodesByAnimal(app, uAns{1}, uStims);
            uStims = s;
            uRefs = unique(app.Refs);
            r = OrderElectrodesByAnimal(app, uAns{1}, uRefs);
            uRefs = r;
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];

            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, 0);


            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                magPlot = subtightplot(2,1,1,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                misalignPlot = subtightplot(2,1,2,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                slopesV = [];
                slopesM = [];
                for i = 1:length(uStims)
                    [color,stimName,mark,shift,~] = GetElecColorandNameByAnimal(app, uAns{1}, uStims(i));
                    if (i == 1) && (plotLoc == 1)
                        app.CompareStandardAcrossTimeTab.UserData = [];
                    end
                    for j = 1:length(uRefs)
                        ids2U = GetDataInds(app, uAns{1}, uStims(i), uRefs(j), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, 0);
                        if ~isempty(ids2U)
                            if app.AnimalsandEyesToUse{1,3}
                                eyeSel = 'LEye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagL_NystagCorr';
                                    toUseMag = 'MagL_NystagCorr';
                                    toUseSMag = 'stdMagL_NystagCorr';
                                    toUseMMis = 'meanMisalignL_NystagCorr';
                                    toUseMis = 'MisalignL_NystagCorr';
                                    toUseSMis = 'stdMisalignL_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagL';
                                    toUseMag = 'MagL';
                                    toUseSMag = 'stdMagL';
                                    toUseMMis = 'meanMisalignL';
                                    toUseMis = 'MisalignL';
                                    toUseSMis = 'stdMisalignL';
                                    DataType = 'NonCorrected';
                                end
                            else
                                eyeSel = 'REye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagR_NystagCorr';
                                    toUseMag = 'MagR_NystagCorr';
                                    toUseSMag = 'stdMagR_NystagCorr';
                                    toUseMMis = 'meanMisalignR_NystagCorr';
                                    toUseMis = 'MisalignR_NystagCorr';
                                    toUseSMis = 'stdMisalignR_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagR';
                                    toUseMag = 'MagR';
                                    toUseSMag = 'stdMagR';
                                    toUseMMis = 'meanMisalignR';
                                    toUseMis = 'MisalignR';
                                    toUseSMis = 'stdMisalignR';
                                    DataType = 'NonCorrected';
                                end
                            end
                            if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                transformed = 'NONTRANSFORMED';
                            else
                                transformed = 'TRANSFORMED';
                            end
                            hold(magPlot,'on');
                            if ~ldgFlg
                                if length(ids2U) > 3
                                    ampL = [100+shift 200+shift 300+shift 400+shift 500+shift 600+shift];
                                    a={app.params(ids2U(3:end)).(toUseMag)};
                                    aa=cellfun(@size,a,'uni',false);
                                    ca = cell2mat(aa');
                                    vel = [app.params(ids2U(3:end)).(toUseMMag)];
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [app.params(ids2U(3:end)).(toUseSMag)]./sqrt([ca(:,2)']);
                                        stType = 'SEM';
                                    else
                                        std = [app.params(ids2U(3:end)).(toUseSMag)];
                                        stType = 'STD';
                                    end
                                elseif length(ids2U) == 3
                                    amp = [100+shift 200+shift 300+shift];
                                    a={app.params(ids2U).(toUseMag)};
                                    aa=cellfun(@size,a,'uni',false);
                                    ca = cell2mat(aa');
                                    vel = [app.params(ids2U).(toUseMMag)];
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [app.params(ids2U).(toUseSMag)]./sqrt([ca(:,2)']);
                                        stType = 'SEM';
                                    else
                                        std = [app.params(ids2U).(toUseSMag)];
                                        stType = 'STD';
                                    end
                                    clV = polyfit([0 19.1 38.2],vel,1);
                                elseif length(ids2U) > 1
                                    a={app.params(ids2U).(toUseMag)};
                                    aa=cellfun(@size,a,'uni',false);
                                    amp = [100+shift 200+shift];
                                    vel = [app.params(ids2U).(toUseMMag)];
                                    ca = cell2mat(aa');
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [app.params(ids2U).(toUseSMag)]./sqrt([ca(:,2)']);
                                        stType = 'SEM';
                                    else
                                        std = [app.params(ids2U).(toUseSMag)];
                                        stType = 'STD';
                                    end
                                    clV = polyfit([0 19.1],vel,1);
                                else
                                    amp = [100+shift];
                                    vel = [app.params(ids2U).(toUseMMag)];
                                    a={app.params(ids2U).(toUseMag)};
                                    aa=cellfun(@size,a,'uni',false);
                                    ca = cell2mat(aa');
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [app.params(ids2U).(toUseSMag)]./sqrt([ca(:,2)']);
                                        stType = 'SEM';
                                    else
                                        std = [app.params(ids2U).(toUseSMag)];
                                        stType = 'STD';
                                    end
                                end
                            else
                                if app.PlottingStandardDeviationCheckBox.Value
                                    stType = 'SEM';
                                else
                                    stType = 'STD';
                                end
                                amp = [0];
                                vel = [0];
                                std = [0];
                            end
                            plot(magPlot,amp,vel,'color',color{1},'LineWidth',1.5,'LineStyle','--');
                            points = plot(magPlot,amp,vel,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                            %                                 slopesV = [slopesV clV(1)];
                            erPlot = errorbar(magPlot,amp,vel,std,'color',color{1},'LineWidth',4,'CapSize',12,'LineStyle','none');
                            erPlot.UserData = ids2U;
                            if any([app.params(ids2U).FacialNerve])
                                if ~ldgFlg
                                    f2u = find([app.params(ids2U).FacialNerve]);
                                    twitchPlot = plot(magPlot,amp(f2u),vel(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                else
                                    twitchPlot = plot(magPlot,0,0,'rx','MarkerSize', 30,'LineWidth',4);
                                end
                                allTwitch = [allTwitch twitchPlot];
                            else
                                twitchPlot = 0;
                            end
                            hold(magPlot,'off');

                            if ~ldgFlg
                                hold(misalignPlot,'on');
                                if ~ldgFlg
                                    if length(ids2U) > 3
                                        a={app.params(ids2U(3:end)).(toUseMag)};
                                        aa=cellfun(@size,a,'uni',false);
                                        amp = [100+shift 200+shift 300+shift 400+shift 500+shift 600+shift];
                                        mis = [app.params(ids2U(3:end)).(toUseMMis)];
                                        ca = cell2mat(aa');
                                        if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                            if app.PlottingStandardDeviationCheckBox.Value
                                                std = [app.params(ids2U(3:end)).(toUseSMis)]./sqrt([ca(:,2)']);
                                                %                                         std = [valsSTD]./sqrt([ca(:,2)']);
                                                stType = 'SEM';
                                            else
                                                std = [app.params(ids2U(3:end)).(toUseSMis)];
                                                %                                         std = [valsSTD];
                                                stType = 'STD';
                                            end
                                            transformed = 'NONTRANSFORMED';
                                        else
                                            [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U(3:end), eyeSel, uAns{1});
                                            mis = misValsMean';
                                            if app.PlottingStandardDeviationCheckBox.Value
                                                std = [misValsSTD']./sqrt(tot');
                                                stType = 'SEM';
                                            else
                                                std = [misValsSTD'];
                                                stType = 'STD';
                                            end
                                            transformed = 'TRANSFORMED';
                                        end
%                                         if app.PlottingStandardDeviationCheckBox.Value
%                                             std = [app.params(ids2U(3:end)).(toUseSMis)]./sqrt([ca(:,2)']);
%                                             stType = 'SEM';
%                                         else
%                                             std = [app.params(ids2U(3:end)).(toUseSMis)];
%                                             stType = 'STD';
%                                         end
                                    elseif length(ids2U) == 3
                                        a={app.params(ids2U).(toUseMag)};
                                        aa=cellfun(@size,a,'uni',false);
                                        amp = [100+shift 200+shift 300+shift];
                                        mis = [app.params(ids2U).(toUseMMis)];
                                        ca = cell2mat(aa');
                                        if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                            if app.PlottingStandardDeviationCheckBox.Value
                                                std = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
                                                %                                         std = [valsSTD]./sqrt([ca(:,2)']);
                                                stType = 'SEM';
                                            else
                                                std = [app.params(ids2U).(toUseSMis)];
                                                %                                         std = [valsSTD];
                                                stType = 'STD';
                                            end
                                            transformed = 'NONTRANSFORMED';
                                        else
                                            [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U, eyeSel, uAns{1});
                                            mis = misValsMean';
                                            if app.PlottingStandardDeviationCheckBox.Value
                                                std = [misValsSTD']./sqrt(tot');
                                                stType = 'SEM';
                                            else
                                                std = [misValsSTD'];
                                                stType = 'STD';
                                            end
                                            transformed = 'TRANSFORMED';
                                        end
%                                         if app.PlottingStandardDeviationCheckBox.Value
%                                             std = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
%                                             stType = 'SEM';
%                                         else
%                                             std = [app.params(ids2U).(toUseSMis)];
%                                             stType = 'STD';
%                                         end
                                        clM = polyfit([0 19.1 38.2],mis,1);
                                    elseif length(ids2U) > 1
                                        a={app.params(ids2U).(toUseMag)};
                                        aa=cellfun(@size,a,'uni',false);
                                        amp = [100+shift 200+shift];
                                        mis = [app.params(ids2U).(toUseMMis)];
                                        ca = cell2mat(aa');
                                        if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                            if app.PlottingStandardDeviationCheckBox.Value
                                                std = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
                                                %                                         std = [valsSTD]./sqrt([ca(:,2)']);
                                                stType = 'SEM';
                                            else
                                                std = [app.params(ids2U).(toUseSMis)];
                                                %                                         std = [valsSTD];
                                                stType = 'STD';
                                            end
                                            transformed = 'NONTRANSFORMED';
                                        else
                                            [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U, eyeSel, uAns{1});
                                            mis = misValsMean';
                                            if app.PlottingStandardDeviationCheckBox.Value
                                                std = [misValsSTD']./sqrt(tot');
                                                stType = 'SEM';
                                            else
                                                std = [misValsSTD'];
                                                stType = 'STD';
                                            end
                                            transformed = 'TRANSFORMED';
                                        end
%                                         if app.PlottingStandardDeviationCheckBox.Value
%                                             std = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
%                                             stType = 'SEM';
%                                         else
%                                             std = [app.params(ids2U).(toUseSMis)];
%                                             stType = 'STD';
%                                         end
                                    else
                                        amp = [100+shift];
                                        mis = [app.params(ids2U).(toUseMMis)];
                                        a={app.params(ids2U).(toUseMag)};
                                        aa=cellfun(@size,a,'uni',false);
                                        ca = cell2mat(aa');
                                        if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                            if app.PlottingStandardDeviationCheckBox.Value
                                                std = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
                                                %                                         std = [valsSTD]./sqrt([ca(:,2)']);
                                                stType = 'SEM';
                                            else
                                                std = [app.params(ids2U).(toUseSMis)];
                                                %                                         std = [valsSTD];
                                                stType = 'STD';
                                            end
                                            transformed = 'NONTRANSFORMED';
                                        else
                                            [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U, eyeSel, uAns{1});
                                            mis = misValsMean';
                                            if app.PlottingStandardDeviationCheckBox.Value
                                                std = [misValsSTD']./sqrt(tot');
                                                stType = 'SEM';
                                            else
                                                std = [misValsSTD'];
                                                stType = 'STD';
                                            end
                                            transformed = 'TRANSFORMED';
                                        end
%                                         if app.PlottingStandardDeviationCheckBox.Value
%                                             std = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
%                                             stType = 'SEM';
%                                         else
%                                             std = [app.params(ids2U).(toUseSMis)];
%                                             stType = 'STD';
%                                         end
                                    end
                                else
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        stType = 'SEM';
                                    else
                                        stType = 'STD';
                                    end
                                    amp = [0];
                                    mis = [0];
                                    std = [0];
                                end
                                plot(misalignPlot,amp,mis,'color',color{1},'LineWidth',1.5,'LineStyle','--');
                                points = plot(misalignPlot,amp,mis,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                %                                     slopesM = [slopesM clM(1)];
                                erPlot = errorbar(misalignPlot,amp,mis,std,'color',color{1},'LineWidth',4,'CapSize',12,'LineStyle','none');
                                erPlot.UserData = ids2U;
                                if any([app.params(ids2U).FacialNerve])
                                    twitchPlot = plot(misalignPlot,amp(f2u),mis(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                else
                                    twitchPlot = 0;
                                end
                                hold(misalignPlot,'off');
                            end

                            if isempty(ldgNames)
                                ldgNames = [ldgNames {[stimName{1}]}];
                                ldgLine = [ldgLine points];
                            else
                                if ~ismember(ldgNames,[stimName{1}])
                                    ldgNames = [ldgNames {[stimName{1}]}];
                                    ldgLine = [ldgLine points];
                                end
                            end
                        end
                    end
                    magPlots = [magPlots magPlot];
                    misalignPlots = [misalignPlots misalignPlot];
                end

                magPlot.YLabel.String = 'Velocity (dps)';
                magPlot.YLabel.FontSize = 30;
                misalignPlot.YLabel.String = 'Misalignment (d)';
                misalignPlot.YLabel.FontSize = 30;
                magPlot.XTickLabel = [];
                if length(ids2U) < 4
                    magPlot.XTick = [100 200 300];
                    misalignPlot.XTick = [100 200 300];
                    misalignPlot.XTickLabel = [{'0'} {'19.1'} {'38.2'}]'; % 44 stim blocks in between...
                    % standards (20 seconds each) as well as 6 seconds of
                    % delays, 4 seconds of before next standard (44*26)+4
                    misalignPlot.XLabel.String = {'Experiment Modulo Time (Minutes)'};
                else
                    magPlot.XTick = [100 200 300 400 500 600];
                    misalignPlot.XTick = [100 200 300 400 500 600];
                    misalignPlot.XTickLabel = [{'0'} {'3.35'} {'6.7'} {'10.05'} {'13.4'} {'16.75'}]';% 7 stim blocks in between...
                    % standards (20 seconds each) as well as 8 seconds of
                    % delays, 5 seconds of before next standard (44*26)+4
                    misalignPlot.XLabel.String = {'Experiment Modulo Time (Minutes)'};
                end
                magPlot.XGrid = 'on';
                magPlot.YGrid = 'on';
                magPlot.FontSize = 30;
                magPlot.Title.String = 'Standard Across Time';
                magPlot.LineWidth = 2.5;
                magPlot.Title.FontSize = 30;
                magPlot.YLim = [0 120];

                misalignPlot.XGrid = 'on';
                misalignPlot.YGrid = 'on';
                misalignPlot.FontSize = 30;
                misalignPlot.XLabel.FontSize = 30;
                misalignPlot.LineWidth = 2.5;
                misalignPlot.YLim = [0 60];
                misalignPlot.YTick = [0:10:60];

                if plotLoc ~= 1
                    if ~isempty(app.CompareStandardAcrossTimeTab.UserData)
                        if isfield(app.CompareStandardAcrossTimeTab.UserData,'MagPlot')
                            if ~isempty(app.CompareStandardAcrossTimeTab.UserData.MagPlot)
                                magPlot.XLim = app.CompareStandardAcrossTimeTab.UserData.MagPlot(1,:);
                                magPlot.YLim = app.CompareStandardAcrossTimeTab.UserData.MagPlot(2,:);
                            end
                        end

                        if isfield(app.CompareStandardAcrossTimeTab.UserData,'MisPlot')
                            if ~isempty(app.CompareStandardAcrossTimeTab.UserData.MisPlot)
                                misalignPlot.XLim = app.CompareStandardAcrossTimeTab.UserData.MisPlot(1,:);
                                misalignPlot.YLim = app.CompareStandardAcrossTimeTab.UserData.MisPlot(2,:);
                            end
                        end
                    end
                end

                sameaxes([],[magPlots])
                sameaxes([],[misalignPlots])

                if ldgFlg
                    magPlot.Visible = 0;
                    misalignPlot.Visible = 0;
                end

                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end


                if ~isempty(allTwitch)
                    ldgNames = [ldgNames {'Facial Twitch Observed'}];
                    ldgLine = [ldgLine allTwitch(1)];
                end
                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                end
%                 slopes = [slopesV' slopesM'];
%                 save(['R:\Morris, Brian\Monkey Data\IPG and SPD Analysis\',app.animal,'Slopes.mat'],'slopes')
                app.FigureName = [uAns{1},'_',eyeSel,'_StandardMisalignmentandVelocity_AcrossTime_ByRef_',transformed,'_',stType,'_',strrep(num2str(uStims),' ',''),'_',strrep(num2str(uRefs),' ',''),'_',cUsed,'_',DataType];
            end
        end
    
        function PlotAvCStandards(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.CompareAvCStandardsTab;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                marg_w = [.05 .55];
            end
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = unique(app.Stims);
            s = OrderElectrodesByAnimal(app, uAns{1}, uStims);
            uStims = s;
            uRefs = unique(app.Refs);
            r = OrderElectrodesByAnimal(app, uAns{1}, uRefs);
            uRefs = r;
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];

            dMag = [];
            dMis = [];

            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, 0);


            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                magPlot = subtightplot(2,1,1,[0.025 0.000000000005],[0.10 0.075],marg_w,'Parent', loc);
                magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                misalignPlot = subtightplot(2,1,2,[0.025 0.000000000005],[0.10 0.075],marg_w,'Parent', loc);
                misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                slopesV = [];
                slopesM = [];
                for i = 1:length(uStims)
                    [color,stimName,mark,shift,~] = GetElecColorandNameByAnimal(app, uAns{1}, uStims(i));
                    if (i == 1) && (plotLoc == 1)
                        app.CompareAvCStandardsTab.UserData = [];
                    end
                    for j = 1:length(uRefs)
                        ids2U = GetDataInds(app, uAns{1}, uStims(i), uRefs(j), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                        ids2U = GetDataInds(app, uAns{1}, uStims(i), uRefs(j), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, app.params(ids2U).normInd);
                        if ~isempty(ids2U)
                            if app.AnimalsandEyesToUse{1,3}
                                eyeSel = 'LEye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagL_NystagCorr';
                                    toUseMag = 'MagL_NystagCorr';
                                    toUseSMag = 'stdMagL_NystagCorr';
                                    toUseMMis = 'meanMisalignL_NystagCorr';
                                    toUseMis = 'MisalignL_NystagCorr';
                                    toUseSMis = 'stdMisalignL_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagL';
                                    toUseMag = 'MagL';
                                    toUseSMag = 'stdMagL';
                                    toUseMMis = 'meanMisalignL';
                                    toUseMis = 'MisalignL';
                                    toUseSMis = 'stdMisalignL';
                                    DataType = 'NonCorrected';
                                end
                            else
                                eyeSel = 'REye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagR_NystagCorr';
                                    toUseMag = 'MagR_NystagCorr';
                                    toUseSMag = 'stdMagR_NystagCorr';
                                    toUseMMis = 'meanMisalignR_NystagCorr';
                                    toUseMis = 'MisalignR_NystagCorr';
                                    toUseSMis = 'stdMisalignR_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagR';
                                    toUseMag = 'MagR';
                                    toUseSMag = 'stdMagR';
                                    toUseMMis = 'meanMisalignR';
                                    toUseMis = 'MisalignR';
                                    toUseSMis = 'stdMisalignR';
                                    DataType = 'NonCorrected';
                                end
                            end
                            if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                transformed = 'NONTRANSFORMED';
                            else
                                transformed = 'TRANSFORMED';
                            end
                            hold(magPlot,'on');
                            if ~ldgFlg
                                a={app.params(ids2U).(toUseMag)};
                                aa=cellfun(@size,a,'uni',false);
                                amp = [100+shift 200+shift];
                                vel = [app.params(ids2U).(toUseMMag)];
                                ca = cell2mat(aa');
                                if app.PlottingStandardDeviationCheckBox.Value
                                    stdV = [app.params(ids2U).(toUseSMag)]./sqrt([ca(:,2)']);
                                    stType = 'SEM';
                                else
                                    stdV = [app.params(ids2U).(toUseSMag)];
                                    stType = 'STD';
                                end
                                %                                 clV = polyfit([0 1],vel,1);
                                dMag = [dMag vel(1)-vel(2)];
                            else
                                if app.PlottingStandardDeviationCheckBox.Value
                                    stType = 'SEM';
                                else
                                    stType = 'STD';
                                end
                                amp = [0];
                                vel = [0];
                                stdV = [0];
                            end
                            plot(magPlot,amp,vel,'color',color{1},'LineWidth',1.5,'LineStyle','--');
                            points = plot(magPlot,amp,vel,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                            %                                 slopesV = [slopesV clV(1)];
                            erPlot = errorbar(magPlot,amp,vel,stdV,'color',color{1},'LineWidth',4,'CapSize',12,'LineStyle','none');
                            erPlot.UserData = ids2U;
                            if any([app.params(ids2U).FacialNerve])
                                if ~ldgFlg
                                    f2u = find([app.params(ids2U).FacialNerve]);
                                    twitchPlot = plot(magPlot,amp(f2u),vel(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                else
                                    twitchPlot = plot(magPlot,0,0,'rx','MarkerSize', 30,'LineWidth',4);
                                end
                                allTwitch = [allTwitch twitchPlot];
                            else
                                twitchPlot = 0;
                            end
                            hold(magPlot,'off');

                            if ~ldgFlg
                                hold(misalignPlot,'on');
                                if ~ldgFlg
                                    a={app.params(ids2U).(toUseMag)};
                                    aa=cellfun(@size,a,'uni',false);
                                    amp = [100+shift 200+shift];
                                    mis = [app.params(ids2U).(toUseMMis)];
                                    ca = cell2mat(aa');
                                    if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            stdV = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
                                            %                                         std = [valsSTD]./sqrt([ca(:,2)']);
                                            stType = 'SEM';
                                        else
                                            stdV = [app.params(ids2U).(toUseSMis)];
                                            %                                         std = [valsSTD];
                                            stType = 'STD';
                                        end
                                        transformed = 'NONTRANSFORMED';
                                    else
                                        [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U, eyeSel, uAns{1});
                                        mis = misValsMean';
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            stdV = [misValsSTD']./sqrt(tot');
                                            stType = 'SEM';
                                        else
                                            stdV = [misValsSTD'];
                                            stType = 'STD';
                                        end
                                        transformed = 'TRANSFORMED';
                                    end
                                    dMis = [dMis mis(1)-mis(2)];
                                else
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        stType = 'SEM';
                                    else
                                        stType = 'STD';
                                    end
                                    amp = [0];
                                    mis = [0];
                                    stdV = [0];
                                end
                                plot(misalignPlot,amp,mis,'color',color{1},'LineWidth',1.5,'LineStyle','--');
                                points = plot(misalignPlot,amp,mis,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                %                                     slopesM = [slopesM clM(1)];
                                erPlot = errorbar(misalignPlot,amp,mis,stdV,'color',color{1},'LineWidth',4,'CapSize',12,'LineStyle','none');
                                erPlot.UserData = ids2U;
                                if any([app.params(ids2U).FacialNerve])
                                    twitchPlot = plot(misalignPlot,amp(f2u),mis(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                else
                                    twitchPlot = 0;
                                end
                                hold(misalignPlot,'off');
                            end

                            if isempty(ldgNames)
                                ldgNames = [ldgNames {[stimName{1}]}];
                                ldgLine = [ldgLine points];
                            else
                                if ~ismember(ldgNames,[stimName{1}])
                                    ldgNames = [ldgNames {[stimName{1}]}];
                                    ldgLine = [ldgLine points];
                                end
                            end
                        end
                    end
                    magPlots = [magPlots magPlot];
                    misalignPlots = [misalignPlots misalignPlot];
                end

                if app.PlottingStandardDeviationCheckBox.Value
                    vs = std(dMag)/sqrt(length(dMag));
                    txt1 = [num2str(round(mean(dMag),3)),'±',num2str(round(vs,3))];
                    
                    vs2 = std(dMis)/sqrt(length(dMis));
                    txt2 = [num2str(round(mean(dMis),3)),'±',num2str(round(vs2,3))];
                else
                    txt1 = [num2str(round(mean(dMag),3)),'±',num2str(round(std(dMag),3))];
                    txt2 = [num2str(round(mean(dMis),3)),'±',num2str(round(std(dMis),3))];
                end
                
%                 mtv = text(magPlot,220,35,txt1,'FontSize',20);
%                 mtm = text(misalignPlot,220,17,txt2,'FontSize',20);

                magPlot.YLabel.String = 'Velocity (dps)';
                magPlot.YLabel.FontSize = 30;
                misalignPlot.YLabel.String = 'Misalignment (d)';
                misalignPlot.YLabel.FontSize = 30;
                magPlot.XTickLabel = [];
                magPlot.XLim = [50 250];
                misalignPlot.XLim = [50 250];
                    magPlot.XTick = [100 200];
                    misalignPlot.XTick = [100 200];
                    misalignPlot.XTickLabel = [{'Cathodic First'} {'Anodic First'}]'; % 44 stim blocks in between...
                    % standards (20 seconds each) as well as 6 seconds of
                    % delays, 4 seconds of before next standard (44*26)+4
%                     misalignPlot.XLabel.String = {'Experiment Modulo Time (Minutes)'};

                magPlot.XGrid = 'on';
                magPlot.YGrid = 'on';
                magPlot.FontSize = 30;
                magPlot.Title.String = 'Standard By First Phase';
                magPlot.LineWidth = 2.5;
                magPlot.Title.FontSize = 30;
%                 magPlot.YLim = [0 120];

                misalignPlot.XGrid = 'on';
                misalignPlot.YGrid = 'on';
                misalignPlot.FontSize = 30;
                misalignPlot.XLabel.FontSize = 30;
                misalignPlot.LineWidth = 2.5;
%                 misalignPlot.YLim = [0 60];
%                 misalignPlot.YTick = [0:10:60];

                if plotLoc ~= 1
                    if ~isempty(app.CompareAvCStandardsTab.UserData)
                        if isfield(app.CompareAvCStandardsTab.UserData,'MagPlot')
                            if ~isempty(app.CompareAvCStandardsTab.UserData.MagPlot)
                                magPlot.XLim = app.CompareAvCStandardsTab.UserData.MagPlot(1,:);
                                magPlot.YLim = app.CompareAvCStandardsTab.UserData.MagPlot(2,:);
                            end
                        end

                        if isfield(app.CompareAvCStandardsTab.UserData,'MisPlot')
                            if ~isempty(app.CompareAvCStandardsTab.UserData.MisPlot)
                                misalignPlot.XLim = app.CompareAvCStandardsTab.UserData.MisPlot(1,:);
                                misalignPlot.YLim = app.CompareAvCStandardsTab.UserData.MisPlot(2,:);
                            end
                        end
                    end
                end

                sameaxes([],[magPlots])
                sameaxes([],[misalignPlots])

                if ldgFlg
                    magPlot.Visible = 0;
                    misalignPlot.Visible = 0;
                end

                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end


                if ~isempty(allTwitch)
                    ldgNames = [ldgNames {'Facial Twitch Observed'}];
                    ldgLine = [ldgLine allTwitch(1)];
                end
                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                end
                

                app.FigureName = [uAns{1},'_',eyeSel,'_AvC_StandardMisalignmentandVelocity_ByRef_',transformed,'_',stType,'_',strrep(num2str(uStims),' ',''),'_',strrep(num2str(uRefs),' ',''),'_',cUsed,'_',DataType];
            end
        end

        function PlotAllStandards(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.AllStandards;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
%                 marg_w = [.05 .015];
                marg_w = [.05 .45];
%                 marg_w = [.05 .5];
            end
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = unique(app.Stims);
            s = OrderElectrodesByAnimal(app, uAns{1}, uStims);
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = s;
            uRefs = unique(app.Refs);
            r = OrderElectrodesByAnimal(app, uAns{1}, uRefs);
            uRefs = r;
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];
            magMean = [];
            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, 0);


            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                magPlot = subtightplot(2,3,1,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                misalignPlot = subtightplot(2,3,4,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                if (plotLoc == 1)
                    app.AllStandards.UserData = [];
                end
                for i = 1:length(uStims)
                    [color,stimName,mark,shift,~] = GetElecColorandNameByAnimal(app, uAns{1}, uStims(i));
                    for j = 1:length(uRefs)
                        ids2U = GetDataInds(app, uAns{1}, uStims(i), uRefs(j), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, 0);

                        if ~isempty(ids2U)
                            if app.AnimalsandEyesToUse{1,3}
                                eyeSel = 'LEye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagL_NystagCorr';
                                    toUseMag = 'MagL_NystagCorr';
                                    toUseSMag = 'stdMagL_NystagCorr';
                                    toUseMMis = 'meanMisalignL_NystagCorr';
                                    toUseMis = 'MisalignL_NystagCorr';
                                    toUseSMis = 'stdMisalignL_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagL';
                                    toUseMag = 'MagL';
                                    toUseSMag = 'stdMagL';
                                    toUseMMis = 'meanMisalignL';
                                    toUseMis = 'MisalignL';
                                    toUseSMis = 'stdMisalignL';
                                    DataType = 'NonCorrected';
                                end
                            else
                                eyeSel = 'REye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagR_NystagCorr';
                                    toUseMag = 'MagR_NystagCorr';
                                    toUseSMag = 'stdMagR_NystagCorr';
                                    toUseMMis = 'meanMisalignR_NystagCorr';
                                    toUseMis = 'MisalignR_NystagCorr';
                                    toUseSMis = 'stdMisalignR_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagR';
                                    toUseMag = 'MagR';
                                    toUseSMag = 'stdMagR';
                                    toUseMMis = 'meanMisalignR';
                                    toUseMis = 'MisalignR';
                                    toUseSMis = 'stdMisalignR';
                                    DataType = 'NonCorrected';
                                end
                            end
                            if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                transformed = 'NONTRANSFORMED';
                            else
                                transformed = 'TRANSFORMED';
                            end
                            hold(magPlot,'on');
                            if ~ldgFlg
                                amp = [app.params(ids2U(1)).p1amp+shift];
                                vel = [app.params(ids2U(1)).(toUseMMag)];
                                a={app.params(ids2U(1)).(toUseMag)};
                                aa=cellfun(@size,a,'uni',false);
                                ca = cell2mat(aa');
                                if app.PlottingStandardDeviationCheckBox.Value
                                    std = [app.params(ids2U(1)).(toUseSMag)]./sqrt([ca(:,2)']);
                                    stType = 'SEM';
                                else
                                    std = [app.params(ids2U(1)).(toUseSMag)];
                                    stType = 'STD';
                                end
                            else
                                if app.PlottingStandardDeviationCheckBox.Value
                                    stType = 'SEM';
                                else
                                    stType = 'STD';
                                end
                                amp = [0];
                                vel = [0];
                                std = [0];
                            end
                            plot(magPlot,amp,vel,'color',color{1},'LineWidth',2.5,'LineStyle','--');
                            points = plot(magPlot,amp,vel,'color',color{1},'marker',mark,'MarkerSize',30,'LineStyle','none','LineWidth',4);
                            magMean = [magMean vel];
                            erPlot = errorbar(magPlot,amp,vel,std,'color',color{1},'LineWidth',5,'CapSize',12,'LineStyle','none');
                            erPlot.UserData = ids2U(1);
                            if any([app.params(ids2U(1)).FacialNerve])
                                if ~ldgFlg
                                    f2u = find([app.params(ids2U(1)).FacialNerve]);
                                    twitchPlot = plot(magPlot,amp(f2u),vel(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                else
                                    twitchPlot = plot(magPlot,0,0,'rx','MarkerSize', 30,'LineWidth',4);
                                end
                                allTwitch = [allTwitch twitchPlot];
                            else
                                twitchPlot = 0;
                            end
                            hold(magPlot,'off');

                            if ~ldgFlg
                                hold(misalignPlot,'on');
                                amp = [app.params(ids2U(1)).p1amp+shift];
                                mis = [app.params(ids2U(1)).(toUseMMis)];
                                a={app.params(ids2U(1)).(toUseMag)};
                                aa=cellfun(@size,a,'uni',false);
                                ca = cell2mat(aa');
                                if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [app.params(ids2U(1)).(toUseSMis)]./sqrt([ca(:,2)']);
                                        %                                         std = [valsSTD]./sqrt([ca(:,2)']);
                                        stType = 'SEM';
                                    else
                                        std = [app.params(ids2U(1)).(toUseSMis)];
                                        %                                         std = [valsSTD];
                                        stType = 'STD';
                                    end
                                    transformed = 'NONTRANSFORMED';
                                else
                                    [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U(1), eyeSel, uAns{1});
                                    mis = misValsMean';
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [misValsSTD']./sqrt(tot');
                                        stType = 'SEM';
                                    else
                                        std = [misValsSTD'];
                                        stType = 'STD';
                                    end
                                    transformed = 'TRANSFORMED';
                                end
                                plot(misalignPlot,amp,mis,'color',color{1},'LineWidth',2.5,'LineStyle','--');
                                points = plot(misalignPlot,amp,mis,'color',color{1},'marker',mark,'MarkerSize',30,'LineStyle','none','LineWidth',4);
                                erPlot = errorbar(misalignPlot,amp,mis,std,'color',color{1},'LineWidth',5,'CapSize',12,'LineStyle','none');
                                erPlot.UserData = ids2U(1);
                                if any([app.params(ids2U(1)).FacialNerve])
                                    twitchPlot = plot(misalignPlot,amp(f2u),mis(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                else
                                    twitchPlot = 0;
                                end
                                hold(misalignPlot,'off');
                            end

                            
                            if isempty(ldgNames)
                                ldgNames = [ldgNames {[stimName{1}]}];
                                ldgLine = [ldgLine points];
                                [color,stimName,mark,shift,~] = GetElecColorandNameByAnimal(app, uAns{1}, 1);
                                if ~ismember(ldgNames, stimName)
                                    if ldgFlg
                                        hold(magPlot,'on');
                                        points = plot(magPlot,amp,vel,'color',color{1},'marker',mark,'MarkerSize',30,'LineStyle','none','LineWidth',4);
                                        hold(magPlot,'off');
                                    end
                                    ldgNames = [ldgNames {[stimName{1}]}];
                                    ldgLine = [ldgLine points];
                                end
                            else
                                if ~ismember(ldgNames,[stimName{1}])
                                    ldgNames = [ldgNames {[stimName{1}]}];
                                    ldgLine = [ldgLine points];
                                    [color,stimName,mark,shift,~] = GetElecColorandNameByAnimal(app, uAns{1}, 1);
                                    if ~ismember(ldgNames, stimName)
                                        if ldgFlg
                                            hold(magPlot,'on');
                                            points = plot(magPlot,amp,vel,'color',color{1},'marker',mark,'MarkerSize',30,'LineStyle','none','LineWidth',4);
                                            hold(magPlot,'off');
                                        end
                                        ldgNames = [ldgNames {[stimName{1}]}];
                                        ldgLine = [ldgLine points];
                                    end
                                end
                            end
                        end
                    end
                end

                magPlot.YLabel.String = 'Velocity (dps)';
                magPlot.YLabel.FontSize = 30;
                misalignPlot.YLabel.String = 'Misalignment (d)';
                misalignPlot.YLabel.FontSize = 30;
                magPlot.XTickLabel = [];
                misalignPlot.XLabel.String = {'Current (uA)'};
%                 magPlot.XLim = [0 200];
%                 misalignPlot.XLim = [0 200];
%                 magPlot.XTick = [65 150];
%                 misalignPlot.XTick = [65 150];
 magPlot.XLim = [50 80];
                misalignPlot.XLim = [50 80];
                magPlot.XTick = [65];
                misalignPlot.XTick = [65];

                %             if length(ids2U) < 4
                %                 magPlot.XTick = [100 200 300];
                %                 misalignPlot.XTick = [100 200 300];
                %                 misalignPlot.XTickLabel = [{'0'} {'19.1'} {'38.2'}]'; % 44 stim blocks in between...
                %                 % standards (20 seconds each) as well as 6 seconds of
                %                 % delays, 4 seconds of before next standard (44*26)+4
                %                 misalignPlot.XLabel.String = {'Experiment Modulo Time (Minutes)'};
                %             else
                %                 magPlot.XTick = [100 200 300 400 500 600];
                %                 misalignPlot.XTick = [100 200 300 400 500 600];
                %                 misalignPlot.XTickLabel = [{'0'} {'3.35'} {'6.7'} {'10.05'} {'13.4'} {'16.75'}]';% 7 stim blocks in between...
                %                 % standards (20 seconds each) as well as 8 seconds of
                %                 % delays, 5 seconds of before next standard (44*26)+4
                %
                %             end
                magPlot.XGrid = 'on';
                magPlot.YGrid = 'on';
                magPlot.FontSize = 30;
                magPlot.Title.String = 'Standard Across Time';
                magPlot.LineWidth = 2.5;
                magPlot.Title.FontSize = 30;
%                             magPlot.YLim = [0 120];

                misalignPlot.XGrid = 'on';
                misalignPlot.YGrid = 'on';
                misalignPlot.FontSize = 30;
                misalignPlot.XLabel.FontSize = 30;
                misalignPlot.LineWidth = 2.5;
%                 misalignPlot.YLim = [0 50];
%                 misalignPlot.YTick = [0:10:50];

                if plotLoc ~= 1
                    if ~isempty(app.AllStandards.UserData)
                        if isfield(app.AllStandards.UserData,'MagPlot')
                            if ~isempty(app.AllStandards.UserData.MagPlot)
                                magPlot.XLim = app.AllStandards.UserData.MagPlot(1,:);
                                magPlot.YLim = app.AllStandards.UserData.MagPlot(2,:);
                            end
                        end

                        if isfield(app.AllStandards.UserData,'MisPlot')
                            if ~isempty(app.AllStandards.UserData.MisPlot)
                                misalignPlot.XLim = app.AllStandards.UserData.MisPlot(1,:);
                                misalignPlot.YLim = app.AllStandards.UserData.MisPlot(2,:);
                            end
                        end
                    end
                end


                if ldgFlg
                    magPlot.Visible = 0;
                    misalignPlot.Visible = 0;
                end

                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end


                if ~isempty(allTwitch)
                    ldgNames = [ldgNames {'Facial Twitch Observed'}];
                    ldgLine = [ldgLine allTwitch(1)];
                end
                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.5];
                end
                app.FigureName = [uAns{1},'_',eyeSel,'_AllStandardMisalignmentandVelocity_ByRef_',transformed,'_',stType,'_',strrep(num2str(uStims),' ',''),'_',strrep(num2str(uRefs),' ',''),'_',cUsed,'_',DataType];
                disp('Average Across Electrodes (deg/s):')
                mean(magMean)
            end
        end
    
        function PlotNormByIPG(app, plotLoc,ldgFlg)
            if plotLoc == 1
                loc = app.NormIPGasX;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                marg_w = [.05 .015];
            end

            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            mpFlg = 0;
            mFlg = 0;
            all = GetIPGandSPDPairs(app);
            sza = size(all{4,1});
            szb = size(all);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];                
            allTwitch = [];

            if isempty(all)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                ids2T = GetDataInds(app,uAns{1}, [all{1,:}], [all{2,:}], [all{3,1}], [all{4,1}], [all{5,1}], [all{6,1}], [all{7,1}], 1, -1);
            end

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                for i = 1:sza(2)
                    maxZV = 0;
                    minZV = 0;
                    maxZM = 0;
                    minZM = 0;
                    allDataX = [];
                    allDataY = [];
                    allDataZ = [];
                    allF = [];
                    allDataZMis = [];

                    if (i == 1) && (plotLoc == 1)
                        app.NormIPGasX.UserData = [];
                    end

                    magPlot = subtightplot(2,sza(2),i,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot = subtightplot(2,sza(2),i+sza(2),[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    mpFlg = 1;
                    mFlg = 1;
                    for j = 1:szb(2)
                        ids2U = GetDataInds(app, uAns{1}, all{1,j}, all{2,j}, all{3,j}, all{4,j}(i), all{5,j}, all{6,j}, all{7,j}, 1, -1);
                        if ~isempty(ids2U)
                            [~,nameS,mark,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, all{1,j});
                            [~,nameR,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, all{2,j});
                            if app.AnimalsandEyesToUse{1,3}
                                eyeSel = 'LEye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagL_NystagCorr';
                                    toUseMag = 'MagL_NystagCorr';
                                    toUseSMag = 'stdMagL_NystagCorr';
                                    toUseMMis = 'meanMisalignL_NystagCorr';
                                    toUseMis = 'MisalignL_NystagCorr';
                                    toUseSMis = 'stdMisalignL_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagL';
                                    toUseMag = 'MagL';
                                    toUseSMag = 'stdMagL';
                                    toUseMMis = 'meanMisalignL';
                                    toUseMis = 'MisalignL';
                                    toUseSMis = 'stdMisalignL';
                                    DataType = 'NonCorrected';
                                end
                            else
                                eyeSel = 'REye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagR_NystagCorr';
                                    toUseMag = 'MagR_NystagCorr';
                                    toUseSMag = 'stdMagR_NystagCorr';
                                    toUseMMis = 'meanMisalignR_NystagCorr';
                                    toUseMis = 'MisalignR_NystagCorr';
                                    toUseSMis = 'stdMisalignR_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagR';
                                    toUseMag = 'MagR';
                                    toUseSMag = 'stdMagR';
                                    toUseMMis = 'meanMisalignR';
                                    toUseMis = 'MisalignR';
                                    toUseSMis = 'stdMisalignR';
                                    DataType = 'NonCorrected';
                                end
                            end
                            color = [0.74 0.74 0.74];
                            Magf2Use = [toUseMMag,num2str(all{3,j})];
                            MagfSD2Use = [toUseSMag,num2str(all{3,j})];
                            Misf2Use = [toUseMMis,num2str(all{3,j})];
                            MisfSD2Use = [toUseSMis,num2str(all{3,j})];

                            hold(magPlot,'on');

                            if ~ldgFlg
                                x = [app.params(ids2U).p2d]';
                                y = [app.params(ids2U).ipg]';
                                tempz = vertcat(app.params(ids2U).(Magf2Use));
                                z = tempz;
                                allDataX = [allDataX; x'];
                                allDataY = [allDataY; y'];
                                allDataZ = [allDataZ; z'];
                                a={app.params(ids2U).(toUseMag)};
                                aa=cellfun(@size,a,'uni',false);
                                ca = cell2mat(aa');
                                ipg = y;
                                normV = z;
                                if app.PlottingStandardDeviationCheckBox.Value
                                    std = [app.params(ids2U).(MagfSD2Use)]./sqrt([ca(:,2)']);
                                    stType = 'SEM';
                                else
                                    std = [app.params(ids2U).(MagfSD2Use)]';
                                    stType = 'STD';
                                end
                            else
                                if app.PlottingStandardDeviationCheckBox.Value
                                    stType = 'SEM';
                                else
                                    stType = 'STD';
                                end
                                ipg = [0];
                                normV = [0];
                                std = [0];
                            end
                            line = plot(magPlot,ipg,normV,'color',color,'LineWidth',4);
                            points = plot(magPlot,ipg,normV,'color',color,'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);

                            erPlot = errorbar(magPlot,ipg,normV,std,'color',color,'LineWidth',4,'CapSize',12);
                            erPlot.UserData = ids2U;
                            if any([app.params(ids2U).FacialNerve])
                                if ~ldgFlg
                                    f2u = find([app.params(ids2U).FacialNerve]);
                                    twitchPlot = plot(magPlot,ipg(f2u),normV(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                else
                                    twitchPlot = plot(magPlot,0,0,'rx','MarkerSize', 30,'LineWidth',4);
                                end
                                allTwitch = [allTwitch twitchPlot];
                            else
                                twitchPlot = 0;
                            end

                            if j == szb(2)
                                if ~ldgFlg
                                    mz = mean(allDataZ,'OmitNaN');
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        s = size(allDataZ);
                                        std = [std(allDataZ,'OmitNaN')]./sqrt(s(1));
                                        stType = 'SEM';
                                    else
                                        std = [std(allDataZ,'OmitNaN')]';
                                        stType = 'STD';
                                    end
                                else
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        stType = 'SEM';
                                    else
                                        stType = 'STD';
                                    end
                                    ipg = [0];
                                    mz = [0];
                                    std = [0];
                                end

                                lineM = plot(magPlot,ipg,mz,'color','k','LineWidth',4);
                                pointsM = plot(magPlot,ipg,mz,'color','k','marker','.','MarkerSize',45,'LineStyle','none','LineWidth',4);
                                erPlotM = errorbar(magPlot,ipg,mz,std,'color','k','LineWidth',4,'CapSize',12);
                            end
                            hold(magPlot,'off');

                            if ~ldgFlg
                                hold(misalignPlot,'on');
                                    if ~issorted([app.params(ids2U).p1amp])
                                        [samp, saI] = sort([app.params(ids2U).p1amp]);
                                        ids2U = ids2U(saI);
                                    end
                                    tempz = vertcat(app.params(ids2U).(Misf2Use));
                                    z = tempz;
                                    allDataZMis = [allDataZMis; z'];
                                    a={app.params(ids2U).(toUseMag)};
                                    aa=cellfun(@size,a,'uni',false);
                                    ca = cell2mat(aa');
                                    x = [app.params(ids2U).p2d]';
                                    y = [app.params(ids2U).ipg]';
                                    ipg = y;
                                    normM = z;
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [app.params(ids2U).(MisfSD2Use)]./sqrt([ca(:,2)']);
                                    else
                                        std = [app.params(ids2U).(MisfSD2Use)];
                                    end
                                line = plot(misalignPlot,ipg,normM,'color',color,'LineWidth',4);
                                points = plot(misalignPlot,ipg,normM,'color',color,'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                erPlot = errorbar(misalignPlot,ipg,normM,std,'color',color,'LineWidth',4,'CapSize',12);
                                erPlot.UserData = ids2U;
                                if any([app.params(ids2U).FacialNerve])
                                    twitchPlot = plot(misalignPlot,ipg(f2u),normM(f2u),'rx','MarkerSize', 30,'LineWidth',4);
                                else
                                    twitchPlot = 0;
                                end

                                if j == szb(2)
                                    if ~ldgFlg
                                        mz = mean(allDataZMis,'OmitNaN');
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            s = size(allDataZMis);
                                            std = [std(allDataZMis,'OmitNaN')]./sqrt(s(1));
                                            stType = 'SEM';
                                        else
                                            std = [std(allDataZMis,'OmitNaN')];
                                            stType = 'STD';
                                        end
                                    else
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            stType = 'SEM';
                                        else
                                            stType = 'STD';
                                        end
                                        ipg = [0];
                                        mz = [0];
                                        std = [0];
                                    end

                                    lineM = plot(misalignPlot,ipg,mz,'color','k','LineWidth',4);
                                    pointsM = plot(misalignPlot,ipg,mz,'color','k','marker','.','MarkerSize',45,'LineStyle','none','LineWidth',4);
                                    erPlotM = errorbar(misalignPlot,ipg,mz,std,'color','k','LineWidth',4,'CapSize',12);
                                end

                                hold(misalignPlot,'off');
                            end

                            if isempty(ldgNames)
                                ldgNames = [ldgNames {[nameS{1}]}];
                                ldgLine = [ldgLine points];
                            else
                                if ~ismember(ldgNames,[nameS{1}])
                                    ldgNames = [ldgNames {[nameS{1}]}];
                                    ldgLine = [ldgLine points];
                                end
                                if (j == szb(2)) && (i == 1)
                                    ldgNames = [ldgNames {'Average'}];
                                    ldgLine = [ldgLine pointsM];
                                end
                            end
                        end
                    end
                    if i == 1
                        magPlot.YLabel.String = 'Velocity (dps)';
                        magPlot.YLabel.FontSize = 30;
                        misalignPlot.YLabel.String = 'Misalignment (d)';
                        misalignPlot.YLabel.FontSize = 30;
                    else
                        magPlot.YTickLabel = [];
                        misalignPlot.YTickLabel = [];
                    end
                    magPlot.XTickLabel = [];
                    magPlot.XTick = [all{7}(1)-25 all{7}];
                    magPlot.XGrid = 'on';
                    magPlot.YGrid = 'on';
                    magPlot.XLim = [all{7}(1)-25 all{7}(end)+25];
                    magPlot.FontSize = 30;
                    magPlot.Title.String = ['All vs ', num2str(all{4}(i))];
                    magPlot.LineWidth = 2.5;
                    magPlot.Title.FontSize = 30;
                    magPlot.YLim = [0 5];

                    misalignPlot.XTick = [all{7}];
                    misalignPlot.XGrid = 'on';
                    misalignPlot.YGrid = 'on';
                    misalignPlot.XLim = [all{7}(1)-25 all{7}(end)+25];
                    misalignPlot.FontSize = 30;
                    misalignPlot.XLabel.String = {'IPG (us)'};
                    misalignPlot.XLabel.FontSize = 30;
                    misalignPlot.LineWidth = 2.5;
                    misalignPlot.YLim = [0 5];

                    magPlots = [magPlots magPlot];
                    misalignPlots = [misalignPlots misalignPlot];

                    if ldgFlg
                        magPlot.Visible = 0;
                        misalignPlot.Visible = 0;
                    end
                end

                if plotLoc ~= 1
                    if ~isempty(app.NormIPGasX.UserData)
                        if isfield(app.NormIPGasX.UserData,'MagPlot')
                            if ~isempty(app.NormIPGasX.UserData.MagPlot)
                                magPlot.XLim = app.NormIPGasX.UserData.MagPlot(1,:);
                                magPlot.YLim = app.NormIPGasX.UserData.MagPlot(2,:);
                            end
                        end

                        if isfield(app.NormIPGasX.UserData,'MisPlot')
                            if ~isempty(app.NormIPGasX.UserData.MisPlot)
                                misalignPlot.XLim = app.NormIPGasX.UserData.MisPlot(1,:);
                                misalignPlot.YLim = app.NormIPGasX.UserData.MisPlot(2,:);
                            end
                        end
                    end
                end

                sameaxes([],[magPlots])
                sameaxes([],[misalignPlots])
                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end

                if ~isempty(allTwitch)
                    ldgNames = [ldgNames {'Facial Twitch Observed'}];
                    ldgLine = [ldgLine allTwitch(1)];
                end
                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                end
                app.FigureName = [uAns{1},'_',eyeSel,'_NormMisalignmentandVelocity_IPG_BySPD_',stType,'_',strrep(num2str([all{1,:}]),' ',''),'_',strrep(num2str([all{2,:}]),' ',''),'_',cUsed,'_',DataType];
            end
        end
        
        function PlotNormByIPGJUSTAVG(app, plotLoc,ldgFlg)
            if plotLoc == 1
                loc = app.NormIPGasX;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                marg_w = [.05 .015];
            end

            mpFlg = 0;
            mFlg = 0;
            all = GetIPGandSPDPairs(app);
            sza = size(all{4,1});
            szb = size(all);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];
            ur = unique([all{2,:}]);
            minV = 100;
            maxV = -1;
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');

            if isempty(all)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                ids2T = GetDataInds(app,uAns{1}, [all{1,:}], [all{2,:}], [all{3,1}], [all{4,1}], [all{5,1}], [all{6,1}], [all{7,1}], 1, -1);
            end

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                for h = 1:length(ur)
                    if (h == 1) && (plotLoc == 1)
                        app.NormIPGasX.UserData = [];
                    end
                    magPlot = subtightplot(2,length(ur),h,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot = subtightplot(2,length(ur),h+length(ur) ,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    for i = sza(2):-1:1
                        maxZV = 0;
                        minZV = 0;
                        maxZM = 0;
                        minZM = 0;
                        allDataX = [];
                        allDataY = [];
                        allDataZ = [];
                        allF = [];
                        allDataZMis = [];
                        fitsV = [];
                        fitsVD = [];
                        fitsM = [];
                        fitsMD = [];
                        switch i
                            case 3
                                col = 'k';
                                cola = [0.1 0.1 0];
                            case 2
                                col = [218,165,32]/255;
                                cola = [1 0.8471 0.3255];
                            case 1
                                col = [0 0 1];
                                cola = [0.4 0.4 1];
                        end

                        mpFlg = 1;
                        mFlg = 1;
                        for j = 1:szb(2)
                            ids2U = GetDataInds(app, uAns{1}, all{1,j}, all{2,j}, all{3,j}, all{4,j}(i), all{5,j}, all{6,j}, all{7,j}, 1, -1);
                            if ~isempty(ids2U)
                                [~,nameS,mark,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, all{1,j});
                                [~,nameR,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, all{2,j});
                                if app.AnimalsandEyesToUse{1,3}
                                    eyeSel = 'LEye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagL_NystagCorr';
                                        toUseMag = 'MagL_NystagCorr';
                                        toUseSMag = 'stdMagL_NystagCorr';
                                        toUseMMis = 'meanMisalignL_NystagCorr';
                                        toUseMis = 'MisalignL_NystagCorr';
                                        toUseSMis = 'stdMisalignL_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagL';
                                        toUseMag = 'MagL';
                                        toUseSMag = 'stdMagL';
                                        toUseMMis = 'meanMisalignL';
                                        toUseMis = 'MisalignL';
                                        toUseSMis = 'stdMisalignL';
                                        DataType = 'NonCorrected';
                                    end
                                else
                                    eyeSel = 'REye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagR_NystagCorr';
                                        toUseMag = 'MagR_NystagCorr';
                                        toUseSMag = 'stdMagR_NystagCorr';
                                        toUseMMis = 'meanMisalignR_NystagCorr';
                                        toUseMis = 'MisalignR_NystagCorr';
                                        toUseSMis = 'stdMisalignR_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagR';
                                        toUseMag = 'MagR';
                                        toUseSMag = 'stdMagR';
                                        toUseMMis = 'meanMisalignR';
                                        toUseMis = 'MisalignR';
                                        toUseSMis = 'stdMisalignR';
                                        DataType = 'NonCorrected';
                                    end
                                end
                                color = [0.74 0.74 0.74];
                                Magf2Use = [toUseMMag,num2str(all{3,j})];
                                MagfSD2Use = [toUseSMag,num2str(all{3,j})];
                                Misf2Use = [toUseMMis,num2str(all{3,j})];
                                MisfSD2Use = [toUseSMis,num2str(all{3,j})];

                                hold(magPlot,'on');
                                if all{2,j} == ur(h)
                                    if ~ldgFlg
                                        x = [app.params(ids2U).p2d]';
                                        y = [app.params(ids2U).ipg]';
                                        tempz = vertcat(app.params(ids2U).(Magf2Use));
                                        z = tempz;
                                        allDataX = [allDataX; x'];
                                        allDataY = [allDataY; y'];
                                        allDataZ = [allDataZ; z'];
                                        clV = polyfit([y],z,1);
                                        fitsV = [fitsV clV(1)];
                                        yyy = polyval(clV,0:600);
                                        fitsVD = [fitsVD; yyy];
                                    else
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            stType = 'SEM';
                                        else
                                            stType = 'STD';
                                        end
                                        ipg = [0];
                                        normV = [0];
                                        std = [0];
                                    end
                                end

                                if j == szb(2)
                                    if ~ldgFlg
                                        x = 0:600;
%                                         save(['R:\Morris, Brian\Monkey Data\IPG and SPD Analysis\',app.animal,'fitsVD.mat'],'fitsVD')
%                                         save(['R:\Morris, Brian\Monkey Data\IPG and SPD Analysis\',app.animal,'fitsV.mat'],'fitsV')
                                        my = mean(fitsVD);
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            s = size(fitsVD);
                                            sy = [std(fitsVD)]./sqrt(s(1));
                                            stType = 'SEM';
                                        else
                                            sy = [std(fitsVD)];
                                            stType = 'STD';
                                        end
                                    else
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            stType = 'SEM';
                                        else
                                            stType = 'STD';
                                        end
                                        x = 0;
                                        my = 0;
                                        sy = 0;
                                    end
                                    Pm = plot(magPlot,x, my,'color',col,'LineWidth',2);
                                    xx = [x flip(x)];
                                    yy = [my-sy flip(my)+flip(sy)];
                                    patch(magPlot,xx,yy,cola,'FaceAlpha',.3)
                                    [aa1, bb1] = min(my);
                                    [aa2, bb2] = max(my);
                                    if aa1<minV
                                        minV = aa1;
                                        minS = sy(bb1);
                                    end
                                    if aa2>maxV
                                        maxV = aa2;
                                        maxS = sy(bb2);
                                    end

                                    if isempty(ldgNames)
                                        ldgNames = [ldgNames {[num2str(all{4}(i)),'us']}];
                                        ldgLine = [ldgLine Pm];
                                    else
                                        if ~ismember(ldgNames,[num2str(all{4}(i)),'us'])
                                            ldgNames = [ldgNames {[num2str(all{4}(i)),'us']}];
                                            ldgLine = [ldgLine Pm];
                                        end
                                    end
                                end
                                hold(magPlot,'off');

                                if ~ldgFlg
                                    hold(misalignPlot,'on');
                                    if all{2,j} == ur(h)
                                            if ~issorted([app.params(ids2U).p1amp])
                                                [samp, saI] = sort([app.params(ids2U).p1amp]);
                                                ids2U = ids2U(saI);
                                            end
                                            tempz = vertcat(app.params(ids2U).(Misf2Use));
                                            z = tempz;
                                            allDataZMis = [allDataZMis; z'];
                                            a={app.params(ids2U).(toUseMag)};
                                            aa=cellfun(@size,a,'uni',false);
                                            ca = cell2mat(aa');
                                            x = [app.params(ids2U).p2d]';
                                            y = [app.params(ids2U).ipg]';
                                            ipg = y;
                                            normM = z;
                                            clV = polyfit([y],z,1);
                                            fitsM = [fitsM clV(1)];
                                            yyy = polyval(clV,0:600);
                                            fitsMD = [fitsMD; yyy];
                                    end

                                    if j == szb(2)
                                            x = 0:600;
                                            my = mean(fitsMD);
%                                             save(['R:\Morris, Brian\Monkey Data\IPG and SPD Analysis\',app.animal,'fitsMD.mat'],'fitsMD')
%                                             save(['R:\Morris, Brian\Monkey Data\IPG and SPD Analysis\',app.animal,'fitsM.mat'],'fitsM')
                                            if app.PlottingStandardDeviationCheckBox.Value
                                                s = size(fitsMD);
                                                sy = [std(fitsMD)]./sqrt(s(1));
                                                stType = 'SEM';
                                            else
                                                sy = [std(fitsMD)];
                                                stType = 'STD';
                                            end
                                        Pm = plot(misalignPlot,x, my,'color',col,'LineWidth',2);
                                        xx = [x flip(x)];
                                        yy = [my-sy flip(my)+flip(sy)];
                                        patch(misalignPlot,xx,yy,cola,'FaceAlpha',.3)
                                    end

                                    hold(misalignPlot,'off');
                                end
                            end
                        end
                    end
                    if h == 1
                        magPlot.YLabel.String = 'Velocity (dps)';
                        magPlot.YLabel.FontSize = 30;
                        misalignPlot.YLabel.String = 'Misalignment (d)';
                        misalignPlot.YLabel.FontSize = 30;
                    else
                        magPlot.YTickLabel = [];
                        misalignPlot.YTickLabel = [];
                    end
                    magPlot.XTickLabel = [];
                    magPlot.XTick = [all{7}(1)-25 all{7}];
                    magPlot.XGrid = 'on';
                    magPlot.YGrid = 'on';
                    magPlot.XLim = [all{7}(1)-25 all{7}(end)+25];
                    magPlot.FontSize = 30;
                    [~,nameR,~,~,~] = GetElecColorandNameByAnimal(app, ur(h));
                    magPlot.Title.String = [nameR];
                    magPlot.LineWidth = 2.5;
                    magPlot.Title.FontSize = 30;
                    magPlot.YLim = [0 5];

                    misalignPlot.XTick = [all{7}];
                    misalignPlot.XGrid = 'on';
                    misalignPlot.YGrid = 'on';
                    misalignPlot.XLim = [all{7}(1)-25 all{7}(end)+25];
                    misalignPlot.FontSize = 30;
                    misalignPlot.XLabel.String = {'IPG (us)'};
                    misalignPlot.XLabel.FontSize = 30;
                    misalignPlot.LineWidth = 2.5;
                    misalignPlot.YLim = [0 2];

                    magPlots = [magPlots magPlot];
                    misalignPlots = [misalignPlots misalignPlot];

                    if ldgFlg
                        magPlot.Visible = 0;
                        misalignPlot.Visible = 0;
                    end
                end
                minV
                minS
                maxV
                maxS
                if plotLoc ~= 1
                    if ~isempty(app.NormIPGasX.UserData)
                        if isfield(app.NormIPGasX.UserData,'MagPlot')
                            if ~isempty(app.NormIPGasX.UserData.MagPlot)
                                magPlot.XLim = app.NormIPGasX.UserData.MagPlot(1,:);
                                magPlot.YLim = app.NormIPGasX.UserData.MagPlot(2,:);
                            end
                        end

                        if isfield(app.NormIPGasX.UserData,'MisPlot')
                            if ~isempty(app.NormIPGasX.UserData.MisPlot)
                                misalignPlot.XLim = app.NormIPGasX.UserData.MisPlot(1,:);
                                misalignPlot.YLim = app.NormIPGasX.UserData.MisPlot(2,:);
                            end
                        end
                    end
                end
                sameaxes([],[magPlots])
                sameaxes([],[misalignPlots])
                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end

                if ~isempty(allTwitch)
                    ldgNames = [ldgNames {'Facial Twitch Observed'}];
                    ldgLine = [ldgLine allTwitch(1)];
                end
                [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                if ldgFlg
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1044 0.1814];
                end
                app.FigureName = [uAns{1},'_',eyeSel,'_LinearFit_NormMisalignmentandVelocity_IPG_BySPD_',stType,'_',strrep(num2str([all{1,:}]),' ',''),'_',strrep(num2str([all{2,:}]),' ',''),'_',cUsed,'_',DataType];
            end
        end
        

        function changeAXLim(app,event)
            toU = isprop(app.UIFigure.Children,'enable');
            set(app.UIFigure.Children(toU),'Enable','off')
            app.ChooseAxLimApp = ChooseAxLimits(app);
            uiwait(app.ChooseAxLimApp.UIFigure)
            lims = [app.XLimMin app.XLimMax; app.YLimMin app.YLimMax];
            if ~isempty(lims)
                switch app.TabGroup.SelectedTab.Title
                    case [{'By Stim, Amp As X'} {'By Ref, Amp As X'} {'By Ref and Canal, Amp as X'} {'Compare Pairs, By Stim, Amp as X'} {'Max Compare Pairs, By Stim'}...
                            {'Compare Pairs, By Ref, Amp as X'} {'Max Compare Pairs, By Ref'} {'Surf Plots, By Stim, IPG and SPD'} {'Compare Standard Across Time'} {'Compare AvC Standards'} {'Plot All Standards'}...
                            {'Norm data, By SPD, IPG as X'} {'Max vs Distance, By Stim'} {'Max vs Distance, By Ref'}]
                        temp = [app.UIFigure.CurrentObject.XLim; app.UIFigure.CurrentObject.YLim];
                        temp(~isnan(lims)) = lims(~isnan(lims));
                        axs = findobj(app.TabGroup.SelectedTab.Children,'Type','Axes');
                        pos = get(axs,'Position');
                        if app.UIFigure.CurrentObject.Position(2) > .5
                            app.UIFigure.CurrentObject.Parent.UserData.MagPlot = temp;
                            for i = 1:length(pos)
                                if pos{i}(2) > 0.5
                                    axs(i).XLim = temp(1,:);
                                    axs(i).YLim = temp(2,:);
                                end
                            end
                        else
                            app.UIFigure.CurrentObject.Parent.UserData.MisPlot = temp;
                            for i = 1:length(pos)
                                if pos{i}(2) < 0.5
                                    axs(i).XLim = temp(1,:);
                                    axs(i).YLim = temp(2,:);
                                end
                            end
                        end
                    case 'Average Surf, By Ref, IPG and PD'
                        lims = [app.XLimMin app.XLimMax; app.YLimMin app.YLimMax; app.ZLimMin app.ZLimMax];
                        temp = [app.UIFigure.CurrentObject.XLim; app.UIFigure.CurrentObject.YLim; app.UIFigure.CurrentObject.ZLim];
                        temp(~isnan(lims)) = lims(~isnan(lims));
                        axs = findobj(app.TabGroup.SelectedTab.Children,'Type','Axes');
                        pos = get(axs,'Position');
                        if app.MisalignmentButton.Value && app.EyeVButton.Value
                            if app.UIFigure.CurrentObject.Position(2) > .5
                                app.AverageSurfByRefIPGandPDTab.UserData.MagPlot = temp;
                                for i = 1:length(pos)
                                        axs(i).XLim = temp(1,:);
                                        axs(i).YLim = temp(2,:);
                                        axs(i).ZLim = temp(3,:);
                                        colormap(axs(i),'jet')
                                        caxis(axs(i),axs(i).ZLim)
                                end
                                
                            else
                                app.AverageSurfByRefIPGandPDTab.UserData.MisPlot = temp;
                                for i = 1:length(pos)
                                    if pos{i}(2) < 0.5
                                        axs(i).XLim = temp(1,:);
                                        axs(i).YLim = temp(2,:);
                                        axs(i).ZLim = temp(3,:);
                                        colormap(axs(i),'jet')
                                        caxis(axs(i),axs(i).ZLim)
                                    end
                                end
                            end
                        elseif app.MisalignmentButton.Value
                            app.AverageSurfByRefIPGandPDTab.UserData.MisPlot = temp;
                            axs.XLim = temp(1,:);
                            axs.YLim = temp(2,:);
                            axs.ZLim = temp(3,:);
                            colormap(axs,'jet')
                            caxis(axs,axs.ZLim)
                        else
                            app.AverageSurfByRefIPGandPDTab.UserData.MagPlot = temp;
                            axs.XLim = temp(1,:);
                            axs.YLim = temp(2,:);
                            axs.ZLim = temp(3,:);
                            colormap(axs,'jet')
                            caxis(axs,axs.ZLim)
                        end
                    case 'Cycle Average'
                        temp = [app.UIFigure.CurrentObject.XLim; app.UIFigure.CurrentObject.YLim];
                        temp(~isnan(lims)) = lims(~isnan(lims));
                        axs = findobj(app.TabGroup.SelectedTab.Children,'Type','Axes');
                        pos = get(axs,'Position');
                        app.UIFigure.CurrentObject.Parent.UserData.MagPlot = temp;
                        for i = 1:length(pos)
                                axs(i).XLim = temp(1,:);
                                axs(i).YLim = temp(2,:);
                        end
                    case [{'Max Point Cloud, By Stim'} {'Max Point Cloud, By Ref'}]
                        temp = [app.UIFigure.CurrentObject.XLim; app.UIFigure.CurrentObject.YLim];
                        temp(~isnan(lims)) = lims(~isnan(lims));
                        axs = findobj(app.TabGroup.SelectedTab.Children,'Type','Axes');
                        pos = get(axs,'Position');
                        if app.UIFigure.CurrentObject.Position(2) > .5
                            app.UIFigure.CurrentObject.Parent.UserData.MagPlot = temp;
                            for i = 1:length(pos)
                                if pos{i}(2) > 0.5
                                    axs(i).YLim = temp(2,:);
                                end
                            end
                        else
                            app.UIFigure.CurrentObject.Parent.UserData.MisPlot = temp;
                            for i = 1:length(pos)
                                if pos{i}(2) < 0.5
                                    axs(i).YLim = temp(2,:);
                                end
                            end
                        end
                end
            end
            set(app.UIFigure.Children(toU),'Enable','on')
        end
    
        function AverageSurfPlotByRefIPGandFPDAvC(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.AverageSurfByRefIPGandPDTab;
%                 marg_h = [0.15 0.075];
                marg_w = [.075 .065];
            else
                loc = app.SaveFigHandle;
%                 marg_h = [0.10 0.075];
                marg_w = [.055 .035];
            end

            mpFlg = 0;
            mFlg = 0;
            all = GetIPGandFPDPairs(app);
            sza = size(all);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];
            maxZV = 0;
            minZV = 0;
            maxZM = 0;
            minZM = 0;
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');

            if isempty(all)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                ids2T = GetDataInds(app, uAns{1}, [all{1,:}], [all{2,:}], [all{3,1}], [all{4,1}], [all{5,1}], [all{6,1}], [all{7,1}], 1, -1);
            end

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                if app.EyeVButton.Value && app.MisalignmentButton.Value
                    misalignPlot = subtightplot(2,1,2,[0.01 0.03],[0.05 0.07],marg_w,'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot.Layer = 'bottom';
                    magPlot = subtightplot(2,1,1,[0.01 0.03],[0.025 0.03],marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    magPlot.Layer = 'Top';
                    mpFlg = 1;
                    mFlg = 1;
                elseif app.EyeVButton.Value
                    magPlot = subtightplot(1,1,1,[0.01 0.03],[0.035 0.03],marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    magPlot.Layer = 'Top';
                    mpFlg = 1;
                else
                    misalignPlot = subtightplot(1,1,1,[0.01 0.03],[0.035 0.03],marg_w,'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot.Layer = 'bottom';
                    mFlg = 1;
                end

                if (plotLoc == 1)
                    app.AverageSurfByRefIPGandPDTab.UserData = [];
                end

                allDataX = [];
                allDataY = [];
                allDataZ = [];
                allDataZMis = [];
                for i = 1:sza(2)

                    %ids2U = GetDataInds(app, stim, ref, p1d, p2d, p1a, p2a, ipg, dup, dsp2d)
                    ids2U = GetDataInds(app, uAns{1}, all{1,i}, all{2,i}, all{3,i}, all{4,i}, all{5,i}, all{6,i}, all{7,i}, 1, -1);

                    if ~isempty(ids2U)
                        if app.AnimalsandEyesToUse{1,3}
                                eyeSel = 'LEye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagL_NystagCorr';
                                    toUseMag = 'MagL_NystagCorr';
                                    toUseSMag = 'stdMagL_NystagCorr';
                                    toUseMMis = 'meanMisalignL_NystagCorr';
                                    toUseMis = 'MisalignL_NystagCorr';
                                    toUseSMis = 'stdMisalignL_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagL';
                                    toUseMag = 'MagL';
                                    toUseSMag = 'stdMagL';
                                    toUseMMis = 'meanMisalignL';
                                    toUseMis = 'MisalignL';
                                    toUseSMis = 'stdMisalignL';
                                    DataType = 'NonCorrected';
                                end
                            else
                                eyeSel = 'REye';
                                if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                    toUseMMag = 'meanMagR_NystagCorr';
                                    toUseMag = 'MagR_NystagCorr';
                                    toUseSMag = 'stdMagR_NystagCorr';
                                    toUseMMis = 'meanMisalignR_NystagCorr';
                                    toUseMis = 'MisalignR_NystagCorr';
                                    toUseSMis = 'stdMisalignR_NystagCorr';
                                    DataType = 'NystagCorr';
                                else
                                    toUseMMag = 'meanMagR';
                                    toUseMag = 'MagR';
                                    toUseSMag = 'stdMagR';
                                    toUseMMis = 'meanMisalignR';
                                    toUseMis = 'MisalignR';
                                    toUseSMis = 'stdMisalignR';
                                    DataType = 'NonCorrected';
                                end
                        end

                        [~,nameS,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, all{1,i});
                        [~,nameR,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, all{2,i});
                        color = 'b';
                        Magf2Use = [toUseMMag,app.NormBy.Value{1}];
                        MagfSD2Use = [toUseSMag,app.NormBy.Value{1}];
                        Misf2Use = [toUseMMis,app.NormBy.Value{1}];
                        MisfSD2Use = [toUseSMis,app.NormBy.Value{1}];

                        x = [app.params(ids2U).p1d]';
                        y = [app.params(ids2U).ipg]';
                        tempz = vertcat(app.params(ids2U).(Magf2Use));
                        z = tempz;
                        tempz = vertcat(app.params(ids2U).(Misf2Use));
                        zm = tempz;
                        if length(x)<length(allDataX)
                            for iii = 1:length(allDataX)
                                if iii >length(x)
                                    x = [x; allDataX(1,iii)];
                                    y = [y; allDataY(1,iii)];
                                    z = [z; NaN];
                                    zm = [zm; NaN];
                                else
                                    if x(iii) ~= allDataX(1,iii)
                                        x = [x(1:iii-1); allDataX(1,iii); x(iii:end)];
                                        y = [y(1:iii-1); allDataY(1,iii); y(iii:end)];
                                        z = [z(1:iii-1); NaN; z(iii:end)];
                                        zm = [zm(1:iii-1); NaN; zm(iii:end)];
                                    end
                                end
                            end
                        end
                        allDataX = [allDataX; x'];
                        allDataY = [allDataY; y'];
                        allDataZ = [allDataZ; z'];
                        allDataZMis = [allDataZMis; zm'];
                    end
                end
                sz = size(allDataY);
                if sz(1) > 1
                    avgX = mean(allDataX)';
                    avgY = mean(allDataY)';
                    avgZ = mean(allDataZ,'omitnan')';
                else
                    avgX = allDataX';
                    avgY = allDataY';
                    avgZ = allDataZ';
                end
                
                
                if app.PlottingStandardDeviationCheckBox.Value
                    stdZ = std(allDataZ,'omitnan')'./sqrt(sz(1));
                    stType = 'SEM';
                else
                    stdZ = std(allDataZ,'omitnan')';
                    stType = 'STD';
                end

                maxZV = max(maxZV,max(avgZ));
                if minZV == 0
                    minZV = min(avgZ);
                else
                    minZV = min(minZV,min(avgZ));
                end
                F = scatteredInterpolant(avgX,avgY,avgZ);
                F.Method = 'natural';
                minVX = min([all{3,1}]');
                maxVX = max([all{3,1}]');
                minVY = min([all{7,1}]');
                maxVY = max([all{7,1}]');
                [xq,yq] = meshgrid([minVX:10:maxVX],[minVY:10:maxVY]);

                F.Method = 'natural';
                vq1 = F(xq,yq);
                nMP = '';
                nm = '';
                if mpFlg
                    hold(magPlot,'on');
                    ptL = plot3(magPlot,avgX,avgY,avgZ,'LineWidth',2.5,'DisplayName','','Marker','o','MarkerSize',20,'MarkerEdgeColor',color,'LineStyle','none');
%                     magPlot.XLim = [minV max(avgX)];
                    magPlot.XGrid = 'On';
%                     magPlot.YLim = [minV max(avgY)];
                    magPlot.YGrid = 'On';
                    magPlot.ZGrid = 'On';
                    magPlot.View = [37.5000 46.5];
                    magPlot.CameraPositionMode = 'auto';

                    magPlot.Title.String = ['Average vs ',nameR{1}];
                    magPlot.YTick = unique(avgY);
                    magPlot.XTick = unique(avgX);
                    magPlot.YTickLabel{2} = ' ';
                    magPlot.YTickLabel{3} = ' ';
                    magPlot.XTickLabel{1} = ' ';

                    for ptS = 1:length(avgZ)
                        stdMagR = stdZ(ptS);
                        plot3(magPlot,[avgX(ptS) avgX(ptS)], [avgY(ptS) avgY(ptS)], [avgZ(ptS)-stdMagR avgZ(ptS)+stdMagR],'Color',color,'LineWidth',4);
                        if ptS == 1
                            zlimmin = avgZ(ptS)-stdMagR;
                            zlimmax = avgZ(ptS)+stdMagR;
                        else
                            zlimmin = min(zlimmin,avgZ(ptS)-stdMagR);
                            zlimmax = max(zlimmax,avgZ(ptS)+stdMagR);
                        end
                        
                    end
                    surfR = mesh(magPlot,xq,yq,vq1,'FaceColor','interp','FaceAlpha', 0.5);
                    colormap(magPlot,'jet')
                    caxis(magPlot,magPlot.ZLim)
                    if plotLoc ~= 1
                        if ~isempty(app.AverageSurfByRefIPGandPDTab.UserData)
                            if isfield(app.AverageSurfByRefIPGandPDTab.UserData,'MagPlot')
                                if ~isempty(app.AverageSurfByRefIPGandPDTab.UserData.MagPlot)
                                    magPlot.XLim = app.AverageSurfByRefIPGandPDTab.UserData.MagPlot(1,:);
                                    magPlot.YLim = app.AverageSurfByRefIPGandPDTab.UserData.MagPlot(2,:);
                                    magPlot.ZLim = app.AverageSurfByRefIPGandPDTab.UserData.MagPlot(3,:);
                                    colormap(magPlot,'jet')
                                    caxis(magPlot,magPlot.ZLim)
%                                     magPlot.ZTick = 0:4;
                                end
                            end
                        end
                    end
%                     magPlot.ZLim = [0 4];
%                     colormap(magPlot,'jet')
%                     caxis(magPlot,[0 4])
                    hold(magPlot,'off');
                    magPlot.FontSize = 20;
%                     magPlot.ZTick = 0:4;
                    magPlot.YTickLabel = cellfun(@num2str, num2cell(all{7,1}), 'UniformOutput', 0);
                    magPlot.XTickLabel = cellfun(@num2str, num2cell(all{3,1}), 'UniformOutput', 0);
%                     magPlot.FontSize = 13.5;
                    magPlot.LineWidth = 3;
                    magPlot.GridAlpha = .3;
                    set(magPlot,'Layer','Top')
                    magPlot.ZLabel.String = 'Normalized VOR';
                    if ~mFlg
                        magPlot.XLabel.String = 'P1D (uS)';
                        magPlot.YLabel.String = 'IPG (uS)';
                    end
                    if ldgFlg
                        magPlot.Visible = 0;
                    end
                    nMP = '-Velocity';
                end

                if mFlg
                    hold(misalignPlot,'on');
                    avgZS = mean(allDataZMis,'omitnan')';
                    if app.PlottingStandardDeviationCheckBox.Value
                        stdZS = std(allDataZMis,'omitnan')'./sqrt(sz(1));
                        stType = 'SEM';
                    else
                        stdZS = std(allDataZMis,'omitnan')';
                        stType = 'STD';
                    end

                    maxZM = max(maxZM,max(avgZS));
                    if minZM == 0
                        minZM = min(avgZS);
                    else
                        minZM = min(minZM,min(avgZS));
                    end
                    F = scatteredInterpolant(avgX,avgY,avgZS);
                    F.Method = 'natural';
                    minVX = min([all{3,1}]');
                maxVX = max([all{3,1}]');
                minVY = min([all{7,1}]');
                maxVY = max([all{7,1}]');
                [xq,yq] = meshgrid([minVX:10:maxVX],[minVY:10:maxVY]);


                    F.Method = 'natural';
                    vq1 = F(xq,yq);
                    ptL = plot3(misalignPlot,avgX,avgY,avgZS,'LineWidth',2.5,'DisplayName','','Marker','o','MarkerSize',20,'MarkerEdgeColor',color,'LineStyle','none');
%                     misalignPlot.XLim = [minV max(avgX)];
                    misalignPlot.XGrid = 'On';
%                     misalignPlot.YLim = [minV max(avgY)];
                    misalignPlot.YGrid = 'On';
                    misalignPlot.ZGrid = 'On';
                    misalignPlot.View = [37.5000 46.5];
                    misalignPlot.CameraPositionMode = 'auto';

                    misalignPlot.YTick = unique(avgY);
                    misalignPlot.XTick = unique(avgX);
                    misalignPlot.YTickLabel{2} = ' ';
                    misalignPlot.YTickLabel{3} = ' ';
                    misalignPlot.XTickLabel{1} = ' ';

                    for ptS = 1:length(avgY)
                        stdMisalignR = stdZS(ptS);
                        plot3(misalignPlot,[avgX(ptS) avgX(ptS)], [avgY(ptS) avgY(ptS)], [avgZS(ptS)-stdMisalignR avgZS(ptS)+stdMisalignR],'Color',color,'LineWidth',4);
                        if ptS == 1
                            zlimmin = avgZS(ptS)-stdMisalignR;
                            zlimmax = avgZS(ptS)+stdMisalignR;
                        else
                            zlimmin = min(zlimmin,avgZS(ptS)-stdMisalignR);
                            zlimmax = max(zlimmax,avgZS(ptS)+stdMisalignR);
                        end
                        
                    end
                    surfR = mesh(misalignPlot,xq,yq,vq1,'FaceColor','interp','FaceAlpha', 0.5);
                    if mpFlg
                        misalignPlot.ZLim = magPlot.ZLim;
                    end
                    colormap(misalignPlot,'jet')
                    caxis(misalignPlot,misalignPlot.ZLim)
                    if plotLoc ~= 1
                        if ~isempty(app.AverageSurfByRefIPGandPDTab.UserData)
                            if isfield(app.AverageSurfByRefIPGandPDTab.UserData,'MisPlot')
                                if ~isempty(app.AverageSurfByRefIPGandPDTab.UserData.MisPlot)
                                    misalignPlot.XLim = app.AverageSurfByRefIPGandPDTab.UserData.MisPlot(1,:);
                                    misalignPlot.YLim = app.AverageSurfByRefIPGandPDTab.UserData.MisPlot(2,:);
                                    misalignPlot.ZLim = app.AverageSurfByRefIPGandPDTab.UserData.MisPlot(3,:);
                                    colormap(misalignPlot,'jet')
                                    caxis(misalignPlot,misalignPlot.ZLim)
                                end
                            end
                        end
                    end
                    
%                     misalignPlot.ZLim = [0 4];
%                     colormap(misalignPlot,'jet')
%                     caxis(misalignPlot,[0 4])
                    hold(misalignPlot,'off');
                    misalignPlot.FontSize = 20;
%                     misalignPlot.ZTick = 0:4;
                    misalignPlot.YTickLabel = cellfun(@num2str, num2cell(all{7,1}), 'UniformOutput', 0);
                    misalignPlot.XTickLabel = cellfun(@num2str, num2cell(all{3,1}), 'UniformOutput', 0);
%                     misalignPlot.FontSize = 13.5;
                    misalignPlot.LineWidth = 3;
                    misalignPlot.GridAlpha = .3;
                    misalignPlot.ZLabel.String = {'Normalized'; 'Misalignment'};
                    misalignPlot.XLabel.String = 'P1D (uS)';
                    misalignPlot.YLabel.String = 'IPG (uS)';
                    if ldgFlg
                        misalignPlot.Visible = 0;
                    end
                    nm = 'Misalignment';
                end
                %             if any([app.params(ids2U).FacialNerve])
                %                 trueFace = find([app.params(ids2U).FacialNerve]);
                %                 for xs = 1:length(trueFace)
                %                     hold(misalignPlot,'on')
                %                     p2d2 = app.params(ids2U(trueFace(xs))).p2d;
                %                     ipg2 = app.params(ids2U(trueFace(xs))).ipg;
                %                     mis2 = app.params(ids2U(trueFace(xs))).(Misf2Use);
                %                     handles.cutOff1 = plot3(misalignPlot,p2d2,ipg2,mis2,'rx','MarkerSize', 30,'LineWidth',4);
                %                     hold(misalignPlot,'off')
                %
                %                     hold(magPlot,'on')
                %                     mag2 = app.params(ids2U(trueFace(xs))).(Magf2Use);
                %                     handles.cutOff2 = plot3(magPlot,p2d2,ipg2,mag2,'rx','MarkerSize', 30,'LineWidth',4);
                %                     hold(magPlot,'off')
                %                 end
                %             end
                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end
                app.FigureName = [uAns{1},'_',eyeSel,'_Normailzed_',app.NormBy.Value{1},nm,nMP,'_IPG_P1D_ByStim_',stType,'_',strrep(num2str([all{1,:}]),' ',''),'_',strrep(num2str([all{2,:}]),' ',''),'_',cUsed,'_',DataType];
                if ldgFlg
                    if mpFlg + mFlg == 2
                        cb = colorbar(misalignPlot);
                    elseif mpFlg
                        cb = colorbar(magPlot);
                    else
                        cb = colorbar(misalignPlot);
                    end
                    cb.FontSize = 22;
                    cb.LineWidth = 2.5;
                    cb.TickLength = 0.02;
                end
            end
            
        end
    
        function PlotCycleAverage(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.CycleAverageTab;
                marg_h = [0.05 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                loc.Units = 'inches';
                loc.Position(4) = (1.8/6.06)*loc.Position(3);
                marg_h = [0.05 0.05];
                marg_w = [.05 .015];
            end
            uStims = unique(app.Stims);
            s = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uStims);
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = s;
            uRefs = unique(app.Refs);
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];

            maxY = 0;
            minY = 0;

            uStimsUsed = [];
            uRefsUsed = [];
            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                for i = 1:length(up1a)
                    magPlot = subtightplot(1,length(up1a),i,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    if (i == 1) && (plotLoc == 1)
                        app.CycleAverageTab.UserData = [];
                    end
                    if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                        transformed = 'NONTRANSFORMED';
                    else
                        transformed = 'TRANSFORMED';
                    end

                    [~,name,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uStims);
                    ids2U = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, up1a(i), up1a(i), app.IPGs, 1, -1);
                    if ~isempty(ids2U)
                        if isempty(uStimsUsed)
                            uStimsUsed = [uStimsUsed uStims];
                        else
                            if ~any(ismember(uStimsUsed,uStims))
                                uStimsUsed = [uStimsUsed uStims];
                            end
                        end
                        if isempty(uRefsUsed)
                            uRefsUsed = [uRefsUsed uRefs];
                        else
                            if ~any(ismember(uRefsUsed,uRefs))
                                uRefsUsed = [uRefsUsed uRefs];
                            end
                        end

                        if app.AnimalsandEyesToUse{1,3}
                            eyeSel = 'LEye';
                            t = 'timeL';
                            if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                toUseMMag = 'cyclesLRZXYL_NystagCorr';
                                %                                     toUseSMag = 'stdL_NystagCorr';
                                %                                     cl = 'MagL_NystagCorr';
                                DataType = 'NystagCorr';
                            else
                                toUseMMag = 'cyclesLRZXYL';
                                %                                     toUseSMag = 'stdL';
                                %                                     cl = 'MagL';
                                DataType = 'NonCorrected';
                            end
                        else
                            eyeSel = 'REye';
                            t = 'timeR';
                            if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                toUseMMag = 'cyclesLRZXYR_NystagCorr';
                                %                                     toUseSMag = 'stdR_NystagCorr';
                                %                                     cl = 'MagR_NystagCorr';
                                DataType = 'NystagCorr';
                            else
                                toUseMMag = 'cyclesLRZXYR';
                                %                                     toUseSMag = 'stdR';
                                %                                     cl = 'MagR';
                                DataType = 'NonCorrected';
                            end
                        end
                        if isfield(app.params(ids2U),[toUseMMag,'_QPR'])
                            if ~isempty(app.params(ids2U).([toUseMMag,'_QPR']))
%                                 answer = questdlg('Would you like to plot the dta with quick phases removed?','QPR Data','Yes','No','Yes');
%                                 switch answer
%                                     case 'Yes'
                                        toUseMMag = [toUseMMag,'_QPR'];
                                        DataType = [DataType,'_QPR'];
%                                     case 'No'
%                                 end
                            end
                        end

                        transparencyTH = '';

                        hold(magPlot,'on');

                        [color,refName,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uRefs);
                        if ~ldgFlg
                            stType = 'STD';
                        else
                            stType = 'STD';
                            amp = [0];
                            vel = [0];
                            stdV = [0];
                        end

                        lng = 1:length(app.params(ids2U).(toUseMMag){1});
                        

                        lstyle = '-';
                        if ~ldgFlg
                            if i == 1
                                trig = [0 ones(1,250) zeros(1,lng(end)-250)]*50;
                                stairs(magPlot,[-app.params(ids2U).(t)(2); app.params(ids2U).(t)(lng)],trig,'color','k','LineWidth',4);
                            end
                            if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                transformed = 'NONTRANSFORMED';
                                m = mean(app.params(ids2U).(toUseMMag){1});
                                s = std(app.params(ids2U).(toUseMMag){1});
                                maxY = max([m(lng) m(lng)+s(lng) maxY]);
                                minY = min([m(lng) m(lng)-s(lng) minY]);

                                p.ll = shadedErrorBar(app.params(ids2U).(t)(lng),m(lng),s(lng),'lineprops',{'r-','MarkerFaceColor',app.colorV.l_l},'Parent',magPlot);
                                set(p.ll.edge,'LineWidth',1.5,'color',app.colorV.l_l)
                                set(p.ll.patch,'facecolor',app.colorV.l_l)
                                set(p.ll.mainLine,'LineWidth',2.5,'color',app.colorV.l_l)
                                set(p.ll.edge,'LineStyle',lstyle)
                                set(p.ll.mainLine,'LineStyle',lstyle)

                                m = mean(app.params(ids2U).(toUseMMag){2});
                                s = std(app.params(ids2U).(toUseMMag){2});
                                maxY = max([m(lng) m(lng)+s(lng) maxY]);
                                minY = min([m(lng) m(lng)-s(lng) minY]);

                                p.lr = shadedErrorBar(app.params(ids2U).(t)(lng),m(lng),s(lng),'lineprops',{'r-','MarkerFaceColor',app.colorV.l_r},'Parent',magPlot);
                                set(p.lr.edge,'LineWidth',1.5,'color',app.colorV.l_r)
                                set(p.lr.patch,'facecolor',app.colorV.l_r)
                                set(p.lr.mainLine,'LineWidth',2.5,'color',app.colorV.l_r)
                                set(p.lr.edge,'LineStyle',lstyle)
                                set(p.lr.mainLine,'LineStyle',lstyle)

                                m = mean(app.params(ids2U).(toUseMMag){3});
                                s = std(app.params(ids2U).(toUseMMag){3});
                                maxY = max([m(lng) m(lng)+s(lng) maxY]);
                                minY = min([m(lng) m(lng)-s(lng) minY]);

                                p.lz = shadedErrorBar(app.params(ids2U).(t)(lng),m(lng),s(lng),'lineprops',{'r-','MarkerFaceColor',app.colorV.l_z},'Parent',magPlot);
                                set(p.lz.edge,'LineWidth',1.5,'color',app.colorV.l_z)
                                set(p.lz.patch,'facecolor',app.colorV.l_z)
                                set(p.lz.mainLine,'LineWidth',2.5,'color',app.colorV.l_z)
                                set(p.lz.edge,'LineStyle',lstyle)
                                set(p.lz.mainLine,'LineStyle',lstyle)
                            else
                                transformed = 'TRANSFORMED';
                                l = [];
                                r = [];
                                z = [];
                                sz = size(app.params(ids2U).(toUseMMag){1});
                                for ii = 1:sz(1)
                                    origVal = [app.params(ids2U).(toUseMMag){1}(ii,:); app.params(ids2U).(toUseMMag){2}(ii,:); app.params(ids2U).(toUseMMag){3}(ii,:)];
                                    magValsN = app.AnimalProperties.(uAns{1}).CLRZfromFLRZ*origVal;
                                    l = [l; magValsN(1,:)];
                                    r = [r; magValsN(2,:)];
                                    z = [z; magValsN(3,:)];
                                end

                                m = mean(l);
                                s = std(l);

                                maxY = max([m(lng) m(lng)+s(lng) maxY]);
                                minY = min([m(lng) m(lng)-s(lng) minY]);

                                p.ll = shadedErrorBar(app.params(ids2U).(t)(lng)',m(lng),s(lng),'lineprops',{'r-','MarkerFaceColor',app.colorV.l_l},'Parent',magPlot);
                                set(p.ll.edge,'LineWidth',1.5,'color',app.colorV.l_l)
                                set(p.ll.patch,'facecolor',app.colorV.l_l)
                                set(p.ll.mainLine,'LineWidth',2.5,'color',app.colorV.l_l)
                                set(p.ll.edge,'LineStyle',lstyle)
                                set(p.ll.mainLine,'LineStyle',lstyle)

                                m = mean(r);
                                s = std(r);
                                maxY = max([m(lng) m(lng)+s(lng) maxY]);
                                minY = min([m(lng) m(lng)-s(lng) minY]);

                                p.lr = shadedErrorBar(app.params(ids2U).(t)(lng),m(lng),s(lng),'lineprops',{'r-','MarkerFaceColor',app.colorV.l_r},'Parent',magPlot);
                                set(p.lr.edge,'LineWidth',1.5,'color',app.colorV.l_r)
                                set(p.lr.patch,'facecolor',app.colorV.l_r)
                                set(p.lr.mainLine,'LineWidth',2.5,'color',app.colorV.l_r)
                                set(p.lr.edge,'LineStyle',lstyle)
                                set(p.lr.mainLine,'LineStyle',lstyle)

                                m = mean(z);
                                s = std(z);
                                maxY = max([m(lng) m(lng)+s(lng) maxY]);
                                minY = min([m(lng) m(lng)-s(lng) minY]);

                                p.lz = shadedErrorBar(app.params(ids2U).(t)(lng),m(lng),s(lng),'lineprops',{'r-','MarkerFaceColor',app.colorV.l_z},'Parent',magPlot);
                                set(p.lz.edge,'LineWidth',1.5,'color',app.colorV.l_z)
                                set(p.lz.patch,'facecolor',app.colorV.l_z)
                                set(p.lz.mainLine,'LineWidth',2.5,'color',app.colorV.l_z)
                                set(p.lz.edge,'LineStyle',lstyle)
                                set(p.lz.mainLine,'LineStyle',lstyle)
                            end
                        end
                        hold(magPlot,'off');

                        ids = [ids; {ids2U}];
                    end

                    if i == 1
                        magPlot.YLabel.String = 'Velocity (dps)';
                        magPlot.YLabel.FontSize = 30;
                        magPlot.YAxis.Visible = 'off';
                    else
                        magPlot.YTickLabel = [];
                        magPlot.YAxis.Visible = 'off';
                    end
                    drawnow
                    magPlot.XRuler.Axle.Visible = 'off';
                    magPlot.XTickLabel = [];
                    magPlot.XTick = [];

                    %                     magPlot.XTick = [up1a(1)-25 up1a];
                    %                     magPlot.XGrid = 'on';
                    %                     magPlot.YGrid = 'on';
                    magPlot.XLim = [-.025 lng(end)/1000];
                    magPlot.FontSize = 30;
                    %                     magPlot.Title.String = name{1};
                    magPlot.LineWidth = 2.5;
                    magPlot.Title.FontSize = 30;


                    if plotLoc ~= 1
                        if ~isempty(app.CycleAverageTab.UserData)
                            if isfield(app.CycleAverageTab.UserData,'MagPlot')
                                if ~isempty(app.CycleAverageTab.UserData.MagPlot)
                                    magPlot.XLim = app.CycleAverageTab.UserData.MagPlot(1,:);
                                    magPlot.YLim = app.CycleAverageTab.UserData.MagPlot(2,:);
                                end
                            end
                        end
                    end

                    magPlots = [magPlots magPlot];

                    if ldgFlg
                        magPlot.Visible = 0;
                    end
                end

                sameaxes([],[magPlots])
                magPlots(end).YLim
                if ~ldgFlg
                    for i = 1:length(ids2T)
                        hold(magPlots(i),'on');
                        if i>1
                            plot(magPlots(i),[-0.0125 -0.0125],[-100 100],'k--','linewidth',4)
                        end

                        dim = [.2 .5 .3 .3];
                        str = [num2str(up1a(i)),'μA'];
                        aa = annotation(app.UIFigure,'textbox',dim,'String',str,'FitBoxToText','on','LineStyle','none');
                        aa.Parent = magPlots(i);
                        aa.Position(1) = .01;
                        aa.Position(2) = maxY+55;
                        aa.FontWeight = 'bold';
                        aa.FontSize = 40;
                        if app.Canals(1) == 1
                            aa.Color = app.colorV.l_l;
                        elseif app.Canals(2) == 1
                            aa.Color = app.colorV.l_r;
                        else
                            aa.Color = app.colorV.l_z;
                        end

                        dim = [.2 .5 .3 .3];
                        if i == 1
                            sz = size(app.params(ids2T(i)).(toUseMMag){1});
                            str = ['n = ',num2str(sz(1))];
                            aa = annotation(app.UIFigure,'textbox',dim,'String',str,'FitBoxToText','on','LineStyle','none');
                            %                         magPlot.XLabel.String = ;
                            aa.Position(1) = .01;
                        else
                            sz = size(app.params(ids2T(i)).(toUseMMag){1});
                            str = [num2str(sz(1))];
                            aa = annotation(app.UIFigure,'textbox',dim,'String',str,'FitBoxToText','on','LineStyle','none');
                            %                         magPlot.XLabel.String = ;
                            aa.Position(1) = .01;
                        end
                        aa.Parent = magPlots(i);
                        
                        aa.Position(2) = minY+5;%magPlots(i).YLim(1)+(magPlots(i).YLim(1)*-.6);
                        aa.FontWeight = 'bold';
                        aa.FontSize = 40;
                        if app.Canals(1) == 1
                            aa.Color = app.colorV.l_l;
                        elseif app.Canals(2) == 1
                            aa.Color = app.colorV.l_r;
                        else
                            aa.Color = app.colorV.l_z;
                        end
%                         if any([app.params(ids2T(i)).FacialNerve])
%                             aa.Color = 'r';
%                         end
                    end
                end
                sameaxes([],[magPlots])

                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end


                if ldgFlg
                    hold(magPlots,'on');
                    ldgLine = [ldgLine plot(magPlot,[0 1],[0 1],'color',app.colorV.l_z,'LineWidth',4)];
                    ldgLine = [ldgLine plot(magPlot,[0 1],[0 1],'color',app.colorV.l_r,'LineWidth',4)];
                    ldgLine = [ldgLine plot(magPlot,[0 1],[0 1],'color',app.colorV.l_l,'LineWidth',4)];
                    hold(magPlots,'off');
                    ldgNames = [{''} {''} {''}];
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','horizontal','AutoUpdate','off');
%                     ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                end
                uStims = sort(uStimsUsed);
                uRefs = sort(uRefsUsed);
                app.FigureName = [uAns{1},'_',eyeSel,'_MisandVel_Current_ByStim_',transformed,'_',transparencyTH,stType,'_',strrep(num2str(uStims),' ',''),'_',strrep(num2str(uRefs),' ',''),'_',cUsed,'_',DataType];
            end
        end

        function Plot3DMisalignment(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.SpherePlotTab;
                marg_h = [0.01 0.01];
                marg_w = [.01 .01];
            else
                loc = app.SaveFigHandle;
%                 loc.Color = 'none';
                marg_h = [0.01 0.01];
                marg_w = [.01 .01];
            end
            uStims = unique(app.Stims,'stable');
            s = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uStims);
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = s;
            uRefs = unique(app.Refs,'stable');
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];

            uStimsUsed = [];
            uRefsUsed = [];
            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);

            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                misalignPlot = subtightplot(1,1,1,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                misalignPlot.Position = [0 0 1 1];
                misalignPlot.Color = 'none';
                
                for i = 1:length(uStims)
                    ids2U = GetDataInds(app, uAns{1}, uStims(i), uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                    ids2U = max(ids2U);
                    if ~isempty(ids2U)
                        if isempty(uStimsUsed)
                            uStimsUsed = [uStimsUsed uStims(i)];
                        else
                            if ~any(ismember(uStimsUsed,uStims(i)))
                                uStimsUsed = [uStimsUsed uStims(i)];
                            end
                        end
                        if isempty(uRefsUsed)
                            uRefsUsed = [uRefsUsed uRefs];
                        else
                            if ~any(ismember(uRefsUsed,uRefs))
                                uRefsUsed = [uRefsUsed uRefs];
                            end
                        end

                        if app.AnimalsandEyesToUse{1,3}
                            eyeSel = 'LEye';
                            if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                toUseMis = 'M3DL_NystagCorr';
                                DataType = 'NystagCorr';
                            else
                                toUseMis = 'M3DL';
                                DataType = 'NonCorrected';
                            end
                        else
                            eyeSel = 'REye';
                            if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                toUseMis = 'M3DR_NystagCorr';
                                DataType = 'NystagCorr';
                            else
                                toUseMis = 'M3DR';
                                DataType = 'NonCorrected';
                            end
                        end
                        if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                            transformed = 'NONTRANSFORMED';
                        else
                            transformed = 'TRANSFORMED';
                        end
                        if app.DataTransparency.Value
                            transparencyTH = ['Thresh',num2str(app.TransparencyThreshold.Value),'_'];
                        else
                            transparencyTH = '';
                        end

                        if ~ldgFlg
                            hold(misalignPlot,'on');
                            if i == 1
                                [x,y,z]=sphere();
                                h=surf(misalignPlot,0.5*x,0.5*y,0.5*z);
                                set(h,'FaceColor','white')
                                axis(misalignPlot,'off')
                                % Align axis for viewing in 3D
                                axis(misalignPlot, 'vis3d')
                                hold(misalignPlot, 'on')

                                [a] = plot3vect([1.5;0;0],'',app.colorV.l_l,3,misalignPlot);
                                a.LineStyle = '--';
                                plot3(misalignPlot,1.5,0,0,'','MarkerSize',8,'LineWidth',3,'Color',app.colorV.l_l)

                                [b] = plot3vect([0;1.5;0],'',app.colorV.l_r,3,misalignPlot);
                                b.LineStyle = '--';
                                plot3(misalignPlot,0,1.5,0,'','MarkerSize',8,'LineWidth',3,'Color',app.colorV.l_r)

                                [c] = plot3vect([0;0;-1.5],'',app.colorV.l_z,3,misalignPlot);
                                c.LineStyle = '--';
                                plot3(misalignPlot,0,0,-1.5,'','MarkerSize',8,'LineWidth',3,'Color',app.colorV.l_z)
                                switch app.ViewOptionsDropDown.Value
                                    case 'Main View'
                                        view(misalignPlot,135,-15)
                                        SPHview = 'MainView';
                                    case 'Top View'
                                        view(misalignPlot,135,90)
                                        misalignPlot.XLim = [-0.8030 1.1970];
                                        misalignPlot.YLim = [-0.8287 1.1713];
                                        SPHview = 'TopView';
                                end
                            end
                                [color,refName,~,~,pureRot,canalColorL,canalColorR] = GetElecColorandNameByAnimal(app, uAns{1}, uStims(i));

                                if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                    mis = app.params(ids2U).(toUseMis);
%                                     a={app.params(ids2U).(toUseMis)};
%                                     aa=cellfun(@size,a,'uni',false);
%                                     ca = cell2mat(aa');
%                                     if app.PlottingStandardDeviationCheckBox.Value
%                                         std = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
%                                         %                                         std = [valsSTD]./sqrt([ca(:,2)']);
%                                         stType = 'SEM';
%                                     else
%                                         std = [app.params(ids2U).(toUseSMis)];
%                                         %                                         std = [valsSTD];
%                                         stType = 'STD';
%                                     end
                                    transformed = 'NONTRANSFORMED';
                                else
                                    mis = app.AnimalProperties.(uAns{1}).CLRZfromFLRZ*app.params(ids2U).(toUseMis)';
                                    mis = mis';
%                                     if app.PlottingStandardDeviationCheckBox.Value
%                                         std = [misValsSTD']./sqrt(tot');
%                                         stType = 'SEM';
%                                     else
%                                         std = [misValsSTD'];
%                                         stType = 'STD';
%                                     end
                                    transformed = 'TRANSFORMED';
                                end
                                sz = size(mis);
                                rpr = repmat(pureRot,sz(1),1);
                                % Recalculate Misalignment Values
                                misVals = acosd(dot(rpr,mis,2)./(vecnorm(rpr,2,2).*vecnorm(mis,2,2)));
                                if length(uRefs) == 1
                                    ['Stim',num2str(uStims(i)),'Ref',num2str(uRefs)]
                                else
                                    ['Stim',num2str(uStims(i)),'Ref',num2str(uRefs(i))]
                                end
                                misValsMean = mean(misVals)
                                misValsSTD = std(misVals)
                
                                % Create a unit vector 
                                unitVects = mis./vecnorm(mis,2,2);
                                % Find the mean vector
                                M = mean(unitVects);

                                [p] = plot3vect(M*1.25,'',canalColorL,3,misalignPlot);

                                % Find covariance matrix
                                C = cov(unitVects);
                                % Find the eigenvalue decomposition
                                [W,D]  = eig(C);
                                N = 1; % choose how many 'eigenvalues' to plot as your vector length
                                radii = N*sqrt(diag(D));
                                % generate surface for "unrotated" ellipsoid with the radii chosen above,
                                % centered at the origin
                                [xc,yc,zc] = ellipsoid(0,0,0,radii(1),radii(2),radii(3));

                                % Rotate this ellipsoid and center it around the mean value

                                % We use the Kronecker Tensor Product to rotate each surface
                                % mesh matrix from the 'ellipsoid' function about the
                                % corresponding component eigenvector

                                % Note, we also 'flatten' the ellispoid about the 'x' axis to
                                % create a 'disc'.
                                a = kron(W(:,1),xc-xc); 
                                b = kron(W(:,2),yc); 
                                c = kron(W(:,3),zc);
                                data = a+b+c; 
                                n = size(data,2);

                                % Center the rotated ellipsoid at the mean data vector
                                x = data(1:n,:)+(M(1)); 
                                y = data(n+1:2*n,:)+(M(2));
                                z = data(2*n+1:end,:)+(M(3));

                                N=200;
                                zerotemp = zeros(size(x,1),size(x,2));
                                %x
                                dx = x/(N-1);
                                X2 = repmat(dx,1,1,N);
                                X2(:,:,1) = zerotemp;
                                X2 = cumsum(X2,3);
                                %y
                                dy = y/(N-1);
                                Y2 = repmat(dy,1,1,N);
                                Y2(:,:,1) = zerotemp;
                                Y2 = cumsum(Y2,3);
                                %z
                                dz = z/(N-1);
                                Z2 = repmat(dz,1,1,N);
                                Z2(:,:,1) = zerotemp;
                                Z2 = cumsum(Z2,3);

                                for ll=1:N
                                    sc = surf(misalignPlot,X2(:,:,ll),Y2(:,:,ll),Z2(:,:,ll));
                                    set(sc,'FaceColor','none','FaceAlpha',0.1,'EdgeColor',canalColorL,'EdgeAlpha',0.1)
                                end

                                hold(misalignPlot,'off');
                        end
                    end
                end
                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end
                if ldgFlg
                    hold(misalignPlot,'on');
                    ldgLine = [ldgLine plot(misalignPlot,[0 1],[0 1],'color',app.colorV.l_z,'LineWidth',2.5)];
                    ldgLine = [ldgLine plot(misalignPlot,[0 1],[0 1],'color',app.colorV.l_r,'LineWidth',2.5)];
                    ldgLine = [ldgLine plot(misalignPlot,[0 1],[0 1],'color',app.colorV.l_l,'LineWidth',2.5)];

                    ldgLine = [ldgLine plot(misalignPlot,[0 1],[0 1],'color',app.colorV.l_z,'LineWidth',2.5,'LineStyle','--')];
                    ldgLine = [ldgLine plot(misalignPlot,[0 1],[0 1],'color',app.colorV.l_r,'LineWidth',2.5,'LineStyle','--')];
                    ldgLine = [ldgLine plot(misalignPlot,[0 1],[0 1],'color',app.colorV.l_l,'LineWidth',2.5,'LineStyle','--')];

                    hold(misalignPlot,'off');
                    ldgNames = [{''} {''} {''} {''} {''} {''}];
                    ldg = legend(misalignPlot,[ldgLine],[ldgNames],'Orientation','horizontal','AutoUpdate','off');
                    ldg.NumColumns = 3;
                    ldg.Position(1) = 0.5079;
                    ldg.Position(2) = 0.2987;
                    ldg.Box = 'off';
                    switch app.ViewOptionsDropDown.Value
                        case 'Main View'
                            view(misalignPlot,135,-15)
                            SPHview = 'MainView';
                        case 'Top View'
                            view(misalignPlot,135,90)
                            SPHview = 'TopView';
                    end
                end
                uStims = sort(uStimsUsed);
                uRefs = sort(uRefsUsed);
                app.FigureName = [uAns{1},'_',eyeSel,'_',SPHview,'_MisalignmentSphere_AverageResponseWithConeSTD_ByStim_',transformed,'_',strrep(num2str(uStims),' ',''),'_',strrep(num2str(uRefs),' ',''),'_',cUsed,'_',DataType];
            end
        end

        function [x, y] = selectDataPoints(~, ax)
            roi = drawpoint(ax);
            x = roi.Position(1);
            y = roi.Position(2);
        end

        function MaxvsDistanceByStim(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.MaxvsDistanceByStimTab;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                marg_w = [.05 .6];
            end
            uStims = unique(app.Stims);
            s = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uStims);
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = s;
            uRefs = unique(app.Refs,'stable');
            r = OrderElectrodesByAnimal(app, uAns{1}, uRefs);
            uRefs = r;
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            XV = [];
            YV = [];
            YM = [];
            canalVal = [];
            elecVal = [];
            anim = [];
            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);

            genLdg = 0;
            animalsUsed = [];
            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                magPlot = subtightplot(2,1,1,[0.05 0.000000000005],marg_h,marg_w,'Parent', loc);
                magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                misalignPlot = subtightplot(2,1,2,[0.05 0.000000000005],marg_h,marg_w,'Parent', loc);
                misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;

                if app.NoParameterizationCheckBox.Value
                    if ldgFlg
                        hold(magPlot,'on');
                        lineM = plot(magPlot,0,0,'color','k','LineWidth',4);
                        hold(magPlot,'off');
                        ldgNames = [ldgNames {'Gaussian Fit'}];
                        ldgLine = [ldgLine lineM];
                    end
                    paramet = 'GaussFit_';
                else
                    paramet = '';
                end

                if plotLoc == 1
                    app.MaxvsDistanceByStimTab.UserData = [];
                end
                ediffsV = [];
                ediffsM = [];
                lnClr = [];
                prevX = [];
                prevY = [];
                prevYM = [];
                prevCanal = 0;
                for i = uAns'
                    if isempty(animalsUsed)
                        animalsUsed = [animalsUsed i{1}];
                    else
                        animalsUsed = [animalsUsed,'-', i{1}];
                    end
                    for j = 1:length(uStims)
                        for k = 1:length(uRefs)
                            r = uRefs(k);
                            if ismember(i,'Opal')
                                if r == 11
                                    r = 13;
                                end
                            end
                            ids2U = GetDataInds(app, i{1}, uStims(j), r, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                            if ~isempty(ids2U)
                                e2U = find(ismember(app.AnimalsandEyesToUse(:,1),i));
                                if app.AnimalsandEyesToUse{e2U,3}
                                    eyeSel = 'LEye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagL_NystagCorr';
                                        toUseMag = 'MagL_NystagCorr';
                                        toUseSMag = 'stdMagL_NystagCorr';
                                        toUseMMis = 'meanMisalignL_NystagCorr';
                                        toUseMis = 'MisalignL_NystagCorr';
                                        toUseSMis = 'stdMisalignL_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagL';
                                        toUseMag = 'MagL';
                                        toUseSMag = 'stdMagL';
                                        toUseMMis = 'meanMisalignL';
                                        toUseMis = 'MisalignL';
                                        toUseSMis = 'stdMisalignL';
                                        DataType = 'NonCorrected';
                                    end
                                else
                                    eyeSel = 'REye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagR_NystagCorr';
                                        toUseMag = 'MagR_NystagCorr';
                                        toUseSMag = 'stdMagR_NystagCorr';
                                        toUseMMis = 'meanMisalignR_NystagCorr';
                                        toUseMis = 'MisalignR_NystagCorr';
                                        toUseSMis = 'stdMisalignR_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagR';
                                        toUseMag = 'MagR';
                                        toUseSMag = 'stdMagR';
                                        toUseMMis = 'meanMisalignR';
                                        toUseMis = 'MisalignR';
                                        toUseSMis = 'stdMisalignR';
                                        DataType = 'NonCorrected';
                                    end
                                end
                                if ~app.AnimalProperties.(i{1}).CorrectionLabel.Value
                                    transformed = 'NONTRANSFORMED';
                                else
                                    transformed = 'TRANSFORMED';
                                end

                                [color,refName,~,~,~,~] = GetElecColorandNameByAnimal(app, i{1}, uStims(j));

                                [~,~,mark,~,~,~,~] = GetElecColorandNameByAnimal(app, i{1}, r);
                                switch i{1}
                                    case 'Nancy'
                                        mark = '^';
                                end
                                if length(uAns) > 1
%                                     switch i{1}
%                                         case 'Nancy'
%                                             switch uStims(j,1)
%                                                 case {5 14}
%                                                     mark = '^';
%                                                 case {8 15}
%                                                     mark = 'v';
%                                             end
%                                         case 'Opal'
%                                             switch uStims(j,1)
%                                                 case {5 14}
%                                                     mark = 'd';
%                                                 case {8 15}
%                                                     mark = 's';
%                                             end
%                                     end
                                end

                                if ~ldgFlg                                
                                    hold(magPlot,'on');
                                    if any([app.params(ids2U).FacialNerve])
                                        FNI = find([app.params(ids2U).FacialNerve]);
                                        ids2U = ids2U(min(FNI)-1);
                                        velY = app.params(ids2U).(toUseMMag);
                                    else
                                        velY = app.params(ids2U(end)).(toUseMMag);
                                        ids2U = ids2U(end);
                                    end

                                    velY = app.params(ids2U).(toUseMMag);

                                    fel = find([app.AnimalProperties.(i{1}).LocationData.ElectrodeNumber]==uStims(j));
                                    X = app.AnimalProperties.(i{1}).LocationData(fel).ElectrodeDistance;
                                    if contains(app.AnimalProperties.(i{1}).LocationData(fel).ElectrodeName,'LA')
                                        canalVal = [canalVal 1];
                                    elseif contains(app.AnimalProperties.(i{1}).LocationData(fel).ElectrodeName,'LP')
                                        canalVal = [canalVal 2];
                                    elseif contains(app.AnimalProperties.(i{1}).LocationData(fel).ElectrodeName,'LH')
                                        canalVal = [canalVal 3];
                                    end
                                    elecVal = [elecVal uStims(j)];
                                    anim = [anim i];
                                    XV = [XV X];
                                    YV = [YV velY];
                                    pointsR = plot(magPlot,X,velY,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                    pointsR.UserData = [ids2U];
                                    if j == 1
                                        lnClr = color{1};
                                        prevX = X;
                                        prevY = velY;
                                    else
                                        if prevCanal == canalVal(end)
                                            st = color{1};
                                            ed = lnClr;
                                            x = [prevX X];
                                            y = [prevY velY];
                                            z = zeros(size(x));
                                            jt = [ed; st];
                                            CO = [];
                                            CO(:,:,1) = ones(2,length(x)).*jt(:,1)'; % red
                                            CO(:,:,2) = ones(2,length(x)).*jt(:,2)'; % green
                                            CO(:,:,3) = ones(2,length(x)).*jt(:,3)'; % blue
                                            surface(magPlot,[x;x],[y;y],[z;z],CO,'facecol','no','edgecol','interp','linew',3,'LineStyle','--');
                                        end
                                        prevY = velY;
                                    end
                                    hold(magPlot,'off');

                                    hold(misalignPlot,'on');
                                    if ~app.AnimalProperties.(i{1}).CorrectionLabel.Value
                                        velY = app.params(ids2U).(toUseMMis);
                                        transformed = 'NONTRANSFORMED';
                                    else
                                        [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U, eyeSel, i{1});
                                        velY = misValsMean;
                                        transformed = 'TRANSFORMED';
                                    end

                                    YM = [YM velY];
                                    pointsR = plot(misalignPlot,X,velY,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                    pointsR.UserData = [ids2U];
                                    if j == 1
                                        prevYM = velY;
                                    else
                                        if prevCanal == canalVal(end)
                                            st = color{1};
                                            ed = lnClr;
                                            x = [prevX X];
                                            y = [prevYM velY];
                                            z = zeros(size(x));
                                            jt = [ed; st];
                                            CO = [];
                                            CO(:,:,1) = ones(2,length(x)).*jt(:,1)'; % red
                                            CO(:,:,2) = ones(2,length(x)).*jt(:,2)'; % green
                                            CO(:,:,3) = ones(2,length(x)).*jt(:,3)'; % blue
                                            surface(misalignPlot,[x;x],[y;y],[z;z],CO,'facecol','no','edgecol','interp','linew',3,'LineStyle','--');
                                        end
                                        lnClr = color{1};
                                        prevX = X;
                                        prevYM = velY;
                                    end
                                    hold(misalignPlot,'off');
                                    prevCanal = canalVal(end);
                                else

                                    velX = 0;
                                    velY = 0;
                                    [color,refName,~,~,~,~] = GetElecColorandNameByAnimal(app, i{1}, uStims(j));
                                    [~,~,mark,~,~,~,~] = GetElecColorandNameByAnimal(app, i{1}, r);
                                    switch i{1}
                                        case 'Nancy'
                                            mark = '^';
                                    end
                                    hold(magPlot,'on');
                                    if (j == 1) && (k ==1)
                                        apm = plot(magPlot,0,0,'color','k','marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                        if isempty(ldgNames)
                                            ldgNames = [ldgNames {[i{1}]}];
                                            ldgLine = [ldgLine apm];
                                        elseif ~ismember(ldgNames,[i{1}])
                                            ldgNames = [ldgNames {[i{1}]}];
                                            ldgLine = [ldgLine apm];
                                        end
                                        
                                    end
                                    if ~ismember(ldgNames,[refName{1}])
                                            line = plot(magPlot,velX,velY,'color',color{1},'LineWidth',4);
                                            ldgNames = [ldgNames {[refName{1}]}];
                                            ldgLine = [ldgLine line];
                                    end
                                    
                                    hold(magPlot,'off');
                                    

                                end

                            end
                        end
                    end

                end
                if ~ldgFlg
%                     f1 = fit(XV',YV','gauss1')
%                     f2 = fit(XV',90-YM','gauss1')

                    % Gaussian function forced to have zero mean
                    meanval=sum(XV.*YV)/sum(YV);
                    sigma0=sqrt(sum(YV.*(XV-meanval).^2)/sum(YV));
                    CenteredGaussian=@(b,x)(b(1)*exp(-x.^2/(2*b(2)^2)));
                    sol=nlinfit(XV,YV,CenteredGaussian,[max(YV) sigma0]);

                    disp('Gaussian Fit: y = a*exp(-x.^2/(2*b^2))')
                    aVEL = sol(1)
                    bVEL = sol(2)

                    sxv = sort(XV);
                    y = sol(1)*exp(-sxv.^2/(2*sol(2)^2));
                    if app.NoParameterizationCheckBox.Value
                    hold(magPlot,'on')
%                     plot(magPlot,sort(XV),f1(sort(XV)),'color','k','LineWidth',4)
                    plot(magPlot,sxv,y,'color','k','LineWidth',4)
                    hold(magPlot,'off')
                    end

                    meanval=sum(XV.*(90-YM))/sum((90-YM));
                    sigma0=sqrt(sum((90-YM).*(XV-meanval).^2)/sum((90-YM)));
                    CenteredGaussian=@(b,x)(b(1)*exp(-x.^2/(2*b(2)^2)));
                    sol=nlinfit(XV,(90-YM),CenteredGaussian,[max((90-YM)) sigma0]);

                    disp('Gaussian Fit: y = 90-a*exp(-x.^2/(2*b^2))')
                    aMis = sol(1)
                    bMis = sol(2)

                    sxv = sort(XV);
                    y = 90-sol(1)*exp(-sxv.^2/(2*sol(2)^2));
                    if app.NoParameterizationCheckBox.Value
                        hold(misalignPlot,'on')
                        plot(misalignPlot,sxv,y,'color','k','LineWidth',4)
                        %                     plot(misalignPlot,sort(XV),90-f2(sort(XV)),'color','k','LineWidth',4)
                        hold(misalignPlot,'off')
                    end

                    nALL = length(XV)
                    disp('All Electrode Distance:')
                    [mALL, CIALL, ~] = CalcCI(app, XV, .95)
                    
                    distWIn1 = length(find(XV>-1 & XV<1))

                    disp('Electrode Distance By Best Electrode:')
                    nBest = find(ismember(anim,'Nancy') & ismember(elecVal,[6 3 7]));
                    oBest = find(ismember(anim,'Opal') & ismember(elecVal,[15 3 6]));
                    gBest = find(ismember(anim,'Gigi') & ismember(elecVal,[5 3 8]));
                    nBst = 9
                    mn = mean(XV([nBest oBest gBest]))
                    sdev = std(XV([nBest oBest gBest]))
                    [mBest, CIBest, ~] = CalcCI(app, XV([nBest oBest gBest]), .95)
                    fLA = [find(ismember(anim,'Nancy') & ismember(elecVal,[6 3 7]) & (canalVal == 1)) find(ismember(anim,'Opal') & ismember(elecVal,[15 3 6]) & (canalVal == 1)) find(ismember(anim,'Gigi') & ismember(elecVal,[5 3 8]) & (canalVal == 1))];
                    XV(fLA)
                    mLA = mean(XV(fLA))

                    fLP = [find(ismember(anim,'Nancy') & ismember(elecVal,[6 3 7]) & (canalVal == 2)) find(ismember(anim,'Opal') & ismember(elecVal,[15 3 6]) & (canalVal == 2)) find(ismember(anim,'Gigi') & ismember(elecVal,[5 3 8]) & (canalVal == 2))];
                    XV(fLP)
                    mLP = mean(XV(fLP))

                    fLH = [find(ismember(anim,'Nancy') & ismember(elecVal,[6 3 7]) & (canalVal == 3)) find(ismember(anim,'Opal') & ismember(elecVal,[15 3 6]) & (canalVal == 3)) find(ismember(anim,'Gigi') & ismember(elecVal,[5 3 8]) & (canalVal == 3))];
                    XV(fLH)
                    mLH = mean(XV(fLH))

                    disp('Electrode Distance By NEXT Best Electrode:')
                    nBest = find(ismember(anim,'Nancy') & ismember(elecVal,[5 2 15]));
                    oBest = find(ismember(anim,'Opal') & ismember(elecVal,[9 2 14]));
                    gBest = find(ismember(anim,'Gigi') & ismember(elecVal,[4 2 7]));
                    nNxtBest = 9
                    mn = mean(XV([nBest oBest gBest]))
                    sdev = std(XV([nBest oBest gBest]))
                    [mNxtBest, CINxtBest, ~] = CalcCI(app, XV([nBest oBest gBest]), .95)

                    disp('Electrode Distance Anterior Canal:')
                    fLA = find(canalVal == 1);
                    nLA = length(fLA)
                    [mLA, CILA, ~] = CalcCI(app, XV(fLA), .95)

                    disp('Electrode Distance Posterior Canal:')
                    fLP = find(canalVal == 2);
                    nLP = length(fLP)
                    [mLP, CILP, ~] = CalcCI(app, XV(fLP), .95)

                    disp('Electrode Distance Horizontal Canal:')
                    fLH = find(canalVal == 3);
                    nLH = length(fLH)
                    [mLH, CILH, ~] = CalcCI(app, XV(fLH), .95)
                end
                magPlot.YLabel.String = ['Velocity (deg/s)'];
                magPlot.YLabel.FontSize = 30;
                misalignPlot.YLabel.String = ['Misalignment (deg)'];
                misalignPlot.YLabel.FontSize = 30;

%                 magPlot.XLabel.String = {[name1{1},', Velocity (deg/s)']};

                magPlot.XGrid = 'on';
                magPlot.YGrid = 'on';

                magPlot.FontSize = 30;
                % magPlot.Title.String = [name1{1}, ' vs ', name2{1}];
                magPlot.LineWidth = 2.5;
                magPlot.Title.FontSize = 30;


                misalignPlot.XGrid = 'on';
                misalignPlot.YGrid = 'on';

                misalignPlot.FontSize = 30;
                misalignPlot.XLabel.String = {['Distance to Target (mm)']};
                misalignPlot.XLabel.FontSize = 30;
                misalignPlot.LineWidth = 2.5;
                if plotLoc ~= 1
                    if ~isempty(app.MaxvsDistanceByStimTab.UserData)
                        if isfield(app.MaxvsDistanceByStimTab.UserData,'MagPlot')
                            if ~isempty(app.MaxvsDistanceByStimTab.UserData.MagPlot)
                                magPlot.XLim = app.MaxvsDistanceByStimTab.UserData.MagPlot(1,:);
                                magPlot.YLim = app.MaxvsDistanceByStimTab.UserData.MagPlot(2,:);
                            end
                        end

                        if isfield(app.MaxvsDistanceByStimTab.UserData,'MisPlot')
                            if ~isempty(app.MaxvsDistanceByStimTab.UserData.MisPlot)
                                misalignPlot.XLim = app.MaxvsDistanceByStimTab.UserData.MisPlot(1,:);
                                misalignPlot.YLim = app.MaxvsDistanceByStimTab.UserData.MisPlot(2,:);
                            end
                        end
                    end
                end

                
                if ldgFlg
                    [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                    magPlot.Visible = 0;
                    misalignPlot.Visible = 0;
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position(1) = 0.3079;
                    ldg.Position(2) = 0.2987;
                    if length(ldgLine) > 1
                        ldg.Title.String = 'Stimulating Electrodes:';
                    else
                        ldg.Title.String = 'Stimulating Electrode:';
                    end
                end
                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end
                app.FigureName = [animalsUsed,'_',eyeSel,'_DistanceCompare','_',paramet,transformed,'_MisandVel_MaxCurrent_ByStim_',strrep(num2str(unique(uStims)),' ',''),'_',strrep(num2str(uRefs),' ',''),'_',cUsed,'_',DataType];


            end
        end
    
        function MaxvsDistanceByRef(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.MaxvsDistanceByRefTab;
                marg_h = [0.15 0.075];
                marg_w = [.1 .015];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                marg_w = [.05 .015];
            end
            uStims = unique(app.Stims);
            s = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uStims);
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = s;
            uRefs = unique(app.Refs,'stable');
            r = OrderElectrodesByAnimal(app, uAns{1}, uRefs);
            uRefs = r;
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            XV = [];
            YV = [];
            YM = [];
            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);

            genLdg = 0;
            animalsUsed = [];
            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                magPlot = subtightplot(2,1,1,[0.05 0.000000000005],marg_h,marg_w,'Parent', loc);
                magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                misalignPlot = subtightplot(2,1,2,[0.05 0.000000000005],marg_h,marg_w,'Parent', loc);
                misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;

%                 if ldgFlg
%                     hold(magPlot,'on');
%                     lineM = plot(magPlot,0,0,'color','k','LineWidth',4);
%                     hold(magPlot,'off');
%                     ldgNames = [ldgNames {'Gaussian Fit'}];
%                     ldgLine = [ldgLine lineM];
%                 end

                if plotLoc == 1
                    app.MaxvsDistanceByRefTab.UserData = [];
                end
                ediffsV = [];
                ediffsM = [];
                for i = uAns'
                    if isempty(animalsUsed)
                        animalsUsed = [animalsUsed i{1}];
                    else
                        animalsUsed = [animalsUsed,'-', i{1}];
                    end
                    for j = 1:length(uStims)
                        for k = 1:length(uRefs)
                            r = uRefs(k);
                            ids2U = GetDataInds(app, i{1}, uStims(j), r, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                            if ~isempty(ids2U)
                                e2U = find(ismember(app.AnimalsandEyesToUse(:,1),i));
                                if app.AnimalsandEyesToUse{e2U,3}
                                    eyeSel = 'LEye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagL_NystagCorr';
                                        toUseMag = 'MagL_NystagCorr';
                                        toUseSMag = 'stdMagL_NystagCorr';
                                        toUseMMis = 'meanMisalignL_NystagCorr';
                                        toUseMis = 'MisalignL_NystagCorr';
                                        toUseSMis = 'stdMisalignL_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagL';
                                        toUseMag = 'MagL';
                                        toUseSMag = 'stdMagL';
                                        toUseMMis = 'meanMisalignL';
                                        toUseMis = 'MisalignL';
                                        toUseSMis = 'stdMisalignL';
                                        DataType = 'NonCorrected';
                                    end
                                else
                                    eyeSel = 'REye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagR_NystagCorr';
                                        toUseMag = 'MagR_NystagCorr';
                                        toUseSMag = 'stdMagR_NystagCorr';
                                        toUseMMis = 'meanMisalignR_NystagCorr';
                                        toUseMis = 'MisalignR_NystagCorr';
                                        toUseSMis = 'stdMisalignR_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagR';
                                        toUseMag = 'MagR';
                                        toUseSMag = 'stdMagR';
                                        toUseMMis = 'meanMisalignR';
                                        toUseMis = 'MisalignR';
                                        toUseSMis = 'stdMisalignR';
                                        DataType = 'NonCorrected';
                                    end
                                end
                                if ~app.AnimalProperties.(i{1}).CorrectionLabel.Value
                                    transformed = 'NONTRANSFORMED';
                                else
                                    transformed = 'TRANSFORMED';
                                end

                                [color,refName,~,~,~,~] = GetElecColorandNameByAnimal(app, i{1}, uStims(j));

                                [~,~,mark,~,~,~,~] = GetElecColorandNameByAnimal(app, i{1}, r);
                                if length(uAns) > 1
%                                     switch i{1}
%                                         case 'Nancy'
%                                             switch uStims(j,1)
%                                                 case {5 14}
%                                                     mark = '^';
%                                                 case {8 15}
%                                                     mark = 'v';
%                                             end
%                                         case 'Opal'
%                                             switch uStims(j,1)
%                                                 case {5 14}
%                                                     mark = 'd';
%                                                 case {8 15}
%                                                     mark = 's';
%                                             end
%                                     end
                                end

                                if ~ldgFlg                                
                                    hold(magPlot,'on');
                                    if any([app.params(ids2U).FacialNerve])
                                        FNI = find([app.params(ids2U).FacialNerve]);
                                        ids2U = ids2U(min(FNI)-1);
                                        velY = app.params(ids2U).(toUseMMag);
                                    else
                                        velY = app.params(ids2U(end)).(toUseMMag);
                                        ids2U = ids2U(end);
                                    end

%                                     velY = app.params(ids2U).(toUseMMag);

                                    fel = find([app.AnimalProperties.(i{1}).LocationData.ElectrodeNumber]==r);
                                    X = app.AnimalProperties.(i{1}).LocationData(fel).ElectrodeDistance;
                                    XV = [XV X];
                                    YV = [YV velY];
                                    pointsR = plot(magPlot,X,velY,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                    pointsR.UserData = [ids2U];
                                    hold(magPlot,'off');

                                    hold(misalignPlot,'on');
                                    if ~app.AnimalProperties.(i{1}).CorrectionLabel.Value
                                        velY = app.params(ids2U).(toUseMMis);
                                        transformed = 'NONTRANSFORMED';
                                    else
                                        [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U, eyeSel, i{1});
                                        velY = misValsMean;
                                        transformed = 'TRANSFORMED';
                                    end

                                    YM = [YM velY];
                                    pointsR = plot(misalignPlot,X,velY,'color',color{1},'marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                    pointsR.UserData = [ids2U];

                                    hold(misalignPlot,'off');
                                else

                                    velX = 0;
                                    velY = 0;
                                    [color,stimName,~,~,~,~] = GetElecColorandNameByAnimal(app, i{1}, uStims(j));
                                    [~,refName,mark,~,~,~,~] = GetElecColorandNameByAnimal(app, i{1}, r);
                                    hold(magPlot,'on');
                                        na = [i{1},' - ',refName{1}];
                                        if isempty(ldgNames)
                                            apm = plot(magPlot,0,0,'color','k','marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                            ldgNames = [ldgNames {na}];
                                            ldgLine = [ldgLine apm];
                                        elseif ~ismember(ldgNames,na)
                                            apm = plot(magPlot,0,0,'color','k','marker',mark,'MarkerSize',20,'LineStyle','none','LineWidth',4);
                                            ldgNames = [ldgNames {na}];
                                            ldgLine = [ldgLine apm];
                                        end
                                        if ~ismember(ldgNames,[stimName{1}])
                                            line = plot(magPlot,velX,velY,'color',color{1},'LineWidth',4);
                                            ldgNames = [ldgNames {[stimName{1}]}];
                                            ldgLine = [ldgLine line];
                                        end
                                    
                                end

                            end
                        end
                    end

                end
%                 if ~ldgFlg
%                     f1 = fit(XV',YV','gauss1')
%                     f2 = fit(XV',90-YM','gauss1')
%                     hold(magPlot,'on')
%                     plot(magPlot,sort(XV),f1(sort(XV)),'color','k','LineWidth',4)
%                     hold(magPlot,'off')
% 
%                     hold(misalignPlot,'on')
%                     plot(misalignPlot,sort(XV),90-f2(sort(XV)),'color','k','LineWidth',4)
%                     hold(misalignPlot,'off')
%                 end
                magPlot.YLabel.String = ['Velocity (deg/s)'];
                magPlot.YLabel.FontSize = 30;
                misalignPlot.YLabel.String = ['Misalignment (deg)'];
                misalignPlot.YLabel.FontSize = 30;

%                 magPlot.XLabel.String = {[name1{1},', Velocity (deg/s)']};

                magPlot.XGrid = 'on';
                magPlot.YGrid = 'on';

                magPlot.FontSize = 30;
                % magPlot.Title.String = [name1{1}, ' vs ', name2{1}];
                magPlot.LineWidth = 2.5;
                magPlot.Title.FontSize = 30;


                misalignPlot.XGrid = 'on';
                misalignPlot.YGrid = 'on';

                misalignPlot.FontSize = 30;
                misalignPlot.XLabel.String = {['Distance to Target (mm)']};
                misalignPlot.XLabel.FontSize = 30;
                misalignPlot.LineWidth = 2.5;
                if plotLoc ~= 1
                    if ~isempty(app.MaxvsDistanceByStimTab.UserData)
                        if isfield(app.MaxvsDistanceByStimTab.UserData,'MagPlot')
                            if ~isempty(app.MaxvsDistanceByStimTab.UserData.MagPlot)
                                magPlot.XLim = app.MaxvsDistanceByStimTab.UserData.MagPlot(1,:);
                                magPlot.YLim = app.MaxvsDistanceByStimTab.UserData.MagPlot(2,:);
                            end
                        end

                        if isfield(app.MaxvsDistanceByStimTab.UserData,'MisPlot')
                            if ~isempty(app.MaxvsDistanceByStimTab.UserData.MisPlot)
                                misalignPlot.XLim = app.MaxvsDistanceByStimTab.UserData.MisPlot(1,:);
                                misalignPlot.YLim = app.MaxvsDistanceByStimTab.UserData.MisPlot(2,:);
                            end
                        end
                    end
                end

                
                if ldgFlg
                    [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                    magPlot.Visible = 0;
                    misalignPlot.Visible = 0;
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position(1) = 0.3079;
                    ldg.Position(2) = 0.2987;
                end
                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end
                app.FigureName = [animalsUsed,'_',eyeSel,'_DistanceCompare','_',transformed,'_MisandVel_MaxCurrent_ByRef_',strrep(num2str(unique(uStims)),' ',''),'_',strrep(num2str(uRefs),' ',''),'_',cUsed,'_',DataType];


            end
        end
    

        function MaxValsByStim(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.MaxPointCloudByStimTab;
                marg_h = [0.15 0.075];
                if sum(app.Canals)<3
                    marg_w = [.1 .5];
                else
                    marg_w = [.1 .2];
                end
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                if sum(app.Canals)<3
                marg_w = [.05 .5];
                else
                    marg_w = [.05 .3];
                end
            end
            uStims = unique(app.Stims);
            s = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uStims);
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = s;
            uRefs = unique(app.Refs,'stable');
            r = OrderElectrodesByAnimal(app, uAns{1}, uRefs);
            uRefs = r;
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];

            uStimsUsed = [];
            uRefsUsed = [];
            
            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
            jitterAM = [0 .4 -.2 .8 -.16 -.4 -.12 .16 -.8 .2 .12];
            
            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                for ii = 1:sum(app.Canals)
                    canalPos = find(app.Canals);
                    canalPos = canalPos(ii);
                    magPlot = subtightplot(2,sum(app.Canals),ii,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot = subtightplot(2,sum(app.Canals),ii+sum(app.Canals),[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    findStims = ismember(app.AnimalProperties.(uAns{1}).elecByCanal{canalPos},[uStims]);
                    uS = app.AnimalProperties.(uAns{1}).elecByCanal{canalPos}(findStims);
                    
                    uS = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uS);
                    if ismember(uAns(1),{'Gigi'})
                        if all(ismember(uS,[4 5 6]))
                            uS = [4 5 14 6];
                        elseif all(ismember(uS,[7 8 9]))
                            uS = [9 8 15 7];
                        end
                    end
                    if (ii == 1) && (plotLoc == 1)
                        app.MaxPointCloudByStimTab.UserData = [];
                    end
                    StimNames = [];
                    dataUsed = struct();
                    avgVel = [];
                    for i = 1:length(uS)
                        
                        if ismember(uAns(1),{'Gigi'})
                            if any(ismember(uS(i),[14 15]))
                                name = {''};
                            else
                                [~,name,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uS(i));
                            end
                        else
                            [~,name,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uS(i));
                        end
                        

                        StimNames = [StimNames name];
                        uRused = 0;
                        for j = 1:length(uRefs)
                            ids2U = GetDataInds(app, uAns{1}, uS(i), uRefs(j), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                            
                            if ~isempty(ids2U)
                                uRused = uRused + 1;
                                if isempty(uStimsUsed)
                                    uStimsUsed = [uStimsUsed uS(i)];
                                else
                                    if ~any(ismember(uStimsUsed,uS(i)))
                                        uStimsUsed = [uStimsUsed uS(i)];
                                    end
                                end
                                if isempty(uRefsUsed)
                                    uRefsUsed = [uRefsUsed uRefs(j)];
                                else
                                    if ~any(ismember(uRefsUsed,uRefs(j)))
                                        uRefsUsed = [uRefsUsed uRefs(j)];
                                    end
                                end

                                if app.AnimalsandEyesToUse{1,3}
                                    eyeSel = 'LEye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagL_NystagCorr';
                                        toUseMag = 'MagL_NystagCorr';
                                        toUseSMag = 'stdMagL_NystagCorr';
                                        toUseMMis = 'meanMisalignL_NystagCorr';
                                        toUseMis = 'MisalignL_NystagCorr';
                                        toUseSMis = 'stdMisalignL_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagL';
                                        toUseMag = 'MagL';
                                        toUseSMag = 'stdMagL';
                                        toUseMMis = 'meanMisalignL';
                                        toUseMis = 'MisalignL';
                                        toUseSMis = 'stdMisalignL';
                                        DataType = 'NonCorrected';
                                    end
                                else
                                    eyeSel = 'REye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagR_NystagCorr';
                                        toUseMag = 'MagR_NystagCorr';
                                        toUseSMag = 'stdMagR_NystagCorr';
                                        toUseMMis = 'meanMisalignR_NystagCorr';
                                        toUseMis = 'MisalignR_NystagCorr';
                                        toUseSMis = 'stdMisalignR_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagR';
                                        toUseMag = 'MagR';
                                        toUseSMag = 'stdMagR';
                                        toUseMMis = 'meanMisalignR';
                                        toUseMis = 'MisalignR';
                                        toUseSMis = 'stdMisalignR';
                                        DataType = 'NonCorrected';
                                    end
                                end
                                if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                    transformed = 'NONTRANSFORMED';
                                else
                                    transformed = 'TRANSFORMED';
                                end
                                if app.DataTransparency.Value
                                    transparencyTH = ['Thresh',num2str(app.TransparencyThreshold.Value),'_'];
                                else
                                    transparencyTH = '';
                                end

                                hold(magPlot,'on');

                                [color,refName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uRefs(j));
                                
                                if ~ldgFlg
                                    if ~issorted([app.params(ids2U).p1amp])
                                        [samp, saI] = sort([app.params(ids2U).p1amp]);
                                        ids2U = ids2U(saI);
                                    end

                                    if any([app.params(ids2U).FacialNerve])
                                        FNI = find([app.params(ids2U).FacialNerve]);
                                        ids2U = ids2U(min(FNI)-1);
                                        vel = app.params(ids2U).(toUseMMag);
                                    else
                                        vel = app.params(ids2U(end)).(toUseMMag);
                                        ids2U = ids2U(end);
                                    end

                                    amp = 10*i;

                                    a={app.params(ids2U).(toUseMag)};
                                    aa=cellfun(@size,a,'uni',false);
                                    ca = cell2mat(aa');
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [app.params(ids2U).(toUseSMag)]./sqrt([ca(:,2)']);
                                        stType = 'SEM';
                                    else
                                        std = [app.params(ids2U).(toUseSMag)];
                                        stType = 'STD';
                                    end
                                    avgVel = [avgVel vel];
                                    erPlot = errorbar(magPlot,amp+jitterAM(uRused),vel,std,'color',color{1},'LineWidth',4,'CapSize',12,'marker','o','MarkerSize',20);
                                    erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                    erPlot.UserData = ids2U;
                                else
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        stType = 'SEM';
                                    else
                                        stType = 'STD';
                                    end
                                    amp = [0];
                                    vel = [0];
                                    std = [0];
                                    line = plot(magPlot,amp,vel,'color',color{1},'LineWidth',4);
                                    if isempty(ldgNames)
                                        ldgNames = [ldgNames {[refName{1}]}];
                                        ldgLine = [ldgLine line];
                                    else
                                        if ~ismember(ldgNames,[refName{1}])
                                            ldgNames = [ldgNames {[refName{1}]}];
                                            ldgLine = [ldgLine line];
                                        end
                                    end
                                end


                                hold(magPlot,'off');

                                if ~ldgFlg
                                    hold(misalignPlot,'on');
                                    [color,refName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{1}, uRefs(j));

                                    amp = 10*i;

                                    if ~app.AnimalProperties.(uAns{1}).CorrectionLabel.Value
                                        mis = [app.params(ids2U).(toUseMMis)];
                                        a={app.params(ids2U).(toUseMis)};
                                        aa=cellfun(@size,a,'uni',false);
                                        ca = cell2mat(aa');
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            std = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
                                            %                                         std = [valsSTD]./sqrt([ca(:,2)']);
                                            stType = 'SEM';
                                        else
                                            std = [app.params(ids2U).(toUseSMis)];
                                            %                                         std = [valsSTD];
                                            stType = 'STD';
                                        end
                                        transformed = 'NONTRANSFORMED';
                                    else
                                        [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U, eyeSel, uAns{1});
                                        mis = misValsMean';
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            std = [misValsSTD']./sqrt(tot');
                                            stType = 'SEM';
                                        else
                                            std = [misValsSTD'];
                                            stType = 'STD';
                                        end
                                        transformed = 'TRANSFORMED';
                                    end

                                    erPlot = errorbar(misalignPlot,amp+jitterAM(uRused),mis,std,'color',color{1},'LineWidth',4,'CapSize',12,'marker','o','MarkerSize',20);
                                    erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                    erPlot.UserData = ids2U;

                                    hold(misalignPlot,'off');

                                    rn = strrep(refName{1},', ','');
                                    rn = strrep(rn,' ','');
                                    if isfield(dataUsed,rn)
                                        dataUsed.(rn).X = [dataUsed.(rn).X amp+jitterAM(uRused)];
                                        dataUsed.(rn).YV = [dataUsed.(rn).YV vel];
                                        dataUsed.(rn).YM = [dataUsed.(rn).YM mis];
                                    else
                                        dataUsed.(rn).X = [amp+jitterAM(uRused)];
                                        dataUsed.(rn).YV = [vel];
                                        dataUsed.(rn).YM = [mis];
                                        dataUsed.(rn).COLOR = color{1};
                                    end
                                end
                                

                                ids = [ids; {ids2U}];
                            end

                        end
                        disp(['Average Velocity using Stim ',num2str(uS(i)),': ',num2str(mean(avgVel))])
                        avgVel = [];
                        
                    end
                    
                    if ii == 1
                        magPlot.YLabel.String = 'Velocity (dps)';
                        misalignPlot.YLabel.String = 'Misalignment (dps)';
                    else
                        magPlot.YTickLabel = [];
                        misalignPlot.YTickLabel = [];
                    end
                    magPlot.YLabel.FontSize = 30;

                    misalignPlot.YLabel.FontSize = 30;
                    magPlot.XTick = [10:10:(10*(length(uS)))];
                    magPlot.XTickLabel = [];
                    %                     magPlot.XTick = [5 up1a];
                    magPlot.XGrid = 'on';
                    magPlot.YGrid = 'on';
                    magPlot.XLim = [5 (10*(length(uS)))+5];

                    magPlot.FontSize = 30;
                    magPlot.Title.String = 'Max Values';
                    magPlot.LineWidth = 2.5;
                    magPlot.Title.FontSize = 30;

                    %                     misalignPlot.XTick = [up1a(1)-25 up1a];
                    misalignPlot.XGrid = 'on';
                    misalignPlot.YGrid = 'on';
                    misalignPlot.XTick = [10:10:(10*(length(uS)))];

                    misalignPlot.XLim = [5 (10*(length(uS)))+5];
                    misalignPlot.FontSize = 30;
%                     misalignPlot.XLabel.String = {'Max Current (uA)'};
                    misalignPlot.XLabel.FontSize = 30;
                    misalignPlot.LineWidth = 2.5;

                    if plotLoc ~= 1
                        if ~isempty(app.MaxPointCloudByStimTab.UserData)
                            if isfield(app.MaxPointCloudByStimTab.UserData,'MagPlot')
                                if ~isempty(app.MaxPointCloudByStimTab.UserData.MagPlot)
                                    %                                         magPlot.XLim = app.MaxPointCloudByStimTab.UserData.MagPlot(1,:);
                                    magPlot.YLim = app.MaxPointCloudByStimTab.UserData.MagPlot(2,:);
                                end
                            end

                            if isfield(app.MaxPointCloudByStimTab.UserData,'MisPlot')
                                if ~isempty(app.MaxPointCloudByStimTab.UserData.MisPlot)
                                    %                                         misalignPlot.XLim = app.MaxPointCloudByStimTab.UserData.MisPlot(1,:);
                                    misalignPlot.YLim = app.MaxPointCloudByStimTab.UserData.MisPlot(2,:);
                                end
                            end
                        end
                    end

                    magPlots = [magPlots magPlot];
                    misalignPlots = [misalignPlots misalignPlot];

                    if ldgFlg
                        magPlot.Visible = 0;
                        misalignPlot.Visible = 0;
                    end
                    if ~ldgFlg
                    alphaVal = .45;
                    lw = 2.5;
                    fs = fields(dataUsed);
                    for i = fs'
                        hold(magPlot,'on')
                        line  = patchline(dataUsed.(i{1}).X,dataUsed.(i{1}).YV,'Parent',magPlot,'linestyle','--','edgecolor',dataUsed.(i{1}).COLOR,'linewidth',lw,'edgealpha',alphaVal);
                        uistack(line,"bottom");
                        hold(magPlot,'off')
                        hold(misalignPlot,'on')
                        line  = patchline(dataUsed.(i{1}).X,dataUsed.(i{1}).YM,'Parent',misalignPlot,'linestyle','--','edgecolor',dataUsed.(i{1}).COLOR,'linewidth',lw,'edgealpha',alphaVal);
                        uistack(line,"bottom");
                        hold(misalignPlot,'off')
                    end
                    end
                    misalignPlot.XTickLabel = StimNames;
                end
                

                sameaxes('y',[magPlots])
                sameaxes('y',[misalignPlots])


                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end


                if ~isempty(allTwitch)
                    ldgLine = [ldgLine allTwitch(1)];
                    ldgNames = [ldgNames {'Facial Twitch Observed'}];
                end
                
                if ldgFlg
                    [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                    if length(ldgLine) > 1
                        ldg.Title.String = 'Return Electrodes:';
                    else
                        ldg.Title.String = 'Return Electrode:';
                    end
                end
                uStims = sort(uStimsUsed);
                uRefs = sort(uRefsUsed);
                app.FigureName = [uAns{1},'_',eyeSel,'_MaxMisandVel_ByStim_',transformed,'_',transparencyTH,stType,'_',strrep(num2str(uStims),' ',''),'_',strrep(num2str(uRefs),' ',''),'_',cUsed,'_',DataType];
            end
        end

        function MaxValsByRef(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.MaxPointCloudByRefTab;
                marg_h = [0.15 0.075];
                if sum(app.Canals)<3
                    marg_w = [.1 .5];
                else
                    marg_w = [.1 .2];
                end
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                if sum(app.Canals)<3
                marg_w = [.05 .5];
                else
                    marg_w = [.05 .3];
                end
            end
            uStims = unique(app.Stims);
            s = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uStims);
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = s;
            uRefs = unique(app.Refs,'stable');
            r = OrderElectrodesByAnimal(app, uAns{1}, uRefs);
            uRefs = r;
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];

            uStimsUsed = [];
            uRefsUsed = [];
            animalsUsed = [];

            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
            jitterAM = [0 .4 -2 .8 -.16 -.4 -.12 .16 -.8 2 .12];
            
            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                for ii = 1:length(uAns)
                    e2U = find(ismember(app.AnimalsandEyesToUse(:,1),uAns(ii)));
                    if app.AnimalsandEyesToUse{e2U,3}
                        e2U = 'LE';
                    else
                        e2U = 'RE';
                    end
                    if isempty(animalsUsed)

                        animalsUsed = [animalsUsed [uAns{ii},e2U]];
                    else
                        animalsUsed = [animalsUsed,'-', [uAns{ii},e2U]];
                    end
                    magPlot = subtightplot(2,length(uAns),ii,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot = subtightplot(2,length(uAns),ii+length(uAns),[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    if (ii == 1) && (plotLoc == 1)
                        app.MaxPointCloudByRefTab.UserData = [];
                    end
                    RefNames = [];
                    dataUsed = struct();
                    if ismember(uAns(1),{'Gigi'})
                        if all(ismember(uRefs,[4 5 6 11]))
                            uRefs = [4 5 14 6 11];
                        elseif all(ismember(uRefs,[7 8 9 11]))
                            uRefs = [9 8 15 7 11];
                        end
                    end
                    avgVel = [];
                    for i = 1:length(uRefs)
                        [~,name,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{ii}, uRefs(i));
                        RefNames = [RefNames name];
                        uSused = 0;
                        for j = 1:length(uStims)
                            ids2U = GetDataInds(app, uAns{ii}, uStims(j), uRefs(i), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                            
                            if ~isempty(ids2U)
                                uSused = uSused + 1;
                                if isempty(uStimsUsed)
                                    uStimsUsed = [uStimsUsed uStims(j)];
                                else
                                    if ~any(ismember(uStimsUsed,uStims(j)))
                                        uStimsUsed = [uStimsUsed uStims(j)];
                                    end
                                end
                                if isempty(uRefsUsed)
                                    uRefsUsed = [uRefsUsed uRefs(i)];
                                else
                                    if ~any(ismember(uRefsUsed,uRefs(i)))
                                        uRefsUsed = [uRefsUsed uRefs(i)];
                                    end
                                end

                                e2U = find(ismember(app.AnimalsandEyesToUse(:,1),uAns(ii)));
                                if app.AnimalsandEyesToUse{e2U,3}
                                    eyeSel = 'LEye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagL_NystagCorr';
                                        toUseMag = 'MagL_NystagCorr';
                                        toUseSMag = 'stdMagL_NystagCorr';
                                        toUseMMis = 'meanMisalignL_NystagCorr';
                                        toUseMis = 'MisalignL_NystagCorr';
                                        toUseSMis = 'stdMisalignL_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagL';
                                        toUseMag = 'MagL';
                                        toUseSMag = 'stdMagL';
                                        toUseMMis = 'meanMisalignL';
                                        toUseMis = 'MisalignL';
                                        toUseSMis = 'stdMisalignL';
                                        DataType = 'NonCorrected';
                                    end
                                else
                                    eyeSel = 'REye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagR_NystagCorr';
                                        toUseMag = 'MagR_NystagCorr';
                                        toUseSMag = 'stdMagR_NystagCorr';
                                        toUseMMis = 'meanMisalignR_NystagCorr';
                                        toUseMis = 'MisalignR_NystagCorr';
                                        toUseSMis = 'stdMisalignR_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagR';
                                        toUseMag = 'MagR';
                                        toUseSMag = 'stdMagR';
                                        toUseMMis = 'meanMisalignR';
                                        toUseMis = 'MisalignR';
                                        toUseSMis = 'stdMisalignR';
                                        DataType = 'NonCorrected';
                                    end
                                end
                                if ~app.AnimalProperties.(uAns{ii}).CorrectionLabel.Value
                                    transformed = 'NONTRANSFORMED';
                                else
                                    transformed = 'TRANSFORMED';
                                end
                                if app.DataTransparency.Value
                                    transparencyTH = ['Thresh',num2str(app.TransparencyThreshold.Value),'_'];
                                else
                                    transparencyTH = '';
                                end

                                hold(magPlot,'on');

                                [color,stimName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{ii}, uStims(j));
                                
                                if ~ldgFlg
                                    if ~issorted([app.params(ids2U).p1amp])
                                        [samp, saI] = sort([app.params(ids2U).p1amp]);
                                        ids2U = ids2U(saI);
                                    end

                                    if any([app.params(ids2U).FacialNerve])
                                        FNI = find([app.params(ids2U).FacialNerve]);
                                        ids2U = ids2U(min(FNI)-1);
                                        vel = app.params(ids2U).(toUseMMag);
                                    else
                                        vel = app.params(ids2U(end)).(toUseMMag);
                                        ids2U = ids2U(end);
                                    end

                                    amp = 10*i;

                                    a={app.params(ids2U).(toUseMag)};
                                    aa=cellfun(@size,a,'uni',false);
                                    ca = cell2mat(aa');
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [app.params(ids2U).(toUseSMag)]./sqrt([ca(:,2)']);
                                        stType = 'SEM';
                                    else
                                        std = [app.params(ids2U).(toUseSMag)];
                                        stType = 'STD';
                                    end
                                    avgVel = [avgVel vel];
                                    erPlot = errorbar(magPlot,amp+jitterAM(uSused),vel,std,'color',color{1},'LineWidth',4,'CapSize',12,'marker','o','MarkerSize',20);
                                    erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                    erPlot.UserData = ids2U;
                                else
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        stType = 'SEM';
                                    else
                                        stType = 'STD';
                                    end
                                    amp = [0];
                                    vel = [0];
                                    std = [0];
                                    line = plot(magPlot,amp,vel,'color',color{1},'LineWidth',4);
                                    if isempty(ldgNames)
                                        ldgNames = [ldgNames {[stimName{1}]}];
                                        ldgLine = [ldgLine line];
                                    else
                                        if ~ismember(ldgNames,[stimName{1}])
                                            ldgNames = [ldgNames {[stimName{1}]}];
                                            ldgLine = [ldgLine line];
                                        end
                                    end
                                end


                                hold(magPlot,'off');

                                if ~ldgFlg
                                    hold(misalignPlot,'on');
                                    [color,stimName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{ii}, uStims(j));

                                    amp = 10*i;

                                    if ~app.AnimalProperties.(uAns{ii}).CorrectionLabel.Value
                                        mis = [app.params(ids2U).(toUseMMis)];
                                        a={app.params(ids2U).(toUseMis)};
                                        aa=cellfun(@size,a,'uni',false);
                                        ca = cell2mat(aa');
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            std = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
                                            %                                         std = [valsSTD]./sqrt([ca(:,2)']);
                                            stType = 'SEM';
                                        else
                                            std = [app.params(ids2U).(toUseSMis)];
                                            %                                         std = [valsSTD];
                                            stType = 'STD';
                                        end
                                        transformed = 'NONTRANSFORMED';
                                    else
                                        [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U, eyeSel, uAns{ii});
                                        mis = misValsMean';
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            std = [misValsSTD']./sqrt(tot');
                                            stType = 'SEM';
                                        else
                                            std = [misValsSTD'];
                                            stType = 'STD';
                                        end
                                        transformed = 'TRANSFORMED';
                                    end

                                    erPlot = errorbar(misalignPlot,amp+jitterAM(uSused),mis,std,'color',color{1},'LineWidth',4,'CapSize',12,'marker','o','MarkerSize',20);
                                    erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                    erPlot.UserData = ids2U;

                                    hold(misalignPlot,'off');

                                    rn = strrep(stimName{1},', ','');
                                    rn = strrep(rn,' ','');
                                    if isfield(dataUsed,rn)
                                        dataUsed.(rn).X = [dataUsed.(rn).X amp+jitterAM(uSused)];
                                        dataUsed.(rn).YV = [dataUsed.(rn).YV vel];
                                        dataUsed.(rn).YM = [dataUsed.(rn).YM mis];
                                    else
                                        dataUsed.(rn).X = [amp+jitterAM(uSused)];
                                        dataUsed.(rn).YV = [vel];
                                        dataUsed.(rn).YM = [mis];
                                        dataUsed.(rn).COLOR = color{1};
                                    end
                                end
                                

                                ids = [ids; {ids2U}];
                            end

                        end

                        if ii == 1
                        magPlot.YLabel.String = 'Velocity (dps)';
                        misalignPlot.YLabel.String = 'Misalignment (dps)';
                        else
                            magPlot.YTickLabel = [];
                            misalignPlot.YTickLabel = [];
                        end
                        magPlot.YLabel.FontSize = 30;
                        
                        misalignPlot.YLabel.FontSize = 30;
                        magPlot.XTick = [10:10:(10*(length(uRefs)))];
                        magPlot.XTickLabel = [];
                        %                     magPlot.XTick = [5 up1a];
                        magPlot.XGrid = 'on';
                        magPlot.YGrid = 'on';
                        magPlot.XLim = [5 (10*(length(uRefs)))+5];

                        magPlot.FontSize = 30;
                        magPlot.Title.String = ['Max Values, ',uAns{ii}];
                        magPlot.LineWidth = 2.5;
                        magPlot.Title.FontSize = 30;

                        %                     misalignPlot.XTick = [up1a(1)-25 up1a];
                        misalignPlot.XGrid = 'on';
                        misalignPlot.YGrid = 'on';
                        misalignPlot.XTick = [10:10:(10*(length(uRefs)))];

                        misalignPlot.XLim = [5 (10*(length(uRefs)))+5];
                        misalignPlot.FontSize = 30;
%                         misalignPlot.XLabel.String = {'Max Current (uA)'};
                        misalignPlot.XLabel.FontSize = 30;
                        misalignPlot.LineWidth = 2.5;

                        if plotLoc ~= 1
                            if ~isempty(app.MaxPointCloudByRefTab.UserData)
                                if isfield(app.MaxPointCloudByRefTab.UserData,'MagPlot')
                                    if ~isempty(app.MaxPointCloudByRefTab.UserData.MagPlot)
%                                         magPlot.XLim = app.MaxPointCloudByRefTab.UserData.MagPlot(1,:);
                                        magPlot.YLim = app.MaxPointCloudByRefTab.UserData.MagPlot(2,:);
                                    end
                                end

                                if isfield(app.MaxPointCloudByRefTab.UserData,'MisPlot')
                                    if ~isempty(app.MaxPointCloudByRefTab.UserData.MisPlot)
%                                         misalignPlot.XLim = app.MaxPointCloudByRefTab.UserData.MisPlot(1,:);
                                        misalignPlot.YLim = app.MaxPointCloudByRefTab.UserData.MisPlot(2,:);
                                    end
                                end
                            end
                        end

                        magPlots = [magPlots magPlot];
                        misalignPlots = [misalignPlots misalignPlot];

                        if ldgFlg
                            magPlot.Visible = 0;
                            misalignPlot.Visible = 0;
                        end
                        disp(['Average Velocity using Ref ',num2str(uRefs(i)),': ',num2str(mean(avgVel))])
                        avgVel = [];
                    end
                    if ~ldgFlg
                    alphaVal = .45;
                    lw = 2.5;
                    fs = fields(dataUsed);
                    for i = fs'
                        hold(magPlot,'on')
                        line  = patchline(dataUsed.(i{1}).X,dataUsed.(i{1}).YV,'Parent',magPlot,'linestyle','--','edgecolor',dataUsed.(i{1}).COLOR,'linewidth',lw,'edgealpha',alphaVal);
                        uistack(line,"bottom");
                        hold(magPlot,'off')
                        hold(misalignPlot,'on')
                        line  = patchline(dataUsed.(i{1}).X,dataUsed.(i{1}).YM,'Parent',misalignPlot,'linestyle','--','edgecolor',dataUsed.(i{1}).COLOR,'linewidth',lw,'edgealpha',alphaVal);
                        uistack(line,"bottom");
                        hold(misalignPlot,'off')
                    end
                    end
                    misalignPlot.XTickLabel = RefNames;
                end
                

                sameaxes('y',[magPlots])
                sameaxes('y',[misalignPlots])


                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end


                if ~isempty(allTwitch)
                    ldgLine = [ldgLine allTwitch(1)];
                    ldgNames = [ldgNames {'Facial Twitch Observed'}];
                end
                
                if ldgFlg
                    [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                    if length(ldgLine) > 1
                        ldg.Title.String = 'Stimulating Electrodes:';
                    else
                        ldg.Title.String = 'Stimulating Electrode:';
                    end
                end
                uStims = sort(uStimsUsed);
                uRefs = sort(uRefsUsed);
                app.FigureName = [animalsUsed,'_MaxMisandVel_ByRef_',transformed,'_',transparencyTH,stType,'_',strrep(num2str(uRefs),' ',''),'_',strrep(num2str(uStims),' ',''),'_',cUsed,'_',DataType];
            end
        end

        function MaxValsByRefByCanal(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.MaxPointCloudByRefTab;
                marg_h = [0.15 0.075];
                if sum(app.Canals)<3
                    marg_w = [.1 .5];
                else
                    marg_w = [.1 .2];
                end
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                if sum(app.Canals)<3
                marg_w = [.05 .5];
                else
                    marg_w = [.05 .3];
                end
            end
            uStims = unique(app.Stims);
            s = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uStims);
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = s;
            uRefs = unique(app.Refs,'stable');
            r = OrderElectrodesByAnimal(app, uAns{1}, uRefs);
            uRefs = r;
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];

            uStimsUsed = [];
            uRefsUsed = [];
            animalsUsed = [];

            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
            jitterAM = [0 .4 -2 .8 -.16 -.4 -.12 .16 -.8 2 .12];
            
            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                for jj = 1:sum(app.Canals)
                    canalPos = find(app.Canals);
                    canalPos = canalPos(jj);
                    magPlot = subtightplot(2,sum(app.Canals),jj,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    misalignPlot = subtightplot(2,sum(app.Canals),jj+sum(app.Canals),[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                    misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                    uR = uRefs(~ismember(uRefs,[app.AnimalProperties.(uAns{1}).elecByCanal{1:3}]));
                    findRefs = ismember(app.AnimalProperties.(uAns{1}).elecByCanal{canalPos},[uRefs]);
                    uR = [app.AnimalProperties.(uAns{1}).elecByCanal{canalPos}(findRefs) uR];
                    
                    uR = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uR);

                    findStims = ismember(app.AnimalProperties.(uAns{1}).elecByCanal{canalPos},[uStims]);
                    uS = app.AnimalProperties.(uAns{1}).elecByCanal{canalPos}(findStims);
                    
                    uS = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uS);
                for ii = 1:length(uAns)
                    e2U = find(ismember(app.AnimalsandEyesToUse(:,1),uAns(ii)));
                    if app.AnimalsandEyesToUse{e2U,3}
                        e2U = 'LE';
                    else
                        e2U = 'RE';
                    end
                    if isempty(animalsUsed)
                        animalsUsed = [animalsUsed [uAns{ii},e2U]];
                    else
                        if ~ismember({animalsUsed},[uAns{ii},e2U])
                            animalsUsed = [animalsUsed,'-', [uAns{ii},e2U]];
                        end
                    end

                    if (ii == 1) && (plotLoc == 1)
                        app.MaxPointCloudByRefTab.UserData = [];
                    end
                    RefNames = [];
                    dataUsed = struct();
                    if ismember(uAns(1),{'Gigi'})
                        if all(ismember(uR,[7 8 11]))
                            uR = [8 20 11];
                        end
                        if all(ismember(uR,[7 8 10]))
                            uR = [8 20 10];
                        end
                        if all(ismember([4 5 6 10 11],uR))
                            uR = [4 5 14 6 10 11];
                        elseif all(ismember([4 5 6 11],uR))
                            uR = [4 5 14 6 11];
                        elseif all(ismember([7 8 9 10 11],uR))
                            uR = [9 8 15 7 10 11];
                        elseif all(ismember([7 8 9 11],uR))
                            uR = [9 8 15 7 11];
                        end
                    end
                    if ismember(uAns(1),{'Nancy'})
                        if all(ismember(uR,[7 8 11]))
                            uR = [20 7 11];
                        end
                        if all(ismember(uR,[7 8 10]))
                            uR = [20 7 10];
                        end
                    end
                    if ismember(uAns(1),{'Opal'})
                        if all(ismember(uR,[5 6 13]))
                            uR = [20 6 13];
                        end
                        if all(ismember(uR,[5 6 10]))
                            uR = [20 6 10];
                        end
                    end
                    for i = 1:length(uR)
                        [~,name,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{ii}, uR(i));
                        RefNames = [RefNames name];
                        uSused = 0;
                        for j = 1:length(uS)
                            ids2U = GetDataInds(app, uAns{ii}, uS(j), uR(i), app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
                            
                            if ~isempty(ids2U)
                                uSused = uSused + 1;
                                if isempty(uStimsUsed)
                                    uStimsUsed = [uStimsUsed uS(j)];
                                else
                                    if ~any(ismember(uStimsUsed,uS(j)))
                                        uStimsUsed = [uStimsUsed uS(j)];
                                    end
                                end
                                if isempty(uRefsUsed)
                                    uRefsUsed = [uRefsUsed uR(i)];
                                else
                                    if ~any(ismember(uRefsUsed,uR(i)))
                                        uRefsUsed = [uRefsUsed uR(i)];
                                    end
                                end

                                e2U = find(ismember(app.AnimalsandEyesToUse(:,1),uAns(ii)));
                                if app.AnimalsandEyesToUse{e2U,3}
                                    eyeSel = 'LEye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagL_NystagCorr';
                                        toUseMag = 'MagL_NystagCorr';
                                        toUseSMag = 'stdMagL_NystagCorr';
                                        toUseMMis = 'meanMisalignL_NystagCorr';
                                        toUseMis = 'MisalignL_NystagCorr';
                                        toUseSMis = 'stdMisalignL_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagL';
                                        toUseMag = 'MagL';
                                        toUseSMag = 'stdMagL';
                                        toUseMMis = 'meanMisalignL';
                                        toUseMis = 'MisalignL';
                                        toUseSMis = 'stdMisalignL';
                                        DataType = 'NonCorrected';
                                    end
                                else
                                    eyeSel = 'REye';
                                    if app.PlottingNystagmusCorrectedDataCheckBox.Value
                                        toUseMMag = 'meanMagR_NystagCorr';
                                        toUseMag = 'MagR_NystagCorr';
                                        toUseSMag = 'stdMagR_NystagCorr';
                                        toUseMMis = 'meanMisalignR_NystagCorr';
                                        toUseMis = 'MisalignR_NystagCorr';
                                        toUseSMis = 'stdMisalignR_NystagCorr';
                                        DataType = 'NystagCorr';
                                    else
                                        toUseMMag = 'meanMagR';
                                        toUseMag = 'MagR';
                                        toUseSMag = 'stdMagR';
                                        toUseMMis = 'meanMisalignR';
                                        toUseMis = 'MisalignR';
                                        toUseSMis = 'stdMisalignR';
                                        DataType = 'NonCorrected';
                                    end
                                end
                                if ~app.AnimalProperties.(uAns{ii}).CorrectionLabel.Value
                                    transformed = 'NONTRANSFORMED';
                                else
                                    transformed = 'TRANSFORMED';
                                end
                                if app.DataTransparency.Value
                                    transparencyTH = ['Thresh',num2str(app.TransparencyThreshold.Value),'_'];
                                else
                                    transparencyTH = '';
                                end

                                hold(magPlot,'on');

                                [color,stimName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{ii}, uS(j));
                                
                                if ~ldgFlg
                                    if ~issorted([app.params(ids2U).p1amp])
                                        [samp, saI] = sort([app.params(ids2U).p1amp]);
                                        ids2U = ids2U(saI);
                                    end

                                    if any([app.params(ids2U).FacialNerve])
                                        FNI = find([app.params(ids2U).FacialNerve]);
                                        ids2U = ids2U(min(FNI)-1);
                                        vel = app.params(ids2U).(toUseMMag);
                                    else
                                        vel = app.params(ids2U(end)).(toUseMMag);
                                        ids2U = ids2U(end);
                                    end

                                    amp = 10*i;

                                    a={app.params(ids2U).(toUseMag)};
                                    aa=cellfun(@size,a,'uni',false);
                                    ca = cell2mat(aa');
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        std = [app.params(ids2U).(toUseSMag)]./sqrt([ca(:,2)']);
                                        stType = 'SEM';
                                    else
                                        std = [app.params(ids2U).(toUseSMag)];
                                        stType = 'STD';
                                    end
                                    erPlot = errorbar(magPlot,amp+jitterAM(uSused),vel,std,'color',color{1},'LineWidth',4,'CapSize',12,'marker','o','MarkerSize',20);
                                    erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                    erPlot.UserData = ids2U;
                                else
                                    if app.PlottingStandardDeviationCheckBox.Value
                                        stType = 'SEM';
                                    else
                                        stType = 'STD';
                                    end
                                    amp = [0];
                                    vel = [0];
                                    std = [0];
                                    line = plot(magPlot,amp,vel,'color',color{1},'LineWidth',4);
                                    if isempty(ldgNames)
                                        ldgNames = [ldgNames {[stimName{1}]}];
                                        ldgLine = [ldgLine line];
                                    else
                                        if ~ismember(ldgNames,[stimName{1}])
                                            ldgNames = [ldgNames {[stimName{1}]}];
                                            ldgLine = [ldgLine line];
                                        end
                                    end
                                end


                                hold(magPlot,'off');

                                if ~ldgFlg
                                    hold(misalignPlot,'on');
                                    [color,stimName,~,~,~,~,~] = GetElecColorandNameByAnimal(app, uAns{ii}, uS(j));

                                    amp = 10*i;

                                    if ~app.AnimalProperties.(uAns{ii}).CorrectionLabel.Value
                                        mis = [app.params(ids2U).(toUseMMis)];
                                        a={app.params(ids2U).(toUseMis)};
                                        aa=cellfun(@size,a,'uni',false);
                                        ca = cell2mat(aa');
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            std = [app.params(ids2U).(toUseSMis)]./sqrt([ca(:,2)']);
                                            %                                         std = [valsSTD]./sqrt([ca(:,2)']);
                                            stType = 'SEM';
                                        else
                                            std = [app.params(ids2U).(toUseSMis)];
                                            %                                         std = [valsSTD];
                                            stType = 'STD';
                                        end
                                        transformed = 'NONTRANSFORMED';
                                    else
                                        [magValsMean, magValsSTD, misValsMean, misValsSTD, tot] = ApplyRotationMatrix(app, ids2U, eyeSel, uAns{ii});
                                        mis = misValsMean';
                                        if app.PlottingStandardDeviationCheckBox.Value
                                            std = [misValsSTD']./sqrt(tot');
                                            stType = 'SEM';
                                        else
                                            std = [misValsSTD'];
                                            stType = 'STD';
                                        end
                                        transformed = 'TRANSFORMED';
                                    end

                                    erPlot = errorbar(misalignPlot,amp+jitterAM(uSused),mis,std,'color',color{1},'LineWidth',4,'CapSize',12,'marker','o','MarkerSize',20);
                                    erPlot.ButtonDownFcn = @(source,event) app.UIFigureWindowButtonDown;
                                    erPlot.UserData = ids2U;

                                    hold(misalignPlot,'off');

                                    rn = strrep(stimName{1},', ','');
                                    rn = strrep(rn,' ','');
                                    if isfield(dataUsed,rn)
                                        dataUsed.(rn).X = [dataUsed.(rn).X amp+jitterAM(uSused)];
                                        dataUsed.(rn).YV = [dataUsed.(rn).YV vel];
                                        dataUsed.(rn).YM = [dataUsed.(rn).YM mis];
                                    else
                                        dataUsed.(rn).X = [amp+jitterAM(uSused)];
                                        dataUsed.(rn).YV = [vel];
                                        dataUsed.(rn).YM = [mis];
                                        dataUsed.(rn).COLOR = color{1};
                                    end
                                end
                                

                                ids = [ids; {ids2U}];
                            end

                        end

                        
                    end
                    if jj == 1
                            magPlot.YLabel.String = 'Velocity (dps)';
                            misalignPlot.YLabel.String = 'Misalignment (dps)';
                        else
                            magPlot.YTickLabel = [];
                            misalignPlot.YTickLabel = [];
                        end
                        magPlot.YLabel.FontSize = 30;
                        
                        misalignPlot.YLabel.FontSize = 30;
                        magPlot.XTick = [10:10:(10*(length(uR)))];
                        magPlot.XTickLabel = [];
                        %                     magPlot.XTick = [5 up1a];
                        magPlot.XGrid = 'on';
                        magPlot.YGrid = 'on';
                        magPlot.XLim = [5 (10*(length(uR)))+5];

                        magPlot.FontSize = 30;
                        magPlot.Title.String = ['Max Values, ',uAns{ii}];
                        magPlot.LineWidth = 2.5;
                        magPlot.Title.FontSize = 30;

                        %                     misalignPlot.XTick = [up1a(1)-25 up1a];
                        misalignPlot.XGrid = 'on';
                        misalignPlot.YGrid = 'on';
                        misalignPlot.XTick = [10:10:(10*(length(uR)))];

                        misalignPlot.XLim = [5 (10*(length(uR)))+5];
                        misalignPlot.FontSize = 30;
%                         misalignPlot.XLabel.String = {'Max Current (uA)'};
                        misalignPlot.XLabel.FontSize = 30;
                        misalignPlot.LineWidth = 2.5;

                        if plotLoc ~= 1
                            if ~isempty(app.MaxPointCloudByRefTab.UserData)
                                if isfield(app.MaxPointCloudByRefTab.UserData,'MagPlot')
                                    if ~isempty(app.MaxPointCloudByRefTab.UserData.MagPlot)
%                                         magPlot.XLim = app.MaxPointCloudByRefTab.UserData.MagPlot(1,:);
                                        magPlot.YLim = app.MaxPointCloudByRefTab.UserData.MagPlot(2,:);
                                    end
                                end

                                if isfield(app.MaxPointCloudByRefTab.UserData,'MisPlot')
                                    if ~isempty(app.MaxPointCloudByRefTab.UserData.MisPlot)
%                                         misalignPlot.XLim = app.MaxPointCloudByRefTab.UserData.MisPlot(1,:);
                                        misalignPlot.YLim = app.MaxPointCloudByRefTab.UserData.MisPlot(2,:);
                                    end
                                end
                            end
                        end

                        magPlots = [magPlots magPlot];
                        misalignPlots = [misalignPlots misalignPlot];

                        if ldgFlg
                            magPlot.Visible = 0;
                            misalignPlot.Visible = 0;
                        end
                    if ~ldgFlg
                    alphaVal = .45;
                    lw = 2.5;
                    fs = fields(dataUsed);
                    for i = fs'
                        hold(magPlot,'on')
                        line  = patchline(dataUsed.(i{1}).X,dataUsed.(i{1}).YV,'Parent',magPlot,'linestyle','--','edgecolor',dataUsed.(i{1}).COLOR,'linewidth',lw,'edgealpha',alphaVal);
                        uistack(line,"bottom");
                        hold(magPlot,'off')
                        hold(misalignPlot,'on')
                        line  = patchline(dataUsed.(i{1}).X,dataUsed.(i{1}).YM,'Parent',misalignPlot,'linestyle','--','edgecolor',dataUsed.(i{1}).COLOR,'linewidth',lw,'edgealpha',alphaVal);
                        uistack(line,"bottom");
                        hold(misalignPlot,'off')
                    end
                    end
                    misalignPlot.XTickLabel = RefNames;
                end
                end
                

                sameaxes('y',[magPlots])
                sameaxes('y',[misalignPlots])


                if sum(app.Canals)==1
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    elseif app.Canals(2) == 1
                        cUsed = 'RALP';
                    else
                        cUsed = 'LHRH';
                    end
                else
                    cUsed = [];
                    if app.Canals(1) == 1
                        cUsed = 'LARP';
                    end
                    if app.Canals(2) == 1
                        cUsed = [cUsed,'-RALP'];
                    end
                    if app.Canals(3) == 1
                        cUsed = [cUsed,'-LHRH'];
                    end
                end


                if ~isempty(allTwitch)
                    ldgLine = [ldgLine allTwitch(1)];
                    ldgNames = [ldgNames {'Facial Twitch Observed'}];
                end
                
                if ldgFlg
                    [ldgLine,ldgNames] = SortLegendData(app,ldgLine,ldgNames);
                    ldg = legend(magPlot,[ldgLine],[ldgNames],'Orientation','vertical','AutoUpdate','off');
                    ldg.Position = [0.3079 0.2987 0.1744 0.2214];
                    if length(ldgLine) > 1
                        ldg.Title.String = 'Stimulating Electrodes:';
                    else
                        ldg.Title.String = 'Stimulating Electrode:';
                    end
                end
                uStims = sort(uStimsUsed);
                uRefs = sort(uRefsUsed);
                app.FigureName = [animalsUsed,'_MaxMisandVel_ByRef_',transformed,'_',stType,'_',strrep(num2str(uRefs),' ',''),'_',strrep(num2str(uStims),' ',''),'_',cUsed,'_',DataType];
            end
        end

        function SvMvDStimMeanDiffPlot(app, plotLoc, ldgFlg)
            if plotLoc == 1
                loc = app.SvMvDStimMeanDiff;
                marg_h = [0.15 0.075];
                    marg_w = [.1 .6];
            else
                loc = app.SaveFigHandle;
                marg_h = [0.10 0.075];
                marg_w = [.05 .7];
            end
            magPlot = subtightplot(2,1,1,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
            magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
            misalignPlot = subtightplot(2,1,2,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
            misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
            v = [44.2 125.2 145.8 21 81.4 79.1 30.7 60.9 72.8];
            vsd = [23.4 47.9 30.1 10.8 7.2 19.1 23 42.3 70.3];
            m = [-1.6 -3.8 2.5 6.1 -15.1 -11.7 -0.3 0.42 -7];
            msd = [2.5 7.2 4.4 1.5 7.9 2.4 5.9 3.8 11.8];

            magPlot = subtightplot(2,1,1,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
            magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
            misalignPlot = subtightplot(2,1,2,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
            misalignPlot.ButtonDownFcn = @(source,event) app.changeAXLim;

            amp = 10:10:length(v)*10;
            cl = [107 107 107]/255;
            hold(magPlot,'on')
            erPlot = errorbar(magPlot,amp,v,vsd,'color',cl,'LineWidth',4,'CapSize',12,'marker','o','MarkerSize',20);
            hold(magPlot,'off')
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center')

            app.AnimalProperties.Nancy.elecByCanal = { [4:6 14] [1:3] [7:9 15] [10] [11:13]};
            app.AnimalProperties.Nancy.RootDirectory = 'R:\Morris, Brian\Monkey Data\Nancy\';
            app.AnimalProperties.Nancy.RingXYZreCoord = ... % note the apostrophe to transpose rows to columns; reCoord means re image system gantry coords
                                                        [-0.0196192	0.999762	-0.00953597;
                                                         -0.999273	 -0.0192961	 0.03287;
                                                          0.0326782	0.0101739	 0.999414]'; % transposed for consistency with C:\Users\cdell\OneDrive - Johns Hopkins\VNEL1DRV\Manuscripts-VNEL1DRV\Morris1-StimRefPosn\  3DSlicerAnalaysisForeeVORCanalVsFieldCoilAlignment.xlsx
            app.AnimalProperties.Nancy.LeftCanalLRZreCoord = ... % note the apostrophe to transpose rows to columns; reCoord means re image system gantry coords
                                                             [ 0.73676	  0.608407	-0.295002;
                                                              -0.664495	0.732182	-0.149518;
                                                               0.125028	0.306186	 0.943726]';
            app.AnimalProperties.Nancy.CLRZfromFXYZ = app.AnimalProperties.Nancy.LeftCanalLRZreCoord\app.AnimalProperties.Nancy.RingXYZreCoord; % x = A\b is computed differently than x = inv(A)*b and is recommended for solving systems of linear equations
            app.AnimalProperties.Nancy.CLRZfromFLRZ = app.AnimalProperties.Nancy.CLRZfromFXYZ*rotZ3deg(-45);
            app.AnimalProperties.Nancy.Implant = 'Left';

            app.AnimalProperties.Gigi.elecByCanal = { [4:6] [1:3] [7:9] [10] [11]};
            app.AnimalProperties.Gigi.RootDirectory = 'R:\Morris, Brian\Monkey Data\GiGi\';
            app.AnimalProperties.Gigi.RingXYZreCoord = ... % note the apostrophe to transpose rows to columns; reCoord means re image system coords
                                                       [-0.207131	 0.978284	-0.00756488; % +X yellow Gigi head ring X (unlike Nancy CT)
                                                        -0.967023	 -0.205906	 -0.149897;   %+Y (neated from A green in ARS coords) for Gigi head ring Y
                                                        -0.148199	-0.0237328	 0.988673]'; % red for Gigi head ring Z
            app.AnimalProperties.Gigi.LeftCanalLRZreCoord = ... % note the apostrophe to transpose rows to columns; re_G mean re image system coords
                                                            [ 0.676684	0.734721	 0.0477903; % green +LA
                                                             -0.726268	0.676745	-0.120628;  % yellow +LP
                                                             -0.12097	0.0469186	 0.991547]';% red +Z
            app.AnimalProperties.Gigi.CLRZfromFXYZ = app.AnimalProperties.Gigi.LeftCanalLRZreCoord\app.AnimalProperties.Gigi.RingXYZreCoord; % x = A\b is computed differently than x = inv(A)*b and is recommended for solving systems of linear equations
            app.AnimalProperties.Gigi.CLRZfromFLRZ = app.AnimalProperties.Gigi.CLRZfromFXYZ*rotZ3deg(-45);

            app.AnimalProperties.Gigi.Implant = 'Left';

            app.AnimalProperties.Opal.elecByCanal = { [7:9 15] [1:3] [4:6 14] [10] [11:13 16:18]};
            app.AnimalProperties.Opal.RootDirectory = 'R:\Morris, Brian\Monkey Data\Opal\';
            app.AnimalProperties.Opal.RingXYZreCoord = ... % note the apostrophe to transpose rows to columns; re_G means re image system gantry coords
                                                       [-0.950912	 0.294825   0.0940482;
                                                        -0.2935    -0.955551   0.027941;
                                                         0.0981056	-0.0010337	0.995175]'; 
            app.AnimalProperties.Opal.LeftCanalLRZreCoord = ... % note the apostrophe to transpose rows to columns; re_G means re image system gantry coords  
                                                            [-0.573072	 0.818776	-0.0345426;
                                                             -0.809681	-0.559193	 0.178101;
                                                              0.126509	 0.130033	 0.983406]'; 
            app.AnimalProperties.Opal.CLRZfromFXYZ = app.AnimalProperties.Opal.LeftCanalLRZreCoord\app.AnimalProperties.Opal.RingXYZreCoord; % x = A\b is computed differently than x = inv(A)*b and is recommended for solving systems of linear equations
            app.AnimalProperties.Opal.CLRZfromFLRZ = app.AnimalProperties.Opal.CLRZfromFXYZ*rotZ3deg(-45);
            app.AnimalProperties.Opal.Implant = 'Left';

            maxY = app.ProgressBar.Position;
            axes(app.ProgressBar)
            app.PBarObj = rectangle(app.ProgressBar,'Position',[0,0,0,maxY(4)],'FaceColor','g');
            app.PBarTxt = text(app.ProgressBar,482,maxY(4)/2,['Waiting']);
            app.Options.Visible = 0;
            app.OptionTxt.Visible = 0;
            warning('off', 'MATLAB:appdesigner:appdesigner:SaveObjWarning');

            app.colorV.l_x = [237,150,33]/255;
            app.colorV.l_y = [125,46,143]/255;
            app.colorV.l_z = [1 0 0];
            app.colorV.l_l = [0,128,0]/255;
            app.colorV.l_r = [0 0 1];
            app.colorV.r_x = [237,204,33]/255;
            app.colorV.r_y = [125,46,230]/255;
            app.colorV.r_z = [255,0,255]/255;
            app.colorV.r_l = [0 1 0];
            app.colorV.r_r = [64,224,208]/255;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.params = [];
            app.ElectrodeMeasurementsButton.BackgroundColor = [0.96,0.96,0.96];
            clearTabs(app);
            DeleteAndEBtnGroups(app)
            app.LarpCombs.Items = {'Listbox'};
            app.RalpCombs.Items = {'Listbox'};
            app.LhrhCombs.Items = {'Listbox'};
            app.P1D.Items = {'Listbox'};
            app.P2D.Items = {'Listbox'};
            app.P1A.Items = {'Listbox'};
            app.P2A.Items = {'Listbox'};
            app.IPG.Items = {'Listbox'};
            app.NormBy.Items = {'Listbox'};
            app.Options.Items = {'Listbox'};
            app.Options.Visible = 0;
            app.OptionTxt.Visible = 0;

            if ~isempty(app.CycleDirectoryText.Value)
                app.ProcessButton.BackgroundColor = 'y';
                app = plotCond(app);
                dataPresent = 0;
                for i = app.animal
                    dir = app.AnimalProperties.(i{1}).RootDirectory;
                    if isfile([dir,'ElectrodeLocationData.mat'])
                        load([dir,'ElectrodeLocationData.mat'],'LocationData')
                        app.AnimalProperties.(i{1}).LocationData = LocationData;
                        dataPresent = dataPresent + 1;
                        app.ElectrodeMeasurementsButton.BackgroundColor = 'g';
                    end
                end
                if dataPresent == length(app.animal)
                    app.ElectrodeMeasurementsButton.BackgroundColor = 'g';
                elseif dataPresent == 0
                    app.ElectrodeMeasurementsButton.BackgroundColor = 'r';
                else
                    app.ElectrodeMeasurementsButton.BackgroundColor = 'y';
                end
                app.ProcessButton.BackgroundColor = 'g';
                pause(.5)
                app.ProcessButton.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Button pushed function: CycleParamsDirectoryButton
        function CycleParamsDirectoryButtonPushed(app, event)
            clearTabs(app);

            app.params = [];

            app.Options.Visible = 0;
            app.OptionTxt.Visible = 0;

            app.cycDir = uigetdir(path,'Choose directory of cycle files');
            if ~(app.cycDir==0)
            cd(app.cycDir)
            app.listing = dir(app.cycDir);
            app.CycleDirectoryText.Value = app.cycDir;
            app.ProcessButton.BackgroundColor = 'g';
            else
                app.cycDir = app.CycleDirectoryText.Value;
            end
        end

        % Button pushed function: PlotSelectedButton
        function PlotSelectedButtonPushed(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            clearTabs(app);
            if ismember(selectedTab.Title,[{'Compare Pairs, By Stim, Amp as X'} {'Max Compare Pairs, By Stim'} {'Compare Pairs, By Ref, Amp as X'} {'Max Compare Pairs, By Ref'}])
                app.Options.Visible = 1;
                app.OptionTxt.Visible = 1;
            else
                app.Options.Visible = 0;
                app.OptionTxt.Visible = 0;
            end
            if ismember(app.PlotSelectedButton.Text,{'Continue'})
                uiresume(app.UIFigure);
            else
                app.PullCombInfo()
                sz = size(app.AnimalsandEyesToUse);
                if ~isempty(app.AnimalsandEyesToUse)
                    if sz(1) == 1
                        switch app.TabGroup.SelectedTab.Title
                            case 'By Stim, Amp As X'
                                plotByStimCurrentAsX(app,1,0)
                            case 'By Ref, Amp As X'
                                plotByRefCurrentAsX(app,1,0)
                            case 'By Ref and Canal, Amp as X'
                                plotByRefandCanalCurrentAsX(app,1,0)
                            case 'Compare Pairs, By Stim, Amp as X'
                                GetPossiblePairsStims(app)
                                app.Options.BackgroundColor = 'y';
                                app.PlotSelectedButton.Text = 'Continue';
                                app.PlotSelectedButton.BackgroundColor = 'y';
                                uiwait(app.UIFigure);
                                app.Options.BackgroundColor = [1 1 1];
                                app.PlotSelectedButton.Text = 'Plot Selected';
                                app.PlotSelectedButton.BackgroundColor = [0.96 0.96 0.96];
                                ComparePairsByStim(app, 1, 0)
                            case 'Max Compare Pairs, By Stim'
                                GetPossiblePairsStims(app)
                                app.Options.BackgroundColor = 'y';
                                app.PlotSelectedButton.Text = 'Continue';
                                app.PlotSelectedButton.BackgroundColor = 'y';
                                uiwait(app.UIFigure);
                                app.Options.BackgroundColor = [1 1 1];
                                app.PlotSelectedButton.Text = 'Plot Selected';
                                app.PlotSelectedButton.BackgroundColor = [0.96 0.96 0.96];
                                if app.AllOnSameAxisButton_4.Value
                                    ComparePairsByStimSameAxisMax(app, 1, 0)
                                else
                                    ComparePairsByStimMax(app, 1, 0)
                                end
                                
                            case 'Compare Pairs, By Ref, Amp as X'
                                GetPossiblePairsRefs(app)
                                app.Options.BackgroundColor = 'y';
                                app.PlotSelectedButton.Text = 'Continue';
                                app.PlotSelectedButton.BackgroundColor = 'y';
                                uiwait(app.UIFigure);
                                app.Options.BackgroundColor = [1 1 1];
                                app.PlotSelectedButton.Text = 'Plot Selected';
                                app.PlotSelectedButton.BackgroundColor = [0.96 0.96 0.96];
                                if app.AllOnSameAxisButton.Value
                                    ComparePairsByRefSameAxis(app, 1, 0)
                                else
                                    ComparePairsByRef(app, 1, 0)
                                end
                            case 'Max Compare Pairs, By Ref'
                                GetPossiblePairsRefs(app)
                                app.Options.BackgroundColor = 'y';
                                app.PlotSelectedButton.Text = 'Continue';
                                app.PlotSelectedButton.BackgroundColor = 'y';
                                uiwait(app.UIFigure);
                                app.Options.BackgroundColor = [1 1 1];
                                app.PlotSelectedButton.Text = 'Plot Selected';
                                app.PlotSelectedButton.BackgroundColor = [0.96 0.96 0.96];
                                if app.AllOnSameAxisButton_2.Value
                                    ComparePairsByRefSameAxisMax(app, 1, 0)
                                else
                                    ComparePairsByRefMax(app, 1, 0)
                                end
                            case 'Surf Plots, By Stim, IPG and PD'
                                SurfPlotByStimIPGandSPD(app, 1, 0)
                            case 'Average Surf, By Ref, IPG and PD'
                                if any(contains(app.NormBy.Items,'Anodic'))
                                    AverageSurfPlotByRefIPGandFPDAvC(app, 1, 0)
                                else
                                    AverageSurfPlotByRefIPGandSPD(app, 1, 0)
                                end
                            case 'Compare Standard Across Time'
                                PlotStandardAcrossTime(app, 1, 0)
                            case 'Compare AvC Standards'
                                PlotAvCStandards(app, 1, 0)
                            case 'Plot All Standards'
                                PlotAllStandards(app, 1, 0)
                            case 'Norm data, By PD, IPG as X'
                                PlotNormByIPGJUSTAVG(app, 1, 0)
                            case 'Cycle Average'
                                PlotCycleAverage(app, 1, 0)
                            case 'Sphere Plot'
                                Plot3DMisalignment(app, 1, 0)
                            case 'Max vs Distance, By Stim'
                                MaxvsDistanceByStim(app, 1, 0)
                            case 'Max Point Cloud, By Stim'
                                MaxValsByStim(app, 1, 0)
                            case 'Max Point Cloud, By Ref'
                                if app.PlotByCanalButton.Value
                                    MaxValsByRefByCanal(app, 1, 0)
                                else
                                    MaxValsByRef(app, 1, 0)
                                end
                        end
                    else
                        switch app.TabGroup.SelectedTab.Title
                            case 'By Stim, Amp As X'
                            case 'By Ref, Amp As X'
                            case 'By Ref and Canal, Amp as X'
                            case 'Compare Pairs, By Stim, Amp as X'
                            case 'Max Compare Pairs, By Stim'
                                GetPossiblePairsStims(app)
                                app.Options.BackgroundColor = 'y';
                                app.PlotSelectedButton.Text = 'Continue';
                                app.PlotSelectedButton.BackgroundColor = 'y';
                                uiwait(app.UIFigure);
                                app.Options.BackgroundColor = [1 1 1];
                                app.PlotSelectedButton.Text = 'Plot Selected';
                                app.PlotSelectedButton.BackgroundColor = [0.96 0.96 0.96];
                                if app.AllOnSameAxisButton_4.Value
                                    ComparePairsByStimSameAxisMax(app, 1, 0)
                                else
                                    ComparePairsByStimMax(app, 1, 0)
                                end
                                
                            case 'Compare Pairs, By Ref, Amp as X'

                            case 'Max Compare Pairs, By Ref'
                                GetPossiblePairsRefs(app)
                                app.Options.BackgroundColor = 'y';
                                app.PlotSelectedButton.Text = 'Continue';
                                app.PlotSelectedButton.BackgroundColor = 'y';
                                uiwait(app.UIFigure);
                                app.Options.BackgroundColor = [1 1 1];
                                app.PlotSelectedButton.Text = 'Plot Selected';
                                app.PlotSelectedButton.BackgroundColor = [0.96 0.96 0.96];
                                if app.AllOnSameAxisButton_2.Value
                                    ComparePairsByRefSameAxisMax(app, 1, 0)
                                else
%                                     ComparePairsByRefMax(app, 1, 0)
                                end
                            case 'Surf Plots, By Stim, IPG and PD'
                            case 'Average Surf, By Ref, IPG and PD'

                            case 'Compare Standard Across Time'
                            case 'Plot All Standards'
                            case 'Norm data, By PD, IPG as X'
                            case 'Max vs Distance, By Stim'
                                MaxvsDistanceByStim(app, 1, 0)
                            case 'Max vs Distance, By Ref'
                                MaxvsDistanceByRef(app, 1, 0)
                            case 'Max Point Cloud, By Ref'
                                if app.PlotByCanalButton.Value
                                    MaxValsByRefByCanal(app, 1, 0)
                                else
                                    MaxValsByRef(app, 1, 0)
                                end
                        end
                    end
                else
                    f = msgbox("Insufficient Data Selected to Plot! Please Choose Animal(s) and Eye(s)","Error","error");
                pause(1.5);
                delete(f);
                end
            end
        end

        % Button pushed function: SaveFigureandLegendButton
        function SaveFigureandLegendButtonPushed(app, event)
            close all
            if isempty(app.FigureDirectoryText.Value)
                FigureDirectoryButtonPushed(app, event)
            end
            app.SaveFigureandLegendButton.BackgroundColor = 'y';
            app.SaveFigHandle = figure('units','normalized','outerposition',[0 0 1 1]);
            app.SaveFigHandle.WindowState = 'maximized';
            app.SaveFigHandle.Visible = 0;
            f = app.SaveFigHandle;
            sz = size(app.AnimalsandEyesToUse);
            if ~isempty(app.AnimalsandEyesToUse)
                if sz(1) == 1
                    for i = 0:1
                        switch app.TabGroup.SelectedTab.Title
                            case 'By Stim, Amp As X'
                                plotByStimCurrentAsX(app,2,i)
                                sname = app.FigureName;
                            case 'By Ref, Amp As X'
                                plotByRefCurrentAsX(app,2,i)
                                sname = app.FigureName;
                            case 'By Ref and Canal, Amp as X'
                                plotByRefandCanalCurrentAsX(app,2,i)
                                sname = app.FigureName;
                            case 'Compare Pairs, By Stim, Amp as X'
                                ComparePairsByStim(app,2,i)
                                sname = app.FigureName;
                            case 'Max Compare Pairs, By Stim'
                                ComparePairsByStimMax(app,2,i)
                                sname = app.FigureName;
                            case 'Compare Pairs, By Ref, Amp as X'
                                if app.AllOnSameAxisButton.Value
                                    ComparePairsByRefSameAxis(app, 2, i)
                                else
                                    ComparePairsByRef(app, 2, i)
                                end
                                sname = app.FigureName;
                            case 'Max Compare Pairs, By Ref'
                                if app.AllOnSameAxisButton_2.Value
                                    ComparePairsByRefSameAxisMax(app, 2, i)
                                else
                                    ComparePairsByRefMax(app, 2, i)
                                end
                                sname = app.FigureName;
                            case 'Surf Plots, By Stim, IPG and PD'
                                SurfPlotByStimIPGandSPD(app, 2, i)
                                sname = app.FigureName;
                            case 'Average Surf, By Ref, IPG and PD'
                                if any(contains(app.NormBy.Items,'Anodic'))
                                    AverageSurfPlotByRefIPGandFPDAvC(app, 2, i)
                                else
                                    AverageSurfPlotByRefIPGandSPD(app, 2, i)
                                end
                                sname = app.FigureName;
                            case 'Compare Standard Across Time'
                                PlotStandardAcrossTime(app, 2, i)
                                sname = app.FigureName;
                            case 'Compare AvC Standards'
                                PlotAvCStandards(app, 2, i)
                                sname = app.FigureName;
                            case 'Plot All Standards'
                                PlotAllStandards(app, 2, i)
                                sname = app.FigureName;
                            case 'Norm data, By PD, IPG as X'
                                PlotNormByIPGJUSTAVG(app, 2, i)
                                sname = app.FigureName;
                            case 'Cycle Average'
                                PlotCycleAverage(app, 2, i)
                                sname = app.FigureName;
                            case 'Sphere Plot'
                                Plot3DMisalignment(app, 2, i)
                                sname = app.FigureName;
                            case 'Max Point Cloud, By Stim'
                                MaxValsByStim(app, 2, i)
                                sname = app.FigureName;
                            case 'Max Point Cloud, By Ref'
                                if app.PlotByCanalButton.Value
                                    MaxValsByRefByCanal(app, 2, i)
                                else
                                    MaxValsByRef(app, 2, i)
                                end
                                sname = app.FigureName;
                        end

                        if i == 1
                            sname = [sname,'_LEGEND'];
                        end
                        set(f, 'CreateFcn', 'set(gcbo,''Visible'',''on'')');
                        saveas(f,[app.FigureDirectoryText.Value,'\',sname,'.svg']);
                        saveas(f,[app.FigureDirectoryText.Value,'\',sname,'.jpg']);
                        frame = getframe(f);
                        im = frame2im(frame);
                        imwrite(im,[app.FigureDirectoryText.Value,'\',sname,'.png'],'png','transparency',f.Color)
                        saveas(f,[app.FigureDirectoryText.Value,'\',sname,'.fig']);
                        delete(f.Children)
                    end

                    app.SaveFigureandLegendButton.BackgroundColor = 'g';
                    pause(.3)
                    app.SaveFigureandLegendButton.BackgroundColor = [0.96,0.96,0.96];
                else
                    for i = 0:1
                        switch app.TabGroup.SelectedTab.Title
                            case 'By Stim, Amp As X'
                            case 'By Ref, Amp As X'
                            case 'By Ref and Canal, Amp as X'
                            case 'Compare Pairs, By Stim, Amp as X'
                            case 'Max Compare Pairs, By Stim'
                                if app.AllOnSameAxisButton_4.Value
                                    ComparePairsByStimSameAxisMax(app, 2, i)
                                else
                                    ComparePairsByStimMax(app, 2, i)
                                end
                                sname = app.FigureName;
                            case 'Compare Pairs, By Ref, Amp as X'
                            case 'Max Compare Pairs, By Ref'
                                if app.AllOnSameAxisButton_2.Value
                                    ComparePairsByRefSameAxisMax(app, 2, i)
                                else
                                    %                                     ComparePairsByRefMax(app, 2, i)
                                end
                                sname = app.FigureName;
                            case 'Surf Plots, By Stim, IPG and PD'
                            case 'Average Surf, By Ref, IPG and PD'
                            case 'Compare Standard Across Time'
                            case 'Plot All Standards'
                            case 'Norm data, By PD, IPG as X'
                            case 'Max vs Distance, By Stim'
                                MaxvsDistanceByStim(app, 2, i)
                                sname = app.FigureName;
                            case 'Max vs Distance, By Ref'
                                MaxvsDistanceByRef(app, 2, i)
                                sname = app.FigureName;
                            case 'Max Point Cloud, By Ref'
                                if app.PlotByCanalButton.Value
                                    MaxValsByRefByCanal(app, 2, i)
                                else
                                    MaxValsByRef(app, 2, i)
                                end
                                sname = app.FigureName;

                        end

                        if i == 1
                            sname = [sname,'_LEGEND'];
                        end
                        set(f, 'CreateFcn', 'set(gcbo,''Visible'',''on'')'); 
                        saveas(f,[app.FigureDirectoryText.Value,'\',sname,'.svg']);
                        saveas(f,[app.FigureDirectoryText.Value,'\',sname,'.jpg']);
                        frame = getframe(f);
                        im = frame2im(frame);
                        imwrite(im,[app.FigureDirectoryText.Value,'\',sname,'.png'],'png','transparency',f.Color)
                        saveas(f,[app.FigureDirectoryText.Value,'\',sname,'.fig']);
                        delete(f.Children)
                    end

                    app.SaveFigureandLegendButton.BackgroundColor = 'g';
                    pause(.3)
                    app.SaveFigureandLegendButton.BackgroundColor = [0.96,0.96,0.96];
                end
            else
                f = msgbox("Insufficient Data Selected to Plot! Please Choose Animal(s) and Eye(s)","Error","error");
                pause(1.5);
                delete(f);
            end

        end

        % Button pushed function: FigureDirectoryButton
        function FigureDirectoryButtonPushed(app, event)
            app.FigureDirectoryText.Value = uigetdir(path,'Choose directory for saved figures');
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            closePtID = [];
            distance = [];
            if ~isempty(app.UIFigure.CurrentObject)
                eb = contains(app.UIFigure.CurrentObject.Type,'errorbar');
                if isfield(app.UIFigure.CurrentObject,'Marker')
                    m = contains(app.UIFigure.CurrentObject.Marker,'x');
                else
                    m = ~1;
                end
            if  eb | m 
                answer = questdlg('Would you like to change the Facial Nerve Stim Status of the data point?', ...
                    'CycleParams File Options', ...
                    'Yes','No','Yes');
                % Handle response
                switch answer
                    case 'Yes'
                        toU = isprop(app.UIFigure.Children,'enable');
                        set(app.UIFigure.Children(toU),'Enable','off')
                        xp = app.UIFigure.CurrentAxes.CurrentPoint(2,1);
                        yp = app.UIFigure.CurrentAxes.CurrentPoint(2,2);
                        ebars = find(isgraphics(app.UIFigure.CurrentAxes.Children,'ErrorBar'));
                        for i = ebars'
                            [distanceT,IDT]=min([sqrt(((app.UIFigure.CurrentAxes.Children(i).XData-xp).^2)+((app.UIFigure.CurrentAxes.Children(i).YData-yp).^2))]);
                            if isempty(distance)
                                distance = distanceT;
                                closePtID = app.UIFigure.CurrentAxes.Children(i).UserData(IDT);
                            else
                                if distanceT<distance
                                    distance = distanceT;
                                    closePtID = app.UIFigure.CurrentAxes.Children(i).UserData(IDT);
                                end
                            end
                        end
                        app.params(closePtID).FacialNerve = ~app.params(closePtID).FacialNerve;
                        tempS = struct();
                        tempS = app.params;
                        save([app.CycleDirectoryText.Value,'\CycleParams.mat'],'tempS')
                        set(app.UIFigure.Children(toU),'Enable','on')
                        PlotSelectedButtonPushed(app, [])
                    case 'No'
                end
            end
            end
        end

        % Button pushed function: MergewithotherCycleParamsFileButton
        function MergewithotherCycleParamsFileButtonPushed(app, event)
            toU = isprop(app.UIFigure.Children,'enable');
            set(app.UIFigure.Children(toU),'Enable','off')
            [file,path] = uigetfile('*.mat','Select the CycleParam file to merge');
            saveDir = uigetdir(path,'Choose directory to save the merged CycleParams file');
            load([path,file])
            fA = fields(app.params);
            fB = fields(tempS);
            if length(fA) > length(fB)
                t = find(~ismember(fA,fB));
                for i = 1:length(t)
                    tempS(1).(fA{t(i)}) = [];
                end
                tempS = orderfields(tempS,app.params);
            elseif length(fA) < length(fB)
                t = find(~ismember(fB,fA));
                for i = 1:length(t)
                    app.params(1).(fB{t(i)}) = [];
                end
                app.params = orderfields(app.params,tempS);
            else
                t = find(~ismember(fA,fB));
                if ~isempty(t)
                    ttt = 1;
                else
                    tempS = orderfields(tempS,app.params);
                end
            end

            dSp2dVal = 0;

            if any([tempS.dSp2d]>0)
                app.ProgressBar.Title.String = ['Adjusting Normalization Indices'];
                drawnow
                ids = find([tempS.dSp2d]>0);
                for ii = ids
                    dS = tempS(ii).dSp2d;
                    dSp2dVal = dSp2dVal + 1;
                    tempS(ii).dSp2d = dSp2dVal;
                    for ii2 = ii:length(tempS)
                        if any([tempS(ii2).normInd]==dS)
                            tempS(ii2).normInd(tempS(ii2).normInd == dS) = dSp2dVal;
                        end
                    end
                    app.PBarObj.Position(3) = dSp2dVal/length(ids)*1000;
                    app.PBarTxt.String = [num2str(round(dSp2dVal/length(ids)*100)),'%'];
                    drawnow
                end
                ll = length(ids);
            end

            
            if any([app.params.dSp2d]>0)
                app.ProgressBar.Title.String = ['Adjusting Normalization Indices'];
                drawnow
                ids = find([app.params.dSp2d]>0);
                for ii = ids
                    dS = app.params(ii).dSp2d;
                    dSp2dVal = dSp2dVal + 1;
                    app.params(ii).dSp2d = dSp2dVal;
                    for ii2 = ii:length(app.params)
                        if any([app.params(ii2).normInd]==dS)
                            app.params(ii2).normInd(app.params(ii2).normInd == dS) = dSp2dVal;
                        end
                    end
                    app.PBarObj.Position(3) = (dSp2dVal-ll)/length(ids)*1000;
                    app.PBarTxt.String = [num2str(round((dSp2dVal-ll)/length(ids)*100)),'%'];
                    drawnow
                end
            end

            app.ProgressBar.Title.String = ['Waiting'];
            app.PBarObj.Position(3) = 0;
            app.PBarTxt.String = 'Waiting';
            drawnow

            app.params = [tempS app.params];
            dupInd = [];
            tp = struct('animal',{app.params.animal},'stim', {app.params.stim}, 'ref', {app.params.ref},...
                'p1d', {app.params.p1d}, 'ipg', {app.params.ipg},...
                'p2d', {app.params.p2d}, 'p1amp', {app.params.p1amp},...
                'p2amp', {app.params.p2amp}, 'dSp2d', {app.params.dSp2d});
            app.ProgressBar.Title.String = ['Checking For Duplicates'];
            app.PBarObj.Position(3) = 0*1000;
            app.PBarTxt.String = 'Waiting';
            drawnow
            for i = 1:length(app.params)
                app.PBarObj.Position(3) = i/length(app.params)*1000;
                app.PBarTxt.String = [num2str(round(i/length(app.params)*100)),'%'];
                drawnow
                for j = i:length(app.params)
                    if j~=i
                        if isequal(tp(i),tp(j))
                            if isempty(dupInd)
                                dupInd = [dupInd; i j];
                                answer = questdlg({'Duplicate data was detected. Choose which will be used.',['FIRST: ',app.params(i).name],['SECOND: ',app.params(j).name]}, ...
                                    'Duplicate Data', ...
                                    'First','Second','Second');
                                switch answer
                                    case 'First'
                                        app.params(i).TOUSE = 1;
                                        app.params(j).TOUSE = 0;
                                    case 'Second'
                                        app.params(i).TOUSE = 0;
                                        app.params(j).TOUSE = 1;
                                end
                                if isempty(answer)
                                    break
                                end
                            elseif ~all(any(ismember(dupInd,[i j])))
                                dupInd = [dupInd; i j];
                                answer = questdlg({'Duplicate data was detected. Choose which will be used.',['FIRST: ',app.params(i).name],['SECOND: ',app.params(j).name]}, ...
                                    'Duplicate Data', ...
                                    'First','Second','Second');
                                switch answer
                                    case 'First'
                                        app.params(i).TOUSE = 1;
                                        app.params(j).TOUSE = 0;
                                    case 'Second'
                                        app.params(i).TOUSE = 0;
                                        app.params(j).TOUSE = 1;
                                end
                                if isempty(answer)
                                    break
                                end
                            end
                        else
                            if ~any(any(ismember(dupInd,j)))
                                app.params(j).TOUSE = 1;
                            end
                        end
                    end
                end
            end

            app.ProgressBar.Title.String = ['Waiting'];
            app.PBarObj.Position(3) = 0*1000;
            app.PBarTxt.String = 'Waiting';
            drawnow
            tempS = struct();
            tempS = app.params;
            cd(saveDir)
            save([saveDir,'\CycleParams.mat'],'tempS')
            app.CycleDirectoryText.Value = saveDir;
            app.listing = dir(saveDir);
            app.ProcessButton.BackgroundColor = 'g';
            app.mergeFlg = 1;

            ProcessButtonPushed(app, event)

            app.mergeFlg = 0;
            toU = isprop(app.UIFigure.Children,'enable');
            set(app.UIFigure.Children(toU),'Enable','on')
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if ismember(selectedTab.Title,[{'Compare Pairs, By Stim, Amp as X'} {'Max Compare Pairs, By Stim'} {'Compare Pairs, By Ref, Amp as X'} {'Max Compare Pairs, By Ref'}])
                app.Options.Visible = 1;
                app.OptionTxt.Visible = 1;
            else
                app.Options.Visible = 0;
                app.OptionTxt.Visible = 0;
            end
        end

        % Value changed function: IncludeAverageButton, 
        % ...and 1 other component
        function IncludeAverageButtonValueChanged(app, event)

            switch app.TabGroup.SelectedTab.Title
                case 'Compare Pairs, By Stim, Amp as X'
                    app.IncludeAverageButton_2.Value = app.IncludeAverageButton.Value;
                    ComparePairsByStim(app, 1, 0)
                case 'Compare Pairs, By Ref, Amp as X'
                    app.IncludeAverageButton.Value = app.IncludeAverageButton_2.Value;
                    if app.AllOnSameAxisButton.Value
                        ComparePairsByRefSameAxis(app, 1, 0)
                    else
                        ComparePairsByRef(app, 1, 0)
                    end
            end
        end

        % Value changed function: DataCursorButton
        function DataCursorButtonValueChanged(app, event)
            value = app.DataCursorButton.Value;
            if value
                app.DataCursorButton.Text = 'Data Cursor On';
                app.DataCursorButton.BackgroundColor = 'g';
                datacursormode(app.UIFigure,'on')
            else
                app.DataCursorButton.Text = 'Data Cursor Off';
                app.DataCursorButton.BackgroundColor = 'r';
                datacursormode(app.UIFigure,'off')
            end
        end

        % Value changed function: PlottingStandardDeviationCheckBox
        function PlottingStandardDeviationCheckBoxValueChanged(app, event)
            value = app.PlottingStandardDeviationCheckBox.Value;
            if value
                app.PlottingStandardDeviationCheckBox.Text = 'Plotting Standard Error of Mean';
            else
                app.PlottingStandardDeviationCheckBox.Text = 'Plotting Standard Deviation';
            end
            PlotSelectedButtonPushed(app,[])
        end

        % Value changed function: MisalignmentButton
        function MisalignmentButtonValueChanged(app, event)
            value = app.MisalignmentButton.Value;
            if value
                app.MisalignmentButton.BackgroundColor = 'g';
            else
                app.MisalignmentButton.BackgroundColor = 'r';
            end
            PlotSelectedButtonPushed(app,[])
        end

        % Value changed function: EyeVButton
        function EyeVButtonValueChanged(app, event)
            value = app.EyeVButton.Value;
            if value
                app.EyeVButton.BackgroundColor = 'g';
            else
                app.EyeVButton.BackgroundColor = 'r';
            end
            PlotSelectedButtonPushed(app,[])
        end

        % Value changed function: PlottingNystagmusCorrectedDataCheckBox
        function PlottingNystagmusCorrectedDataCheckBoxValueChanged(app, event)
            value = app.PlottingNystagmusCorrectedDataCheckBox.Value;
            if value
                app.PlottingNystagmusCorrectedDataCheckBox.Text = {'Plotting Nystagmus'; 'Corrected Data'};
            else
                app.PlottingNystagmusCorrectedDataCheckBox.Text = {'Plotting'; 'Non-Corrected Data'};
            end
            PlotSelectedButtonPushed(app,[])
        end

        % Value changed function: AllOnSameAxisButton, 
        % ...and 1 other component
        function AllOnSameAxisButtonValueChanged(app, event)
            PlotSelectedButtonPushed(app,[])
        end

        % Button pushed function: ElectrodeMeasurementsButton
        function ElectrodeMeasurementsButtonPushed(app, event)
            if length(app.animal)>1
                txt = ['Which animal would you like to enter data for?'];
                answer = nbuttondlg(txt,[app.animal{:},{'All'}],'DefaultButton','All');
                if ismember({answer},'All')
                    an = app.animal;
                else
                    an = {answer};
                end
            else
                an = app.animal;
            end
            for an2Use = an
                dir = app.AnimalProperties.(an2Use{1}).RootDirectory;
                if isfile([dir,'ElectrodeLocationData.mat'])
                    load([dir,'ElectrodeLocationData.mat'],'LocationData')
                    app.AnimalProperties.(an2Use{1}).LocationData = LocationData;
                    answer = questdlg(['Would you like to edit the existing measurements for ',an2Use{1},'?'], ...
                        'Electrode Location Measurements', ...
                        'Yes','No','Yes');
                    switch answer
                        case 'Yes'
                            app.ElectrodePositions = [[LocationData.ElectrodeNumber]' [LocationData.ElectrodeDistance]'];
                            toU = isprop(app.UIFigure.Children,'enable');
                            set(app.UIFigure.Children(toU),'Enable','off')
                            app.EnterElectrodeLocationsApp = EnterElectrodeLocations(app,an2Use);
                            uiwait(app.EnterElectrodeLocationsApp.UIFigure)
                            if ~isempty(app.ElectrodePositions)
                                sz = size(app.ElectrodePositions);
                                for i = 1:sz(1)
                                    [~,name,~,~] = app.GetElecColorandNameByAnimal(an2Use{1},app.ElectrodePositions(i,1));
                                    LocationData(i).ElectrodeNumber = app.ElectrodePositions(i,1);
                                    LocationData(i).ElectrodeDistance = app.ElectrodePositions(i,2);
                                    LocationData(i).ElectrodeName = name{1};
                                end
                                save([dir,'ElectrodeLocationData'],'LocationData')
                                app.AnimalProperties.(an2Use{1}).LocationData = LocationData;
                            end
                            set(app.UIFigure.Children(toU),'Enable','on')
                        case 'No'
                            app.AnimalProperties.(an2Use{1}).LocationData = LocationData;
                    end
                else
                    %%%% Make AnimalProperties into loadable initialization
                    %%%% file that saves electrode location data
                    if isfield(app.AnimalProperties.(an2Use{1}),'LocationData')
                        app.AnimalProperties.(an2Use{1}) = rmfield(app.AnimalProperties.(an2Use{1}),'LocationData');
                    end
                    toU = isprop(app.UIFigure.Children,'enable');
                    set(app.UIFigure.Children(toU),'Enable','off')
                    app.EnterElectrodeLocationsApp = EnterElectrodeLocations(app,an2Use);
                    uiwait(app.EnterElectrodeLocationsApp.UIFigure)
                    if ~isempty(app.ElectrodePositions)
                        LocationData.ElectrodeNumber = [];
                        LocationData.ElectrodeName = [];
                        LocationData.ElectrodeDistance = [];
                        sz = size(app.ElectrodePositions);
                        for i = 1:sz(1)
                            [~,name,~,~] = app.GetElecColorandNameByAnimal(an2Use{1},app.ElectrodePositions(i,1));
                            LocationData(i).ElectrodeNumber = app.ElectrodePositions(i,1);
                            LocationData(i).ElectrodeDistance = app.ElectrodePositions(i,2);
                            LocationData(i).ElectrodeName = name{1};
                        end
                        save([dir,'ElectrodeLocationData.mat'],'LocationData')
                        app.AnimalProperties.(an2Use{1}).LocationData = LocationData;
                    end
                    set(app.UIFigure.Children(toU),'Enable','on')
                end
                app.ElectrodePositions = [];
            end
            avail = 0;
            for a = app.animal
                if isfield(app.AnimalProperties.(a{1}),'LocationData')
                    if ~isempty([app.AnimalProperties.(a{1}).LocationData.ElectrodeDistance])
                        avail = avail +1;
                    end
                end
            end
            if avail == 0
                app.ElectrodeMeasurementsButton.BackgroundColor = 'r';
            elseif avail < length(app.animal)
                app.ElectrodeMeasurementsButton.BackgroundColor = 'y';
            else
                app.ElectrodeMeasurementsButton.BackgroundColor = 'g';
            end


        end

        % Value changed function: TransparencyThreshold
        function TransparencyThresholdValueChanged(app, event)
            PlotSelectedButtonPushed(app,[])
        end

        % Button pushed function: QuickPhaseRemoval
        function QuickPhaseRemovalPushed(app, event)
            app.PullCombInfo()
            loc = app.CycleAverageTab;
            marg_h = [0.05 0.075];
            marg_w = [.1 .015];
            uStims = unique(app.Stims);
            s = OrderElectrodesByAnimal(app, app.AnimalsandEyesToUse{1}, uStims);
            uAns = unique(app.AnimalsandEyesToUse(:,1),'stable');
            uStims = s;
            uRefs = unique(app.Refs);
            up1d = unique(app.P1Ds);
            up2d = unique(app.P2Ds);
            up1a = unique(app.P1As);
            up2a = unique(app.P2As);
            uipg = unique(app.IPGs);
            ids = [];
            ldgLine = [];
            ldgNames = [];
            magPlots = [];
            misalignPlots = [];
            allTwitch = [];

            maxY = 0;
            minY = 0;

            uStimsUsed = [];
            uRefsUsed = [];
            ids2T = GetDataInds(app, uAns{1}, uStims, uRefs, app.P1Ds, app.P2Ds, app.P1As, app.P2As, app.IPGs, 1, -1);
            
            stpFlg = 0;
            if isempty(ids2T)
                f = msgbox("Insufficient Data Selected to Plot!","Error","error");
                pause(1.5);
                delete(f);
            else
                clearTabs(app);
                magPlot = subtightplot(1,1,1,[0.025 0.000000000005],marg_h,marg_w,'Parent', loc);
                magPlot.ButtonDownFcn = @(source,event) app.changeAXLim;
                ip = 1;
                for i = ids2T
                    app.ProgressBar.Title.String = ['Stim',num2str(uStims),'Ref',num2str(uRefs),' ',num2str(app.P1As(ip)),'uA'];
                    app.PBarObj.Position(3) = (ip/length(ids2T))*1000;
                    app.PBarTxt.String = [num2str(round(ip/length(ids2T)*100)),'%'];
                    if app.AnimalsandEyesToUse{1,3}
                        eyeSel = 'LEye';
                        t = 'timeL';
                        if app.PlottingNystagmusCorrectedDataCheckBox.Value
                            toUseMMag = 'cyclesLRZXYL_NystagCorr';
                        else
                            toUseMMag = 'cyclesLRZXYL';
                        end
                    else
                        eyeSel = 'REye';
                        t = 'timeR';
                        if app.PlottingNystagmusCorrectedDataCheckBox.Value
                            toUseMMag = 'cyclesLRZXYR_NystagCorr';
                        else
                            toUseMMag = 'cyclesLRZXYR';
                        end
                    end
                    sz = size(app.params(i).(toUseMMag){1});
                    LARPF = [];
                    RALPF = [];
                    ZF = [];
                    XF = [];
                    YF = [];
                    for j = 1:sz(1)
                        t = 1:sz(2);
                        trig = [ones(1,250) zeros(1,sz(2)-250)];
                        hold(magPlot,'on');
                        magPlot.Title.String = [num2str(j),' out of ',num2str(sz(1)),' cycles'];
                        stairs(magPlot,t,trig,'color','k','LineWidth',2);
                        plot(magPlot,t,app.params(i).(toUseMMag){1}(j,:),'color',app.colorV.l_l,'LineWidth',4);
                        plot(magPlot,t,app.params(i).(toUseMMag){2}(j,:),'color',app.colorV.l_r,'LineWidth',4);
                        plot(magPlot,t,app.params(i).(toUseMMag){3}(j,:),'color',app.colorV.l_z,'LineWidth',4);
                        hold(magPlot,'off');
                        grp = [max(app.params(i).(toUseMMag){1}(j,1:250))+10 max(app.params(i).(toUseMMag){2}(j,1:250))+10 max(app.params(i).(toUseMMag){3}(j,1:250))+10 min(app.params(i).(toUseMMag){1}(j,1:250))-10 min(app.params(i).(toUseMMag){2}(j,1:250))-10 min(app.params(i).(toUseMMag){3}(j,1:250))-10 10 -10];
                        magPlot.YLim = [min(grp) max(grp)];
                        magPlot.XLim = [-20 sz(2)+40];

                        LARP = app.params(i).(toUseMMag){1}(j,:);
                        RALP = app.params(i).(toUseMMag){2}(j,:);
                        Z = app.params(i).(toUseMMag){3}(j,:);
                        X = app.params(i).(toUseMMag){4}(j,:);
                        Y = app.params(i).(toUseMMag){5}(j,:);

                        answer = questdlg('Would you like to remove the quick phases?','QPR','Yes','No','Stop','Yes');
                        switch answer
                            case 'Yes'
                                go = 1;
                                k1 = [];
                                k2 = [];
                                while go
                                    if ~isempty(k1)
                                        answer = questdlg('Would you like select another pair of points?','QPR','Yes','No','No');
                                    else
                                        answer = 'Yes';
                                    end
                                    switch answer
                                        case 'Yes'
                                            uiwait(msgbox('Click ''OK'' when you are ready to choose the first data point','Ang. Vel QPR'));
                                            [x, y] = selectDataPoints(app, magPlot);
                                             if x < length(LARP)
                                                LARP(1) = 0;
                                                RALP(1) = 0;
                                                Z(1) = 0;
                                                X(1) = 0;
                                                Y(1) = 0;
                                            end
                                            [~,s]=min(abs(t-x));
                                            uiwait(msgbox('Click ''OK'' when you are ready to choose the second data point','Ang. Vel QPR'));
                                            [x, y] = selectDataPoints(app, magPlot);
                                            if x > length(LARP)
                                                LARP(end) = 0;
                                                RALP(end) = 0;
                                                Z(end) = 0;
                                                X(end) = 0;
                                                Y(end) = 0;
                                            end
                                            [~,s2]=min(abs(t-x));

                                            k1 = s;
                                            k2 = s2;
                                            LARP(k1:k2)=spline([k1 k2],[0 LARP(k1) LARP(k2) 0], k1:k2);
                                            RALP(k1:k2)=spline([k1 k2],[0 RALP(k1) RALP(k2) 0], k1:k2);
                                            Z(k1:k2)=spline([k1 k2],[0 Z(k1) Z(k2) 0], k1:k2);
                                            X(k1:k2)=spline([k1 k2],[0 X(k1) X(k2) 0], k1:k2);
                                            Y(k1:k2)=spline([k1 k2],[0 Y(k1) Y(k2) 0], k1:k2);
                                            %                         cla(magPlot)
                                            hold(magPlot,'on');
                                            patchline(t(k1:k2),LARP(k1:k2),'Parent',magPlot,'linestyle','-','edgecolor',app.colorV.l_l,'linewidth',4,'edgealpha',.4);
                                            patchline(t(k1:k2),RALP(k1:k2),'Parent',magPlot,'linestyle','-','edgecolor',app.colorV.l_r,'linewidth',4,'edgealpha',.4);
                                            patchline(t(k1:k2),Z(k1:k2),'Parent',magPlot,'linestyle','-','edgecolor',app.colorV.l_z,'linewidth',4,'edgealpha',.4);
                                            hold(magPlot,'off');
                                            pause(1)

                                        case 'No'
                                            go = 0;
                                    end
                                end
                            case 'No'
                            case 'Stop'
                                stpFlg = 1;
                                break

                        end

                        if ~stpFlg
                            LARPF = [LARPF; LARP];
                            RALPF = [RALPF; RALP];
                            ZF = [ZF; Z];
                            XF = [XF; X];
                            YF = [YF; Y];
                            cla(magPlot)
                        end
                    end
                    if ~stpFlg
                        newField = [toUseMMag,'_QPR'];
                        app.params(i).(newField) = [{LARPF} {RALPF} {ZF} {XF} {YF}];
                        ip = ip +1;
                    else
                        break
                    end

                end

                app.ProgressBar.Title.String = ['Waiting'];
                app.PBarObj.FaceColor = 'g';
                app.PBarObj.Position(3) = 0*1000;
                app.PBarTxt.String = ['Waiting'];
                drawnow
                clearTabs(app);
            end
        end

        % Button pushed function: SavecycleParmsDataChangesButton
        function SavecycleParmsDataChangesButtonPushed(app, event)
            toU = isprop(app.UIFigure.Children,'enable');
            set(app.UIFigure.Children(toU),'Enable','off')
            app.ProgressBar.Title.String = ['Saving cycleParams Data'];
            app.PBarObj.Position(3) = 1*1000;
            app.PBarObj.FaceColor = 'y';
            app.PBarTxt.String = 'Waiting';
            drawnow
            tempS = struct();
            tempS = app.params;
            cd(app.cycDir)
            save([app.cycDir,'\CycleParams.mat'],'tempS')

            app.ProgressBar.Title.String = ['Waiting'];
            app.PBarObj.FaceColor = 'g';
            app.PBarObj.Position(3) = 0*1000;
            drawnow
            toU = isprop(app.UIFigure.Children,'enable');
            set(app.UIFigure.Children(toU),'Enable','on')
        end

        % Value changed function: NoParameterizationCheckBox
        function NoParameterizationCheckBoxValueChanged(app, event)
            value = app.NoParameterizationCheckBox.Value;
            if value
                app.NoParameterizationCheckBox.Text = 'Plotting Parameterization';
            else
                app.NoParameterizationCheckBox.Text = 'No Parameterization';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.ProgressBar = uiaxes(app.UIFigure);
            title(app.ProgressBar, 'Waiting')
            app.ProgressBar.XLim = [0 1000];
            app.ProgressBar.XTick = [];
            app.ProgressBar.YTick = [];
            app.ProgressBar.Tag = 'ProgressBar';
            app.CycleDirectoryText = uieditfield(app.UIFigure, 'text');
            app.CycleDirectoryText.Tag = 'CycleDirectoryText';
            app.ConsolidateandOpenCycleAvergaeDataLabel = uilabel(app.UIFigure);
            app.ConsolidateandOpenCycleAvergaeDataLabel.Text = 'Consolidate and Open Cycle Avergae Data';
            app.LarpCombs = uilistbox(app.UIFigure);
            app.LarpCombs.Items = {'Listbox'};
            app.LarpCombs.Multiselect = 'on';
            app.LarpCombs.Tag = 'LarpCombs';
            app.LarpCombs.Value = {'Listbox'};
            app.text4 = uilabel(app.UIFigure);
            app.text4.Tag = 'text4';
            app.text4.Text = 'LARP (Stim-Ref)';
            app.RalpCombs = uilistbox(app.UIFigure);
            app.RalpCombs.Items = {'Listbox'};
            app.RalpCombs.Multiselect = 'on';
            app.RalpCombs.Tag = 'RalpCombs';
            app.RalpCombs.Value = {'Listbox'};
            app.text4_2 = uilabel(app.UIFigure);
            app.text4_2.Tag = 'text4';
            app.text4_2.Text = 'RALP (Stim-Ref)';
            app.LhrhCombs = uilistbox(app.UIFigure);
            app.LhrhCombs.Items = {'Listbox'};
            app.LhrhCombs.Multiselect = 'on';
            app.LhrhCombs.Tag = 'LhrhCombs';
            app.LhrhCombs.Value = {'Listbox'};
            app.text4_3 = uilabel(app.UIFigure);
            app.text4_3.Tag = 'text4';
            app.text4_3.Text = 'LHRH (Stim-Ref)';
            app.P1D = uilistbox(app.UIFigure);
            app.P1D.Items = {'Listbox'};
            app.P1D.Multiselect = 'on';
            app.P1D.Tag = 'P1D';
            app.P1D.Value = {'Listbox'};
            app.text4_4 = uilabel(app.UIFigure);
            app.text4_4.Tag = 'text4';
            app.text4_4.Text = 'Phase 1 Duration';
            app.P2D = uilistbox(app.UIFigure);
            app.P2D.Items = {'Listbox'};
            app.P2D.Multiselect = 'on';
            app.P2D.Tag = 'P2D';
            app.P2D.Value = {'Listbox'};
            app.text4_5 = uilabel(app.UIFigure);
            app.text4_5.Tag = 'text4';
            app.text4_5.Text = 'Phase 2 Duration';
            app.P1A = uilistbox(app.UIFigure);
            app.P1A.Items = {'Listbox'};
            app.P1A.Multiselect = 'on';
            app.P1A.Tag = 'P1A';
            app.P1A.Value = {'Listbox'};
            app.text4_6 = uilabel(app.UIFigure);
            app.text4_6.Tag = 'text4';
            app.text4_6.Text = 'Phase 1 Amplitude';
            app.P2A = uilistbox(app.UIFigure);
            app.P2A.Items = {'Listbox'};
            app.P2A.Multiselect = 'on';
            app.P2A.Tag = 'P2A';
            app.P2A.Value = {'Listbox'};
            app.text4_7 = uilabel(app.UIFigure);
            app.text4_7.Tag = 'text4';
            app.text4_7.Text = 'Phase 2 Amplitude';
            app.IPG = uilistbox(app.UIFigure);
            app.IPG.Items = {'Listbox'};
            app.IPG.Multiselect = 'on';
            app.IPG.Tag = 'IPG';
            app.IPG.Value = {'Listbox'};
            app.text4_8 = uilabel(app.UIFigure);
            app.text4_8.Tag = 'text4';
            app.text4_8.Text = 'IPG';
            app.NormBy = uilistbox(app.UIFigure);
            app.NormBy.Items = {'Listbox'};
            app.NormBy.Multiselect = 'on';
            app.NormBy.Tag = 'NormBy';
            app.NormBy.Value = {'Listbox'};
            app.text4_9 = uilabel(app.UIFigure);
            app.text4_9.Tag = 'text4';
            app.text4_9.Text = 'Normalize By:';
            app.FigureDirectoryText = uieditfield(app.UIFigure, 'text');
            app.FigureDirectoryText.Tag = 'CycleDirectoryText';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.CycleParamsDirectoryButton = uibutton(app.UIFigure, 'push');
            app.CycleParamsDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CycleParamsDirectoryButtonPushed, true);
            app.CycleParamsDirectoryButton.Tag = 'CycleDirectory';
            app.CycleParamsDirectoryButton.Text = {'CycleParams'; 'Directory'};
            app.FigureDirectoryButton = uibutton(app.UIFigure, 'push');
            app.FigureDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @FigureDirectoryButtonPushed, true);
            app.FigureDirectoryButton.Tag = 'FigureDirectory';
            app.FigureDirectoryButton.Text = 'Figure Directory';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ByStimAmpX = uitab(app.TabGroup);
            app.ByStimAmpX.Title = 'By Stim, Amp As X';
            app.ByRefAmpX = uitab(app.TabGroup);
            app.ByRefAmpX.Title = 'By Ref, Amp As X';
            app.ByRefandCanalAmpasXTab = uitab(app.TabGroup);
            app.ByRefandCanalAmpasXTab.Title = 'By Ref and Canal, Amp as X';
            app.ComparePairsByStimAmpasXTab = uitab(app.TabGroup);
            app.ComparePairsByStimAmpasXTab.Title = 'Compare Pairs, By Stim, Amp as X';
            app.IncludeAverageButton = uibutton(app.ComparePairsByStimAmpasXTab, 'state');
            app.IncludeAverageButton.ValueChangedFcn = createCallbackFcn(app, @IncludeAverageButtonValueChanged, true);
            app.IncludeAverageButton.Text = 'Include Average';
            app.AllOnSameAxisButton_3 = uibutton(app.ComparePairsByStimAmpasXTab, 'state');
            app.AllOnSameAxisButton_3.Text = 'All On Same Axis';
            app.MaxComparePairsByStimTab = uitab(app.TabGroup);
            app.MaxComparePairsByStimTab.Title = 'Max Compare Pairs, By Stim';
            app.AllOnSameAxisButton_4 = uibutton(app.MaxComparePairsByStimTab, 'state');
            app.AllOnSameAxisButton_4.Text = 'All On Same Axis';
            app.ComparePairsByRefAmpasXTab = uitab(app.TabGroup);
            app.ComparePairsByRefAmpasXTab.Title = 'Compare Pairs, By Ref, Amp as X';
            app.IncludeAverageButton_2 = uibutton(app.ComparePairsByRefAmpasXTab, 'state');
            app.IncludeAverageButton_2.ValueChangedFcn = createCallbackFcn(app, @IncludeAverageButtonValueChanged, true);
            app.IncludeAverageButton_2.Text = 'Include Average';
            app.AllOnSameAxisButton = uibutton(app.ComparePairsByRefAmpasXTab, 'state');
            app.AllOnSameAxisButton.ValueChangedFcn = createCallbackFcn(app, @AllOnSameAxisButtonValueChanged, true);
            app.AllOnSameAxisButton.Text = 'All On Same Axis';
            app.MaxComparePairsByRefTab = uitab(app.TabGroup);
            app.MaxComparePairsByRefTab.Title = 'Max Compare Pairs, By Ref';
            app.AllOnSameAxisButton_2 = uibutton(app.MaxComparePairsByRefTab, 'state');
            app.AllOnSameAxisButton_2.ValueChangedFcn = createCallbackFcn(app, @AllOnSameAxisButtonValueChanged, true);
            app.AllOnSameAxisButton_2.Text = 'All On Same Axis';
            app.SurfPlotsByStimIPGandPDTab = uitab(app.TabGroup);
            app.SurfPlotsByStimIPGandPDTab.Title = 'Surf Plots, By Stim, IPG and PD';
            app.AverageSurfByRefIPGandPDTab = uitab(app.TabGroup);
            app.AverageSurfByRefIPGandPDTab.Title = 'Average Surf, By Ref, IPG and PD';
            app.EyeVButton = uibutton(app.AverageSurfByRefIPGandPDTab, 'state');
            app.EyeVButton.ValueChangedFcn = createCallbackFcn(app, @EyeVButtonValueChanged, true);
            app.EyeVButton.Text = 'EyeV';
            app.EyeVButton.Value = true;
            app.MisalignmentButton = uibutton(app.AverageSurfByRefIPGandPDTab, 'state');
            app.MisalignmentButton.ValueChangedFcn = createCallbackFcn(app, @MisalignmentButtonValueChanged, true);
            app.MisalignmentButton.Text = 'Misalignment';
            app.MisalignmentButton.Value = true;
            app.CompareStandardAcrossTimeTab = uitab(app.TabGroup);
            app.CompareStandardAcrossTimeTab.Title = 'Compare Standard Across Time';
            app.CompareAvCStandardsTab = uitab(app.TabGroup);
            app.CompareAvCStandardsTab.Title = 'Compare AvC Standards';
            app.AllStandards = uitab(app.TabGroup);
            app.AllStandards.Title = 'Plot All Standards';
            app.NormIPGasX = uitab(app.TabGroup);
            app.NormIPGasX.Title = 'Norm data, By PD, IPG as X';
            app.CycleAverageTab = uitab(app.TabGroup);
            app.CycleAverageTab.Title = 'Cycle Average';
            app.QuickPhaseRemoval = uibutton(app.CycleAverageTab, 'push');
            app.QuickPhaseRemoval.ButtonPushedFcn = createCallbackFcn(app, @QuickPhaseRemovalPushed, true);
            app.QuickPhaseRemoval.Text = 'Remove Quick Phases';
            app.SavecycleParmsDataChangesButton = uibutton(app.CycleAverageTab, 'push');
            app.SavecycleParmsDataChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SavecycleParmsDataChangesButtonPushed, true);
            app.SavecycleParmsDataChangesButton.Text = 'Save cycleParms Data Changes';
            app.SpherePlotTab = uitab(app.TabGroup);
            app.SpherePlotTab.Title = 'Sphere Plot';
            app.ViewOptionsLabel = uilabel(app.SpherePlotTab);
            app.ViewOptionsLabel.Text = 'View Options:';
            app.ViewOptionsDropDown = uidropdown(app.SpherePlotTab);
            app.ViewOptionsDropDown.Items = {'Main View', 'Top View'};
            app.ViewOptionsDropDown.Value = 'Main View';
            app.MaxvsDistanceByStimTab = uitab(app.TabGroup);
            app.MaxvsDistanceByStimTab.Title = 'Max vs Distance, By Stim';
            app.NoParameterizationCheckBox = uicheckbox(app.MaxvsDistanceByStimTab);
            app.NoParameterizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoParameterizationCheckBoxValueChanged, true);
            app.NoParameterizationCheckBox.Text = 'No Parameterization';
            app.MaxvsDistanceByRefTab = uitab(app.TabGroup);
            app.MaxvsDistanceByRefTab.Title = 'Max vs Distance, By Ref';
            app.MaxPointCloudByStimTab = uitab(app.TabGroup);
            app.MaxPointCloudByStimTab.Title = 'Max Point Cloud, By Stim';
            app.MaxPointCloudByRefTab = uitab(app.TabGroup);
            app.MaxPointCloudByRefTab.Title = 'Max Point Cloud, By Ref';
            app.PlotByCanalButton = uibutton(app.MaxPointCloudByRefTab, 'state');
            app.PlotByCanalButton.Text = 'Plot By Canal';
            app.SvMvDStimMeanDiff = uitab(app.TabGroup);
            app.SvMvDStimMeanDiff.Title = 'SvMvD Stim, Mean Diff';
            app.PlotSelectedButton = uibutton(app.UIFigure, 'push');
            app.PlotSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectedButtonPushed, true);
            app.PlotSelectedButton.Text = 'Plot Selected';
            app.SaveFigureandLegendButton = uibutton(app.UIFigure, 'push');
            app.SaveFigureandLegendButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureandLegendButtonPushed, true);
            app.SaveFigureandLegendButton.Text = 'Save Figure and Legend';
            app.MergewithotherCycleParamsFileButton = uibutton(app.UIFigure, 'push');
            app.MergewithotherCycleParamsFileButton.ButtonPushedFcn = createCallbackFcn(app, @MergewithotherCycleParamsFileButtonPushed, true);
            app.MergewithotherCycleParamsFileButton.Text = {'Merge with other'; 'CycleParams File'};
            app.Options = uilistbox(app.UIFigure);
            app.Options.Items = {'Listbox'};
            app.Options.Multiselect = 'on';
            app.Options.Tag = 'NormBy';
            app.Options.Value = {'Listbox'};
            app.OptionTxt = uilabel(app.UIFigure);
            app.OptionTxt.Tag = 'text4';
            app.OptionTxt.Text = 'Other Options';
            app.DataCursorButton = uibutton(app.UIFigure, 'state');
            app.DataCursorButton.ValueChangedFcn = createCallbackFcn(app, @DataCursorButtonValueChanged, true);
            app.DataCursorButton.Text = 'Data Cursor Off';
            app.PlottingStandardDeviationCheckBox = uicheckbox(app.UIFigure);
            app.PlottingStandardDeviationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlottingStandardDeviationCheckBoxValueChanged, true);
            app.PlottingStandardDeviationCheckBox.Text = 'Plotting Standard Deviation';
            app.PlottingNystagmusCorrectedDataCheckBox = uicheckbox(app.UIFigure);
            app.PlottingNystagmusCorrectedDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlottingNystagmusCorrectedDataCheckBoxValueChanged, true);
            app.PlottingNystagmusCorrectedDataCheckBox.Text = {'Plotting Nystagmus'; 'Corrected Data'};
            app.PlottingNystagmusCorrectedDataCheckBox.Value = true;
            app.ElectrodeMeasurementsButton = uibutton(app.UIFigure, 'push');
            app.ElectrodeMeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @ElectrodeMeasurementsButtonPushed, true);
            app.ElectrodeMeasurementsButton.Text = 'Electrode Measurements';
            app.text4_10 = uilabel(app.UIFigure);
            app.text4_10.Tag = 'text4';
            app.text4_10.Text = {'Animal'; 'and Eyes'};
            app.DataTransparency = uicheckbox(app.UIFigure);
            app.DataTransparency.Text = {'Change Transparency of '; 'Data Using the Threshold:'};
            app.degsVORMagnitudeEditFieldLabel = uilabel(app.UIFigure);
            app.degsVORMagnitudeEditFieldLabel.Text = 'deg/s VOR Magnitude';
            app.TransparencyThreshold = uieditfield(app.UIFigure, 'numeric');
            app.TransparencyThreshold.Limits = [0 Inf];
            app.TransparencyThreshold.ValueChangedFcn = createCallbackFcn(app, @TransparencyThresholdValueChanged, true);
            app.TransparencyThreshold.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessAndPlotFromCycles

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end